classdef FeatureEngineeringMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FeatureEngineeringMenuUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        CancelButton                    matlab.ui.control.Button
        DoneButton                      matlab.ui.control.Button
        PleaseselecttheoptionalengineeredfeaturestouseLabel  matlab.ui.control.Label
        feature_tree                    matlab.ui.container.CheckBoxTree
        AlloptionalfeaturesNode         matlab.ui.container.TreeNode
        AllstaticfeaturesNode           matlab.ui.container.TreeNode
        StepsizeNode                    matlab.ui.container.TreeNode
        SmoothedstepsizeNode            matlab.ui.container.TreeNode
        TimestepfrompreviouslocalisationNode  matlab.ui.container.TreeNode
        TimesincestartofexperimentNode  matlab.ui.container.TreeNode
        TimefromstartoftrackNode        matlab.ui.container.TreeNode
        SetexperimentreferencetimepointsNode  matlab.ui.container.TreeNode
        CurrentdistancefromstartoftrackNode  matlab.ui.container.TreeNode
        CumulativedistancetravelledNode  matlab.ui.container.TreeNode
        TimeshiftedstepsizesNode        matlab.ui.container.TreeNode
        DistancetocellmembraneNode      matlab.ui.container.TreeNode
        DistancetonearestcellpoleNode   matlab.ui.container.TreeNode
        SpotsizeNode                    matlab.ui.container.TreeNode
        SpotareaNode                    matlab.ui.container.TreeNode
        StepanglerelativetopreviousstepNode  matlab.ui.container.TreeNode
        StepanglerelativetofieldofviewNode  matlab.ui.container.TreeNode
        StepanglerelativetocellmajoraxisNode  matlab.ui.container.TreeNode
        LocaldiffusioncoefficientNode   matlab.ui.container.TreeNode
        ProximitytoendsoftrackNode      matlab.ui.container.TreeNode
        RollingwindowfeaturesNode       matlab.ui.container.TreeNode
        LocalchangeindiffusioncoefficientNode  matlab.ui.container.TreeNode
        LocalchangeinmeanstepsizeNode   matlab.ui.container.TreeNode
        LocalchangeinstandarddeviationofstepsizesNode  matlab.ui.container.TreeNode
        LocalchangeinstandarddeviationofpositionsNode  matlab.ui.container.TreeNode
        LocalchangeindispersionofpositionsNode  matlab.ui.container.TreeNode
        LocaldisplacementincentroidoflocalisationsNode  matlab.ui.container.TreeNode
    end

    
    properties (Access = private)
        mainapp;            %handle for the main app, enabling access to its data
        selected_features;  %keeps hold user choices of which features to engineer
    end
    
    methods (Access = private)
        
        function [] = expandAllNodes(app, node)
            %recursive function to expand all nodes in the feature menu tree
            expand(node);
            if ~isempty(node.Children)
                for ii = 1:numel(node.Children)
                    %expand recursively expand child nodes
                    app.expandAllNodes(node.Children(ii));
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
            app.expandAllNodes(app.feature_tree);   %pass root node of tree to expand it, and all children of the root node
            
            %move the prompt to be in the centre of wherever the main GUI is
            app.FeatureEngineeringMenuUIFigure.Position(1) = (app.mainapp.InVivoKineticsUIFigure.Position(1) + (app.mainapp.InVivoKineticsUIFigure.Position(3)/2)) - ((app.FeatureEngineeringMenuUIFigure.Position(3)/2));
            app.FeatureEngineeringMenuUIFigure.Position(2) = (app.mainapp.InVivoKineticsUIFigure.Position(2) + (app.mainapp.InVivoKineticsUIFigure.Position(4)/2)) - ((app.FeatureEngineeringMenuUIFigure.Position(4)/2));
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            %retrieve all checked nodes, and extract text of all checked nodes
            checked_nodes = app.feature_tree.CheckedNodes;
            app.selected_features = arrayfun(@(node) node.Text, checked_nodes, 'UniformOutput', false);
            
            %write selected_features into main app's app.movie_data.state substruct
            if isvalid(app.mainapp)
                app.mainapp.movie_data.state.selected_features = app.selected_features;
            end
            
            %close 
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            %return an empty cell array if user presses cancel, regardless of any boxes that may have been checked
            app.mainapp.movie_data.state.selected_features = {};
        
            %close the feature selection menu
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FeatureEngineeringMenuUIFigure = uifigure('Visible', 'off');
            app.FeatureEngineeringMenuUIFigure.Name = 'Feature Engineering Menu';
            app.GridLayout = uigridlayout(app.FeatureEngineeringMenuUIFigure);
            app.feature_tree = uitree(app.GridLayout, 'checkbox');
            app.AlloptionalfeaturesNode = uitreenode(app.feature_tree);
            app.AlloptionalfeaturesNode.Text = 'All optional features';
            app.AllstaticfeaturesNode = uitreenode(app.AlloptionalfeaturesNode);
            app.AllstaticfeaturesNode.Text = 'All static features';
            app.StepsizeNode = uitreenode(app.AllstaticfeaturesNode);
            app.StepsizeNode.Text = 'Step size';
            app.SmoothedstepsizeNode = uitreenode(app.AllstaticfeaturesNode);
            app.SmoothedstepsizeNode.Text = 'Smoothed step size';
            app.TimestepfrompreviouslocalisationNode = uitreenode(app.AllstaticfeaturesNode);
            app.TimestepfrompreviouslocalisationNode.Text = 'Time step from previous localisation';
            app.TimesincestartofexperimentNode = uitreenode(app.AllstaticfeaturesNode);
            app.TimesincestartofexperimentNode.Text = 'Time since start of experiment';
            app.TimefromstartoftrackNode = uitreenode(app.AllstaticfeaturesNode);
            app.TimefromstartoftrackNode.Text = 'Time from start of track';
            app.SetexperimentreferencetimepointsNode = uitreenode(app.AllstaticfeaturesNode);
            app.SetexperimentreferencetimepointsNode.Text = 'Set experiment reference timepoints';
            app.CurrentdistancefromstartoftrackNode = uitreenode(app.AllstaticfeaturesNode);
            app.CurrentdistancefromstartoftrackNode.Text = 'Current distance from start of track';
            app.CumulativedistancetravelledNode = uitreenode(app.AllstaticfeaturesNode);
            app.CumulativedistancetravelledNode.Text = 'Cumulative distance travelled';
            app.TimeshiftedstepsizesNode = uitreenode(app.AllstaticfeaturesNode);
            app.TimeshiftedstepsizesNode.Text = 'Time shifted step sizes';
            app.DistancetocellmembraneNode = uitreenode(app.AllstaticfeaturesNode);
            app.DistancetocellmembraneNode.Text = 'Distance to cell membrane';
            app.DistancetonearestcellpoleNode = uitreenode(app.AllstaticfeaturesNode);
            app.DistancetonearestcellpoleNode.Text = 'Distance to nearest cell pole';
            app.SpotsizeNode = uitreenode(app.AllstaticfeaturesNode);
            app.SpotsizeNode.Text = 'Spot size';
            app.SpotareaNode = uitreenode(app.AllstaticfeaturesNode);
            app.SpotareaNode.Text = 'Spot area';
            app.StepanglerelativetopreviousstepNode = uitreenode(app.AllstaticfeaturesNode);
            app.StepanglerelativetopreviousstepNode.Text = 'Step angle relative to previous step';
            app.StepanglerelativetofieldofviewNode = uitreenode(app.AllstaticfeaturesNode);
            app.StepanglerelativetofieldofviewNode.Text = 'Step angle relative to field of view';
            app.StepanglerelativetocellmajoraxisNode = uitreenode(app.AllstaticfeaturesNode);
            app.StepanglerelativetocellmajoraxisNode.Text = 'Step angle relative to cell major axis';
            app.LocaldiffusioncoefficientNode = uitreenode(app.AllstaticfeaturesNode);
            app.LocaldiffusioncoefficientNode.Text = 'Local diffusion coefficient';
            app.ProximitytoendsoftrackNode = uitreenode(app.AllstaticfeaturesNode);
            app.ProximitytoendsoftrackNode.Text = 'Proximity to ends of track';
            app.RollingwindowfeaturesNode = uitreenode(app.AlloptionalfeaturesNode);
            app.RollingwindowfeaturesNode.Text = 'Rolling window features';
            app.LocalchangeindiffusioncoefficientNode = uitreenode(app.RollingwindowfeaturesNode);
            app.LocalchangeindiffusioncoefficientNode.Text = 'Local change in diffusion coefficient';
            app.LocalchangeinmeanstepsizeNode = uitreenode(app.RollingwindowfeaturesNode);
            app.LocalchangeinmeanstepsizeNode.Text = 'Local change in mean step size';
            app.LocalchangeinstandarddeviationofstepsizesNode = uitreenode(app.RollingwindowfeaturesNode);
            app.LocalchangeinstandarddeviationofstepsizesNode.Text = 'Local change in standard deviation of step sizes';
            app.LocalchangeinstandarddeviationofpositionsNode = uitreenode(app.RollingwindowfeaturesNode);
            app.LocalchangeinstandarddeviationofpositionsNode.Text = 'Local change in standard deviation of positions';
            app.LocalchangeindispersionofpositionsNode = uitreenode(app.RollingwindowfeaturesNode);
            app.LocalchangeindispersionofpositionsNode.Text = 'Local change in dispersion of positions';
            app.LocaldisplacementincentroidoflocalisationsNode = uitreenode(app.RollingwindowfeaturesNode);
            app.LocaldisplacementincentroidoflocalisationsNode.Text = 'Local displacement in centroid of localisations';
            app.PleaseselecttheoptionalengineeredfeaturestouseLabel = uilabel(app.GridLayout);
            app.PleaseselecttheoptionalengineeredfeaturestouseLabel.Text = {'Please select the optional engineered features from the menu below.'; ''; 'For a full description of each feature, and advice on which may be most useful for your application, please consult the user manual and publication.'; ''; 'The features you select below will be available for all downstream analysis.'; ''; 'Selecting all features can help evaluate which you find most useful, however this will require additional time to complete the feature engineering procedure and require further user input.'};
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.FeatureEngineeringMenuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FeatureEngineeringMenu(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FeatureEngineeringMenuUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FeatureEngineeringMenuUIFigure)
        end
    end
end