classdef InVivoKineticsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InVivoKineticsUIFigure          matlab.ui.Figure
        MainTabGroup                    matlab.ui.container.TabGroup
        HumanannotationTab              matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        EventlabellersettingsTab        matlab.ui.container.TabGroup
        LabellingcontrolsTab            matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        TogglesecondaryfeatureButton    matlab.ui.control.StateButton
        SaveillustrationButton          matlab.ui.control.Button
        UndoButton                      matlab.ui.control.Button
        GotoendButton                   matlab.ui.control.Button
        GotostartButton                 matlab.ui.control.Button
        forwardButton                   matlab.ui.control.Button
        backwardButton                  matlab.ui.control.Button
        MoreinfoButton                  matlab.ui.control.Button
        DiscardmoleculeButton           matlab.ui.control.Button
        RestarttrackButton              matlab.ui.control.Button
        BeginButton                     matlab.ui.control.Button
        Event_label_buttons             matlab.ui.container.Panel
        PlotsettingsTab                 matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        PrimaryplottedfeatureLabel      matlab.ui.control.Label
        FeatureDropDownLabel            matlab.ui.control.Label
        PrimaryFeatureDropDown          matlab.ui.control.DropDown
        PrimarySetlowerlimttozeroCheckBox  matlab.ui.control.CheckBox
        PrimaryFixupperlimitCheckBox    matlab.ui.control.CheckBox
        PrimaryYaxisupperlimitSpinner   matlab.ui.control.Spinner
        YaxisupperlimitSpinnerLabel     matlab.ui.control.Label
        LinecolourDropDownLabel         matlab.ui.control.Label
        PrimaryLinecolourDropDown       matlab.ui.control.DropDown
        PrimaryLinethicknessSpinner     matlab.ui.control.Spinner
        LinethicknessSpinnerLabel       matlab.ui.control.Label
        FeatureDropDown_2Label          matlab.ui.control.Label
        SecondaryplottedfeatureLabel    matlab.ui.control.Label
        SecondaryFeatureDropDown        matlab.ui.control.DropDown
        SecondarySetlowerlimttozeroCheckBox  matlab.ui.control.CheckBox
        SecondaryFixupperlimitCheckBox  matlab.ui.control.CheckBox
        SecondaryYaxisupperlimitSpinner  matlab.ui.control.Spinner
        YaxisupperlimitSpinner_2Label   matlab.ui.control.Label
        SecondaryLinethicknessSpinner   matlab.ui.control.Spinner
        LinethicknessSpinner_2Label     matlab.ui.control.Label
        IndicatorandreferencelinesLabel  matlab.ui.control.Label
        ReferencelinecolourDropDownLabel  matlab.ui.control.Label
        IndicatorthicknessSpinnerLabel  matlab.ui.control.Label
        ReferencelinethicknessSpinnerLabel  matlab.ui.control.Label
        IndicatorthicknessSpinner       matlab.ui.control.Spinner
        ReferencelineEditFieldLabel     matlab.ui.control.Label
        ReferencelinecolourDropDown     matlab.ui.control.DropDown
        ReferencelinethicknessSpinner   matlab.ui.control.Spinner
        ReferencelineEditField          matlab.ui.control.NumericEditField
        AddreferencelineButton          matlab.ui.control.Button
        ClearreferencelinesButton       matlab.ui.control.Button
        TrajectoryillustrationTab       matlab.ui.container.Tab
        GridLayout26                    matlab.ui.container.GridLayout
        TrackstyleDropDown              matlab.ui.control.DropDown
        TrackstyleDropDownLabel         matlab.ui.control.Label
        ScalebarcolourDropDown          matlab.ui.control.DropDown
        ScalebarlengthmSpinnerLabel     matlab.ui.control.Label
        ScalebarpositionDropDown        matlab.ui.control.DropDown
        ScalebarpositionDropDownLabel   matlab.ui.control.Label
        ScalebarcolourDropDownLabel     matlab.ui.control.Label
        ScalebarlengthSpinner           matlab.ui.control.Spinner
        ScalebarCheckBox                matlab.ui.control.CheckBox
        ExternalplotTab                 matlab.ui.container.Tab
        GridLayout21_2                  matlab.ui.container.GridLayout
        FeaturestoplotinexternalwindowLabel  matlab.ui.control.Label
        PlotMoreFeatures                matlab.ui.container.CheckBoxTree
        VideodisplayTab                 matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        DynamicrangemodeDropDownLabel   matlab.ui.control.Label
        DynamicrangemodeDropDown        matlab.ui.control.DropDown
        OverlayopacityEditFieldLabel    matlab.ui.control.Label
        DynamicrangelowEditField        matlab.ui.control.NumericEditField
        DynamicrangehighEditField       matlab.ui.control.NumericEditField
        DynamicrangehighEditFieldLabel  matlab.ui.control.Label
        OverlayopacityEditField         matlab.ui.control.NumericEditField
        FluorescenceimageCheckBox       matlab.ui.control.CheckBox
        BrightfieldoverlayCheckBox      matlab.ui.control.CheckBox
        VideoupscalefactorSpinner       matlab.ui.control.Spinner
        ExportframerateEditField        matlab.ui.control.NumericEditField
        DynamicrangelowEditFieldLabel   matlab.ui.control.Label
        ExportvideoframerateHzEditFieldLabel  matlab.ui.control.Label
        VideoupscalefactorSpinnerLabel  matlab.ui.control.Label
        AutosaveTab                     matlab.ui.container.Tab
        GridLayout25                    matlab.ui.container.GridLayout
        AutosavefrequencytrajectoriesSpinner  matlab.ui.control.Spinner
        NumberofautosvefilestokeepSpinner  matlab.ui.control.Spinner
        NumberofautosvefilestokeepSpinnerLabel  matlab.ui.control.Label
        AutosavefrequencytrajectoriesSpinnerLabel  matlab.ui.control.Label
        ExportexamplesTab               matlab.ui.container.Tab
        GridLayout25_2                  matlab.ui.container.GridLayout
        IllustrateafterlabellingCheckBox  matlab.ui.control.CheckBox
        PlayexportedvideoCheckBox       matlab.ui.control.CheckBox
        SavealldatawithvideoCheckBox    matlab.ui.control.CheckBox
        SaveeveryviewedmoleculeCheckBox  matlab.ui.control.CheckBox
        MolIDTextArea                   matlab.ui.control.TextArea
        MolIDLabel                      matlab.ui.control.Label
        CellIDTextArea                  matlab.ui.control.TextArea
        CellIDTextAreaLabel             matlab.ui.control.Label
        UIAxes_event_labeller_mesh      matlab.ui.control.UIAxes
        UIAxes_labelling_progress       matlab.ui.control.UIAxes
        UIAxes_event_labeller_status    matlab.ui.control.UIAxes
        UIAxes_image_event_labeller     matlab.ui.control.UIAxes
        UIAxes_event_labeller           matlab.ui.control.UIAxes
        InsightsTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        TabGroup4                       matlab.ui.container.TabGroup
        DatasetoverviewTab              matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        DescriptivestatsTable           matlab.ui.control.Table
        TrajectorylengthbinsizessSpinner  matlab.ui.control.Spinner
        TrajectorylengthbinsizessSpinnerLabel  matlab.ui.control.Label
        PlotalltrajectorylengthsButton  matlab.ui.control.Button
        Binwidtharrivaltime             matlab.ui.control.Spinner
        ArrivaltimebinwidthsLabel       matlab.ui.control.Label
        DisplaymoleculearrivaltimesButton  matlab.ui.control.Button
        MaximumstepsizesSpinner         matlab.ui.control.Spinner
        MaximumstepsizesSpinnerLabel    matlab.ui.control.Label
        DisplaystepsizeprofileButton    matlab.ui.control.Button
        StatetransitiondiagramButton    matlab.ui.control.Button
        UpdatedescriptivestatisticsButton  matlab.ui.control.Button
        InsightsSourcedataDropDown      matlab.ui.control.DropDown
        SourcedataDropDown_2Label       matlab.ui.control.Label
        StatehistogramsTab              matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        ExporthistogramButton           matlab.ui.control.Button
        HistogramdatatoexportDropDown   matlab.ui.control.DropDown
        HistogramdatatoexportDropDownLabel  matlab.ui.control.Label
        DisplaystatehistogramButton     matlab.ui.control.Button
        BinwidthsSpinner                matlab.ui.control.Spinner
        BinwidthsSpinnerLabel           matlab.ui.control.Label
        TruncationDropDown              matlab.ui.control.DropDown
        TruncationDropDownLabel         matlab.ui.control.Label
        EventstoviewDropDown            matlab.ui.control.DropDown
        EventstoviewDropDownLabel       matlab.ui.control.Label
        MSDanalysisTab                  matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        ComputeDButton                  matlab.ui.control.Button
        FastmodeCheckBox                matlab.ui.control.CheckBox
        DcalculationmethodDropDown      matlab.ui.control.DropDown
        LocalisationerrormSpinner       matlab.ui.control.Spinner
        LocalisationerrormSpinnerLabel  matlab.ui.control.Label
        DcalculationmethodDropDownLabel  matlab.ui.control.Label
        NumberofstepsforDcalculationSpinner  matlab.ui.control.Spinner
        NumberofstepsforDcalculationSpinnerLabel  matlab.ui.control.Label
        ExportMSDdataCheckBox           matlab.ui.control.CheckBox
        MinimumlocalisationsforMSDSpinner  matlab.ui.control.Spinner
        MinimumlocalisationsforMSDSpinnerLabel  matlab.ui.control.Label
        LagtimetodisplaysSpinner        matlab.ui.control.Spinner
        LagtimetodisplaysSpinnerLabel   matlab.ui.control.Label
        ProcessaveragesDropDown         matlab.ui.control.DropDown
        ProcessaveragesDropDownLabel    matlab.ui.control.Label
        DiffusionhistogramsTab          matlab.ui.container.Tab
        GridLayout35                    matlab.ui.container.GridLayout
        DiffusionHistPlotresidualsCheckBox  matlab.ui.control.CheckBox
        DiffusionHistGammaInitialValues  matlab.ui.control.Table
        DiffusionHistSetmanualinitialGammafitparametersCheckBox  matlab.ui.control.CheckBox
        DiffusionHistNumberofGammasSpinner  matlab.ui.control.Spinner
        NumberoffitsLabel               matlab.ui.control.Label
        DiffusionHistPerformgammafitCheckBox  matlab.ui.control.CheckBox
        DiffusionHistRunincontinuousupdatemodeCheckBox  matlab.ui.control.CheckBox
        DiffusionHistMaxlagframesSpinner  matlab.ui.control.Spinner
        MaxlagframesSpinnerLabel        matlab.ui.control.Label
        DiffusionHistExportdatatoCSVCheckBox  matlab.ui.control.CheckBox
        DiffusionHistPlotlocationDropDown  matlab.ui.control.DropDown
        PlotlocationDropDownLabel       matlab.ui.control.Label
        DiffusionHistRestricttorangeofcelllengthsCheckBox  matlab.ui.control.CheckBox
        DiffusionHistMaxcelllengthmSpinner  matlab.ui.control.Spinner
        MaxcelllengthmSpinnerLabel      matlab.ui.control.Label
        DiffusionHistMincelllengthmSpinner  matlab.ui.control.Spinner
        MincelllengthmSpinnerLabel      matlab.ui.control.Label
        DiffusionHistStateDropDown      matlab.ui.control.DropDown
        StateDropDownLabel              matlab.ui.control.Label
        DiffusionHistBinSizeSpinner     matlab.ui.control.Spinner
        Binwidthm2sLabel                matlab.ui.control.Label
        GeneratediffusionhistogramsButton  matlab.ui.control.Button
        SpatialmappingTab               matlab.ui.container.Tab
        GridLayout30                    matlab.ui.container.GridLayout
        SpatialMappingMirrorplotCheckBox  matlab.ui.control.CheckBox
        BackgroundcolourDropDown        matlab.ui.control.DropDown
        BackgroundcolourDropDownLabel   matlab.ui.control.Label
        ReconstructionPSFFWHMSpinner    matlab.ui.control.Spinner
        ReconstructionPSFFWHMSpinnerLabel  matlab.ui.control.Label
        HeatmapAvailableDatasetsTree    matlab.ui.container.CheckBoxTree
        AlllocalisastionscombinedNode   matlab.ui.container.TreeNode
        AllavailableclassesNode         matlab.ui.container.TreeNode
        noclassesavailableNode          matlab.ui.container.TreeNode
        HeatmapstyleDropDown            matlab.ui.control.DropDown
        HeatmapstyleDropDownLabel       matlab.ui.control.Label
        HeatmapBinsizenmSpinner         matlab.ui.control.Spinner
        HeatmapbinsizenmLabel           matlab.ui.control.Label
        ModelcellwidthmSpinner          matlab.ui.control.Spinner
        ModelcellwidthmSpinnerLabel     matlab.ui.control.Label
        ModelcelllengthmSpinner         matlab.ui.control.Spinner
        ModelcelllengthmSpinnerLabel    matlab.ui.control.Label
        HeatmapfigureheightpxSpinner    matlab.ui.control.Spinner
        FigureheightpxSpinnerLabel      matlab.ui.control.Label
        HeatmapfigurewidthpxSpinner     matlab.ui.control.Spinner
        FigurewidthpxSpinnerLabel       matlab.ui.control.Label
        GenerateheatmapsButton          matlab.ui.control.Button
        GalleryviewerTab                matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        StopgalleryButton               matlab.ui.control.StateButton
        GalleryClassificationsourceDropDown  matlab.ui.control.DropDown
        ClassificationsourceDropDownLabel  matlab.ui.control.Label
        TrackcolouringDropDown          matlab.ui.control.DropDown
        TrackcolouringDropDownLabel     matlab.ui.control.Label
        FeatureDropDownLabel_2          matlab.ui.control.Label
        GalleryFeatureDropDown          matlab.ui.control.DropDown
        GeneratetrackgalleryButton      matlab.ui.control.Button
        EventoverlayerTab               matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        GeneratevideoCheckBox           matlab.ui.control.CheckBox
        SaveplotteddataCheckBox         matlab.ui.control.CheckBox
        EventOverlayerFeatureDropDown   matlab.ui.control.DropDown
        FeatureofinterestDropDownLabel  matlab.ui.control.Label
        EventhistogramvideosettingsPanel  matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        EntriesorexitsDropDownLabel     matlab.ui.control.Label
        EventhistogramvideobinwidthSpinner  matlab.ui.control.Spinner
        EntriesorexitsDropDown          matlab.ui.control.DropDown
        EventhistogramvideobinwidthSpinnerLabel  matlab.ui.control.Label
        OverlayplotsettingsPanel        matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        OverridefeatureaxisupperlimitSpinner  matlab.ui.control.Spinner
        OverridefeatureaxisupperlimitSpinnerLabel  matlab.ui.control.Label
        OverridefeatureaxislowerlimitSpinner  matlab.ui.control.Spinner
        OverridefeatureaxislowerlimitSpinnerLabel  matlab.ui.control.Label
        EndtimerelativetoeventsSpinner  matlab.ui.control.Spinner
        EndtimerelativetoeventsSpinnerLabel  matlab.ui.control.Label
        StarttimerelativetoeventsSpinner  matlab.ui.control.Spinner
        StarttimerelativetoeventsSpinnerLabel  matlab.ui.control.Label
        AutoaticallysetfeatureaxislimitsCheckBox  matlab.ui.control.CheckBox
        StyleDropDownLabel              matlab.ui.control.Label
        Overlayerstyle                  matlab.ui.control.DropDown
        StateofinterestLabel            matlab.ui.control.Label
        DisplayalloverlayedeventsButton  matlab.ui.control.Button
        EventoverlayerSOI               matlab.ui.control.DropDown
        AdvancedsettingsTab             matlab.ui.container.Tab
        GridLayout33                    matlab.ui.container.GridLayout
        StatsoverlapCheckBox            matlab.ui.control.CheckBox
        UIAxes_compiled_events          matlab.ui.control.UIAxes
        MLclassificationTab             matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        MLControlTabs                   matlab.ui.container.TabGroup
        FeaturevisualisationTab         matlab.ui.container.Tab
        GridLayout34                    matlab.ui.container.GridLayout
        FeatureAnalysisClassBDropDown   matlab.ui.control.DropDown
        ClassBDropDownLabel             matlab.ui.control.Label
        ClassseparabilitymetricDropDown  matlab.ui.control.DropDown
        ClassseparabilitymetricDropDownLabel  matlab.ui.control.Label
        FeatureAnalysisClassADropDown   matlab.ui.control.DropDown
        ClassADropDownLabel             matlab.ui.control.Label
        FeatureAnalysisTransitionregionrangestepsSpinner  matlab.ui.control.Spinner
        TransitionregionrangestepsSpinnerLabel  matlab.ui.control.Label
        FeatureAnalysisDatasubsetDropDown  matlab.ui.control.DropDown
        DatasubsetDropDownLabel         matlab.ui.control.Label
        FeatureAnalysisIgnorerowsfromendSpinner  matlab.ui.control.Spinner
        IgnorerowsfromendSpinner_2Label  matlab.ui.control.Label
        FeatureAnalysisIgnorerowsfromstartSpinner  matlab.ui.control.Spinner
        IgnorerowsfromstartSpinner_2Label  matlab.ui.control.Label
        FeatureVisualisationMethodDropDown  matlab.ui.control.DropDown
        VisualisationMethodLabel        matlab.ui.control.Label
        FeatureAnalysisSourcedataDropDown  matlab.ui.control.DropDown
        SourcedataDropDown_2Label_2     matlab.ui.control.Label
        PlotresolutionSpinner           matlab.ui.control.Spinner
        PlotresolutionSpinnerLabel      matlab.ui.control.Label
        SmoothingfactorSpinner          matlab.ui.control.Spinner
        SmoothingfactorSpinnerLabel     matlab.ui.control.Label
        PlotclassconditionalfeaturedistributionsButton  matlab.ui.control.Button
        FeaturerankingTab               matlab.ui.container.Tab
        GridLayout36                    matlab.ui.container.GridLayout
        DisplaymutualinformationbetweenfeaturesButton  matlab.ui.control.Button
        FeatureImportanceChangepointmasksizeSpinner  matlab.ui.control.Spinner
        ChangepointmasksizeSpinnerLabel  matlab.ui.control.Label
        DisplayfeaturecorrelationsButton  matlab.ui.control.Button
        FeatureImportanceFiguredisplaylocationDropDown  matlab.ui.control.DropDown
        FiguredisplaylocationDropDownLabel  matlab.ui.control.Label
        FeatureimportancemetricDropDown  matlab.ui.control.DropDown
        FeatureimportancemetricDropDownLabel  matlab.ui.control.Label
        FeatureImportanceSourcedataDropDown  matlab.ui.control.DropDown
        SourcedataDropDown_3Label       matlab.ui.control.Label
        FeatureImportanceFeaturesubsetDropDown  matlab.ui.control.DropDown
        FeaturesubsetDropDownLabel      matlab.ui.control.Label
        RankfeaturesbyimportanceButton  matlab.ui.control.Button
        GeneratetrainingdataTab         matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        SetmaxwindowButton              matlab.ui.control.Button
        TrainingdataexportformatDropDown  matlab.ui.control.DropDown
        TrainingdataexportformatDropDownLabel  matlab.ui.control.Label
        SourcedataDropDownLabel         matlab.ui.control.Label
        IgnorerowsfromendSpinner        matlab.ui.control.Spinner
        IgnorerowsfromstartSpinner      matlab.ui.control.Spinner
        ContextLengthSpinner            matlab.ui.control.Spinner
        ContextlengthLabel              matlab.ui.control.Label
        TracksamplingDropDownLabel      matlab.ui.control.Label
        IgnorerowsfromendSpinnerLabel   matlab.ui.control.Label
        KnockoutzerosCheckBox           matlab.ui.control.CheckBox
        IgnorerowsfromstartSpinnerLabel  matlab.ui.control.Label
        FeaturescalingDropDownLabel     matlab.ui.control.Label
        SourcedataDropDown              matlab.ui.control.DropDown
        ExporttrainingdataButton        matlab.ui.control.Button
        GeneratetrainingsetButton       matlab.ui.control.Button
        TrainingvalidationtestsplitEditField  matlab.ui.control.EditField
        TrainingvalidationtestsplitEditFieldLabel  matlab.ui.control.Label
        TracksamplingDropDown           matlab.ui.control.DropDown
        ShufflingmethodDropDown         matlab.ui.control.DropDown
        ShufflingmethodLabel            matlab.ui.control.Label
        FeaturescalingDropDown          matlab.ui.control.DropDown
        VisualisationTab                matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        MaxpointstodisplaySpinnerLabel  matlab.ui.control.Label
        PlotcontrolsPanel               matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        ZaxisLabel                      matlab.ui.control.Label
        YaxisLabel                      matlab.ui.control.Label
        XaxisLabel                      matlab.ui.control.Label
        ZLimHighSpinner                 matlab.ui.control.Spinner
        ZLimLowSpinner                  matlab.ui.control.Spinner
        YLimHighSpinner                 matlab.ui.control.Spinner
        YLimLowSpinner                  matlab.ui.control.Spinner
        XLimHighSpinner                 matlab.ui.control.Spinner
        XLimLowSpinner                  matlab.ui.control.Spinner
        ResetButton                     matlab.ui.control.Button
        YZButton                        matlab.ui.control.Button
        XZButton                        matlab.ui.control.Button
        XYButton                        matlab.ui.control.Button
        RotateButton                    matlab.ui.control.Button
        ZoomButton                      matlab.ui.control.Button
        PanButton                       matlab.ui.control.Button
        UpdateButton                    matlab.ui.control.Button
        MaxpointstodisplaySpinner       matlab.ui.control.Spinner
        DatatodisplayDropDownLabel      matlab.ui.control.Label
        ZaxisDropDown                   matlab.ui.control.DropDown
        ZaxisDropDownLabel              matlab.ui.control.Label
        YaxisDropDown                   matlab.ui.control.DropDown
        YaxisDropDownLabel              matlab.ui.control.Label
        XaxisDropDown                   matlab.ui.control.DropDown
        DatatodisplayDropDown           matlab.ui.control.DropDown
        XaxisDropDownLabel              matlab.ui.control.Label
        TrainmodelsTab                  matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        AttentionheadsSpinner           matlab.ui.control.Spinner
        AttentionheadsSpinnerLabel      matlab.ui.control.Label
        AttentionCheckBox               matlab.ui.control.CheckBox
        LossfunctionDropDown            matlab.ui.control.DropDown
        LossfunctionDropDownLabel       matlab.ui.control.Label
        WatchallcrossvalidationtrainingCheckBox  matlab.ui.control.CheckBox
        TrainingmodeDropDown            matlab.ui.control.DropDown
        TrainingmodeDropDownLabel       matlab.ui.control.Label
        ModelexportformatDropDown       matlab.ui.control.DropDown
        BootstrapsamplesSpinner         matlab.ui.control.Spinner
        BootstrapsamplesSpinnerLabel    matlab.ui.control.Label
        UsebootstrappingCheckBox        matlab.ui.control.CheckBox
        KfoldsSpinner                   matlab.ui.control.Spinner
        KfoldsSpinnerLabel              matlab.ui.control.Label
        ExportmodelButton               matlab.ui.control.Button
        ModelexportformatDropDownLabel  matlab.ui.control.Label
        ModeltypeDropDownLabel          matlab.ui.control.Label
        SavemodelButton                 matlab.ui.control.Button
        TrainMLmodelButton              matlab.ui.control.Button
        ModeltypeDropDown               matlab.ui.control.DropDown
        ClassifyusingmodelTab           matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        DisplaydatasetoverviewButton    matlab.ui.control.Button
        DatasetforclassificationTextArea  matlab.ui.control.TextArea
        DatasetforclassificationTextAreaLabel  matlab.ui.control.Label
        CurrentmodelfileTextAreaLabel   matlab.ui.control.Label
        DisplaymodeldetailsButton       matlab.ui.control.Button
        CurrentmodelfileTextArea        matlab.ui.control.TextArea
        ClassifywithmodelButton         matlab.ui.control.Button
        LoaddatasetforclassificationButton  matlab.ui.control.Button
        LoadexistingmodelButton         matlab.ui.control.Button
        PermutationimportanceTab        matlab.ui.container.Tab
        GridLayout37                    matlab.ui.container.GridLayout
        PermutationImportanceChangepointrangeSpinner  matlab.ui.control.Spinner
        ChangepointrangeSpinnerLabel    matlab.ui.control.Label
        PermutationImportanceDatasubsetDropDown  matlab.ui.control.DropDown
        DatasubsetDropDownLabel_2       matlab.ui.control.Label
        PermutationImportanceSourcedataDropDown  matlab.ui.control.DropDown
        SourcedataDropDown_2Label_3     matlab.ui.control.Label
        PermutationImportanceRepeatsperfeatureSpinner  matlab.ui.control.Spinner
        RepeatsperfeatureSpinnerLabel   matlab.ui.control.Label
        ComputefeaturepermutationimportanceButton  matlab.ui.control.Button
        SHAPTab                         matlab.ui.container.Tab
        GridLayout24                    matlab.ui.container.GridLayout
        EvaluateindividualframesButton  matlab.ui.control.Button
        DisplaySHAPvaluetableButton     matlab.ui.control.Button
        ComputeglobalfeatureimportanceButton  matlab.ui.control.Button
        SHAPvaluedisplayDropDown        matlab.ui.control.DropDown
        SHAPvaluedisplayDropDownLabel   matlab.ui.control.Label
        NumberofexamplesSpinner         matlab.ui.control.Spinner
        NumberofexamplesSpinnerLabel    matlab.ui.control.Label
        PostprocessingTab               matlab.ui.container.Tab
        GridLayout27                    matlab.ui.container.GridLayout
        PostprocessingmethodDropDown    matlab.ui.control.DropDown
        PostprocessingmethodDropDownLabel  matlab.ui.control.Label
        PostprocessingwindowSpinner     matlab.ui.control.Spinner
        PostprocessingwindowSpinnerLabel  matlab.ui.control.Label
        PostprocessclassificationsButton  matlab.ui.control.Button
        FeaturesLabel                   matlab.ui.control.Label
        MLfeatures                      matlab.ui.container.CheckBoxTree
        MLDataView                      matlab.ui.control.UIAxes
        InspectannotationsTab           matlab.ui.container.Tab
        GridLayout28                    matlab.ui.container.GridLayout
        InspectMolID                    matlab.ui.control.Label
        MolIDLabel_2                    matlab.ui.control.Label
        CellIDLabel                     matlab.ui.control.Label
        InspectCellID                   matlab.ui.control.Label
        TabGroup5                       matlab.ui.container.TabGroup
        PlottrackannotationTab          matlab.ui.container.Tab
        GridLayout29                    matlab.ui.container.GridLayout
        InspectfeatureDropDown          matlab.ui.control.DropDown
        InspectfeatureDropDownLabel     matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        RandomtrackButton               matlab.ui.control.RadioButton
        SpecifictrackButton             matlab.ui.control.RadioButton
        CreatevideoillustrationButton   matlab.ui.control.Button
        PlotannotatedtrackButton        matlab.ui.control.Button
        ExportannotationdataButton      matlab.ui.control.Button
        PopoutannotationsButton         matlab.ui.control.Button
        CompareAnnotationsTree          matlab.ui.container.CheckBoxTree
        NoannotationsloadedNode         matlab.ui.container.TreeNode
        MolIDSpinner                    matlab.ui.control.Spinner
        MolIDSpinnerLabel               matlab.ui.control.Label
        CellIDSpinner                   matlab.ui.control.Spinner
        CellIDSpinnerLabel              matlab.ui.control.Label
        DisplaytrackButton              matlab.ui.control.Button
        ComputemetricsTab               matlab.ui.container.Tab
        GridLayout31                    matlab.ui.container.GridLayout
        MaxchangepointerrorSpinner      matlab.ui.control.Spinner
        MaxchangepointerrorSpinnerLabel  matlab.ui.control.Label
        MinimumchangepointseparationSpinner  matlab.ui.control.Spinner
        MinimumchangepointseparationSpinnerLabel  matlab.ui.control.Label
        IgnoreatendoftrackSpinner       matlab.ui.control.Spinner
        IgnoreatendoftrackSpinnerLabel  matlab.ui.control.Label
        IgnoreframeatstartoftrackSpinner  matlab.ui.control.Spinner
        IgnoreframeatstartoftrackSpinnerLabel  matlab.ui.control.Label
        ComputeannotationmetricsButton  matlab.ui.control.Button
        VideoandtrackplottingoptionsTab  matlab.ui.container.Tab
        GridLayout32                    matlab.ui.container.GridLayout
        FlipvideoverticallyCheckBox     matlab.ui.control.CheckBox
        FixdynamicrangeacrossvideoCheckBox  matlab.ui.control.CheckBox
        AnnotationInspectormeshlinecolourDropDown  matlab.ui.control.DropDown
        SegmentedlinecolourDropDownLabel  matlab.ui.control.Label
        UsecellreferenceimageasbackgroundCheckBox  matlab.ui.control.CheckBox
        AnnotationUIAxes                matlab.ui.control.UIAxes
        DiscoveryTab                    matlab.ui.container.Tab
        DiscoveryGrid                   matlab.ui.container.GridLayout
        TabGroup6                       matlab.ui.container.TabGroup
        ExplorerelationshipsTab         matlab.ui.container.Tab
        ExploreRelationshipsGrid        matlab.ui.container.GridLayout
        DiscoveryDisplayDropDown        matlab.ui.control.DropDown
        DisplayDropDownLabel            matlab.ui.control.Label
        DiscoveryPlotlocationDropDown   matlab.ui.control.DropDown
        PlotlocationDropDownLabel_2     matlab.ui.control.Label
        MutualinformationthresholdSpinner  matlab.ui.control.Spinner
        MutualinformationthresholdSpinnerLabel  matlab.ui.control.Label
        DiscoveryRedundancythresholdSpinner  matlab.ui.control.Spinner
        RedundancythresholdSpinnerLabel  matlab.ui.control.Label
        DiscoverySourceDataDropDown     matlab.ui.control.DropDown
        SourcedataDropDown_4Label       matlab.ui.control.Label
        FindHiddenrelationshipsButton   matlab.ui.control.Button
        DiscoveryUIAxes                 matlab.ui.control.UIAxes
        textout                         matlab.ui.control.TextArea
        SideBarTabGroup                 matlab.ui.container.TabGroup
        LoadSaveTab                     matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        ImportResAnDi2Button            matlab.ui.control.Button
        ExportResAnDI2Button            matlab.ui.control.Button
        LoaddataButton                  matlab.ui.control.Button
        UserEditField                   matlab.ui.control.EditField
        FileoverviewTextArea            matlab.ui.control.TextArea
        CurrentlyloadeddatasetTextArea  matlab.ui.control.TextArea
        UserLabel                       matlab.ui.control.Label
        FileoverviewTextAreaLabel       matlab.ui.control.Label
        ClearanalysisButton             matlab.ui.control.Button
        SaveanalysisButton              matlab.ui.control.Button
        LoadanalysisButton              matlab.ui.control.Button
        CurrentlyloadeddatasetTextAreaLabel  matlab.ui.control.Label
        SaveconfigurationButton         matlab.ui.control.Button
        LoadconfigurationButton         matlab.ui.control.Button
        ImportgroundtruthButton         matlab.ui.control.Button
        PrepareTab                      matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        UndriftingmethodDropDownLabel   matlab.ui.control.Label
        IllustrationborderpxSpinnerLabel  matlab.ui.control.Label
        PreparedataforkineticsanalysisButton  matlab.ui.control.StateButton
        FiltertoleranceSpinner          matlab.ui.control.Spinner
        FiltertoleranceSpinnerLabel     matlab.ui.control.Label
        FilteragainstDropDown           matlab.ui.control.DropDown
        FilteragainstDropDownLabel      matlab.ui.control.Label
        FilteringmethodDropDown         matlab.ui.control.DropDown
        FilteringmethodDropDownLabel    matlab.ui.control.Label
        IllustrationborderSpinner       matlab.ui.control.Spinner
        MemoryparameterSpinner          matlab.ui.control.Spinner
        MemoryparameterSpinnerLabel     matlab.ui.control.Label
        MinimumtracklengthSpinner       matlab.ui.control.Spinner
        MinimumtracklengthSpinnerLabel  matlab.ui.control.Label
        IntensityboxpxSpinner           matlab.ui.control.Spinner
        IntensityboxpxSpinnerLabel      matlab.ui.control.Label
        TrackbufferframesSpinner        matlab.ui.control.Spinner
        TrackbufferframesSpinnerLabel   matlab.ui.control.Label
        UndriftingmethodDropDown        matlab.ui.control.DropDown
        PixelscaleEditField             matlab.ui.control.NumericEditField
        PixelscalenmEditFieldLabel      matlab.ui.control.Label
        NotesTab                        matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        NotesexportformatDropDownLabel  matlab.ui.control.Label
        ExportanalysisnotesButton       matlab.ui.control.Button
        NotesexportformatDropDown       matlab.ui.control.DropDown
        PasteparametersButton           matlab.ui.control.Button
        SelectparameterstocopyButton    matlab.ui.control.Button
        DataanalysisnotesTextArea       matlab.ui.control.TextArea
        DataanalysisnotesTextAreaLabel  matlab.ui.control.Label
        HelpaboutTab                    matlab.ui.container.Tab
        GridLayout20                    matlab.ui.container.GridLayout
        Authorship                      matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        TextArea2                       matlab.ui.control.TextArea
        VisittheGitHubrepositoryButton  matlab.ui.control.Button
        OpenPDFmanualButton             matlab.ui.control.Button
        QuickstartguideButton           matlab.ui.control.Button
    end

    properties (Access = public)
        %declare the movie_data property inside the app object to later use to handle data
        movie_data = struct();
        
        annotation_data = struct(); %local, fast-access copy of current track in human annotator
        draggable_line              %stores handle to the draggable line ROI in human annotator
        current_video               %video in human annotator - ordinarily wouldn't be public, but in this case speed of access is paramount to smoothness of scrolling
    end
    
    properties (Access = private)
        software_version = '0.8';
        user_name = 'Default user';
        
        %properties associated with line-dragging actions in human annotator
        debounce_timer              %controls update rate of GUI during rapid drag movements; improves performance of human annotation by limiting GUI updates during fast slider movements
        listener_moving_annotator   %listener for moving human annotator line
        listener_moved_annotator    %listener for having moved human annotator line
        video_handle                %handle to displayed video data in the human annotation tab
        
        %objects that appear when user clicks the state rectangles in the
        %human annotator system
        highlight_state_left
        highlight_state_right
        highlight_patch
        highlight_text
    end
    
    methods (Access = private)
        
        function agreed = displayToSDialog(app)
        %Force user to respond to the terms of service every time the
        %application is launched, Oliver Pambos, 17/11/2023.
        %
        %This was necessitated by a collaborator lab this week using
        %another software system I custom built for them, publishing
        %without properly assigning credit
            
            ToS_text = [
                "By using this software, you agree to be bound by the following terms and conditions.",
                "",
                "This software is provided to you, the user, and is not tied to any specific machine or installation. You agree not to distribute, share, or allow others to use this software, regardless of whether they are using your machine. Any use of this software must be explicitly authorized by Oliver James Pambos, Department of Physics, University of Oxford, UK.",
                "",
                "This software is an unpublished, pre-release version. If you use this application for analysis, exploration of data, or gaining insights that may influence your work, prior to its public release, you agree to include Oliver James Pambos as a co-author on any resulting publications. This co-authorship agreement applies regardless of whether this software is used in the final analysis.",
                "",
                "Upon the public release of this software, the terms of use will change. The publicly available version can be downloaded from the designated online repository and will be subject to different conditions, potentially including the requirement for citation of the original paper.",
                "",
                "Before using this pre-release version of the software, it is mandatory for you, the user, to ensure that all terms and conditions, including those related to authorship, have been reviewed and agreed upon by the person in charge of your laboratory or the individual responsible for authorship decisions at your institution or company. By proceeding to use this software, you confirm that such agreement has been obtained and that all parties involved acknowledge and consent to these terms.",
                "",
                "It is important to note that if you have used this pre-release version for any form of data analysis, exploration, or gained insights that contribute to your research, you are obligated to honor this co-authorship agreement. This obligation persists even if you subsequently use the publicly released version for further analysis or for the final version of your research output. In any disputes regarding the use of this software, the benefit of the doubt will be given to the author, Oliver James Pambos.",
                "",
                "Your use of this pre-release version, or any of its code, signifies your acceptance of these terms and conditions.",
            ];
            
            %generate the dialog window
            app_pos       = app.InVivoKineticsUIFigure.Position;
            dialog_wid    = 500;
            dialog_height = 400;
            dlg           = uifigure('Name', 'Terms of Service', 'WindowStyle', 'modal', 'Position', [app_pos(1) + (app_pos(3) - dialog_wid) / 2, app_pos(2) + (app_pos(4) - dialog_height) / 2, dialog_wid, dialog_height], 'Resize', 'off');
            txt_area      = uitextarea(dlg, 'Value', ToS_text, 'Editable', 'off', 'Position', [10, 60, dialog_wid - 20, dialog_height - 100], 'FontSize', 14);
            btn_agree     = uibutton(dlg, 'Text', 'I Agree', 'Position', [50, 10, 100, 30], 'ButtonPushedFcn', @(btn,event) uiresume(dlg));
            btn_disagree  = uibutton(dlg, 'Text', 'I Disagree', 'Position', [dialog_wid - 150, 10, 100, 30], 'ButtonPushedFcn', @(btn,event) close(dlg));
            
            %make the window visible
            dlg.Visible = 'on';
            
            uiwait(dlg);
            agreed = isvalid(dlg);
            if agreed
                close(dlg);
            end
        end
        
        
        function activateStateLabelButtonByKeypress(app, numericKey)
        %When the user presses a number in human annotation system, execute
        %the corresponding dynamic label state selection button, Oliver
        %Pambos, 15/05/2024.
        %oliver.pambos@physics.ox.ac.uk
        %
        %Further updates are required here to check that user is focused on
        %human annotation tab at the time, and that dynamic buttons have
        %been assigned. A future update will insert a conditional return
        %statement prior to execution.
            
            %ensure the key is within the range of custom buttons created by user
            if numericKey > 0 && numericKey <= numel(app.Event_label_buttons.Children.Children) / 2
                %find the button keypressed by the user
                button_idx = 2 * numericKey;
                button = app.Event_label_buttons.Children.Children(button_idx);

                %trigger the approriate button
                eventLabelButtonCallback(app, button);
            end
        end
        
        
        %===============================================
        %Functions associated with the draggable line in
        %human annotation system
        %===============================================
        function dragLine(app)
        %Enable user to drag vertical line across human annotation UIAxes,
        %and get the next available point as it moves, Oliver Pambos,
        %01/06/2024.
        %oliver.pambos@physics.ox.ac.uk
            
            %ensure primary feature axes are active
            yyaxis(app.UIAxes_event_labeller, 'left');
            curr_track = app.annotation_data.current_track;

            %convert mouse pos'n on human annotator UIAxes to axes data coordinates, and extract the x-coordinate
            pt_axes = app.UIAxes_event_labeller.CurrentPoint;
            x_pos   = pt_axes(1, 1);
            
            %ensure x_pos is within the x-axis limits
            x_range = xlim(app.UIAxes_event_labeller);
            x_pos   = max(min(x_pos, x_range(2)), x_range(1));
            
            %update draggable line's position
            app.draggable_line.Position = [x_pos, min(ylim(app.UIAxes_event_labeller)); x_pos, max(ylim(app.UIAxes_event_labeller))];
            
            col_t = app.annotation_data.col_time;
            [vid_idx, loc_idx, coords] = getNextAvailablePoint(x_pos, [0 : (1/app.annotation_data.frame_rate) : curr_track(end, col_t)]', [curr_track(:, col_t) curr_track(:,app.movie_data.state.col_feature)]);
            app.movie_data.state.labeller_track_pos = loc_idx;
            
            %only update the video and next datapoint if it is new (important for performance on slower machines)
            if app.movie_data.state.labeller_frame ~= vid_idx
                app.movie_data.state.labeller_frame_video   = vid_idx;
                app.movie_data.state.coords                 = coords;
                
                %restart debounce timer
                stop(app.debounce_timer);
                start(app.debounce_timer);
            end
            
        end
        
        
        function clickAxes(app, src, event)
        %Move line to user click location, then call the dragLine function
        %to enable dragging until the user lets go of mouse, Oliver Pambos,
        %01/06/2024.
        %oliver.pambos@physics.ox.ac.uk
        %
        %Note that there is a lot of code re-used from dragLien that could
        %be refactored into a helper function to tidy up.
            
            %ensure primary feature axes are active
            yyaxis(app.UIAxes_event_labeller, 'left');
            
            %get current point clicked on the axes
            %convert mouse pos'n on human annotator UIAxes to axes data coordinates, and extract the x-coordinate
            pt = app.UIAxes_event_labeller.CurrentPoint;
            x_pos = pt(1, 1);
        
            %ensure x_pos is within the x-axis limits
            xRange = xlim(app.UIAxes_event_labeller);
            x_pos = max(min(x_pos, xRange(2)), xRange(1));
        
            %update draggable line's position
            app.draggable_line.Position = [x_pos, min(ylim(src)); x_pos, max(ylim(src))];
        
            %enable dragging interactions
            app.draggable_line.InteractionsAllowed = 'translate';
            
            %set up dragging immediately using WindowButtonMotionFcn
            app.InVivoKineticsUIFigure.WindowButtonMotionFcn = @(fig, evt) dragLine(app);
            
            %set up the release mouse button function to stop dragging
            app.InVivoKineticsUIFigure.WindowButtonUpFcn = @(fig, evt) endDrag(app);
        
            %start the drag process immediately
            dragLine(app);
        end
        
        
        function endDrag(app)
        %When the user lets go of the mouse after draggling line on human
        %annotation system, snap to the next available data point, Oliver
        %Pambos, 01/06/2024.
        %oliver.pambos@physics.ox.ac.uk
        %
        %Note that it's faster overall to access col_t twice from a local
        %variable that has to call find in the tracks header cell array
        %than it is to call it twice from the pre-saved state substruct;
        %using find directly here also makes this function more modular,
        %robust, and results in no requirement to assign the col_t state
        %variable during feature engineering.
            
            %clear WindowButtonMotionFcn and WindowButtonUpFcn to stop dragging
            app.InVivoKineticsUIFigure.WindowButtonMotionFcn    = '';
            app.InVivoKineticsUIFigure.WindowButtonUpFcn        = '';

            %ensure primary feature axes are active
            yyaxis(app.UIAxes_event_labeller, 'left');
            curr_track = app.annotation_data.current_track;

            %get final pos'n of draggable line
            final_pos = app.draggable_line.Position(1, 1);
            
            %get next available point in time series
            col_t = app.annotation_data.col_time;
            [~, ~, coords] = getNextAvailablePoint(final_pos, [0 : (1/app.annotation_data.frame_rate) : curr_track(end, col_t)]', [curr_track(:, col_t) curr_track(:,app.movie_data.state.col_feature)]);
            
            %snap line to the next available point
            x_pos = coords(1);
            app.draggable_line.Position = [x_pos, min(ylim(app.UIAxes_event_labeller)); x_pos, max(ylim(app.UIAxes_event_labeller))];
            
            %perform debounced update
            performDebouncedUpdate(app);
        end
        
        
        function setupListeners(app)
        %Remove previous listeners for movement in human annotation
        %system if they exist, and add a new one; avoids duplicate calls,
        %Oliver Pambos, 01/06/2024.
        %oliver.pambos@physics.ox.ac.uk
            
            if isfield(app, 'listenerMovingROI') && isvalid(app.listener_moving_annotator)
                delete(app.listener_moving_annotator);
            end
            app.listener_moving_annotator = addlistener(app.draggable_line, 'MovingROI', @(src, evt) dragLine(app));
            
            if isfield(app, 'listenerROIMoved') && isvalid(app.listener_moved_annotator)
                delete(app.listener_moved_annotator);
            end
            app.listener_moved_annotator = addlistener(app.draggable_line, 'ROIMoved', @(src, evt) endDrag(app));
        end
        
        
        function performDebouncedUpdate(app)
        %Update the video frame in fluorescence reference video and the
        %location marker in main plot of human annotator if debounce timer
        %has been satified, Oliver Pambos, 01/06/2024.
        %oliver.pambos@physics.ox.ac.uk
            
            %retrieve stored frame index and coords
            vid_idx = app.movie_data.state.labeller_frame_video;
            coords = app.movie_data.state.coords;
            
            %update video frame
            app.updateVideoFrame(vid_idx);
            
            %update current location marker
            current_loc = findobj(app.UIAxes_event_labeller, 'Tag', 'current_loc');
            current_loc.XData = coords(1);
            current_loc.YData = coords(2);
        end
        
    end

    
    methods (Access = public)
        
        function updateVideoFrame(app, frame_index)
        %Update video frame inside human annotation system, Oliver Pambos, 13/05/2024.
        %oliver.pambos@physics.ox.ac.uk
        %
        %This function reuses app.video_handle when scrolling throught a video, grealy enhancing performance by reducing calls to imagesc().
        %As before, continuing use of imagesc() over imshow() to avoid inclusion unnecessary tools.
        %Access is public as this is called from repopulateEventLabeller.m.
        %Code was moved from an earlier callback.
            
            if isempty(app.video_handle) || ~isvalid(app.video_handle)
                app.video_handle = imagesc(app.UIAxes_image_event_labeller, app.current_video(:, :, frame_index));
                axis(app.UIAxes_image_event_labeller, 'equal');
                axis(app.UIAxes_image_event_labeller, 'off');
                colormap(app.UIAxes_image_event_labeller, gray(256));
            else
                set(app.video_handle, 'CData', app.current_video(:, :, frame_index));
            end
        end
        
        
        function setupDraggableLine(app)
        %Set up draggable line in main plot of human annotation system,
        %Oliver Pambos, 01/06/2024.
        %oliver.pambos@physics.ox.ac.uk
        %
        %This function uses an ROI line to replace the previous slider
        %implementation I had built. This appears to greatly outperform the
        %slider functionality I suspect due to some low-level optimisation
        %inside MATLAB as this is designed for user interaction.
        %
        %This function is placed inside public methods because it has to be
        %called from repopulateEventLabeller.m when new tracks are loaded
        %because it required clearing the axes (and therefore previous ROI
        %line) each time a track has been labelled.
            
            ax          = app.UIAxes_event_labeller;
            x_range     = xlim(ax);
            start_pos   = x_range(1);   %put line initially at t = 0
            
            %clear any existing line first
            if isfield(app, 'draggable_line') && isvalid(app.draggable_line)
                delete(app.draggable_line); 
            end
            
            %ensure primary axes are active
            yyaxis(ax, 'left');

            %add the ROI line, and allow translation interactions
            app.draggable_line = images.roi.Line(ax, ...
                'Position', [start_pos, min(ylim(ax)); start_pos, max(ylim(ax))], ...
                'Color', 'r', 'LineWidth', 2, 'InteractionsAllowed', 'translate');
            
            %set the ButtonDownFcn of the axes to reposition line (clickAxes is defined in the private methods above)
            ax.ButtonDownFcn = @(src, event) clickAxes(app, src, event);
        
            %set up listeners for moving and moving completion
            setupListeners(app);
        end
        
        %==========================================================
        %Functions to control the highlighting of states when user
        %clicks on a state rectangle in the human annotation system
        %==========================================================
        function rectangleButtonDown(app, left, right, colour, state_str)
        %Highlight the currently selected state when the user activates the
        %button action of a state rectangle in the human annotator top bar,
        %Oliver Pambos, 03/06/2024.
        %oliver.pambos@physics.ox.ac.uk
        %
        %This function highlights an annotated state selected by the user
        %by activating the state in the top bar in the human annotator
        %system. This funciton generates a background patch behind the
        %relevant region of the time trace, bounded on either side by
        %dotted red lines, all in the appropriate colour associated with
        %the state. When the user releases the mouse button a call to
        %another function removeHighlight() is made using WindowButtonUpFcn
        %which removes the objects used to produce the highlight.
        %
        %Inputs
        %------
        %app        (handle)    main GUI handle
        %left       (float)     time (x) coordinate of start of state
        %right      (float)     time (x) coordinate of end of state
        %colour     (vec)       1x3 row vector of RGB colours of state to be highlighted
        %state_str  (str)       the name of the state being highlighted
        %
        %Output
        %------
        %None
        %
        %Dependent functions (excluding callbacks)
        %-----------------------------------------
        %removeHighlight()  - local to this .mlapp file
            
            %display vertical lines for start and end of state over main plot
            yyaxis(app.UIAxes_event_labeller, 'left');
            hold(app.UIAxes_event_labeller, 'on');
            app.highlight_state_left    = xline(app.UIAxes_event_labeller, left, 'LineWidth', 1.5, 'LineStyle', ':', 'Color', colour);
            app.highlight_state_right   = xline(app.UIAxes_event_labeller, right,'LineWidth', 1.5, 'LineStyle', ':', 'Color', colour);
            
            %create a patch object behind data
            y_limits = ylim(app.UIAxes_event_labeller);
            app.highlight_patch = patch(app.UIAxes_event_labeller, ...
                [left, right, right, left], [y_limits(1), y_limits(1), y_limits(2), y_limits(2)], ...
                colour, 'FaceAlpha', 0.3, 'EdgeColor', 'none');
            
            %add state label to middle of patch
            app.highlight_text = text(app.UIAxes_event_labeller,...
                (left + right) / 2, (y_limits(1) + y_limits(2)) / 2, state_str, ...
                'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 16, ...
                'FontWeight', 'bold', 'Color', 'w');
            
            hold(app.UIAxes_event_labeller, 'off');
            
            %use WindowButtonUpFcn to remove highlights when user lets go of mouse button
            app.InVivoKineticsUIFigure.WindowButtonUpFcn = @(src, event) app.removeHighlight();
        end
        
        function removeHighlight(app)
        %Remove highlights currently displayed int he main plot of the
        %human annotator, Oliver Pambos, 03/06/2024.
        %oliver.pambos@physics.ox.ac.uk
        %
        %This function executes using WindowButtonUpFcn when the user
        %releases the mouse button after selecting a state to view.
        %
        %Inputs
        %------
        %app        (handle)    main GUI handle
        %
        %Output
        %------
        %None
        %
        %Dependent functions (excluding callbacks)
        %-----------------------------------------
        %None
            
            %remove the vertical lines
            if isvalid(app.highlight_state_left)
                delete(app.highlight_state_left);
            end
            if isvalid(app.highlight_state_right)
                delete(app.highlight_state_right);
            end
    
            %remove patch and text
            if isvalid(app.highlight_patch)
                delete(app.highlight_patch);
            end
            if isvalid(app.highlight_text)
                delete(app.highlight_text);
            end

            %clear WindowButtonUpFcn
            app.InVivoKineticsUIFigure.WindowButtonUpFcn = '';
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %ensure that the user has not set unusual custom figure creation function
            defaultCreateFcn = get(groot, 'defaultFigureCreateFcn');
            if ~isempty(defaultCreateFcn)
                disp('Custom figure creation function is set. Adjusting for compatibility.');
                %reset the figure creation callback
                set(groot, 'defaultFigureCreateFcn', '');
            end
            
            %disable appearance of the main GUI (which is also locked)
            app.InVivoKineticsUIFigure.Visible = "off";

            %display Terms of Service
            tos_agreed = app.displayToSDialog();
            if ~tos_agreed
                close(app.InVivoKineticsUIFigure);
                return;
            else
                %only display GUI if ToS agreed
                app.InVivoKineticsUIFigure.Visible = "on";
            end
            
            %setup timmer for debounding in ROI line implementation
            app.debounce_timer = timer('ExecutionMode', 'singleShot', 'StartDelay', 0.01, ...        %adjust delay based on desired responsiveness
                'TimerFcn', @(~, ~) performDebouncedUpdate(app));
            
            %pause to enable the app to load fully; this prevents the username prompt moving behind GUI
            pause(1);
            
            %prompt user to enter their name - replace later with bespoke input GUI to avoid pause above; necessary as inputdlg cannot be made modal
            app.user_name = inputdlg('Please enter your name (this is used only to annotate analysis files, and not shared outside of the app):', 'Name', [1 120], {'Default user'});
            if ~isempty(app.user_name)
                app.UserEditField.Value = app.user_name{1};
            else
                app.UserEditField.Value = 'Default user';
            end

            focus(app.InVivoKineticsUIFigure);
        end

        % Callback function
        function LoaddataButtonValueChanged(app, event)
            loadDataset(app);
        end

        % Value changed function: PreparedataforkineticsanalysisButton
        function PreparedataforkineticsanalysisButtonValueChanged(app, event)
            %obtain parameters from GUI inputs - not placed in startupFcn because the contents of app.movie_data is overwritten by the LoColi file import
            app.movie_data.params.px_scale                  = app.PixelscaleEditField.Value;            %pixel scale (nm/pixel)
            app.movie_data.params.track_buffer              = app.TrackbufferframesSpinner.Value;       %minimum number of empty frames between tracks in same cell
            app.movie_data.params.I_border                  = app.IntensityboxpxSpinner.Value;          %size of border around localisation to obtain fluorescence intensity (not currently used)
            app.movie_data.params.dr_auto                   = app.DynamicrangemodeDropDown.Value;       %mode used to set dynamic range of fluor data in illustrations ('auto' or 'manual')
            app.movie_data.params.dr_lo                     = app.DynamicrangelowEditField.Value;       %lower limit of dynamic range of fluor data in 'manual' mode
            app.movie_data.params.dr_hi                     = app.DynamicrangehighEditField.Value;      %upper limit of dynamic range of fluor data in 'manual' mode
            app.movie_data.params.ill_border                = app.IllustrationborderSpinner.Value;      %size of border around cells in illustrations (pixels)
            app.movie_data.params.overlay_pc                = app.OverlayopacityEditField.Value;        %percentage intensity of brightfield overlay in illustrations
            app.movie_data.params.FOV                       = size(app.movie_data.brightfield_image);   %dimensions of recording
            app.movie_data.params.min_track_len             = app.MinimumtracklengthSpinner.Value;      %minimum length of a track (in localisations) to use in analysis
            app.movie_data.params.video_upscaling_factor    = app.VideoupscalefactorSpinner.Value;      %integer value used to upscale exported videos
            app.movie_data.params.export_frame_rate         = app.ExportframerateEditField.Value;       %frame rate used for export videos
            app.movie_data.params.filtering_method          = app.FilteringmethodDropDown.Value;        %filtering method which determines how filterTracks() handles multiple trajectories with temporal overlaps in a cell; this is currently used as a record of the process used to filter, assigned during filterTracks.m
            app.movie_data.params.filter_against            = app.FilteragainstDropDown.Value;          %determine whether to filter basaed on the presence of other tracks or other localisations; this has been superceeded as a record of the filtering process by app.movie_data.params.filtering_method, which is assigned during filterTracks.m
            app.movie_data.params.filter_tolerance          = app.FiltertoleranceSpinner.Value;         %maximum number of localisations that can coincide with the track before it is filtered when filtering by localisations
            app.movie_data.params.mem_param                 = app.MemoryparameterSpinner.Value;         %memory parameter used to filter tracks
            app.movie_data.state.play_exported_video        = app.PlayexportedvideoCheckBox.Value;      %play back exported video generated by the human annotation system
            app.movie_data.params.hist_bin_wid              = app.BinwidthsSpinner.Value;               %histogram bin width used by the Insights tab
            
            %prepare data
            prepData(app);
        end

        % Value changed function: PixelscaleEditField
        function PixelscaleEditFieldValueChanged(app, event)
            app.movie_data.params.px_scale = app.PixelscaleEditField.Value;
            app.textout.Value = "The pixel scale was changed to " + num2str(app.movie_data.params.px_scale) + " nm/pixel";
        end

        % Value changed function: TrackbufferframesSpinner
        function TrackbufferframesSpinnerValueChanged(app, event)
            app.movie_data.params.track_buffer = app.TrackbufferframesSpinner.Value;
            app.textout.Value = "The minimum temporal separation between tracks in the same cell was changed to " + num2str(app.movie_data.params.track_buffer) + " frames";
        end

        % Value changed function: IntensityboxpxSpinner
        function IntensityboxpxSpinnerValueChanged(app, event)
            app.movie_data.params.I_border = app.IntensityboxpxSpinner.Value;
            app.textout.Value = "The distance between each localisation, and the edge of the intensity profile box was changed to " + num2str(app.movie_data.params.I_border) + " pixels";
        end

        % Value changed function: DynamicrangelowEditField
        function DynamicrangelowEditFieldValueChanged(app, event)
            app.movie_data.params.dr_lo = app.DynamicrangelowEditField.Value;
            
            switch app.DynamicrangemodeDropDown.Value
                case "Auto"
                    app.textout.Value = "The lower limit of the dynamic range of illustrations in manual mode has been set to " + num2str(app.movie_data.params.dr_lo) + "." + newline + ...
                        "As this is currently in Auto mode, you will need to move back into Manual mode to carry out the adjustments.";
                case "Manual"
                    %re-capture video from FITS stack using new dynamic range
                    cell_ID = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.CellID;
                    mol_ID  = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.MolID;
                    if isfield(app.movie_data.params, 'flipped') && app.movie_data.params.flipped   %maintaining backwards compatibility until import function for old save files is updated
                        flipped = true;
                    else
                        flipped = false;
                    end
                    app.current_video = illustrateMol(app.movie_data, cell_ID, mol_ID, 0, app.SaveeveryviewedmoleculeCheckBox.Value, strcat('Cell', num2str(cell_ID), '_Mol', num2str(mol_ID)), 1, flipped);
                    
                    %display updated video at correct frame
                    app.updateVideoFrame(app.movie_data.state.labeller_frame_video);
                    
                    app.textout.Value = "The lower limit of the dynamic range of illustrations in manual mode has been set to " + num2str(app.movie_data.params.dr_lo) + "." + newline + newline + ...
                        "The video has been updated accordingly.";
                otherwise

            end
        end

        % Value changed function: DynamicrangehighEditField
        function DynamicrangehighEditFieldValueChanged(app, event)
            app.movie_data.params.dr_hi = app.DynamicrangehighEditField.Value;
            
            switch app.DynamicrangemodeDropDown.Value
                case "Auto"
                    app.textout.Value = "The upper limit of the dynamic range of illustrations in manual mode has been set to " + num2str(app.movie_data.params.dr_hi) + "." + newline + ...
                        "As this is currently in Auto mode, you will need to move back into Manual mode to carry out the adjustments.";
                case "Manual"
                    %re-capture video from FITS stack using new dynamic range
                    cell_ID = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.CellID;
                    mol_ID  = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.MolID;
                    if isfield(app.movie_data.params, 'flipped') && app.movie_data.params.flipped   %maintaining backwards compatibility until import function for old save files is updated
                        flipped = true;
                    else
                        flipped = false;
                    end
                    app.current_video = illustrateMol(app.movie_data, cell_ID, mol_ID, 0, app.SaveeveryviewedmoleculeCheckBox.Value, strcat('Cell', num2str(cell_ID), '_Mol', num2str(mol_ID)), 1, flipped);
                    
                    %display updated video at correct frame
                    app.updateVideoFrame(app.movie_data.state.labeller_frame_video);
                    
                    app.textout.Value = "The upper limit of the dynamic range of illustrations in manual mode has been set to " + num2str(app.movie_data.params.dr_lo) + "." + newline + newline + ...
                        "The video has been updated accordingly.";
                otherwise
                
            end
        end

        % Value changed function: DynamicrangemodeDropDown
        function DynamicrangemodeDropDownValueChanged(app, event)
            app.movie_data.params.dr_auto = app.DynamicrangemodeDropDown.Value;
            app.textout.Value = strcat('The dynamic range of illustrations is running in', {' '}, app.movie_data.params.dr_auto, ' mode');
            
            %re-capture video from FITS stack using new dynamic range
            cell_ID = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.CellID;
            mol_ID  = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.MolID;
            if isfield(app.movie_data.params, 'flipped') && app.movie_data.params.flipped   %maintaining backwards compatibility until import function for old save files is updated
                flipped = true;
            else
                flipped = false;
            end
            app.current_video = illustrateMol(app.movie_data, cell_ID, mol_ID, 0, app.SaveeveryviewedmoleculeCheckBox.Value, strcat('Cell', num2str(cell_ID), '_Mol', num2str(mol_ID)), 1, flipped);
            app.updateVideoFrame(app.movie_data.state.labeller_frame_video);
        end

        % Value changed function: IllustrationborderSpinner
        function IllustrationborderSpinnerValueChanged(app, event)
            app.movie_data.params.ill_border = app.IllustrationborderSpinner.Value;
            app.textout.Value = "The border size (in pixels) around each cell ROI for use in illustrations has been set to " + num2str(app.movie_data.params.ill_border);
        end

        % Value changed function: OverlayopacityEditField
        function OverlayopacityEditFieldValueChanged(app, event)
            app.movie_data.params.overlay_pc    = app.OverlayopacityEditField.Value;
            app.textout.Value                   = "Brightfield overlay opacity for illustrations has been set to " + num2str(app.movie_data.params.overlay_pc) + "%";
            
            %re-capture video from FITS stack using new overlay opacity
            cell_ID = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.CellID;
            mol_ID  = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.MolID;
            if isfield(app.movie_data.params, 'flipped') && app.movie_data.params.flipped   %maintaining backwards compatibility until import function for old save files is updated
                flipped = true;
            else
                flipped = false;
            end
            app.current_video = illustrateMol(app.movie_data, cell_ID, mol_ID, 0, app.SaveeveryviewedmoleculeCheckBox.Value, strcat('Cell', num2str(cell_ID), '_Mol', num2str(mol_ID)), 1, flipped);
            
            %display updated video at correct frame
            app.updateVideoFrame(app.movie_data.state.labeller_frame_video);
        end

        % Selection change function: MainTabGroup
        function MainTabGroupSelectionChanged(app, event)
            selectedTab = app.MainTabGroup.SelectedTab;
            
            %code to run when user moves between tabs
            if strcmp(selectedTab.Title, 'Human annotation')
                % << for future use >>
            elseif strcmp(selectedTab.Title, 'Insights')
                %====================================================
                %repopulate the checklist tree in spatial mapping tab
                %====================================================
                %clear existing available classes in checklist tree
                node_all_classes = app.HeatmapAvailableDatasetsTree.Children(2);
                delete(node_all_classes.Children);
                
                %handle cleared data
                if ~isprop(app, "movie_data") || isempty(app.movie_data) || ~isfield(app.movie_data, "params") || isfield(app.movie_data.params, "class_names")
                    return;
                end

                %update the available classes in checklist tree
                class_names = app.movie_data.params.class_names;
                if ~isempty(class_names)
                    for ii = 1:size(class_names, 1)
                        new_node = uitreenode('Parent', node_all_classes, 'Text', class_names{ii});
                    end
                else
                    new_node = uitreenode('Parent', node_all_classes, 'Text', '<< no class annotations available >>');
                end
                
                %pre-select all checkboxes, and expand tree by default
                all_nodes = node_all_classes.Children;
                for ii = 1:length(all_nodes)
                    app.HeatmapAvailableDatasetsTree.CheckedNodes = [app.HeatmapAvailableDatasetsTree.CheckedNodes; all_nodes(ii)];
                end
                expand(app.HeatmapAvailableDatasetsTree);

            elseif strcmp(selectedTab.Title, 'ML classification')
                %repopulate the ML features list in the ML classificaiton tab
                if isprop(app, 'movie_data') && isfield(app.movie_data, 'params') && isfield(app.movie_data.params, 'column_titles')
                    a = app.MLfeatures.Children;
                    a.delete;
                    for ii = 1:(size(app.movie_data.params.column_titles.tracks,2) - 1)
                        features(ii) = uitreenode(app.MLfeatures);
                        features(ii).Text = string(app.movie_data.params.column_titles.tracks(ii));
                    end
                end
                
                if isprop(app, 'movie_data')
%                    setDefaultsAllModels(app); % << placeholder >>
                end
            elseif strcmp(selectedTab.Title, 'Inspect annotations')
                %remove existing nodes
                delete(app.CompareAnnotationsTree.Children);
            
                if isfield(app.movie_data, 'results')
                    %initialize a cell array to hold the nodes
                    nodes = {};
                    
                    %update tree
                    if isfield(app.movie_data.results, 'GroundTruth')
                        nodes{end+1} = uitreenode(app.CompareAnnotationsTree, 'Text', 'Ground truth annotations');
                    end
                    if isfield(app.movie_data.results, 'VisuallyLabelled')
                        nodes{end+1} = uitreenode(app.CompareAnnotationsTree, 'Text', 'Human annotations');
                    end
                    if isfield(app.movie_data.results, 'LSTMLabelled')
                        nodes{end+1} = uitreenode(app.CompareAnnotationsTree, 'Text', 'LSTM annotations');
                    end
                    if isfield(app.movie_data.results, 'BiLSTMLabelled')
                        nodes{end+1} = uitreenode(app.CompareAnnotationsTree, 'Text', 'Bidirectional LSTM annotations');
                    end
                    if isfield(app.movie_data.results, 'RFLabelled')
                        nodes{end+1} = uitreenode(app.CompareAnnotationsTree, 'Text', 'Random forest annotations');
                    end
                    if isfield(app.movie_data.results, 'GRULabelled')
                        nodes{end+1} = uitreenode(app.CompareAnnotationsTree, 'Text', 'GRU annotations');
                    end
                    if isfield(app.movie_data.results, 'BiGRULabelled')
                        nodes{end+1} = uitreenode(app.CompareAnnotationsTree, 'Text', 'Bidirectional GRU annotations');
                    end
                    if isfield(app.movie_data.results, 'ResAnDi')
                        nodes{end+1} = uitreenode(app.CompareAnnotationsTree, 'Text', 'ResAnDi2 annotations');
                    end
                    
                    %if no nodes added, put back notification to user
                    if isempty(nodes)
                        nodes{end+1} = uitreenode(app.CompareAnnotationsTree, 'Text', '<< No annotations loaded >>');
                    end
                else
                    %if results struct doesn't exist, add no annotations message
                    no_annotations_node = uitreenode(app.CompareAnnotationsTree, 'Text', '<< No annotations loaded >>');
                end
                
                %update features to inspect
                if isprop(app, "movie_data") && isfield(app.movie_data, 'results') && isfield(app.movie_data.params.column_titles, 'tracks')
                    %wipe previous items
                    app.InspectfeatureDropDown.Items = {};
                    
                    %update available features in dropdown box
                    app.InspectfeatureDropDown.Items = app.movie_data.params.column_titles.tracks;
                else
                    app.InspectfeatureDropDown.Items = {'<< select feature >>'};
                end

                %tick all tree nodes
                app.CompareAnnotationsTree.CheckedNodes = app.CompareAnnotationsTree.Children;
                
                %select step size feature in dropdown list if it exists
                step_size_idx = find(strcmp(app.InspectfeatureDropDown.Items, 'Step size (nm)'), 1);
                if ~isempty(step_size_idx)
                    app.InspectfeatureDropDown.Value = 'Step size (nm)';
                end
            end
        end

        % Value changed function: MinimumtracklengthSpinner
        function MinimumtracklengthSpinnerValueChanged(app, event)
            app.movie_data.params.min_track_len = app.MinimumtracklengthSpinner.Value;
            app.textout.Value = "Minimum track length changed to " + num2str(app.movie_data.params.min_track_len) + " frames";
        end

        % Button pushed function: LoadanalysisButton
        function LoadanalysisButtonPushed(app, event)
            %return value here will enable further code to check the loaded file for partially-complete human annotations, and if found to offer the user to return to those
            load_successful = loadAnalysis(app);
            
            focus(app.InVivoKineticsUIFigure);
        end

        % Button pushed function: OpenPDFmanualButton
        function OpenPDFmanualButtonPushed(app, event)
            app.textout.Value = "Opening PDF manual";
            open('InVivoKinetics_manual.pdf');
        end

        % Value changed function: VideoupscalefactorSpinner
        function VideoupscalefactorSpinnerValueChanged(app, event)
            app.movie_data.params.video_upscaling_factor = app.VideoupscalefactorSpinner.Value;
            app.textout.Value = "Upscaling factor for video illustrations has been set to " + num2str(app.movie_data.params.video_upscaling_factor);
        end

        % Value changed function: ExportframerateEditField
        function ExportframerateEditFieldValueChanged(app, event)
            app.movie_data.params.export_frame_rate = app.ExportframerateEditField.Value;
            app.textout.Value = "Export video frame rate has been set to " + num2str(app.movie_data.params.export_frame_rate);
        end

        % Value changed function: FilteringmethodDropDown
        function FilteringmethodDropDownValueChanged(app, event)
            app.movie_data.params.filtering_method = app.FilteringmethodDropDown.Value;
            app.textout.Value = "Filtering method has been set to " + num2str(app.movie_data.params.filtering_method);
        end

        % Value changed function: MemoryparameterSpinner
        function MemoryparameterSpinnerValueChanged(app, event)
            app.movie_data.params.mem_param = app.MemoryparameterSpinner.Value;
            app.textout.Value = "Memory parameter has been set to " + num2str(app.movie_data.params.mem_param);
        end

        % Button pushed function: GotostartButton
        function GotostartButtonPushed(app, event)
            col_t = findColumnIdx(app.movie_data.params.column_titles.tracks, 'Time from start of track (s)');
            
            %move draggable line in human annotator back to start
            x_track_start               = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(1, col_t);
            app.draggable_line.Position = [x_track_start, min(ylim(app.UIAxes_event_labeller)); x_track_start, max(ylim(app.UIAxes_event_labeller))];
            
            %set the position within the track
            app.movie_data.state.labeller_track_pos = 1;
            
            %put the video back to start
            app.movie_data.state.labeller_frame_video = 1;
            app.updateVideoFrame(app.movie_data.state.labeller_frame_video);
            
            %put the circle back to start
            current_loc = findobj(app.UIAxes_event_labeller, 'Tag', 'current_loc');
            current_loc.XData = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(1, col_t);
            current_loc.YData = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(1,app.movie_data.state.col_feature);
            
            %return keyboard focus to the human annotation system
            focus(app.InVivoKineticsUIFigure);
        end

        % Button pushed function: GotoendButton
        function GotoendButtonPushed(app, event)
            col_t = findColumnIdx(app.movie_data.params.column_titles.tracks, 'Time from start of track (s)');
            
            %move draggable line in human annotator to end of plot
            x_track_end                 = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.Mol(end, col_t);
            app.draggable_line.Position = [x_track_end, min(ylim(app.UIAxes_event_labeller)); x_track_end, max(ylim(app.UIAxes_event_labeller))];
            
            %set the position within the track
            app.movie_data.state.labeller_track_pos = size(app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.Mol, 1);
            
            %display final frame of video
            app.movie_data.state.labeller_frame_video = size(app.current_video, 3);
            app.updateVideoFrame(app.movie_data.state.labeller_frame_video);
            
            %put the circle on final point
            current_loc = findobj(app.UIAxes_event_labeller, 'Tag', 'current_loc');
            current_loc.XData = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(end, col_t);
            current_loc.YData = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(end, app.movie_data.state.col_feature);
            
            %return keyboard focus to the human annotation system
            focus(app.InVivoKineticsUIFigure);
        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            %if data from a previous event labelling session already exists, the user needs to verify overwrite
            try
                if isfield(app.movie_data, 'results') && isfield(app.movie_data.results, 'VisuallyLabelled') && ~isempty(app.movie_data.results.VisuallyLabelled) &&...
                        ~isempty(app.movie_data.results.VisuallyLabelled.LabelledMols) && size(app.movie_data.results.VisuallyLabelled.LabelledMols, 1) > 0
                
                    answer = questdlg('This datafile contains data that has already been manually labelled, are you sure you want to overwrite this?',...
                                      'Warning: you are about to overwrite existing data', 'Yes, overwrite', 'Continue where I left off', 'Cancel', 'Cancel');
                    %handle response
                    switch answer
                        case 'Cancel'
                            return;
                        case 'Continue where I left off'
                            %populate buttons using class names stored in params substruct
                            regenerateLabelButtons(app);
                        case 'Yes, overwrite'
                            %start labelling from scratch
                            labelFromScratch(app);
                    end
                    
                else
                    %start labelling from scratch
                    labelFromScratch(app);
                end
                
                repopulateEventLabeller(app);
                focus(app.InVivoKineticsUIFigure);
                
            catch ME
                switch ME.identifier
                    case "repopulateEventLabeller:MissingTimeColumn"
                        app.textout.Value = "Error: The required time column ('Time from start of track (s)') is missing from the dataset.";
                        h_popup = warndlg("The required time column is missing from the dataset.", "Data Error", "modal");
                        uiwait(h_popup);
                    case "labelFromScratch:UserOmittedClassNames"
                        app.textout.Value = "Error: you have not entered any class names.";
                        h_popup = warndlg("Error: User did not enter classes.", "User entry error", "modal");
                        uiwait(h_popup);
                    otherwise
                        % General error handling
                        app.textout.Value = sprintf("Error: %s", ME.message);
                        warndlg(sprintf("Error encountered: %s", ME.message), "Error", "modal");
                end
                
                focus(app.InVivoKineticsUIFigure);
                return;
            end
            
        end

        % Button pushed function: DisplaystatehistogramButton
        function DisplaystatehistogramButtonPushed(app, event)
            %find the class ID
            class_number = strcmp(app.movie_data.params.class_names, app.EventstoviewDropDown.Value);
            class_number = find(class_number);
            
            %compile the labelled states in the system
            extractAllStates(app);
            
            if isempty(app.movie_data.results.InsightData.extractedStates{1, class_number})
                app.textout.Value = 'There is no data to show for this class';
                cla(app.UIAxes_compiled_events, 'reset');
                xlabel(app.UIAxes_compiled_events, 'Number of events');
                ylabel(app.UIAxes_compiled_events, 'Event duration (s)');
                title(app.UIAxes_compiled_events, 'Histogram of manually-labelled events');
                box(app.UIAxes_compiled_events, 'on');
                return
            end
            
            %compile the state histogram data
            data = gatherEventHistogramData(app, class_number);
            
            %transfer a copy to the results substruct of app handles for use elsewhere (e.g. CSV export)
            app.movie_data.results.current_histogram_data = data;
            
            if isempty(data)
                app.textout.Value = 'There is no data to display for these settings';
                cla(app.UIAxes_compiled_events, 'reset');
                xlabel(app.UIAxes_compiled_events, 'Number of events'); ylabel(app.UIAxes_compiled_events, 'Event duration (s)'); title(app.UIAxes_compiled_events, 'Histogram of manually-labelled events'); box(app.UIAxes_compiled_events, 'on');
            else
                %plot the histogram
                h = histogram(app.UIAxes_compiled_events, data(:,4), 'BinWidth', app.BinwidthsSpinner.Value);
                h.FaceColor = 'black';
                h.EdgeColor = 'white';
                h.LineWidth = 2;
                xlabel(app.UIAxes_compiled_events, 'Event duration (s)'); ylabel(app.UIAxes_compiled_events, 'Number of events');
                title(app.UIAxes_compiled_events, 'Histogram of manually-labelled events');
                box(app.UIAxes_compiled_events, 'on');
                app.UIAxes_compiled_events.YLim = [0 ceil(max(h.Values) * 1.1)];
                app.UIAxes_compiled_events.XLim = [0 max(h.BinEdges)];
            end
        end

        % Button pushed function: SaveillustrationButton
        function SaveillustrationButtonPushed(app, event)
            %save illlustration (and optionally data) as requested by user
            video_name = inputdlg('Please enter a name for the video', 'Saving video', [1 150], {strcat('[', char(app.CurrentlyloadeddatasetTextArea.Value), '] ')});
            if ~isempty(video_name)
                video_name = video_name{1};
                if isfield(app.movie_data.params, 'flipped') && app.movie_data.params.flipped   %maintaining backwards compatibility until import function for old save files is updated
                    flipped = true;
                else
                    flipped = false;
                end
                %save video
                illustrateMol(app.movie_data, app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.CellID,...
                    app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.MolID,...
                    app.PlayexportedvideoCheckBox.Value, 1, video_name, app.movie_data.params.video_upscaling_factor, flipped);
                
                app.textout.Value = "Illustration has been saved for cell " + num2str(app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.CellID) +...
                    ", molecule " + num2str(app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.MolID) + ".";
                
                %save molecule data to same location
                if app.SavealldatawithvideoCheckBox.Value == 1
                    saveMolCSV(app, app.movie_data.state.event_labeller_current_ID, app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.CellID,...
                        app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.MolID, video_name);
                    
                    app.textout.Value = "Illustration and data have been saved for cell " + num2str(app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.CellID) +...
                        ", molecule " + num2str(app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.MolID) + ".";
                end
            else
                app.textout.Value = 'Illustration has not been saved at the request of the user';
            end
            
        end

        % Button pushed function: backwardButton
        function backwardButtonPushed(app, event)
            col_t = findColumnIdx(app.movie_data.params.column_titles.tracks, 'Time from start of track (s)');
            
            %if video frame is > 1, move one frame to the left
            if app.movie_data.state.labeller_frame_video > 1
                new_timepoint = (app.movie_data.state.labeller_frame_video - 2) * (1/app.movie_data.params.frame_rate);
                %super-hacky
                [~, loc_idx, coords] = getNextAvailablePoint((new_timepoint - 0.00001),...
                    [0 : (1/app.movie_data.params.frame_rate) : app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(end, col_t)]',...
                    [app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(:, col_t)...
                    app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(:,app.movie_data.state.col_feature)]);
                
                %move the draggable line
                app.draggable_line.Position = [new_timepoint, min(ylim(app.UIAxes_event_labeller)); new_timepoint, max(ylim(app.UIAxes_event_labeller))];
                
                %update the video index, and display the previous video frame
                app.movie_data.state.labeller_frame_video = app.movie_data.state.labeller_frame_video - 1;
                app.updateVideoFrame(app.movie_data.state.labeller_frame_video);
                
                %work out if the localisation needs to be updated, and if so update the red circle and localisation index
                app.movie_data.state.labeller_track_pos = loc_idx;
                current_loc_obj = findobj(app.UIAxes_event_labeller, 'Tag', 'current_loc');
                current_loc_obj.XData = coords(1);
                current_loc_obj.YData = coords(2);
            end
            
            %not applying GUI refocusing here due to latency considerations
        end

        % Button pushed function: forwardButton
        function forwardButtonPushed(app, event)
            col_t = findColumnIdx(app.movie_data.params.column_titles.tracks, 'Time from start of track (s)');
            
            %if video frame is not the final frame, move one frame to the right
            if app.movie_data.state.labeller_frame_video < size(app.current_video,3)
                new_timepoint = (app.movie_data.state.labeller_frame_video) * (1/app.movie_data.params.frame_rate);
                %super-hacky
                [~, loc_idx, coords] = getNextAvailablePoint((new_timepoint - 0.00001), [0 : (1/app.movie_data.params.frame_rate) : app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(end, col_t)]', [app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(:, col_t) app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(:,app.movie_data.state.col_feature)]);
                
                %move the draggable line
                app.draggable_line.Position = [new_timepoint, min(ylim(app.UIAxes_event_labeller)); new_timepoint, max(ylim(app.UIAxes_event_labeller))];
                
                %update the video index, and display the previous video frame
                app.movie_data.state.labeller_frame_video = app.movie_data.state.labeller_frame_video + 1;
                app.updateVideoFrame(app.movie_data.state.labeller_frame_video);
                
                %work out if the localisation needs to be updated, and if so update the red circle and localisation index
                app.movie_data.state.labeller_track_pos = loc_idx;
                current_loc_obj = findobj(app.UIAxes_event_labeller, 'Tag', 'current_loc');
                current_loc_obj.XData = coords(1);
                current_loc_obj.YData = coords(2);
            end

            %not applying GUI refocusing here due to latency considerations
        end

        % Callback function
        function PlayexportedvideoCheckBoxValueChanged(app, event)
            app.movie_data.state.play_exported_video = app.PlayexportedvideoCheckBox.Value;
            if app.PlayexportedvideoCheckBox.Value
                app.textout.Value = "Video illustrations will now play after saving";
            else
                app.textout.Value = "Video illustrations will no longer play after saving";
            end
        end

        % Value changed function: BinwidthsSpinner
        function BinwidthsSpinnerValueChanged(app, event)
            app.movie_data.params.hist_bin_wid = app.BinwidthsSpinner.Value;
            app.textout.Value = "Histogram bin width has been set to " + num2str(app.BinwidthsSpinner.Value);
            DisplaystatehistogramButtonPushed(app);
        end

        % Button pushed function: DiscardmoleculeButton
        function DiscardmoleculeButtonPushed(app, event)
            %get IDs of cell to be deleted (this cannot be moved inside textout command below as the IDs would have been deleted)
            del_cell = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.CellID;
            del_mol = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.MolID;
            
            %delete the current entry from the next, and load the next
            app.movie_data.results.VisuallyLabelled.LabelledMols(app.movie_data.state.event_labeller_current_ID,:) = [];
            
            %notify user of deleted molecule
            app.textout.Value = "Molecule " + num2str(del_mol) + " in cell " + num2str(del_cell) + " was discarded";
            
            %if this is not the final molecule, display the next one
            if app.movie_data.state.event_labeller_current_ID < size(app.movie_data.results.VisuallyLabelled.LabelledMols,1) + 1
                repopulateEventLabeller(app);
            else
                app.textout.Value = "Molecule " + num2str(del_mol) + " in cell " + num2str(del_cell) + " was discarded." + newline +...
                    "You have completed labelling the dataset!";
                
                %increment current mol counter
                app.movie_data.state.event_labeller_current_ID = app.movie_data.state.event_labeller_current_ID + 1;
                
                %clear the displayed data in human annotation tab
                cla(app.UIAxes_event_labeller);
                cla(app.UIAxes_image_event_labeller);
                cla(app.UIAxes_event_labeller_mesh);
                cla(app.UIAxes_event_labeller_status);
                cla(app.UIAxes_labelling_progress);
                
                %redraw the progress bar as complete
                axis(app.UIAxes_labelling_progress, 'off');
                app.UIAxes_labelling_progress.YLim = [0 1];
                app.UIAxes_labelling_progress.XLim = [0 size(app.movie_data.results.VisuallyLabelled,1)];
                rectangle(app.UIAxes_labelling_progress, 'Position', [0, 0, 1, 1], 'EdgeColor','k', 'FaceColor', 'none');
                rectangle(app.UIAxes_labelling_progress, 'Position', [0, 0, size(app.movie_data.results.VisuallyLabelled, 1), 1], 'EdgeColor','none', 'FaceColor', 'g');
                text(app.UIAxes_labelling_progress, app.UIAxes_labelling_progress.XLim(2)/2, 0.5, "Annotation complete!", 'HorizontalAlignment', 'center');
                app.CellIDTextArea.Value = '';
                app.MolIDTextArea.Value  = '';
            end
            
            %return keyboard focus to the human annotation system
            focus(app.InVivoKineticsUIFigure);
        end

        % Button pushed function: ComputeDButton
        function ComputeDButtonPushed(app, event)
            app.textout.Value = "Computing MSDs for all labelled molecules";
            compileMSDsAllLabelled(app);
        end

        % Button pushed function: ExporthistogramButton
        function ExporthistogramButtonPushed(app, event)
            %obtain a suitable filename
            hist_name = string(datetime);
            hist_name = erase(hist_name,':');
            hist_name = strcat(hist_name, '_choose a file name.csv');
            
            %prompt user to modify it; then ensure they've not deleted extension
            hist_name = inputdlg('Please enter a name for the exported file', 'Export current histogram data', [1 35], hist_name);
            hist_name = hist_name{1};
            if ~strcmp('.csv', hist_name(end-3:end))
                hist_name = strcat(hist_name, '.csv');
            end
            
            if ~exist(fullfile(app.movie_data.params.ffPath, 'saved histogram data'), 'dir')
                mkdir(fullfile(app.movie_data.params.ffPath, 'saved histogram data'));
            end
            
            %write histogram data
            export_format = app.HistogramdatatoexportDropDown.Value;
            switch export_format
                case 'Raw data'
                    %write the underlying data used to generate the histogram
                    T = array2table([app.UIAxes_compiled_events.Children.Data]);
                    T.Properties.VariableNames = {'Event duration (s)'};
                    writetable(T, fullfile(app.movie_data.params.ffPath, 'saved histogram data', hist_name));
                    app.textout.Value = "The data used to generate the histogram has been exported to a CSV file.";
                case 'Only bins shown'
                    %write the data currently displayed in the histogram
                    T = array2table([app.UIAxes_compiled_events.Children.BinEdges(1,2:end)' app.UIAxes_compiled_events.Children.Values']);
                    T.Properties.VariableNames = {'Bin centre', 'Counts'};
                    writetable(T, fullfile(app.movie_data.params.ffPath, 'saved histogram data', hist_name));
                    app.textout.Value = "The bin centres and occupancies for the current histogram has been exported to a CSV file.";
                otherwise
                    error("IVK:ExporthistogramButtonPushed:UnknownExportFormat");
            end
            
        end

        % Button pushed function: RestarttrackButton
        function RestarttrackButtonPushed(app, event)
            app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(:, end) = -1;
            repopulateEventLabeller(app);
        end

        % Key press function: InVivoKineticsUIFigure
        function InVivoKineticsUIFigureKeyPress(app, event)
            key = event.Key;
            
            switch key
                case 'f'
                    forwardButtonPushed(app);
                case 'rightarrow'
                    forwardButtonPushed(app);
                case 'd'
                    backwardButtonPushed(app);
                case 'leftarrow'
                    backwardButtonPushed(app);
                case 's'
                    GotostartButtonPushed(app);
                case 'home'
                    GotostartButtonPushed(app);
                case 'e'
                    GotoendButtonPushed(app);
                case 'end'
                    GotoendButtonPushed(app);
                case 'u'
                    UndoButtonPushed(app);
                case 'backspace'
                    DiscardmoleculeButtonPushed(app);
                case 'delete'
                    DiscardmoleculeButtonPushed(app);
                case 'm'
                    MoreinfoButtonPushed(app);
                case 'i'
                    SaveillustrationButtonPushed(app);
                case 't'
                    TogglesecondaryfeatureButtonValueChanged(app);
                otherwise
                    if ismember(key, {'1','2','3','4','5','6','7','8','9', 'numpad1', 'numpad2', 'numpad3', 'numpad4', 'numpad5', 'numpad6', 'numpad7', 'numpad8', 'numpad9'})
                        if contains(key, 'numpad')
                            label_key = str2double(key(end));
                        else
                            label_key = str2double(key);
                        end
                        activateStateLabelButtonByKeypress(app, label_key);
                    end
            end
        end

        % Button pushed function: GeneratetrainingsetButton
        function GeneratetrainingsetButtonPushed(app, event)
            if strcmp(app.SourcedataDropDown.Value, "<< Select dataset >>")
                app.textout.Value = "Training set not generated: you must first select a valid data source from the dropdown menu in this tab";
                return;
            end
            
            if isempty(app.MLfeatures.CheckedNodes)
                app.textout.Value = "You must select features from the list of features on the left hand menu in the [ML Classification] tab. If none are present move to another tab and return to refresh the list.";
                warndlg("You must select features from the list of features on the left hand menu in the [ML Classification] tab. If none are present move to another tab and return to refresh the list.", "No features selected!");
                return;
            end
            
            %wipe previous feature scaling variables
            app.movie_data.models.temp_params = [];
            
            %obtain the inputs for shuffling, and splitting, the training data
            app.movie_data.models.temp_params.data_split = str2double(strsplit(app.TrainingvalidationtestsplitEditField.Value, ','));
            
            %check the data split adds to one
            if sum(app.movie_data.models.temp_params.data_split) ~= 1
                msgbox('The sum of the data split between training, validation, and test data is not currently 1. Please adjust the split to sum to 1 and try again.', 'Error', 'warn');
                app.textout.Value = "You have entered an incorrect split between traning, validation, and test data. Please update these values and try again.";
                return;
            end
            
            %perform feature scaling on labelled data; produces a new cell array FeatureScaledData inside the results substruct, which ignores unlabelled molecules, and crops trajectories according to selected feature types
            success = scaleLabelledFeatures(app);
            if ~success
                app.textout.Value = "Generation of training data was not successful; please clear and reload data, and try again.";
                return;
            end
            
            %erase any previous training data; handles cases where the user may train a model with validation data, followed by without, the previous validation dataset would persist
            [app.movie_data.results.labelled_data, app.movie_data.results.train_data, app.movie_data.results.val_data, app.movie_data.results.test_data,...
                app.movie_data.results.ref_labelled_data, app.movie_data.results.ref_train_data, app.movie_data.results.ref_val_data, app.movie_data.results.ref_test_data] = deal([]);
            
            %shuffle the scaled data
            shuffleScaledData(app);
            
            %split the data into training, validation, and test datasets
            splitData(app);
            
            %reformat the training data
            switch app.TracksamplingDropDown.Value
                case "Random subsampling (recommended)"
                    app.textout.Value = "Processing training data with a sliding window";
                    reformatToSpans(app);
                    app.movie_data.results.padding = false;
                case "Non-overapping segments"
                    app.textout.Value = "Splitting training data into discrete segments";
                    reformatToSpans(app);
                    app.movie_data.results.padding = false;
                case "Whole tracks"
                    app.movie_data.results.padding = true;
                otherwise
                    
            end
            
            %obliterate intermediate struct FeatureScaledData as no longer required
            app.movie_data.results.FeatureScaledData.LabelledMols = {};
            
            app.textout.Value = "Generated training set";
        end

        % Button pushed function: TrainMLmodelButton
        function TrainMLmodelButtonPushed(app, event)
            %check the training data exists
            if ~isprop(app, 'movie_data') || ~isfield(app.movie_data, 'results') || ~isfield(app.movie_data.results, 'train_data') || ~size(app.movie_data.results.train_data, 1) > 0
                warndlg("No training data is currently loaded, please load the data before continuing","Warning");
                return;
            end
            
            %temporary redirection of most requests to trainRNN, which is a refactored version of trainModel; trainModel is temporarily still required to handle cross-validation and RF model
            if strcmp(app.TrainingmodeDropDown.Value, "Final training") && (strcmp(app.ModeltypeDropDown.Value, "Gated Recurrent Unit (GRU)") || strcmp(app.ModeltypeDropDown.Value, "Bidirectional Gated Recurrent Unit (BiGRU)") || strcmp(app.ModeltypeDropDown.Value, "Long Short-Term Memory (LSTM)") || strcmp(app.ModeltypeDropDown.Value, "Bidirectional LSTM (BiLSTM)"))
                trainRNN(app);
            else
                trainModel(app);
                
                %automatically prompt user to save model
                saveModel(app);
            end
        end

        % Selection change function: MLControlTabs
        function MLControlTabsSelectionChanged(app, event)
            if isprop(app, 'movie_data') && isfield(app.movie_data, 'params')
                selected_tab = app.MLControlTabs.SelectedTab.Title;
    
                %code to run when user moves between tabs
                switch selected_tab
                    case "Generate training data"
                        
                    case "Train models"
                        
                    case "Visualisation"
                        
                        if isfield(app.movie_data, "results") && isfield(app.movie_data, "models") && isfield(app.movie_data.models, "current_model")
                            
                            if strcmp(app.movie_data.models.current_model, "Random forest")
                                app.XaxisDropDown.Items = app.movie_data.models.RF.feature_names;
                                app.XaxisDropDown.Value = app.movie_data.models.RF.feature_names(1);
                                app.YaxisDropDown.Items = app.movie_data.models.RF.feature_names;
                                app.YaxisDropDown.Value = app.movie_data.models.RF.feature_names(2);
                                app.ZaxisDropDown.Items = app.movie_data.models.RF.feature_names;
                                
                                %add 'Do not display' option to handle 2D case
                                app.ZaxisDropDown.Items = cat(2, app.movie_data.models.RF.feature_names, "Do not display");
                                app.ZaxisDropDown.Value = app.movie_data.models.RF.feature_names(3);
                                
                            elseif strcmp(app.movie_data.models.current_model, "Gated Recurrent Unit")
                                app.XaxisDropDown.Items = app.movie_data.models.GRU.feature_names;
                                app.XaxisDropDown.Value = app.movie_data.models.GRU.feature_names(1);
                                app.YaxisDropDown.Items = app.movie_data.models.GRU.feature_names;
                                app.YaxisDropDown.Value = app.movie_data.models.GRU.feature_names(2);
                                app.ZaxisDropDown.Items = app.movie_data.models.GRU.feature_names;
                                
                                %add 'Do not display' option to handle 2D case
                                app.ZaxisDropDown.Items = cat(2, app.movie_data.models.GRU.feature_names, "Do not display");
                                app.ZaxisDropDown.Value = app.movie_data.models.GRU.feature_names(3);
                            
                            elseif strcmp(app.movie_data.models.current_model, "Bidirectional Gated Recurrent Unit")
                                app.XaxisDropDown.Items = app.movie_data.models.BiGRU.feature_names;
                                app.XaxisDropDown.Value = app.movie_data.models.BiGRU.feature_names(1);
                                app.YaxisDropDown.Items = app.movie_data.models.BiGRU.feature_names;
                                app.YaxisDropDown.Value = app.movie_data.models.BiGRU.feature_names(2);
                                app.ZaxisDropDown.Items = app.movie_data.models.BiGRU.feature_names;
                                
                                %add 'Do not display' option to handle 2D case
                                app.ZaxisDropDown.Items = cat(2, app.movie_data.models.BiGRU.feature_names, "Do not display");
                                app.ZaxisDropDown.Value = app.movie_data.models.BiGRU.feature_names(3);

                            elseif strcmp(app.movie_data.models.current_model, "Long Short-Term Memory")
                                app.XaxisDropDown.Items = app.movie_data.models.LSTM.feature_names;
                                app.XaxisDropDown.Value = app.movie_data.models.LSTM.feature_names(1);
                                app.YaxisDropDown.Items = app.movie_data.models.LSTM.feature_names;
                                app.YaxisDropDown.Value = app.movie_data.models.LSTM.feature_names(2);
                                app.ZaxisDropDown.Items = app.movie_data.models.LSTM.feature_names;
                                
                                %add 'Do not display' option to handle 2D case
                                app.ZaxisDropDown.Items = cat(2, app.movie_data.models.LSTM.feature_names, "Do not display");
                                app.ZaxisDropDown.Value = app.movie_data.models.LSTM.feature_names(3);

                            elseif strcmp(app.movie_data.models.current_model, "Bidirectional Long Short-Term Memory")
                                app.XaxisDropDown.Items = app.movie_data.models.BiLSTM.feature_names;
                                app.XaxisDropDown.Value = app.movie_data.models.BiLSTM.feature_names(1);
                                app.YaxisDropDown.Items = app.movie_data.models.BiLSTM.feature_names;
                                app.YaxisDropDown.Value = app.movie_data.models.BiLSTM.feature_names(2);
                                app.ZaxisDropDown.Items = app.movie_data.models.BiLSTM.feature_names;
                                
                                %add 'Do not display' option to handle 2D case
                                app.ZaxisDropDown.Items = cat(2, app.movie_data.models.BiLSTM.feature_names, "Do not display");
                                app.ZaxisDropDown.Value = app.movie_data.models.BiLSTM.feature_names(3);
                                
                            end
                        
                        else
                            %if no current model is selected
                            if isfield(app.movie_data.models, "temp_params") && isfield(app.movie_data.models.temp_params, "feature_names")
                                app.XaxisDropDown.Items = app.movie_data.models.temp_params.feature_names;
                                app.XaxisDropDown.Value = app.movie_data.models.temp_params.feature_names(1);
                                app.YaxisDropDown.Items = app.movie_data.models.temp_params.feature_names;
                                app.YaxisDropDown.Value = app.movie_data.models.temp_params.feature_names(2);
                                app.ZaxisDropDown.Items = app.movie_data.models.temp_params.feature_names;
                                
                                %add 'Do not display' option to handle 2D case
                                app.ZaxisDropDown.Items = cat(2, app.movie_data.models.temp_params.feature_names, "Do not display");
                                app.ZaxisDropDown.Value = app.movie_data.models.temp_params.feature_names(3);
                            end

                        end
                    
                    otherwise
                        
                end
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            %get the features currently selected in the dropdown menus
            if ~strcmp(app.ZaxisDropDown.Value, "Do not display")
                %3D plot
                features_to_plot_names = [{app.XaxisDropDown.Value}, {app.YaxisDropDown.Value}, {app.ZaxisDropDown.Value}];
                features_to_plot_idx = [find(strcmp(app.movie_data.models.temp_params.feature_names, app.XaxisDropDown.Value)),...
                                    find(strcmp(app.movie_data.models.temp_params.feature_names, app.YaxisDropDown.Value)),...
                                    find(strcmp(app.movie_data.models.temp_params.feature_names, app.ZaxisDropDown.Value))];
            else
                %2D plot
                features_to_plot_names = [{app.XaxisDropDown.Value}, {app.YaxisDropDown.Value}];
                features_to_plot_idx = [find(strcmp(app.movie_data.models.temp_params.feature_names, app.XaxisDropDown.Value)),...
                                    find(strcmp(app.movie_data.models.temp_params.feature_names, app.YaxisDropDown.Value))];
            end
            
            %compile an array of handles for reading the UIAxes limits
            h_array = {app.MLDataView, app.XLimLowSpinner, app.XLimHighSpinner,...
                                       app.YLimLowSpinner, app.YLimHighSpinner,...
                                       app.ZLimLowSpinner, app.ZLimHighSpinner};
            
            %clarify the state of the MLDataView UIAxes component
            if ~isfield(app.movie_data.state, "data_viewer_content")
                app.movie_data.state.data_viewer_content = "Visualisation";
            end
            
            switch app.DatatodisplayDropDown.Value
                case 'All user-classified data'
                    updateMLPlot(app.MLDataView, app.movie_data.results.ref_labelled_data, features_to_plot_idx,...
                        features_to_plot_names,...
                        app.movie_data.params.class_names, app.movie_data.params.event_label_colours, 'All user classified data', app.MaxpointstodisplaySpinner.Value, app.movie_data.state.data_viewer_content);
                    
                    %read the limits in the axes, and update in GUI
                    readLims(h_array);
                    
                case 'Training data'
                    updateMLPlot(app.MLDataView, app.movie_data.results.ref_train_data, features_to_plot_idx,...
                        features_to_plot_names,...
                        app.movie_data.params.class_names, app.movie_data.params.event_label_colours, 'Training data labelled by user', app.MaxpointstodisplaySpinner.Value, app.movie_data.state.data_viewer_content);
                    
                    %read the limits in the axes, and update in GUI
                    readLims(h_array);

                case 'Validation data'
                    updateMLPlot(app.MLDataView, app.movie_data.results.ref_val_data, features_to_plot_idx,...
                        features_to_plot_names,...
                        app.movie_data.params.class_names, app.movie_data.params.event_label_colours, 'Validation data labelled by user', app.MaxpointstodisplaySpinner.Value, app.movie_data.state.data_viewer_content);
                    
                    %read the limits in the axes, and update in GUI
                    readLims(h_array);

                case 'Test data'
                    updateMLPlot(app.MLDataView, app.movie_data.results.ref_test_data, features_to_plot_idx,...
                    features_to_plot_names,...
                    app.movie_data.params.class_names, app.movie_data.params.event_label_colours, 'Test data labelled by user', app.MaxpointstodisplaySpinner.Value, app.movie_data.state.data_viewer_content);
                    
                    %read the limits in the axes, and update in GUI
                    readLims(h_array);

                case 'ML classified data'
                    cla(app.MLDataView);
            end
            
            app.movie_data.state.data_viewer_content = 'Visualisation';

        end

        % Button pushed function: PanButton
        function PanButtonPushed(app, event)
            zoom(app.MLDataView, 'off');
            rotate3d(app.MLDataView, 'off');
            pan(app.MLDataView, 'on');
        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            rotate3d(app.MLDataView, 'off');
            pan(app.MLDataView, 'off');
            zoom(app.MLDataView, 'on');
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            pan(app.MLDataView, 'off');
            zoom(app.MLDataView, 'off');
            rotate3d(app.MLDataView, 'on');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %initialise limits
            min_x = Inf; max_x = -Inf;
            min_y = Inf; max_y = -Inf;
            min_z = Inf; max_z = -Inf;
            
            %extract plots currently held by the ML data viewer
            children = app.MLDataView.Children;
            
            for ii = 1:length(children)
                %check it's a scsatter plot, and whether it exceeds current limits
                if isa(children(ii), 'matlab.graphics.chart.primitive.Scatter')
                    min_x = min(min_x, min(children(ii).XData));
                    max_x = max(max_x, max(children(ii).XData));
                    min_y = min(min_y, min(children(ii).YData));
                    max_y = max(max_y, max(children(ii).YData));
                    min_z = min(min_z, min(children(ii).ZData));
                    max_z = max(max_z, max(children(ii).ZData));
                end
            end
            
            %reset the axes limits
            xlim(app.MLDataView, [min_x, max_x]);
            ylim(app.MLDataView, [min_y, max_y]);
            
            %reset values in GUI axis limit controls
            app.XLimLowSpinner.Value    = min_x;
            app.XLimHighSpinner.Value   = max_x;
            app.YLimLowSpinner.Value    = min_y;
            app.YLimHighSpinner.Value   = max_y;
            
            %zlim contains no data for 2D plots, if so use zlim([0 1])
            if min_z < Inf
                %reset the z-axis limits
                zlim(app.MLDataView, [min_z, max_z]);
                
                %reset values in GUI axis limit controls
                app.YLimLowSpinner.Value    = min_y;
                app.YLimHighSpinner.Value   = max_y;

                %reset the angle of the 3D viewport
                view(app.MLDataView, [45 30]);
                zoom(app.MLDataView, 'reset');
            else
                %set z-axis limits as 0 to 1
                zlim(app.MLDataView, [0, 1]);
                
                %set values in GUI z-axis limit controls as 0 to 1
                app.ZLimLowSpinner.Value    = 0;
                app.ZLimHighSpinner.Value   = 1;

                %change view to X-Y plane
                view(app.MLDataView, [0 90]);
            end
            
        end

        % Button pushed function: XYButton
        function XYButtonPushed(app, event)
            view(app.MLDataView, [0 90]);
        end

        % Button pushed function: XZButton
        function XZButtonPushed(app, event)
            view(app.MLDataView, [0 0]);
        end

        % Button pushed function: YZButton
        function YZButtonPushed(app, event)
            view(app.MLDataView, [90 0]);
        end

        % Button pushed function: ClassifywithmodelButton
        function ClassifywithmodelButtonPushed(app, event)
            %this logic of looking to see if the model was trained
            %in windowed mode should could be replaced with logic
            %that instead looks at the size of the input layer and
            %the longest trajectory; this may be useful in cases
            %where a molecule longer than the input layer is
            %present in the unseen data being labelled

            switch app.movie_data.models.current_model
                case "RF"
                    labelWithRF(app);

                case "GB"
                    

                case "GRU"
                    if app.movie_data.models.GRU.windowed == true
                        labelWithSlidingWindow(app, 'GRU');
                    elseif app.movie_data.models.GRU.windowed == false
                        labelWithGRU(app);
                    else
                        % << error: windowed nature was not recorded >>
                    end

                case "BiGRU"
                    if app.movie_data.models.BiGRU.windowed == true
                        labelWithSlidingWindow(app, 'BiGRU');
                    elseif app.movie_data.models.BiGRU.windowed == false
                        labelWithBiGRU(app);
                    else
                        % << error: windowed nature was not recorded >>
                    end
                    
                case "LSTM"
                    if app.movie_data.models.LSTM.windowed == true
                        labelWithSlidingWindow(app, 'LSTM');
                    elseif app.movie_data.models.LSTM.windowed == false
                        labelWithLSTM(app);
                    else
                        % << error: windowed nature was not recorded >>
                    end
                    
                case "BiLSTM"
                    if app.movie_data.models.BiLSTM.windowed == true
                        labelWithSlidingWindow(app, 'BiLSTM');
                    elseif app.movie_data.models.BiLSTM.windowed == false
                        labelWithBiLSTM(app);
                    else
                        % << error: windowed nature was not recorded >>
                    end
                    
                otherwise
                
            end
            
            %compare labels to human classification
            if isfield(app.movie_data.results, 'GroundTruth')
                computeAnnotationMetrics(app, {'GroundTruth'; char(strcat(app.movie_data.models.current_model, 'Labelled'))});
            else
                computeAnnotationMetrics(app, {'VisuallyLabelled'; char(strcat(app.movie_data.models.current_model, 'Labelled'))});
            end
        end

        % Button pushed function: SavemodelButton
        function SavemodelButtonPushed(app, event)
            saveModel(app);
        end

        % Button pushed function: GeneratetrackgalleryButton
        function GeneratetrackgalleryButtonPushed(app, event)
            app.StopgalleryButton.Value = false;
            viewAllMols(app);
        end

        % Button pushed function: DisplayalloverlayedeventsButton
        function DisplayalloverlayedeventsButtonPushed(app, event)
            %handling user error
            if strcmp(app.EventoverlayerSOI.Value, "<< Select state >>")
                warndlg("You must select a state of interest into and out of which you intend to study entrances and exits. Please select a state from the dropdown box in the [Event overlayer] subtab inside the [Insights] tab.", "No state selected!", "modal");
                app.textout.Value = "You must select a state of interest into and out of which you intend to study entrances and exits. Please select a state from the dropdown box in the [Event overlayer] subtab inside the [Insights] tab.";
                return;
            end
            if strcmp(app.EventOverlayerFeatureDropDown.Value, "<< Select state >>")
                warndlg("You must select a feature to study. Please select from the feature dropdown box in the [Event overlayer] subtab inside the [Insights] tab.", "No state selected!", "modal");
                app.textout.Value = "You must select a feature to study. Please select from the feature dropdown box in the [Event overlayer] subtab inside the [Insights] tab.";
                return;
            end
            
            %obtain feature of interest
            feature_col = findColumnIdx(app.movie_data.params.column_titles.tracks, app.EventOverlayerFeatureDropDown.Value);
            if feature_col == 0
                app.textout.Value = "Error: invalid feature, please select a valid feature to plot from the feature selection box in the event overlayer.";
                warndlg("Error: invalid feature, please select a valid feature to plot from the feature selection box in the event overlayer.", "Invalid feature ID");
                return;
            end
            feature_name = app.movie_data.params.column_titles.tracks(feature_col);
            
            %obtain state of interest
            state_ID = find(strcmp(app.EventoverlayerSOI.Value, app.movie_data.params.class_names), 1);
            if isempty(state_ID)
                warndlg("Please select a valid state of interest. This is the state into and out of which you intend to study entrances and exits.", "State of interest not selected!");
                app.textout.Value = "Please select a valid state of interest. This is the state into and out of which you intend to study entrances and exits.";
                return;
            end
            state_name  = string(app.movie_data.params.class_names(state_ID));
            
            %call event overlayer function
            display_range   = [app.StarttimerelativetoeventsSpinner.Value app.EndtimerelativetoeventsSpinner.Value];    %set range of time values over which to plot, and to generate video
            [data_in, data_out, t] = genEventOverlays(app.UIAxes_compiled_events,...
                app.movie_data.results.InsightData.LabelledMols,...
                state_ID,...
                app.movie_data.params.frame_rate,...
                feature_col,...
                feature_name,...
                app.Overlayerstyle.Value,...
                display_range,...
                app.AutoaticallysetfeatureaxislimitsCheckBox.Value,...
                app.OverridefeatureaxislowerlimitSpinner.Value,...
                app.OverridefeatureaxisupperlimitSpinner.Value,...
                app.movie_data.params.ffPath,...
                app.SaveplotteddataCheckBox.Value,...
                state_name);
                
            %generate video if required
            if app.GeneratevideoCheckBox.Value
                %strip the zeros from either side of the aligned matrices; note that t is then different for the entries and exits
                [data_in, t_in_trimmed]     = trimAlignedMatrix(data_in, t);
                [data_out, t_out_trimmed]   = trimAlignedMatrix(data_out, t);
                
                %set parameters for histogram video generation - a future version may replace this with GUI input; perhaps with a pre-filled value to match the source video frame rate
                frame_delay = 0.2;      %time between frames of gif video
                
                %construct the full path with the subdirectory
                suggested_path = fullfile(app.movie_data.params.ffPath, 'Aligned event videos');
                
                if ~exist(suggested_path, 'dir')
                    mkdir(suggested_path);
                end
                
                %save file
                app.textout.Value    = "Please select a file name and location to save the generated video of events.";
                suggested_filename = string(app.movie_data.params.title) + " aligned event histogram video";
                [file, path] = uiputfile({'*.gif', 'Save event overlay video (*.gif)'}, 'Save event overlay video as', fullfile(suggested_path, suggested_filename));
                
                %check if the user pressed cancel
                if isequal(file, 0) || isequal(path, 0)
                    disp('User pressed cancel.');
                else
                    file_pathname = fullfile(path, file);
                    
                    %generate, save, and display the aligned videos
                    if strcmp(app.EntriesorexitsDropDown.Value, "Entries")
                        genAlignedVideo(data_in, t_in_trimmed, display_range, app.AutoaticallysetfeatureaxislimitsCheckBox.Value, app.OverridefeatureaxislowerlimitSpinner.Value, app.OverridefeatureaxisupperlimitSpinner.Value, app.EventhistogramvideobinwidthSpinner.Value, frame_delay, file_pathname, feature_name);
                        web(file_pathname);
                    elseif strcmp(app.EntriesorexitsDropDown.Value, "Exits")
                        genAlignedVideo(data_out, t_out_trimmed, display_range, app.AutoaticallysetfeatureaxislimitsCheckBox.Value, app.OverridefeatureaxislowerlimitSpinner.Value, app.OverridefeatureaxisupperlimitSpinner.Value, app.EventhistogramvideobinwidthSpinner.Value, frame_delay, file_pathname, feature_name);
                        web(file_pathname);
                    end
                end
            end
        end

        % Value changed function: FilteragainstDropDown
        function FilteragainstDropDownValueChanged(app, event)
            app.movie_data.params.filter_against = app.FilteragainstDropDown.Value;
            app.textout.Value = strcat('Filtering against', {' '}, num2str(app.movie_data.params.filter_against));
        end

        % Value changed function: FiltertoleranceSpinner
        function FiltertoleranceSpinnerValueChanged(app, event)
            app.movie_data.params.filter_tolerance = app.FiltertoleranceSpinner.Value;
            app.textout.Value = strcat('Localisation filtering tolerance', {' '}, num2str(app.movie_data.params.filter_tolerance));
        end

        % Button pushed function: UpdatedescriptivestatisticsButton
        function UpdatedescriptivestatisticsButtonPushed(app, event)
            computeDescriptiveStats(app);
        end

        % Button pushed function: AddreferencelineButton
        function AddreferencelineButtonPushed(app, event)
            if isfield(app.movie_data.params, 'reference_lines')
                app.movie_data.params.reference_lines = cat(1, app.movie_data.params.reference_lines, app.ReferencelineEditField.Value);
            else
                app.movie_data.params.reference_lines = app.ReferencelineEditField.Value;
            end
            yline(app.UIAxes_event_labeller, app.movie_data.params.reference_lines, '--', string(app.movie_data.params.reference_lines) + "  ", 'Color', app.ReferencelinecolourDropDown.Value, 'LineWidth', app.ReferencelinethicknessSpinner.Value);
            
            setLabellerAxesRange(app);
        end

        % Button pushed function: ClearreferencelinesButton
        function ClearreferencelinesButtonPushed(app, event)
            app.movie_data.params = rmfield(app.movie_data.params, 'reference_lines');
            repopulateEventLabeller(app);
        end

        % Button pushed function: PlotalltrajectorylengthsButton
        function PlotalltrajectorylengthsButtonPushed(app, event)
            t_obs = [];   %should pre-alocate
            
            %loop over cells
            for ii = 1:size(app.movie_data.cellROI_data, 1)
                if isempty(app.movie_data.cellROI_data(ii).tracks)
                    continue;
                end

                %loop over tracks
                track_IDs = unique(app.movie_data.cellROI_data(ii).tracks(:, 4));
                for jj = 1:size(track_IDs, 1)
                    t_obs = cat(1, t_obs, sum(app.movie_data.cellROI_data(ii).tracks(:, 4)==track_IDs(jj)));
                end
            end
            
            %apply frame rate, and plot histogram
            t_obs = t_obs ./ app.movie_data.params.frame_rate;
            h = histogram(app.UIAxes_compiled_events, t_obs, 'BinWidth', app.TrajectorylengthbinsizessSpinner.Value);
            app.UIAxes_compiled_events.YLim = [0 ceil(max(h.Values) * 1.1)];
            app.UIAxes_compiled_events.XLim = [0 max(h.BinEdges) + app.Binwidtharrivaltime.Value/5];    %add 1/5th of a bin to make plot more visible
            xlabel(app.UIAxes_compiled_events, "Molecule duration (s)"); ylabel(app.UIAxes_compiled_events, "Number of molecules");
            title(app.UIAxes_compiled_events, "Molecule observation times prior to filtering (N = " + num2str(size(t_obs, 1)) + ")");
            h.FaceColor = 'black';
            h.EdgeColor = 'white';
            h.LineWidth = 2;
            box(app.UIAxes_compiled_events, 'on');
        end

        % Button pushed function: StatetransitiondiagramButton
        function StatetransitiondiagramButtonPushed(app, event)
            [state_times, ~]        = computeTotalStateTimes(app.movie_data);
            [transition_rates, ~]   = computeTransitionRates(app.movie_data);
            drawStateDiagram(app.movie_data.params.class_names, state_times, transition_rates, app.movie_data.params.event_label_colours)
        end

        % Button pushed function: DisplaystepsizeprofileButton
        function DisplaystepsizeprofileButtonPushed(app, event)
            N_lim = app.MaximumstepsizesSpinner.Value;
            computeStepSizeProfile(app.movie_data, N_lim, app.UIAxes_compiled_events);
        end

        % Button pushed function: VisittheGitHubrepositoryButton
        function VisittheGitHubrepositoryButtonPushed(app, event)
            app.textout.Value = "Directing you to the GitHub repository";
            web('http://github.com/opambos', '-browser');
        end

        % Button pushed function: ClearanalysisButton
        function ClearanalysisButtonPushed(app, event)
            %return app to original state, and inform user of the need to load new data or analysis file
            
            %clear main struct
            app.movie_data = struct();
            
            %clear text fields
            app.CurrentlyloadeddatasetTextArea.Value    = "";
            app.FileoverviewTextArea.Value              = "";
            app.DataanalysisnotesTextArea.Value         = "";
            app.CellIDTextArea.Value                    = "";
            app.MolIDTextArea.Value                     = "";
            app.ReferencelineEditField.Value            = 0;
            app.CurrentmodelfileTextArea.Value          = "Internal or not loaded";
            app.DatasetforclassificationTextArea.Value  = "";
            
            %clear UIAxes
            cla(app.UIAxes_event_labeller, "reset");
            cla(app.UIAxes_compiled_events);
            cla(app.UIAxes_image_event_labeller);
            cla(app.UIAxes_event_labeller_mesh);
            cla(app.UIAxes_labelling_progress);
            cla(app.UIAxes_event_labeller_status);
            cla(app.AnnotationUIAxes, "reset");     %using reset here due to use of yyaxis right
            
            %clear table of descriptive stats
            app.DescriptivestatsTable.Data = [];
            
            %clear event labelling buttons
            delete(app.Event_label_buttons.Children);
            
            %clear ML features list
            a = app.MLfeatures.Children;
            a.delete;
            
            app.EventstoviewDropDown.Items  = {'<< No data >>'};
            app.EventoverlayerSOI.Items     = {'<< No data >>'};
            
            %notify user
            app.textout.Value = "All data has been cleared. Please start by loading new data or an existing analysis file.";
        end

        % Button pushed function: SaveanalysisButton
        function SaveanalysisButtonPushed(app, event)
            saveAnalysis(app);

            %return keyboard focus to the human annotation system
            focus(app.InVivoKineticsUIFigure);
        end

        % Value changed function: XLimLowSpinner
        function XLimLowSpinnerValueChanged(app, event)
            x_lo = app.XLimLowSpinner.Value;
            x_limits = xlim(app.MLDataView);
            xlim(app.MLDataView, [x_lo x_limits(2)]);
        end

        % Value changed function: XLimHighSpinner
        function XLimHighSpinnerValueChanged(app, event)
            x_hi = app.XLimHighSpinner.Value;
            x_limits = xlim(app.MLDataView);
            xlim(app.MLDataView, [x_limits(1) x_hi]);
        end

        % Value changed function: YLimLowSpinner
        function YLimLowSpinnerValueChanged(app, event)
            y_lo = app.YLimLowSpinner.Value;
            y_limits = ylim(app.MLDataView);
            ylim(app.MLDataView, [y_lo y_limits(2)]);
        end

        % Value changed function: YLimHighSpinner
        function YLimHighSpinnerValueChanged(app, event)
            y_hi = app.YLimHighSpinner.Value;
            y_limits = ylim(app.MLDataView);
            ylim(app.MLDataView, [y_limits(1) y_hi]);
        end

        % Value changed function: ZLimLowSpinner
        function ZLimLowSpinnerValueChanged(app, event)
            z_lo = app.ZLimLowSpinner.Value;
            z_limits = zlim(app.MLDataView);
            zlim(app.MLDataView, [z_lo z_limits(2)]);
        end

        % Value changed function: ZLimHighSpinner
        function ZLimHighSpinnerValueChanged(app, event)
            z_hi = app.ZLimHighSpinner.Value;
            z_limits = zlim(app.MLDataView);
            zlim(app.MLDataView, [z_limits(1) z_hi]);
        end

        % Button pushed function: LoadexistingmodelButton
        function LoadexistingmodelButtonPushed(app, event)
            %user provides file containing saved model
            app.textout.Value = "Please select a saved trained model.";
            [filename, pathname] = uigetfile({'*.mat', 'Select a saved model (*.mat)'}, 'Load file containing trained model');
            
            %check if the user pressed 'Cancel'
            if isequal(filename, 0) || isequal(pathname, 0)
                app.textout.Value = "User pressed cancel";
                return;
            else
                full_filepath = fullfile(pathname, filename);

                %load the struct from the file
                loaded_data = load(full_filepath);

                %check if the loaded data contains required fields; if so load it into the struct overwriting any existing model
                if isfield(loaded_data, 'model_data') && isfield(loaded_data.model_data, 'model')
                    switch loaded_data.model_data.model.model_type
                        case "GRU"
                            app.movie_data.models.GRU = loaded_data.model_data.model;
                            app.movie_data.models.current_model = "GRU";
                        case "BiGRU"
                            app.movie_data.models.BiGRU = loaded_data.model_data.model;
                            app.movie_data.models.current_model = "BiGRU";
                        case "RF"
                            app.movie_data.models.RF = loaded_data.model_data.model;
                            app.movie_data.models.current_model = "RF";
                        case "LSTM"
                            app.movie_data.models.LSTM = loaded_data.model_data.model;
                            app.movie_data.models.current_model = "LSTM";
                        case "BiLSTM"
                            app.movie_data.models.BiLSTM = loaded_data.model_data.model;
                            app.movie_data.models.current_model = "BiLSTM";
                        otherwise
                            
                    end
                    
                    app.textout.Value = "Model loaded from " + full_filepath;
                else
                    app.textout.Value = "Invalid file format";
                end
            end
            
            focus(app.InVivoKineticsUIFigure);
        end

        % Callback function: MLfeatures
        function MLfeaturesCheckedNodesChanged(app, event)
            identifyExcludedSteps(app);
        end

        % Button pushed function: MoreinfoButton
        function MoreinfoButtonPushed(app, event)
            plotMore(app);
        end

        % Selection change function: EventlabellersettingsTab
        function EventlabellersettingsTabSelectionChanged(app, event)
            selected_tab = app.EventlabellersettingsTab.SelectedTab;
            
            %code to run when user moves between tabs
            if strcmp(selected_tab.Title, 'Plot settings')
                
                %repopulate the features lists for user selection of all types of plotting (in the UIAxes and external additional info plot)
                if isprop(app, 'movie_data') && isfield(app.movie_data, 'params') && isfield(app.movie_data.params, 'column_titles')
                    %repopulate the list of features to plot inside the UIAxes
                    app.PrimaryFeatureDropDown.Items    = app.movie_data.params.column_titles.tracks(1:end-1);
                    app.SecondaryFeatureDropDown.Items  = ['<< None >>', app.movie_data.params.column_titles.tracks(1:end-1)];

                    %repopulate the checklist for external plotting
                    a = app.PlotMoreFeatures.Children;
                    a.delete;
                    for ii = 1:(size(app.movie_data.params.column_titles.tracks,2) - 1)
                        features(ii) = uitreenode(app.PlotMoreFeatures);
                        features(ii).Text = string(app.movie_data.params.column_titles.tracks(ii));
                    end
                end
                
            end
        end

        % Value changed function: PrimaryFeatureDropDown
        function PrimaryFeatureDropDownValueChanged(app, event)
            repopulateEventLabeller(app);
        end

        % Value changed function: PrimaryLinecolourDropDown
        function PrimaryLinecolourDropDownValueChanged(app, event)
            new_colour = app.PrimaryLinecolourDropDown.Value;

            %find the line object, and change it's colour
            line_obj = findobj(app.UIAxes_event_labeller, 'Tag', 'step_trace');
            
            if ~isempty(line_obj)
                line_obj.Color = new_colour;
            end
        end

        % Value changed function: PrimaryLinethicknessSpinner
        function PrimaryLinethicknessSpinnerValueChanged(app, event)
            new_thickness = app.PrimaryLinethicknessSpinner.Value;

            %find the line object, and change the line thickness
            line_obj = findobj(app.UIAxes_event_labeller, 'Tag', 'step_trace');
            
            if ~isempty(line_obj)
                line_obj.LineWidth = new_thickness;
            end
        end

        % Value changed function: PrimarySetlowerlimttozeroCheckBox
        function PrimarySetlowerlimttozeroCheckBoxValueChanged(app, event)
            setLabellerAxesRange(app);
        end

        % Value changed function: IndicatorthicknessSpinner
        function IndicatorthicknessSpinnerValueChanged(app, event)
            app.draggable_line.LineWidth = app.IndicatorthicknessSpinner.Value;
        end

        % Value changed function: ScalebarCheckBox
        function ScalebarCheckBoxValueChanged(app, event)
            regenerateTrackViewer(app);
        end

        % Value changed function: ScalebarlengthSpinner
        function ScalebarlengthSpinnerValueChanged(app, event)
            regenerateTrackViewer(app);
        end

        % Value changed function: ScalebarpositionDropDown
        function ScalebarpositionDropDownValueChanged(app, event)
            regenerateTrackViewer(app);
        end

        % Value changed function: ScalebarcolourDropDown
        function ScalebarcolourDropDownValueChanged(app, event)
            regenerateTrackViewer(app);
        end

        % Button pushed function: ComputeglobalfeatureimportanceButton
        function ComputeglobalfeatureimportanceButtonPushed(app, event)
            %prepare map of class names: silly and redundant as this is mapping the char arrays of integers of class names to numbers as a proxy for the
            %custom strings provided by the user. This will be resolved in a future version when I have more time available
            class_to_num = containers.Map(app.movie_data.models.RF.model.ClassNames, 1:numel(app.movie_data.models.RF.model.ClassNames));

            %define a function handle for predictions
            f = @(X) cell2mat(values(class_to_num, predict(app.movie_data.models.RF.model, X)));
            
            %extract just the training data without labels
            data_train = app.movie_data.results.train_data(:, 1:end-1);
            
            %create shapley object
            explainer = shapley(f, data_train, UseParallel=true);
            
            %select subset of the training data for SHAP value computation, and initialise a matrix to store results
            N_examples = app.NumberofexamplesSpinner.Value;
            data_subset = data_train(1:N_examples, :);
            all_shap_values = zeros(N_examples, numel(app.movie_data.models.RF.feature_names));
            
            h = waitbar(0, 'Computing SHAP values, this may take some time...');
            
            %turn off slow SHAP computation warning
            warning('off', 'stats:responsible:shapley:BigXSlowComputation');
            
            %loop over examples computing and storing SHAP values
            for ii = 1:N_examples
                explainer = fit(explainer, data_subset(ii, :));
                all_shap_values(ii, :) = explainer.ShapleyValues.ShapleyValue;
            
                waitbar(ii / N_examples, h, sprintf('Computing SHAP values... (%d%%)', ii*100/N_examples));
            end
            
            %turn warning back on
            warning('on', 'stats:responsible:shapley:BigXSlowComputation');
            
            close(h);
            
            %compute mean absolute SHAP values for each feature
            global_abs_shap_values = mean(abs(all_shap_values), 1);
            global_raw_shap_values = mean(all_shap_values, 1);
            
            %reset the figure if it contains existing data
            if isfield(app.movie_data.state, "data_viewer_content") && ~strcmp(app.movie_data.state.data_viewer_content, "SHAP")
                reset(app.MLDataView);
            end
            
            %plot horizontal bar graph of global SHAP values
            switch app.SHAPvaluedisplayDropDown.Value
                case "Absolute"
                    bh = barh(app.MLDataView, global_abs_shap_values);
                    xlabel(app.MLDataView, 'Mean absolute SHAP Value');
                case "Raw"
                    bh = barh(app.MLDataView, global_raw_shap_values);
                    xlabel(app.MLDataView, 'Mean raw SHAP Value');
                otherwise
                    
            end
            
            %plot style
            bh.FaceColor = [0.4 0.4 0.4];
            bh.EdgeColor = 'white';
            bh.LineWidth = 2;
            set(app.MLDataView, 'yticklabel', app.movie_data.models.RF.feature_names);
            ylabel(app.MLDataView, '');
            title(app.MLDataView, 'Global Feature Importance');
            
            %keep track of the state of the MLDataView UIAxes
            app.movie_data.state.data_viewer_content = 'SHAP';
            
            %store metrics for future access
            app.movie_data.models.RF.metrics.global_SHAP_abs = global_abs_shap_values;
            app.movie_data.models.RF.metrics.global_SHAP_raw = global_raw_shap_values;
            
            app.textout.Value = "Global SHAP values computed and displayed";
        end

        % Button pushed function: DisplaySHAPvaluetableButton
        function DisplaySHAPvaluetableButtonPushed(app, event)
            %display in a pop-up window a table with the SHAP values from the last time this was calculated
            if isfield(app.movie_data.models, 'RF') && isfield(app.movie_data.models.RF, 'metrics')
                if isfield(app.movie_data.models.RF.metrics, 'global_SHAP_raw') && isfield(app.movie_data.models.RF.metrics, 'global_SHAP_abs')
                    %construct and display table
                    table_data  = [app.movie_data.models.RF.feature_names', num2cell(app.movie_data.models.RF.metrics.global_SHAP_raw'), num2cell(app.movie_data.models.RF.metrics.global_SHAP_abs')];
                    popup_fig   = uifigure('Name', 'SHAP Values', 'Position', [100 100 600 400]);

                    uitable(popup_fig, 'Data', table_data, 'Position', [20 20 560 360], ...
                            'ColumnName', {'Feature', 'Raw SHAP', 'Absolute SHAP'}, ...
                            'ColumnWidth', {200, 'auto', 'auto'}, ...
                            'ColumnEditable', [false false false]);
                else
                    uialert(app.UIFigure, 'Please compute the SHAP values first.', 'SHAP values not computed');
                end
            else
                uialert(app.UIFigure, 'Please train a random forest model first.', 'Model not trained');
            end
        end

        % Button pushed function: EvaluateindividualframesButton
        function EvaluateindividualframesButtonPushed(app, event)
            explainIndividualFrame(app);
        end

        % Button pushed function: ExportanalysisnotesButton
        function ExportanalysisnotesButtonPushed(app, event)
            exportNotes(app);
        end

        % Clicked callback: EventstoviewDropDown
        function EventstoviewDropDownClicked(app, event)
            if isprop(app, "movie_data") && isfield(app.movie_data, "params") && isfield(app.movie_data.params, "class_names") && ~isempty(app.movie_data.params.class_names)
                app.EventstoviewDropDown.Items = app.movie_data.params.class_names;
                
                %loop through each color and apply it to the corresponding dropdown item
                for ii = 1:size(app.movie_data.params.event_label_colours, 1)
                    %get the color as a hexadecimal string
                    %color_hex = sprintf('#%02x%02x%02x', app.movie_data.params.event_label_colours(ii, 1), app.movie_data.params.event_label_colours(ii, 2), app.movie_data.params.event_label_colours(ii, 3));
                    
                    %create a style object with this color
                    item_style = uistyle('FontColor', app.movie_data.params.event_label_colours(ii, :));
                    
                    %apply the style to the dropdown item
                    addStyle(app.EventstoviewDropDown, item_style, 'item', ii);
                end
            else
                app.textout.Value = "There is no valid list of class names. Please load a valid analysis file or start human annotation of the dataset to define the class names.";
            end
        end

        % Button pushed function: ImportgroundtruthButton
        function ImportgroundtruthButtonPushed(app, event)
            importGroundTruth(app);
        end

        % Drop down opening function: SourcedataDropDown
        function SourcedataDropDownOpening(app, event)
            if ~isprop(app, "movie_data") || ~isfield(app.movie_data, "results")
                return;
            end
            
            %generate a user-understandable list of the available source data types
            items = {};
            if isfield(app.movie_data.results, 'VisuallyLabelled')
                items{end+1} = 'Human annotations';
            end
            if isfield(app.movie_data.results, 'GroundTruth')
                items{end+1} = 'Ground truth';
            end
            items{end+1} = 'Human annotations (multiple experiments)';
            items{end+1} = 'Ground truth (multiple simulations)';
            
            %if there are no results structs to train from; now redundant
            if isempty(items)
                items{end+1} = '<no labels available>';
            end
            
            %update the dropdown box
            app.SourcedataDropDown.Items = items;
        end

        % Drop down opening function: InsightsSourcedataDropDown
        function InsightsSourcedataDropDownOpening(app, event)
            if ~isprop(app, "movie_data") || ~isfield(app.movie_data, "results")
                return;
            end
            
            %generate a user-understandable list of the available source data types
            items = {};
            if isfield(app.movie_data.results, 'VisuallyLabelled')
                items{end+1} = 'Human annotations';
            end
            if isfield(app.movie_data.results, 'GroundTruth')
                items{end+1} = 'Ground truth';
            end
            if isfield(app.movie_data.results, 'RFLabelled')
                items{end+1} = 'Labels from RF';
            end
            if isfield(app.movie_data.results, 'LSTMLabelled')
                items{end+1} = 'Labels from LSTM';
            end
            if isfield(app.movie_data.results, 'BiLSTMLabelled')
                items{end+1} = 'Labels from BiLSTM';
            end
            if isfield(app.movie_data.results, 'GRULabelled')
                items{end+1} = 'Labels from GRU';
            end
            if isfield(app.movie_data.results, 'BiGRULabelled')
                items{end+1} = 'Labels from BiGRU';
            end
            if isfield(app.movie_data.results, 'ResAnDi')
                items{end+1} = 'Labels from ResAnDi2';
            end
            
            %if there are no results structs to train from
            if isempty(items)
                items{end+1} = '<< Select dataset >>';
            end
            
            %update the dropdown box
            app.InsightsSourcedataDropDown.Items = items;
        end

        % Value changed function: InsightsSourcedataDropDown
        function InsightsSourcedataDropDownValueChanged(app, event)
            data_available = copyLabelsToInsights(app);

            if ~data_available
                app.textout.Value = "The selected data was not available, please select another option or load new data.";
            end
        end

        % Drop down opening function: EventoverlayerSOI
        function EventoverlayerSOIDropDownOpening(app, event)
            if isfield(app.movie_data.params, "class_names")
                app.EventoverlayerSOI.Items = app.movie_data.params.class_names;
            end
        end

        % Button pushed function: DisplaymoleculearrivaltimesButton
        function DisplaymoleculearrivaltimesButtonPushed(app, event)
            computeArrivalTimes(app);
        end

        % Value changed function: EventstoviewDropDown
        function EventstoviewDropDownValueChanged(app, event)
            DisplaystatehistogramButtonPushed(app);
        end

        % Value changed function: TruncationDropDown
        function TruncationDropDownValueChanged(app, event)
            DisplaystatehistogramButtonPushed(app);
        end

        % Value changed function: MaximumstepsizesSpinner
        function MaximumstepsizesSpinnerValueChanged(app, event)
            DisplaystepsizeprofileButtonPushed(app);
        end

        % Value changed function: Binwidtharrivaltime
        function BinwidtharrivaltimeValueChanged(app, event)
            computeArrivalTimes(app);
        end

        % Value changed function: TrajectorylengthbinsizessSpinner
        function TrajectorylengthbinsizessSpinnerValueChanged(app, event)
            PlotalltrajectorylengthsButtonPushed(app);
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            undoLabel(app);
        end

        % Value changed function: PrimaryFixupperlimitCheckBox
        function PrimaryFixupperlimitCheckBoxValueChanged(app, event)
            setLabellerAxesRange(app);
            
            focus(app.InVivoKineticsUIFigure);
        end

        % Value changed function: PrimaryYaxisupperlimitSpinner
        function PrimaryYaxisupperlimitSpinnerValueChanged(app, event)
            if app.PrimaryFixupperlimitCheckBox.Value
                setLabellerAxesRange(app);
            end

            focus(app.InVivoKineticsUIFigure);
        end

        % Button pushed function: ExporttrainingdataButton
        function ExporttrainingdataButtonPushed(app, event)
            exportTrainingData(app);
            
            focus(app.InVivoKineticsUIFigure);
        end

        % Callback function
        function SecondaryLinecolourDropDownValueChanged(app, event)
            repopulateEventLabeller(app);
        end

        % Value changed function: SecondaryFeatureDropDown
        function SecondaryFeatureDropDownValueChanged(app, event)
            repopulateEventLabeller(app);
        end

        % Value changed function: SecondarySetlowerlimttozeroCheckBox
        function SecondarySetlowerlimttozeroCheckBoxValueChanged(app, event)
            repopulateEventLabeller(app);
        end

        % Value changed function: SecondaryFixupperlimitCheckBox
        function SecondaryFixupperlimitCheckBoxValueChanged(app, event)
            repopulateEventLabeller(app);
        end

        % Value changed function: SecondaryYaxisupperlimitSpinner
        function SecondaryYaxisupperlimitSpinnerValueChanged(app, event)
            repopulateEventLabeller(app);
        end

        % Value changed function: SecondaryLinethicknessSpinner
        function SecondaryLinethicknessSpinnerValueChanged(app, event)
            repopulateEventLabeller(app);
        end

        % Value changed function: TogglesecondaryfeatureButton
        function TogglesecondaryfeatureButtonValueChanged(app, event)
            %rapidly toggles back and forth between displaying and
            %removing the secondary feature in the human annotation system

            %check the value is valid (handles rare cases which have never occurred where file user has recently loaded a file with a feature set different to that available in the dropdown menu)
            if isfield(app.movie_data.state, 'col_feature_secondary') && app.movie_data.state.col_feature_secondary < size(app.movie_data.params.column_titles.tracks, 2) && app.movie_data.state.col_feature_secondary > 0
                
                if strcmp(app.SecondaryFeatureDropDown.Value, '<< None >>')
                    app.SecondaryFeatureDropDown.Value = app.movie_data.params.column_titles.tracks(app.movie_data.state.col_feature_secondary);
                else
                    app.SecondaryFeatureDropDown.Value = '<< None >>';
                end
                repopulateEventLabeller(app);
            else
                app.textout.Value = "You have not yet set a valid secondary feature to plot. To set a feature please select the [Plot settings] tab in the human annotation system.";
                h_warn = warndlg("You have not yet set a valid secondary feature to plot. To set a feature please select the [Plot settings] tab in the human annotation system.", "No secondary feature set!", 'modal');
                uiwait(h_warn);
            end
        end

        % Button pushed function: DisplaytrackButton
        function DisplaytrackButtonPushed(app, event)
            displayTrackAnnotations(app);
        end

        % Clicked callback: CompareAnnotationsTree
        function CompareAnnotationsTreeClicked(app, event)
            
        end

        % Button pushed function: GenerateheatmapsButton
        function GenerateheatmapsButtonPushed(app, event)
            genHeatmap(app);
        end

        % Button pushed function: ComputeannotationmetricsButton
        function ComputeannotationmetricsButtonPushed(app, event)
            computeAnnotationMetrics(app);
        end

        % Button pushed function: PopoutannotationsButton
        function PopoutannotationsButtonPushed(app, event)
            popoutAnnotationFigure(app);
        end

        % Button pushed function: ExportannotationdataButton
        function ExportannotationdataButtonPushed(app, event)
            %store the data in two empty tables that are later concatenated, table_left contains the left y-axis
            %data (plotted feature), and table_right cotnains the right y-axis data (the annotations)
            table_left = table();
            table_right = table();
            
            %capture data from left y-axis plot
            yyaxis(app.AnnotationUIAxes, 'left');
            left_children = app.AnnotationUIAxes.Children;
            for ii = 1:length(left_children)
                if isa(left_children(ii), 'matlab.graphics.chart.primitive.Line')
                    left_xdata = left_children(ii).XData;
                    left_ydata = left_children(ii).YData;
                    left_xlabel = app.AnnotationUIAxes.XLabel.String;
                    left_ylabel = app.AnnotationUIAxes.YLabel.String;
                    table_left.(left_xlabel) = left_xdata';
                    table_left.(left_ylabel) = left_ydata';
                end
            end
            
            %capture data from right y-axis plot
            yyaxis(app.AnnotationUIAxes, 'right');
            right_children = app.AnnotationUIAxes.Children;
            legend_entries = app.AnnotationUIAxes.Legend.String;
            for ii = 1:length(right_children)
                if isa(right_children(ii), 'matlab.graphics.chart.primitive.Line')
                    right_xdata = right_children(ii).XData;
                    right_ydata = right_children(ii).YData;
                    if length(legend_entries) >= ii
                        right_ylabel = legend_entries{ii};
                    else
                        right_ylabel = ['Right YData ' num2str(ii)];
                    end
                    table_right.(['Right ' left_xlabel]) = right_xdata';
                    table_right.(right_ylabel) = right_ydata';
                end
            end
            
            %combine the two tables
            table_out = [table_left table_right];
            
            %save table as a CSV
            [fileName, pathName] = uiputfile('*.csv', 'Save CSV file as');
            if isequal(fileName, 0) || isequal(pathName, 0)
                disp('User canceled file save.');
                return;
            end
            fullPath = fullfile(pathName, fileName);
            writetable(table_out, fullPath);
            
            app.textout.Value = "Data for this currently displayed annotations were exported to " + fullPath;
        end

        % Button pushed function: PlotannotatedtrackButton
        function PlotannotatedtrackButtonPushed(app, event)
            plotAnnotatedTrack(app);
        end

        % Button pushed function: CreatevideoillustrationButton
        function CreatevideoillustrationButtonPushed(app, event)
            genAnnotationVideo(app);
        end

        % Button pushed function: PostprocessclassificationsButton
        function PostprocessclassificationsButtonPushed(app, event)
            N_tracks = numel(app.movie_data.results.(strcat(app.movie_data.models.current_model, 'Labelled')).LabelledMols);
            
            %get window size from the GUI input - halved for symmetric smoothing
            window_size = app.PostprocessingwindowSpinner.Value;
            half_window = floor(window_size / 2);
            
            post_proc_method = app.PostprocessingmethodDropDown.Value;
            
            for ii = 1:N_tracks
                %extract classifications, and initialise output
                classifications = app.movie_data.results.(strcat(app.movie_data.models.current_model, 'Labelled')).LabelledMols{ii, 1}.Mol(:, end);
                seq_processed = classifications; 
                
                switch post_proc_method
                    case 'Majority vote smoothing'
                        %apply majority voting
                        for jj = (1+half_window):(length(classifications)-half_window)
                            window = classifications(jj-half_window:jj+half_window);
                            seq_processed(jj) = mode(window); %mode returns the most frequent value
                        end
                        
                    case 'Gaussian smoothing'
                        %set Gaussian kernel
                        sigma = 1;  %stdev of kernel - req GUI input
                        kernel = fspecial('gaussian', [window_size, 1], sigma);
                        
                        %apply conv with kernel, then round to get class integer
                        seq_processed = round(conv(classifications, kernel, 'same'));
                        
                    case 'Exponential moving average smoothing'
                        %set smoothing factor - req GUI input
                        alpha = 0.2;
                        for jj = 2:length(classifications)
                            seq_processed(jj) = alpha * classifications(jj) + (1 - alpha) * seq_processed(jj-1);
                        end

                        seq_processed = round(seq_processed);
                        
                    % << other cases here >>

                    otherwise
                        %default case if no valid smoothing method is selected
                        warndlg('Requested post processing method is not currently available');
                        app.textout.Value = "Requested post processing method is not currently available";
                        return;
                end
                
                %write processed classifications back into track data
                app.movie_data.results.(strcat(app.movie_data.models.current_model, 'Labelled')).LabelledMols{ii, 1}.Mol(:, end) = seq_processed;
            end
        end

        % Value changed function: InspectfeatureDropDown
        function InspectfeatureDropDownValueChanged(app, event)
            %update the feature displayed automatically when dropdown selection changes, without waiting for user to press button;
            %this works by executing the specific track option with displayTrackAnnotations() for the current displayed
            %track, then immediately returning the GUI controls to their previous state before the user interacts again.
            
            %keep track of the current state to restore GUI after updating plot
            store_cell_ID   = app.CellIDSpinner.Value;
            store_mol_ID    = app.MolIDSpinner.Value;
            store_specific_radio = app.SpecifictrackButton.Value;
            
            %change the IDs of the mol to be displayed
            app.CellIDSpinner.Value = str2double(app.InspectCellID.Text);
            app.MolIDSpinner.Value  = str2double(app.InspectMolID.Text);
            app.SpecifictrackButton.Value = 1;
            
            %replot
            displayTrackAnnotations(app);
            
            %return GUI to original state
            app.CellIDSpinner.Value = store_cell_ID;
            app.MolIDSpinner.Value  = store_mol_ID;
            app.SpecifictrackButton.Value = store_specific_radio;
        end

        % Drop down opening function: EventOverlayerFeatureDropDown
        function EventOverlayerFeatureDropDownOpening(app, event)
            if isfield(app.movie_data.params, "column_titles") && isfield(app.movie_data.params.column_titles, "tracks")
                app.EventOverlayerFeatureDropDown.Items = app.movie_data.params.column_titles.tracks;
            end
        end

        % Button pushed function: 
        % PlotclassconditionalfeaturedistributionsButton
        function PlotclassconditionalfeaturedistributionsButtonPushed(app, event)
            plotClassDistributions(app);
        end

        % Button pushed function: RankfeaturesbyimportanceButton
        function RankfeaturesbyimportanceButtonPushed(app, event)
            switch app.FeatureimportancemetricDropDown.Value
                case "Mutual information (recommended)"
                    computeMutualInformation(app, 'ranked');
                case "Difference of histograms"
                    %rankFeaturesByOverlap(app);
                case "Wasserstein distance"
                    %rankFeaturesByOverlap(app);
                otherwise
                    app.textout.Value = "Feature ranking method unknown, please select a different method from the dropdown options.";
            end
        end

        % Drop down opening function: FeatureAnalysisSourcedataDropDown
        function FeatureAnalysisSourcedataDropDownOpening(app, event)
            if ~isprop(app, "movie_data") || ~isfield(app.movie_data, "results")
                return;
            end
            
            %generate a user-understandable list of the available source data types
            items = {};
            if isfield(app.movie_data.results, 'VisuallyLabelled')
                items{end+1} = 'Human annotations';
            end
            if isfield(app.movie_data.results, 'GroundTruth')
                items{end+1} = 'Ground truth';
            end
            %currently not implementing multiple files
            % items{end+1} = 'Human annotations (multiple experiments)';
            % items{end+1} = 'Ground truth (multiple simulations)';
            
            %if there are no results structs to train from; now redundant
            if isempty(items)
                items{end+1} = '<no labels available>';
            end
            
            %update the dropdown box
            app.FeatureAnalysisSourcedataDropDown.Items = items;
        end

        % Button pushed function: GeneratediffusionhistogramsButton
        function GeneratediffusionhistogramsButtonPushed(app, event)
            %generate diffusion histograms
            genDHistograms(app);
        end

        % Drop down opening function: DiffusionHistStateDropDown
        function DiffusionHistStateDropDownOpening(app, event)
            if isprop(app, "movie_data") && isfield(app.movie_data, "params") && isfield(app.movie_data.params, "class_names") && ~isempty(app.movie_data.params.class_names)
                app.DiffusionHistStateDropDown.Items = [{'All'}; {'Stack states'}; app.movie_data.params.class_names];
                
                %loop through each color and apply it to the corresponding dropdown item
                for ii = 1:size(app.movie_data.params.event_label_colours, 1)
                    %create a style object with this color
                    item_style = uistyle('FontColor', app.movie_data.params.event_label_colours(ii, :));
                    
                    %apply the style to the dropdown item
                    addStyle(app.DiffusionHistStateDropDown, item_style, 'item', ii+2);
                end
            else
                app.textout.Value = "There is no valid list of class names. Please load a valid analysis file or start human annotation of the dataset to define the class names.";
            end
        end

        % Value changed function: DiffusionHistStateDropDown
        function DiffusionHistStateDropDownValueChanged(app, event)
            if app.DiffusionHistRunincontinuousupdatemodeCheckBox.Value && strcmp(app.DiffusionHistPlotlocationDropDown.Value, "Inside GUI") && ~app.DiffusionHistExportdatatoCSVCheckBox.Value
                app.GeneratediffusionhistogramsButtonPushed();
            end
        end

        % Value changed function: DiffusionHistMaxlagframesSpinner
        function DiffusionHistMaxlagframesSpinnerValueChanged(app, event)
            if app.DiffusionHistRunincontinuousupdatemodeCheckBox.Value && strcmp(app.DiffusionHistPlotlocationDropDown.Value, "Inside GUI") && ~app.DiffusionHistExportdatatoCSVCheckBox.Value
                app.GeneratediffusionhistogramsButtonPushed();
            end
        end

        % Value changed function: DiffusionHistBinSizeSpinner
        function DiffusionHistBinSizeSpinnerValueChanged(app, event)
            if app.DiffusionHistRunincontinuousupdatemodeCheckBox.Value && strcmp(app.DiffusionHistPlotlocationDropDown.Value, "Inside GUI") && ~app.DiffusionHistExportdatatoCSVCheckBox.Value
                app.GeneratediffusionhistogramsButtonPushed();
            end
        end

        % Value changed function: DiffusionHistMincelllengthmSpinner
        function DiffusionHistMincelllengthmSpinnerValueChanged(app, event)
            if app.DiffusionHistRunincontinuousupdatemodeCheckBox.Value && strcmp(app.DiffusionHistPlotlocationDropDown.Value, "Inside GUI") && ~app.DiffusionHistExportdatatoCSVCheckBox.Value
                app.GeneratediffusionhistogramsButtonPushed();
            end
        end

        % Value changed function: DiffusionHistMaxcelllengthmSpinner
        function DiffusionHistMaxcelllengthmSpinnerValueChanged(app, event)
            if app.DiffusionHistRunincontinuousupdatemodeCheckBox.Value && strcmp(app.DiffusionHistPlotlocationDropDown.Value, "Inside GUI") && ~app.DiffusionHistExportdatatoCSVCheckBox.Value
                app.GeneratediffusionhistogramsButtonPushed();
            end
        end

        % Value changed function: 
        % DiffusionHistRestricttorangeofcelllengthsCheckBox
        function DiffusionHistRestricttorangeofcelllengthsCheckBoxValueChanged(app, event)
            if app.DiffusionHistRunincontinuousupdatemodeCheckBox.Value && strcmp(app.DiffusionHistPlotlocationDropDown.Value, "Inside GUI") && ~app.DiffusionHistExportdatatoCSVCheckBox.Value
                app.GeneratediffusionhistogramsButtonPushed();
            end
        end

        % Value changed function: DiffusionHistNumberofGammasSpinner
        function DiffusionHistNumberofGammasSpinnerValueChanged(app, event)
            %when the number of Gammas requested changes, this updates the
            %associated table of initial guesses with evenly-spaced values
            
            N_gammas = app.DiffusionHistNumberofGammasSpinner.Value;
            default_D_values = linspace(0.1, 0.5, N_gammas)';
            
            %ensure sum of A values is 1
            default_A_values = ones(N_gammas, 1) / N_gammas;
            
            %update table
            app.DiffusionHistGammaInitialValues.Data = table(default_D_values, default_A_values, ...
                'VariableNames', {'Coefficients', 'Occupancy'});

            if app.DiffusionHistRunincontinuousupdatemodeCheckBox.Value && strcmp(app.DiffusionHistPlotlocationDropDown.Value, "Inside GUI") && ~app.DiffusionHistExportdatatoCSVCheckBox.Value
                app.GeneratediffusionhistogramsButtonPushed();
            end
        end

        % Value changed function: DiffusionHistPerformgammafitCheckBox
        function DiffusionHistPerformgammafitCheckBoxValueChanged(app, event)
            if app.DiffusionHistRunincontinuousupdatemodeCheckBox.Value && strcmp(app.DiffusionHistPlotlocationDropDown.Value, "Inside GUI") && ~app.DiffusionHistExportdatatoCSVCheckBox.Value
                app.GeneratediffusionhistogramsButtonPushed();
            end
        end

        % Drop down opening function: FeatureImportanceSourcedataDropDown
        function FeatureImportanceSourcedataDropDownOpening(app, event)
            if ~isprop(app, "movie_data") || ~isfield(app.movie_data, "results")
                return;
            end
            
            %generate a user-understandable list of the available source data types
            items = {};
            if isfield(app.movie_data.results, 'VisuallyLabelled')
                items{end+1} = 'Human annotations';
            end
            if isfield(app.movie_data.results, 'GroundTruth')
                items{end+1} = 'Ground truth';
            end
            %for adding multiple external files
            items{end+1} = 'Human annotations (multiple experiments)';
            items{end+1} = 'Ground truth (multiple simulations)';
            
            %if there are no results structs to train from; now redundant
            if isempty(items)
                items{end+1} = '<no labels available>';
            end
            
            %update the dropdown box
            app.FeatureImportanceSourcedataDropDown.Items = items;
        end

        % Button pushed function: DisplayfeaturecorrelationsButton
        function DisplayfeaturecorrelationsButtonPushed(app, event)
            computeFeatureCorrelation(app);
        end

        % Button pushed function: 
        % DisplaymutualinformationbetweenfeaturesButton
        function DisplaymutualinformationbetweenfeaturesButtonPushed(app, event)
           computeMutualInformation(app, 'pairwise');
        end

        % Value changed function: FeatureimportancemetricDropDown
        function FeatureimportancemetricDropDownValueChanged(app, event)
            if ~strcmp(app.FeatureimportancemetricDropDown.Value, "Mutual information (recommended)")
                app.FeatureimportancemetricDropDown.Value = "Mutual information (recommended)";
                warndlg("This method is disabled in the current version.", "Metric unavailable");
            end
        end

        % Drop down opening function: FeatureAnalysisClassADropDown
        function FeatureAnalysisClassADropDownOpening(app, event)
            if isprop(app, 'movie_data') && isfield(app.movie_data, 'params') && isfield(app.movie_data.params, 'class_names')
                app.FeatureAnalysisClassADropDown.Items = app.movie_data.params.class_names;
            else
                app.FeatureAnalysisClassADropDown.Items = {'<< Select class >>'};
                warndlg("The currently loaded data does not contain any classes; please load a dataset containing annotated data or ground truth.", "No classes available");
            end
        end

        % Drop down opening function: FeatureAnalysisClassBDropDown
        function FeatureAnalysisClassBDropDownOpening(app, event)
            if isprop(app, 'movie_data') && isfield(app.movie_data, 'params') && isfield(app.movie_data.params, 'class_names')
                app.FeatureAnalysisClassBDropDown.Items = app.movie_data.params.class_names;
            else
                app.FeatureAnalysisClassBDropDown.Items = {'<< Select class >>'};
                warndlg("The currently loaded data does not contain any classes; please load a dataset containing annotated data or ground truth.", "No classes available");
            end
        end

        % Button pushed function: ComputefeaturepermutationimportanceButton
        function ComputefeaturepermutationimportanceButtonPushed(app, event)
            computePermutationImportance(app);
        end

        % Drop down opening function: 
        % PermutationImportanceSourcedataDropDown
        function PermutationImportanceSourcedataDropDownOpening(app, event)
            if ~isprop(app, "movie_data") || ~isfield(app.movie_data, "results")
                return;
            end
            
            %generate a user-understandable list of the available source data types
            items = {};
            if isfield(app.movie_data.results, 'VisuallyLabelled')
                items{end+1} = 'Human annotations';
            end
            if isfield(app.movie_data.results, 'GroundTruth')
                items{end+1} = 'Ground truth';
            end
            items{end+1} = 'Human annotations (multiple experiments)';
            items{end+1} = 'Ground truth (multiple simulations)';
            
            %if there are no results structs to train from; now redundant
            if isempty(items)
                items{end+1} = '<no labels available>';
            end
            
            %update the dropdown box
            app.PermutationImportanceSourcedataDropDown.Items = items;
        end

        % Button pushed function: SetmaxwindowButton
        function SetmaxwindowButtonPushed(app, event)
            setMaxContextLen(app);
        end

        % Drop down opening function: DiscoverySourceDataDropDown
        function DiscoverySourceDataDropDownOpening(app, event)
            if ~isprop(app, "movie_data") || ~isfield(app.movie_data, "results")
                return;
            end
            
            %generate a user-understandable list of the available source data types
            items = {};
            if isfield(app.movie_data.results, 'RFLabelled')
                items{end+1} = 'Labels from RF';
            end
            if isfield(app.movie_data.results, 'LSTMLabelled')
                items{end+1} = 'Labels from LSTM';
            end
            if isfield(app.movie_data.results, 'BiLSTMLabelled')
                items{end+1} = 'Labels from BiLSTM';
            end
            if isfield(app.movie_data.results, 'GRULabelled')
                items{end+1} = 'Labels from GRU';
            end
            if isfield(app.movie_data.results, 'BiGRULabelled')
                items{end+1} = 'Labels from BiGRU';
            end
            
            %if there are no results structs to train from
            if isempty(items)
                items{end+1} = '<< Select dataset >>';
            end
            
            %update the dropdown box
            app.DiscoverySourceDataDropDown.Items = items;
        end

        % Button pushed function: FindHiddenrelationshipsButton
        function FindHiddenrelationshipsButtonPushed(app, event)
            discoverRelationships(app);
        end

        % Value changed function: ClassseparabilitymetricDropDown
        function ClassseparabilitymetricDropDownValueChanged(app, event)
            if ~strcmp(app.ClassseparabilitymetricDropDown.Value, "Kolmogorov-Smirnov") && ~strcmp(app.ClassseparabilitymetricDropDown.Value, "None")
                app.ClassseparabilitymetricDropDown.Value = "Kolmogorov-Smirnov";
                warndlg("This method is disabled in the current version.", "Metric unavailable");
            end
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            %obtain raw data
            loadDataset(app);
            
            %user confirms track filtering options
            popup = PrepareDataPopUp(app);
            uiwait(popup.PrepareDataUIFigure);
            if isfield(app.movie_data, 'state') && isfield(app.movie_data.state, 'cancel') && app.movie_data.state.cancel == true
                ClearanalysisButtonPushed(app);
                app.textout.Value = "Data preparation was cancelled by the user. The loaded raw data has also been cleared.";
                return;
            end
            
            %data is prepared into the DeepTRACE format
            prepData(app);
        end

        % Drop down opening function: GalleryFeatureDropDown
        function GalleryFeatureDropDownOpening(app, event)
            if isprop(app, "movie_data") && isfield(app.movie_data, "params") && isfield(app.movie_data.params, "column_titles") && isfield (app.movie_data.params.column_titles, "tracks")
                app.GalleryFeatureDropDown.Items = app.movie_data.params.column_titles.tracks(1:end-1);
            end
        end

        % Drop down opening function: GalleryClassificationsourceDropDown
        function GalleryClassificationsourceDropDownOpening(app, event)
            if ~isprop(app, "movie_data") || ~isfield(app.movie_data, "results")
                return;
            end
            
            %generate a user-understandable list of the available source data types
            items = {};
            if isfield(app.movie_data.results, 'VisuallyLabelled')
                items{end+1} = 'Human annotations';
            end
            if isfield(app.movie_data.results, 'GroundTruth')
                items{end+1} = 'Ground truth';
            end
            if isfield(app.movie_data.results, 'RFLabelled')
                items{end+1} = 'Labels from RF';
            end
            if isfield(app.movie_data.results, 'LSTMLabelled')
                items{end+1} = 'Labels from LSTM';
            end
            if isfield(app.movie_data.results, 'BiLSTMLabelled')
                items{end+1} = 'Labels from BiLSTM';
            end
            if isfield(app.movie_data.results, 'GRULabelled')
                items{end+1} = 'Labels from GRU';
            end
            if isfield(app.movie_data.results, 'BiGRULabelled')
                items{end+1} = 'Labels from BiGRU';
            end
            
            %if there are no results structs to train from
            if isempty(items)
                items{end+1} = '<< Select dataset >>';
            end
            
            %update the dropdown box
            app.GalleryClassificationsourceDropDown.Items = items;
        end

        % Button pushed function: ExportResAnDI2Button
        function ExportResAnDI2ButtonPushed(app, event)
            exportResAnDi2(app);
        end

        % Button pushed function: ImportResAnDi2Button
        function ImportResAnDi2ButtonPushed(app, event)
            importResAnDi2(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InVivoKineticsUIFigure = uifigure('Visible', 'off');
            app.InVivoKineticsUIFigure.Name = 'In Vivo Kinetics';
            app.InVivoKineticsUIFigure.KeyPressFcn = createCallbackFcn(app, @InVivoKineticsUIFigureKeyPress, true);
            app.SideBarTabGroup = uitabgroup(app.InVivoKineticsUIFigure);
            app.LoadSaveTab = uitab(app.SideBarTabGroup);
            app.LoadSaveTab.Title = 'Load/Save';
            app.GridLayout14 = uigridlayout(app.LoadSaveTab);
            app.ImportgroundtruthButton = uibutton(app.GridLayout14, 'push');
            app.ImportgroundtruthButton.ButtonPushedFcn = createCallbackFcn(app, @ImportgroundtruthButtonPushed, true);
            app.ImportgroundtruthButton.Text = 'Import ground truth';
            app.LoadconfigurationButton = uibutton(app.GridLayout14, 'push');
            app.LoadconfigurationButton.Tooltip = {'Load a configuration file. This will populate options throughout the interface.'};
            app.LoadconfigurationButton.Text = 'Load configuration';
            app.SaveconfigurationButton = uibutton(app.GridLayout14, 'push');
            app.SaveconfigurationButton.Text = 'Save configuration';
            app.CurrentlyloadeddatasetTextAreaLabel = uilabel(app.GridLayout14);
            app.CurrentlyloadeddatasetTextAreaLabel.Text = 'Currently loaded dataset';
            app.LoadanalysisButton = uibutton(app.GridLayout14, 'push');
            app.LoadanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @LoadanalysisButtonPushed, true);
            app.LoadanalysisButton.Text = 'Load analysis';
            app.SaveanalysisButton = uibutton(app.GridLayout14, 'push');
            app.SaveanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SaveanalysisButtonPushed, true);
            app.SaveanalysisButton.Text = 'Save analysis';
            app.ClearanalysisButton = uibutton(app.GridLayout14, 'push');
            app.ClearanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ClearanalysisButtonPushed, true);
            app.ClearanalysisButton.Text = 'Clear analysis';
            app.FileoverviewTextAreaLabel = uilabel(app.GridLayout14);
            app.FileoverviewTextAreaLabel.Text = 'File overview';
            app.UserLabel = uilabel(app.GridLayout14);
            app.UserLabel.Text = 'User';
            app.CurrentlyloadeddatasetTextArea = uitextarea(app.GridLayout14);
            app.FileoverviewTextArea = uitextarea(app.GridLayout14);
            app.UserEditField = uieditfield(app.GridLayout14, 'text');
            app.UserEditField.Value = 'Default user';
            app.LoaddataButton = uibutton(app.GridLayout14, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.ExportResAnDI2Button = uibutton(app.GridLayout14, 'push');
            app.ExportResAnDI2Button.ButtonPushedFcn = createCallbackFcn(app, @ExportResAnDI2ButtonPushed, true);
            app.ExportResAnDI2Button.Text = 'Export ResAnDI2';
            app.ImportResAnDi2Button = uibutton(app.GridLayout14, 'push');
            app.ImportResAnDi2Button.ButtonPushedFcn = createCallbackFcn(app, @ImportResAnDi2ButtonPushed, true);
            app.ImportResAnDi2Button.Text = 'Import ResAnDi2';
            app.PrepareTab = uitab(app.SideBarTabGroup);
            app.PrepareTab.Title = 'Prepare';
            app.GridLayout = uigridlayout(app.PrepareTab);
            app.PixelscalenmEditFieldLabel = uilabel(app.GridLayout);
            app.PixelscalenmEditFieldLabel.Text = 'Pixel scale (nm)';
            app.PixelscaleEditField = uieditfield(app.GridLayout, 'numeric');
            app.PixelscaleEditField.ValueChangedFcn = createCallbackFcn(app, @PixelscaleEditFieldValueChanged, true);
            app.PixelscaleEditField.Tooltip = {'The size of each pixel in the video.'; ''; 'Important: the value used here during data preparation will scale the entire downstream analysis, updating this later will not change the pixel scale of existing data.'};
            app.PixelscaleEditField.Value = 96;
            app.UndriftingmethodDropDown = uidropdown(app.GridLayout);
            app.UndriftingmethodDropDown.Items = {'Mesh-based', 'Image correlation', 'Global adaptive'};
            app.UndriftingmethodDropDown.Value = 'Mesh-based';
            app.TrackbufferframesSpinnerLabel = uilabel(app.GridLayout);
            app.TrackbufferframesSpinnerLabel.Text = 'Track buffer (frames)';
            app.TrackbufferframesSpinner = uispinner(app.GridLayout);
            app.TrackbufferframesSpinner.ValueChangedFcn = createCallbackFcn(app, @TrackbufferframesSpinnerValueChanged, true);
            app.TrackbufferframesSpinner.Tooltip = {'Minimum number of empty frames between tracks in the same cell.'; ''; 'Trajectories spaced by less than this number will result in a conflict, which is resolved using the settings below.'};
            app.TrackbufferframesSpinner.Value = 3;
            app.IntensityboxpxSpinnerLabel = uilabel(app.GridLayout);
            app.IntensityboxpxSpinnerLabel.Text = 'Intensity box (px)';
            app.IntensityboxpxSpinner = uispinner(app.GridLayout);
            app.IntensityboxpxSpinner.ValueChangedFcn = createCallbackFcn(app, @IntensityboxpxSpinnerValueChanged, true);
            app.IntensityboxpxSpinner.Tooltip = {'Size of box to use for calculating spot intensities. The number is represents the distance between the localised point, and the edge of the box.'; ''; 'This parameter is important for datasets localised using a non-Gaussian fitting algorithm.'};
            app.IntensityboxpxSpinner.Value = 3;
            app.MinimumtracklengthSpinnerLabel = uilabel(app.GridLayout);
            app.MinimumtracklengthSpinnerLabel.Tooltip = {'Minimum length of trajectory measured by the number of localisations'};
            app.MinimumtracklengthSpinnerLabel.Text = 'Minimum track length';
            app.MinimumtracklengthSpinner = uispinner(app.GridLayout);
            app.MinimumtracklengthSpinner.ValueChangedFcn = createCallbackFcn(app, @MinimumtracklengthSpinnerValueChanged, true);
            app.MinimumtracklengthSpinner.Tooltip = {'Minimum length of trajectories. Trajectories shorter than this minimum length are filtered in the data preparation stage. Note that following filtering using the ''truncate'' method some trajectories may have shorter durations.'};
            app.MinimumtracklengthSpinner.Value = 50;
            app.MemoryparameterSpinnerLabel = uilabel(app.GridLayout);
            app.MemoryparameterSpinnerLabel.Text = 'Memory parameter';
            app.MemoryparameterSpinner = uispinner(app.GridLayout);
            app.MemoryparameterSpinner.ValueChangedFcn = createCallbackFcn(app, @MemoryparameterSpinnerValueChanged, true);
            app.MemoryparameterSpinner.Tooltip = {'The number of consecutive frames that can be missed from a trajectory without filtering.'; ''; 'A memory parameter of 1 is typical in single molecule tracking applications to account for the possilbility of blinking events.'};
            app.MemoryparameterSpinner.Value = 1;
            app.IllustrationborderSpinner = uispinner(app.GridLayout);
            app.IllustrationborderSpinner.ValueChangedFcn = createCallbackFcn(app, @IllustrationborderSpinnerValueChanged, true);
            app.IllustrationborderSpinner.Tooltip = {'Size of border (in pixels) around each cell''s ROI to use in illustrations.'};
            app.IllustrationborderSpinner.Value = 5;
            app.FilteringmethodDropDownLabel = uilabel(app.GridLayout);
            app.FilteringmethodDropDownLabel.Text = 'Filtering method';
            app.FilteringmethodDropDown = uidropdown(app.GridLayout);
            app.FilteringmethodDropDown.Items = {'truncate', 'eliminate', 'keep all'};
            app.FilteringmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @FilteringmethodDropDownValueChanged, true);
            app.FilteringmethodDropDown.Tooltip = {'Determines how the software handles temporal overlaps of molecules in the same cell. Options are,'; ''; '''truncate'': eliminates all frames from overlapping regions of tracks to retain only the regions for which a single molecule is present, splitting tracks if necessary.'; ''; '''eliminate'': removes all trajectories that fall within the track buffer of neighbouring trajectories.'; ''; '''keep all'': does not apply any filtering resulting from temporal overlaps of multiple molecules in the same cell.'};
            app.FilteringmethodDropDown.Value = 'truncate';
            app.FilteragainstDropDownLabel = uilabel(app.GridLayout);
            app.FilteragainstDropDownLabel.Text = 'Filter against';
            app.FilteragainstDropDown = uidropdown(app.GridLayout);
            app.FilteragainstDropDown.Items = {'localisations', 'tracks'};
            app.FilteragainstDropDown.ValueChangedFcn = createCallbackFcn(app, @FilteragainstDropDownValueChanged, true);
            app.FilteragainstDropDown.Tooltip = {'Determines whether to perform the filtering process against the list of other existing trajectories in the same cell, or against the full database of localisations, which includes those that did not form trajectories.'; ''; 'Filtering against localisations imposes a stricter criteria for trajectory conflict resolution.'};
            app.FilteragainstDropDown.Value = 'localisations';
            app.FiltertoleranceSpinnerLabel = uilabel(app.GridLayout);
            app.FiltertoleranceSpinnerLabel.Text = 'Filter tolerance';
            app.FiltertoleranceSpinner = uispinner(app.GridLayout);
            app.FiltertoleranceSpinner.Limits = [0 Inf];
            app.FiltertoleranceSpinner.ValueDisplayFormat = '%.0f';
            app.FiltertoleranceSpinner.ValueChangedFcn = createCallbackFcn(app, @FiltertoleranceSpinnerValueChanged, true);
            app.FiltertoleranceSpinner.Tooltip = {'Minimum number of conflicts (defined by the trajectory conflict resolution settings above) before a trajectory is filtered. A higher number here indicates a more tolerate approach to filtering, and may require the user to discard more molecules during the labelling process.'};
            app.FiltertoleranceSpinner.Value = 1;
            app.PreparedataforkineticsanalysisButton = uibutton(app.GridLayout, 'state');
            app.PreparedataforkineticsanalysisButton.ValueChangedFcn = createCallbackFcn(app, @PreparedataforkineticsanalysisButtonValueChanged, true);
            app.PreparedataforkineticsanalysisButton.Tooltip = {'Performs trajectory filtering, construction of synthetic features, and formatting of the data for downstream analysis with manual user labelling and ML classification tasks.'};
            app.PreparedataforkineticsanalysisButton.Text = 'Prepare data for kinetics analysis';
            app.IllustrationborderpxSpinnerLabel = uilabel(app.GridLayout);
            app.IllustrationborderpxSpinnerLabel.Text = 'Illustration border (px)';
            app.UndriftingmethodDropDownLabel = uilabel(app.GridLayout);
            app.UndriftingmethodDropDownLabel.Text = 'Undrifting method';
            app.NotesTab = uitab(app.SideBarTabGroup);
            app.NotesTab.Tooltip = {'Export the notes you have taken above to the file type you have requested.'; ''; 'Note that the title of the exported document will be the first line of the file description you have provided in the Load/Save tab.'};
            app.NotesTab.Title = 'Notes';
            app.GridLayout19 = uigridlayout(app.NotesTab);
            app.DataanalysisnotesTextAreaLabel = uilabel(app.GridLayout19);
            app.DataanalysisnotesTextAreaLabel.Text = 'Data analysis notes';
            app.DataanalysisnotesTextArea = uitextarea(app.GridLayout19);
            app.DataanalysisnotesTextArea.Tooltip = {'You can keep your data analysis notes here local to the file being used. When you save your analysis, these notes are stored in plain text inside the analysis file. They will be re-loaded when you next continue your work.'};
            app.SelectparameterstocopyButton = uibutton(app.GridLayout19, 'push');
            app.SelectparameterstocopyButton.Text = 'Select parameters to copy';
            app.PasteparametersButton = uibutton(app.GridLayout19, 'push');
            app.PasteparametersButton.Text = 'Paste parameters';
            app.NotesexportformatDropDown = uidropdown(app.GridLayout19);
            app.NotesexportformatDropDown.Items = {'PDF (.pdf)', 'Plain text (.txt)', 'Rich text (.rtf)', 'LaTeX (.tex)', 'Markdown (.md)', 'HTML (.html)'};
            app.NotesexportformatDropDown.Tooltip = {'Export formats for analysis notes. Options are,'; ''; '"PDF (.pdf)": Adobe PDF format. For very long notes this will be split into multiple PDFs with consecutive filename suffixes.'; ''; '"Plain text (.txt)": a single ordinary plain text document. The provides most compatibility, but offers no formatting.'; ''; '"Rich text (.rtf)": a single Rich Text Format (RTF) document. This enables easy editing and formatting of the text in any common word processor.'; ''; '"LaTeX (.tex)": a single TeX source file which can be professionally typeset and exported to a wide range of formats.'};
            app.NotesexportformatDropDown.Value = 'PDF (.pdf)';
            app.ExportanalysisnotesButton = uibutton(app.GridLayout19, 'push');
            app.ExportanalysisnotesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportanalysisnotesButtonPushed, true);
            app.ExportanalysisnotesButton.Tooltip = {'Export the notes you have taken to the requested file type.'; ''; 'Note that the '};
            app.ExportanalysisnotesButton.Text = 'Export analysis notes';
            app.NotesexportformatDropDownLabel = uilabel(app.GridLayout19);
            app.NotesexportformatDropDownLabel.Text = 'Notes export format';
            app.HelpaboutTab = uitab(app.SideBarTabGroup);
            app.HelpaboutTab.Title = 'Help & about';
            app.GridLayout20 = uigridlayout(app.HelpaboutTab);
            app.QuickstartguideButton = uibutton(app.GridLayout20, 'push');
            app.QuickstartguideButton.Text = 'Quickstart guide';
            app.OpenPDFmanualButton = uibutton(app.GridLayout20, 'push');
            app.OpenPDFmanualButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPDFmanualButtonPushed, true);
            app.OpenPDFmanualButton.Text = 'Open PDF manual';
            app.VisittheGitHubrepositoryButton = uibutton(app.GridLayout20, 'push');
            app.VisittheGitHubrepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @VisittheGitHubrepositoryButtonPushed, true);
            app.VisittheGitHubrepositoryButton.Text = 'Visit the GitHub repository';
            app.TextArea2 = uitextarea(app.GridLayout20);
            app.TextArea2.Editable = 'off';
            app.TextArea2.Value = {'THIS IS A PRE-RELEASE UNPUBLISHED VERSION; DO NOT DISTRIBUTE. BY USING THIS SOFTWARE YOU AGREE THAT YOU HAVE READ, UNDERSTOOD, AND ACCEPTED THE TERMS OF SERVICE AGREEMENT DISPLAYED ON LAUNCH.'; ''; 'This software is built for classification, segmentation, prediction, and data mining of single molecule superresolution microscopy data to gain insights into biological processes.'; ''; 'To view the quickstart guide, release notes, PDF manual, or video guide, please click the links below'; ''; 'Tooltips are provided throughout the software; if you are unsure about the meaning of a particular button or component, hover the mouse over it for two seconds and a description will appear.'};
            app.Label = uilabel(app.GridLayout20);
            app.Label.Text = '';
            app.Authorship = uitextarea(app.GridLayout20);
            app.Authorship.Editable = 'off';
            app.Authorship.Value = {'This software was developed by Dr Oliver Pambos, November 2020.'; ''; 'For more information the author can be contacted via,'; ''; 'Dr Oliver Pambos'; 'Condensed Matter Physics'; 'Department of Physics'; 'University of Oxford'; 'United Kingdom'; 'OX4 1GE'; ''; 'The code is available for download via GitHub: github.com/opambos'; ''; ''};
            app.textout = uitextarea(app.InVivoKineticsUIFigure);
            app.textout.Editable = 'off';
            app.textout.Value = {'Welcome to the In Vivo Kinetics reduction and analysis tool.'; 'November 2020.'; ''; 'Developed by,'; ''; 'Dr Oliver Pambos'; 'Condensed Matter Physics'; 'Department of Physics'; 'University of Oxford'};
            app.MainTabGroup = uitabgroup(app.InVivoKineticsUIFigure);
            app.MainTabGroup.SelectionChangedFcn = createCallbackFcn(app, @MainTabGroupSelectionChanged, true);
            app.HumanannotationTab = uitab(app.MainTabGroup);
            app.HumanannotationTab.Title = 'Human annotation';
            app.GridLayout10 = uigridlayout(app.HumanannotationTab);
            app.UIAxes_event_labeller = uiaxes(app.GridLayout10);
            xlabel(app.UIAxes_event_labeller, 'Time (s)')
            ylabel(app.UIAxes_event_labeller, 'Step size (nm)')
            zlabel(app.UIAxes_event_labeller, 'Z')
            app.UIAxes_image_event_labeller = uiaxes(app.GridLayout10);
            app.UIAxes_image_event_labeller.CLim = [0 255];
            app.UIAxes_image_event_labeller.Visible = 'off';
            app.UIAxes_event_labeller_status = uiaxes(app.GridLayout10);
            app.UIAxes_event_labeller_status.XTick = [];
            app.UIAxes_event_labeller_status.YTick = [];
            app.UIAxes_event_labeller_status.ZTick = [];
            app.UIAxes_labelling_progress = uiaxes(app.GridLayout10);
            app.UIAxes_labelling_progress.XTick = [];
            app.UIAxes_labelling_progress.YTick = [];
            app.UIAxes_labelling_progress.ZTick = [];
            app.UIAxes_event_labeller_mesh = uiaxes(app.GridLayout10);
            app.UIAxes_event_labeller_mesh.Visible = 'off';
            app.CellIDTextAreaLabel = uilabel(app.GridLayout10);
            app.CellIDTextAreaLabel.Text = 'Cell ID:';
            app.CellIDTextArea = uitextarea(app.GridLayout10);
            app.MolIDLabel = uilabel(app.GridLayout10);
            app.MolIDLabel.Text = 'Mol ID:';
            app.MolIDTextArea = uitextarea(app.GridLayout10);
            app.EventlabellersettingsTab = uitabgroup(app.GridLayout10);
            app.EventlabellersettingsTab.SelectionChangedFcn = createCallbackFcn(app, @EventlabellersettingsTabSelectionChanged, true);
            app.LabellingcontrolsTab = uitab(app.EventlabellersettingsTab);
            app.LabellingcontrolsTab.Title = 'Labelling controls';
            app.GridLayout11 = uigridlayout(app.LabellingcontrolsTab);
            app.Event_label_buttons = uipanel(app.GridLayout11);
            app.Event_label_buttons.Title = 'Label state selection';
            app.BeginButton = uibutton(app.GridLayout11, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.RestarttrackButton = uibutton(app.GridLayout11, 'push');
            app.RestarttrackButton.ButtonPushedFcn = createCallbackFcn(app, @RestarttrackButtonPushed, true);
            app.RestarttrackButton.Text = 'Restart track';
            app.DiscardmoleculeButton = uibutton(app.GridLayout11, 'push');
            app.DiscardmoleculeButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardmoleculeButtonPushed, true);
            app.DiscardmoleculeButton.Text = 'Discard molecule';
            app.MoreinfoButton = uibutton(app.GridLayout11, 'push');
            app.MoreinfoButton.ButtonPushedFcn = createCallbackFcn(app, @MoreinfoButtonPushed, true);
            app.MoreinfoButton.Text = 'More info';
            app.backwardButton = uibutton(app.GridLayout11, 'push');
            app.backwardButton.ButtonPushedFcn = createCallbackFcn(app, @backwardButtonPushed, true);
            app.backwardButton.Tooltip = {'Move backwards one frame. Alternatively press the ''d'' key on the keyboard when focused on the figure.'};
            app.backwardButton.Text = 'backwar(d)';
            app.forwardButton = uibutton(app.GridLayout11, 'push');
            app.forwardButton.ButtonPushedFcn = createCallbackFcn(app, @forwardButtonPushed, true);
            app.forwardButton.Tooltip = {'Move forward one frame. Alternatively press the ''f'' key on the keyboard when focused on the figure.'};
            app.forwardButton.Text = '(f)orward';
            app.GotostartButton = uibutton(app.GridLayout11, 'push');
            app.GotostartButton.ButtonPushedFcn = createCallbackFcn(app, @GotostartButtonPushed, true);
            app.GotostartButton.Tooltip = {'Move immediately to the first frame in the trajectory.'; ''; 'This can also be operated using the keyboard shortcut `s`.'};
            app.GotostartButton.Text = 'Go to (s)tart';
            app.GotoendButton = uibutton(app.GridLayout11, 'push');
            app.GotoendButton.ButtonPushedFcn = createCallbackFcn(app, @GotoendButtonPushed, true);
            app.GotoendButton.Tooltip = {'Move immediately to the final frame in the trajectory.'; ''; 'This can also be operated using the keyboard shortcut `e`.'};
            app.GotoendButton.Text = 'Go to (e)nd';
            app.UndoButton = uibutton(app.GridLayout11, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.SaveillustrationButton = uibutton(app.GridLayout11, 'push');
            app.SaveillustrationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveillustrationButtonPushed, true);
            app.SaveillustrationButton.Text = 'Save illustration';
            app.TogglesecondaryfeatureButton = uibutton(app.GridLayout11, 'state');
            app.TogglesecondaryfeatureButton.ValueChangedFcn = createCallbackFcn(app, @TogglesecondaryfeatureButtonValueChanged, true);
            app.TogglesecondaryfeatureButton.Text = 'Toggle secondary feature';
            app.PlotsettingsTab = uitab(app.EventlabellersettingsTab);
            app.PlotsettingsTab.Title = 'Plot settings';
            app.GridLayout21 = uigridlayout(app.PlotsettingsTab);
            app.ClearreferencelinesButton = uibutton(app.GridLayout21, 'push');
            app.ClearreferencelinesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearreferencelinesButtonPushed, true);
            app.ClearreferencelinesButton.Tooltip = {'Clear the reference lines from the plot. Note that this will also clear the current labelling state of the trajectory.'};
            app.ClearreferencelinesButton.Text = 'Clear reference lines';
            app.AddreferencelineButton = uibutton(app.GridLayout21, 'push');
            app.AddreferencelineButton.ButtonPushedFcn = createCallbackFcn(app, @AddreferencelineButtonPushed, true);
            app.AddreferencelineButton.Text = 'Add reference line';
            app.ReferencelineEditField = uieditfield(app.GridLayout21, 'numeric');
            app.ReferencelinethicknessSpinner = uispinner(app.GridLayout21);
            app.ReferencelinethicknessSpinner.Step = 0.5;
            app.ReferencelinethicknessSpinner.Limits = [0 Inf];
            app.ReferencelinethicknessSpinner.Value = 1;
            app.ReferencelinecolourDropDown = uidropdown(app.GridLayout21);
            app.ReferencelinecolourDropDown.Items = {'black', 'red', 'blue', 'green', 'yellow', 'cyan'};
            app.ReferencelinecolourDropDown.Value = 'red';
            app.ReferencelineEditFieldLabel = uilabel(app.GridLayout21);
            app.ReferencelineEditFieldLabel.Tooltip = {'Enter the value of a refernce line to place on the plot, and press the button below. The units of the lines are the same as those currently displayed on the Y-axis.'};
            app.ReferencelineEditFieldLabel.Text = 'Reference line';
            app.IndicatorthicknessSpinner = uispinner(app.GridLayout21);
            app.IndicatorthicknessSpinner.Step = 0.5;
            app.IndicatorthicknessSpinner.Limits = [0 Inf];
            app.IndicatorthicknessSpinner.ValueChangedFcn = createCallbackFcn(app, @IndicatorthicknessSpinnerValueChanged, true);
            app.IndicatorthicknessSpinner.Value = 1.5;
            app.ReferencelinethicknessSpinnerLabel = uilabel(app.GridLayout21);
            app.ReferencelinethicknessSpinnerLabel.Text = 'Reference line thickness';
            app.IndicatorthicknessSpinnerLabel = uilabel(app.GridLayout21);
            app.IndicatorthicknessSpinnerLabel.Text = 'Indicator thickness';
            app.ReferencelinecolourDropDownLabel = uilabel(app.GridLayout21);
            app.ReferencelinecolourDropDownLabel.Text = 'Reference line colour';
            app.IndicatorandreferencelinesLabel = uilabel(app.GridLayout21);
            app.IndicatorandreferencelinesLabel.Text = 'Indicator and reference lines';
            app.LinethicknessSpinner_2Label = uilabel(app.GridLayout21);
            app.LinethicknessSpinner_2Label.Text = 'Line thickness';
            app.SecondaryLinethicknessSpinner = uispinner(app.GridLayout21);
            app.SecondaryLinethicknessSpinner.Step = 0.5;
            app.SecondaryLinethicknessSpinner.Limits = [0.5 10];
            app.SecondaryLinethicknessSpinner.ValueDisplayFormat = '%.1f';
            app.SecondaryLinethicknessSpinner.ValueChangedFcn = createCallbackFcn(app, @SecondaryLinethicknessSpinnerValueChanged, true);
            app.SecondaryLinethicknessSpinner.Value = 1.5;
            app.YaxisupperlimitSpinner_2Label = uilabel(app.GridLayout21);
            app.YaxisupperlimitSpinner_2Label.Text = 'Y-axis upper limit';
            app.SecondaryYaxisupperlimitSpinner = uispinner(app.GridLayout21);
            app.SecondaryYaxisupperlimitSpinner.ValueChangedFcn = createCallbackFcn(app, @SecondaryYaxisupperlimitSpinnerValueChanged, true);
            app.SecondaryYaxisupperlimitSpinner.Value = 500;
            app.SecondaryFixupperlimitCheckBox = uicheckbox(app.GridLayout21);
            app.SecondaryFixupperlimitCheckBox.ValueChangedFcn = createCallbackFcn(app, @SecondaryFixupperlimitCheckBoxValueChanged, true);
            app.SecondaryFixupperlimitCheckBox.Text = 'Fix upper limit';
            app.SecondarySetlowerlimttozeroCheckBox = uicheckbox(app.GridLayout21);
            app.SecondarySetlowerlimttozeroCheckBox.ValueChangedFcn = createCallbackFcn(app, @SecondarySetlowerlimttozeroCheckBoxValueChanged, true);
            app.SecondarySetlowerlimttozeroCheckBox.Text = 'Set lower limt to zero';
            app.SecondarySetlowerlimttozeroCheckBox.Value = true;
            app.SecondaryFeatureDropDown = uidropdown(app.GridLayout21);
            app.SecondaryFeatureDropDown.Items = {'<< None >>'};
            app.SecondaryFeatureDropDown.ValueChangedFcn = createCallbackFcn(app, @SecondaryFeatureDropDownValueChanged, true);
            app.SecondaryFeatureDropDown.Value = '<< None >>';
            app.SecondaryplottedfeatureLabel = uilabel(app.GridLayout21);
            app.SecondaryplottedfeatureLabel.Text = 'Secondary plotted feature';
            app.FeatureDropDown_2Label = uilabel(app.GridLayout21);
            app.FeatureDropDown_2Label.Text = 'Feature';
            app.LinethicknessSpinnerLabel = uilabel(app.GridLayout21);
            app.LinethicknessSpinnerLabel.Text = 'Line thickness';
            app.PrimaryLinethicknessSpinner = uispinner(app.GridLayout21);
            app.PrimaryLinethicknessSpinner.Step = 0.5;
            app.PrimaryLinethicknessSpinner.Limits = [0.5 10];
            app.PrimaryLinethicknessSpinner.ValueDisplayFormat = '%.1f';
            app.PrimaryLinethicknessSpinner.ValueChangedFcn = createCallbackFcn(app, @PrimaryLinethicknessSpinnerValueChanged, true);
            app.PrimaryLinethicknessSpinner.Value = 1.5;
            app.PrimaryLinecolourDropDown = uidropdown(app.GridLayout21);
            app.PrimaryLinecolourDropDown.Items = {'black', 'red', 'blue', 'green', 'yellow', 'cyan'};
            app.PrimaryLinecolourDropDown.ValueChangedFcn = createCallbackFcn(app, @PrimaryLinecolourDropDownValueChanged, true);
            app.PrimaryLinecolourDropDown.Value = 'black';
            app.LinecolourDropDownLabel = uilabel(app.GridLayout21);
            app.LinecolourDropDownLabel.Text = 'Line colour';
            app.YaxisupperlimitSpinnerLabel = uilabel(app.GridLayout21);
            app.YaxisupperlimitSpinnerLabel.Text = 'Y-axis upper limit';
            app.PrimaryYaxisupperlimitSpinner = uispinner(app.GridLayout21);
            app.PrimaryYaxisupperlimitSpinner.ValueChangedFcn = createCallbackFcn(app, @PrimaryYaxisupperlimitSpinnerValueChanged, true);
            app.PrimaryYaxisupperlimitSpinner.Value = 500;
            app.PrimaryFixupperlimitCheckBox = uicheckbox(app.GridLayout21);
            app.PrimaryFixupperlimitCheckBox.ValueChangedFcn = createCallbackFcn(app, @PrimaryFixupperlimitCheckBoxValueChanged, true);
            app.PrimaryFixupperlimitCheckBox.Text = 'Fix upper limit';
            app.PrimarySetlowerlimttozeroCheckBox = uicheckbox(app.GridLayout21);
            app.PrimarySetlowerlimttozeroCheckBox.ValueChangedFcn = createCallbackFcn(app, @PrimarySetlowerlimttozeroCheckBoxValueChanged, true);
            app.PrimarySetlowerlimttozeroCheckBox.Text = 'Set lower limt to zero';
            app.PrimarySetlowerlimttozeroCheckBox.Value = true;
            app.PrimaryFeatureDropDown = uidropdown(app.GridLayout21);
            app.PrimaryFeatureDropDown.Items = {'Step size (nm)'};
            app.PrimaryFeatureDropDown.ValueChangedFcn = createCallbackFcn(app, @PrimaryFeatureDropDownValueChanged, true);
            app.PrimaryFeatureDropDown.Value = 'Step size (nm)';
            app.FeatureDropDownLabel = uilabel(app.GridLayout21);
            app.FeatureDropDownLabel.Text = 'Feature';
            app.PrimaryplottedfeatureLabel = uilabel(app.GridLayout21);
            app.PrimaryplottedfeatureLabel.Text = 'Primary plotted feature';
            app.TrajectoryillustrationTab = uitab(app.EventlabellersettingsTab);
            app.TrajectoryillustrationTab.Title = 'Trajectory illustration';
            app.GridLayout26 = uigridlayout(app.TrajectoryillustrationTab);
            app.ScalebarCheckBox = uicheckbox(app.GridLayout26);
            app.ScalebarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScalebarCheckBoxValueChanged, true);
            app.ScalebarCheckBox.Tooltip = {'Add a scale bar to the trajectory plot'};
            app.ScalebarCheckBox.Text = 'Scale bar';
            app.ScalebarCheckBox.Value = true;
            app.ScalebarlengthSpinner = uispinner(app.GridLayout26);
            app.ScalebarlengthSpinner.Step = 0.5;
            app.ScalebarlengthSpinner.Limits = [0 Inf];
            app.ScalebarlengthSpinner.ValueChangedFcn = createCallbackFcn(app, @ScalebarlengthSpinnerValueChanged, true);
            app.ScalebarlengthSpinner.Tooltip = {'Set the scale bar length in units of micrometers (μm)'};
            app.ScalebarlengthSpinner.Value = 1;
            app.ScalebarcolourDropDownLabel = uilabel(app.GridLayout26);
            app.ScalebarcolourDropDownLabel.Text = 'Scale bar colour';
            app.ScalebarpositionDropDownLabel = uilabel(app.GridLayout26);
            app.ScalebarpositionDropDownLabel.Text = 'Scale bar position';
            app.ScalebarpositionDropDown = uidropdown(app.GridLayout26);
            app.ScalebarpositionDropDown.Items = {'Top left', 'Top right', 'Bottom left', 'Bottom right'};
            app.ScalebarpositionDropDown.ValueChangedFcn = createCallbackFcn(app, @ScalebarpositionDropDownValueChanged, true);
            app.ScalebarpositionDropDown.Tooltip = {'The corner of the trajectory plot in which to place the scale bar.'};
            app.ScalebarpositionDropDown.Value = 'Bottom right';
            app.ScalebarlengthmSpinnerLabel = uilabel(app.GridLayout26);
            app.ScalebarlengthmSpinnerLabel.Text = 'Scale bar length (μm)';
            app.ScalebarcolourDropDown = uidropdown(app.GridLayout26);
            app.ScalebarcolourDropDown.Items = {'white', 'black', 'red', 'blue', 'green', 'yellow', 'cyan'};
            app.ScalebarcolourDropDown.ValueChangedFcn = createCallbackFcn(app, @ScalebarcolourDropDownValueChanged, true);
            app.ScalebarcolourDropDown.Tooltip = {'The colour of the scale bar and its label'};
            app.ScalebarcolourDropDown.Value = 'white';
            app.TrackstyleDropDownLabel = uilabel(app.GridLayout26);
            app.TrackstyleDropDownLabel.Text = 'Track style';
            app.TrackstyleDropDown = uidropdown(app.GridLayout26);
            app.TrackstyleDropDown.Items = {'Colour by time', 'Single colour', 'Colour by classification'};
            app.TrackstyleDropDown.Value = 'Colour by time';
            app.ExternalplotTab = uitab(app.EventlabellersettingsTab);
            app.ExternalplotTab.Title = 'External plot';
            app.GridLayout21_2 = uigridlayout(app.ExternalplotTab);
            app.PlotMoreFeatures = uitree(app.GridLayout21_2, 'checkbox');
            app.FeaturestoplotinexternalwindowLabel = uilabel(app.GridLayout21_2);
            app.FeaturestoplotinexternalwindowLabel.Text = 'Features to plot in external window';
            app.VideodisplayTab = uitab(app.EventlabellersettingsTab);
            app.VideodisplayTab.Title = 'Video display';
            app.GridLayout22 = uigridlayout(app.VideodisplayTab);
            app.VideoupscalefactorSpinnerLabel = uilabel(app.GridLayout22);
            app.VideoupscalefactorSpinnerLabel.Text = 'Video upscale factor';
            app.ExportvideoframerateHzEditFieldLabel = uilabel(app.GridLayout22);
            app.ExportvideoframerateHzEditFieldLabel.Text = 'Export video frame rate (Hz)';
            app.DynamicrangelowEditFieldLabel = uilabel(app.GridLayout22);
            app.DynamicrangelowEditFieldLabel.Text = 'Dynamic range low';
            app.ExportframerateEditField = uieditfield(app.GridLayout22, 'numeric');
            app.ExportframerateEditField.ValueChangedFcn = createCallbackFcn(app, @ExportframerateEditFieldValueChanged, true);
            app.ExportframerateEditField.Value = 5;
            app.VideoupscalefactorSpinner = uispinner(app.GridLayout22);
            app.VideoupscalefactorSpinner.ValueChangedFcn = createCallbackFcn(app, @VideoupscalefactorSpinnerValueChanged, true);
            app.VideoupscalefactorSpinner.Value = 4;
            app.BrightfieldoverlayCheckBox = uicheckbox(app.GridLayout22);
            app.BrightfieldoverlayCheckBox.Text = 'Brightfield overlay';
            app.FluorescenceimageCheckBox = uicheckbox(app.GridLayout22);
            app.FluorescenceimageCheckBox.Text = 'Fluorescence image';
            app.OverlayopacityEditField = uieditfield(app.GridLayout22, 'numeric');
            app.OverlayopacityEditField.ValueChangedFcn = createCallbackFcn(app, @OverlayopacityEditFieldValueChanged, true);
            app.OverlayopacityEditField.Tooltip = {'Opacity of the brightfield image overlaid on top of fluorescence signal in illustrations'};
            app.OverlayopacityEditField.Value = 30;
            app.DynamicrangehighEditFieldLabel = uilabel(app.GridLayout22);
            app.DynamicrangehighEditFieldLabel.Text = 'Dynamic range high';
            app.DynamicrangehighEditField = uieditfield(app.GridLayout22, 'numeric');
            app.DynamicrangehighEditField.ValueChangedFcn = createCallbackFcn(app, @DynamicrangehighEditFieldValueChanged, true);
            app.DynamicrangehighEditField.Tooltip = {'The upper limit of the dynamic range of the fluorescence movie shown in the human annotation tab.'; ''; 'Selecting values far above the signal maximum will make the signal harder to see above the background noise. Selecting values significantly below the signal will cause the signal to become saturated. Ideally this number should be set close to the maximum value of the signal.'};
            app.DynamicrangehighEditField.Value = 1200;
            app.DynamicrangelowEditField = uieditfield(app.GridLayout22, 'numeric');
            app.DynamicrangelowEditField.ValueChangedFcn = createCallbackFcn(app, @DynamicrangelowEditFieldValueChanged, true);
            app.DynamicrangelowEditField.Tooltip = {'The lower limit of the dynamic range of the fluorescence movie shown in the human annotation tab.'; ''; 'Lower values will increase background noise in the image but display a larger dynamic range of data. Higher values risk losing information in the signal. Ideally this number should be set at a value that best separates the signal and noise in the image.'};
            app.DynamicrangelowEditField.Value = 525;
            app.OverlayopacityEditFieldLabel = uilabel(app.GridLayout22);
            app.OverlayopacityEditFieldLabel.Text = 'Overlay opacity (%)';
            app.DynamicrangemodeDropDown = uidropdown(app.GridLayout22);
            app.DynamicrangemodeDropDown.Items = {'Auto', 'Manual'};
            app.DynamicrangemodeDropDown.ValueChangedFcn = createCallbackFcn(app, @DynamicrangemodeDropDownValueChanged, true);
            app.DynamicrangemodeDropDown.Tooltip = {'How to compute the dynamic range of the fluorescence video. Options are,'; ''; '"Auto": software automatically computes sensible values that provide a view of most of the dynamic range across the video of this specific molecule. This mode will ignore the manually entered dynamic range values below.'; ''; '"Manual": overrides the automatically-generated dynamic range scale. Switching to manual mode will set the lower and upper limits of the dynamic range to the user-entered values below.'};
            app.DynamicrangemodeDropDown.Value = 'Auto';
            app.DynamicrangemodeDropDownLabel = uilabel(app.GridLayout22);
            app.DynamicrangemodeDropDownLabel.Text = 'Dynamic range mode';
            app.AutosaveTab = uitab(app.EventlabellersettingsTab);
            app.AutosaveTab.Title = 'Autosave';
            app.GridLayout25 = uigridlayout(app.AutosaveTab);
            app.AutosavefrequencytrajectoriesSpinnerLabel = uilabel(app.GridLayout25);
            app.AutosavefrequencytrajectoriesSpinnerLabel.Text = 'Autosave frequency (trajectories)';
            app.NumberofautosvefilestokeepSpinnerLabel = uilabel(app.GridLayout25);
            app.NumberofautosvefilestokeepSpinnerLabel.Text = 'Number of autosve files to keep';
            app.NumberofautosvefilestokeepSpinner = uispinner(app.GridLayout25);
            app.NumberofautosvefilestokeepSpinner.Limits = [0 Inf];
            app.NumberofautosvefilestokeepSpinner.Tooltip = {'The maximum number of autosave files to maintain. Only these most recent autosave files will be retained.'};
            app.NumberofautosvefilestokeepSpinner.Value = 3;
            app.AutosavefrequencytrajectoriesSpinner = uispinner(app.GridLayout25);
            app.AutosavefrequencytrajectoriesSpinner.Limits = [0 Inf];
            app.AutosavefrequencytrajectoriesSpinner.ValueDisplayFormat = '%.0f';
            app.AutosavefrequencytrajectoriesSpinner.Tooltip = {'Frequency for autosaving during manual annotation. The number here represents the number of trajectories labelled between autosave events.'; ''; 'To disable autosaving, set this number to zero.'};
            app.AutosavefrequencytrajectoriesSpinner.Value = 10;
            app.ExportexamplesTab = uitab(app.EventlabellersettingsTab);
            app.ExportexamplesTab.Title = 'Export examples';
            app.GridLayout25_2 = uigridlayout(app.ExportexamplesTab);
            app.SaveeveryviewedmoleculeCheckBox = uicheckbox(app.GridLayout25_2);
            app.SaveeveryviewedmoleculeCheckBox.Text = 'Save every viewed molecule';
            app.SavealldatawithvideoCheckBox = uicheckbox(app.GridLayout25_2);
            app.SavealldatawithvideoCheckBox.Tooltip = {'Saves a CSV-formatted datafile of the current track with all available features together with the generated illustration.'};
            app.SavealldatawithvideoCheckBox.Text = 'Save all data with video';
            app.SavealldatawithvideoCheckBox.Value = true;
            app.PlayexportedvideoCheckBox = uicheckbox(app.GridLayout25_2);
            app.PlayexportedvideoCheckBox.Text = 'Play exported video';
            app.PlayexportedvideoCheckBox.Value = true;
            app.IllustrateafterlabellingCheckBox = uicheckbox(app.GridLayout25_2);
            app.IllustrateafterlabellingCheckBox.Text = 'Illustrate after labelling';
            app.InsightsTab = uitab(app.MainTabGroup);
            app.InsightsTab.Title = 'Insights';
            app.GridLayout2 = uigridlayout(app.InsightsTab);
            app.UIAxes_compiled_events = uiaxes(app.GridLayout2);
            title(app.UIAxes_compiled_events, 'Histogram of manually-labelled events')
            xlabel(app.UIAxes_compiled_events, 'Event duration (s)')
            ylabel(app.UIAxes_compiled_events, 'Number of events')
            app.TabGroup4 = uitabgroup(app.GridLayout2);
            app.DatasetoverviewTab = uitab(app.TabGroup4);
            app.DatasetoverviewTab.Title = 'Dataset overview';
            app.GridLayout3 = uigridlayout(app.DatasetoverviewTab);
            app.SourcedataDropDown_2Label = uilabel(app.GridLayout3);
            app.SourcedataDropDown_2Label.Text = 'Source data';
            app.InsightsSourcedataDropDown = uidropdown(app.GridLayout3);
            app.InsightsSourcedataDropDown.Items = {'<< Select dataset >>'};
            app.InsightsSourcedataDropDown.DropDownOpeningFcn = createCallbackFcn(app, @InsightsSourcedataDropDownOpening, true);
            app.InsightsSourcedataDropDown.ValueChangedFcn = createCallbackFcn(app, @InsightsSourcedataDropDownValueChanged, true);
            app.InsightsSourcedataDropDown.Tooltip = {'Select the labelled source data to analyse. This dropdown box contains a list of all of the available labellings for the current dataset.'; ''; 'This may include (where available): human annotations, ground truth, and ML-labelled data.'};
            app.InsightsSourcedataDropDown.Value = '<< Select dataset >>';
            app.UpdatedescriptivestatisticsButton = uibutton(app.GridLayout3, 'push');
            app.UpdatedescriptivestatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatedescriptivestatisticsButtonPushed, true);
            app.UpdatedescriptivestatisticsButton.Tooltip = {'Display descriptive statistics for the currently loaded dataset using the source of data labels selected in the dropdown menu above.'};
            app.UpdatedescriptivestatisticsButton.Text = 'Update descriptive statistics';
            app.StatetransitiondiagramButton = uibutton(app.GridLayout3, 'push');
            app.StatetransitiondiagramButton.ButtonPushedFcn = createCallbackFcn(app, @StatetransitiondiagramButtonPushed, true);
            app.StatetransitiondiagramButton.Tooltip = {'Generate a state transition diagram for the currently labelled data.'; ''; 'This is displayed in an external window.'};
            app.StatetransitiondiagramButton.Text = 'State transition diagram';
            app.DisplaystepsizeprofileButton = uibutton(app.GridLayout3, 'push');
            app.DisplaystepsizeprofileButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaystepsizeprofileButtonPushed, true);
            app.DisplaystepsizeprofileButton.Tooltip = {'Display a histogram of step sizes for all molecules in the currently loaded dataset.'; ''; 'The number of steps to extract from each trajectory is set by the parameter below.'};
            app.DisplaystepsizeprofileButton.Text = 'Display step size profile';
            app.MaximumstepsizesSpinnerLabel = uilabel(app.GridLayout3);
            app.MaximumstepsizesSpinnerLabel.Text = 'Maximum step sizes';
            app.MaximumstepsizesSpinner = uispinner(app.GridLayout3);
            app.MaximumstepsizesSpinner.Limits = [0 Inf];
            app.MaximumstepsizesSpinner.ValueDisplayFormat = '%.0f';
            app.MaximumstepsizesSpinner.ValueChangedFcn = createCallbackFcn(app, @MaximumstepsizesSpinnerValueChanged, true);
            app.MaximumstepsizesSpinner.Tooltip = {'When computing the step size distribution, this parameters determines the number of steps to sample from each trajectory.'; ''; 'Setting a value of `0` will result in all steps sizes being compiled for all molecules. This is useful for capturing the full step size distribution, but can bias the data slightly towards longer molecules for datasets containing extremely heterogenous trajectory lengths.'; ''; 'When selecting a number of steps, N > 0, the first N steps from each trajectory are used. When N exceeds the number of steps in the trajectory, all steps in the trajectory are used.'};
            app.DisplaymoleculearrivaltimesButton = uibutton(app.GridLayout3, 'push');
            app.DisplaymoleculearrivaltimesButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaymoleculearrivaltimesButtonPushed, true);
            app.DisplaymoleculearrivaltimesButton.Tooltip = {'Displays the arrival time of molecules in the dataset. This produces a histogram of the time between the start of the recording and the first appearance of each molecule.'};
            app.DisplaymoleculearrivaltimesButton.Text = 'Display molecule arrival times';
            app.ArrivaltimebinwidthsLabel = uilabel(app.GridLayout3);
            app.ArrivaltimebinwidthsLabel.Text = 'Arrival time bin width (s)';
            app.Binwidtharrivaltime = uispinner(app.GridLayout3);
            app.Binwidtharrivaltime.Step = 100;
            app.Binwidtharrivaltime.Limits = [10 Inf];
            app.Binwidtharrivaltime.ValueDisplayFormat = '%.0f';
            app.Binwidtharrivaltime.ValueChangedFcn = createCallbackFcn(app, @BinwidtharrivaltimeValueChanged, true);
            app.Binwidtharrivaltime.Value = 500;
            app.PlotalltrajectorylengthsButton = uibutton(app.GridLayout3, 'push');
            app.PlotalltrajectorylengthsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotalltrajectorylengthsButtonPushed, true);
            app.PlotalltrajectorylengthsButton.Tooltip = {'Plots a histogram of observation time for all trajectories in the dataset. This can be particularly useful for assessment of fluorophore photobleaching times.'};
            app.PlotalltrajectorylengthsButton.Text = 'Plot all trajectory lengths';
            app.TrajectorylengthbinsizessSpinnerLabel = uilabel(app.GridLayout3);
            app.TrajectorylengthbinsizessSpinnerLabel.Text = 'Trajectory length bin sizes (s)';
            app.TrajectorylengthbinsizessSpinner = uispinner(app.GridLayout3);
            app.TrajectorylengthbinsizessSpinner.Limits = [0.05 Inf];
            app.TrajectorylengthbinsizessSpinner.ValueChangedFcn = createCallbackFcn(app, @TrajectorylengthbinsizessSpinnerValueChanged, true);
            app.TrajectorylengthbinsizessSpinner.Tooltip = {'Bin sizes used to compile trajectory length histogram.'};
            app.TrajectorylengthbinsizessSpinner.Value = 2;
            app.DescriptivestatsTable = uitable(app.GridLayout3);
            app.DescriptivestatsTable.ColumnName = {'Parameter'; 'Value'};
            app.DescriptivestatsTable.RowName = {};
            app.StatehistogramsTab = uitab(app.TabGroup4);
            app.StatehistogramsTab.Title = 'State histograms';
            app.GridLayout4 = uigridlayout(app.StatehistogramsTab);
            app.EventstoviewDropDownLabel = uilabel(app.GridLayout4);
            app.EventstoviewDropDownLabel.Text = 'Events to view';
            app.EventstoviewDropDown = uidropdown(app.GridLayout4);
            app.EventstoviewDropDown.Items = {'<< Select state >>'};
            app.EventstoviewDropDown.ValueChangedFcn = createCallbackFcn(app, @EventstoviewDropDownValueChanged, true);
            app.EventstoviewDropDown.Tooltip = {'Select the class name to plot.'; ''; 'Note that if no class names are selected click another tab in this group and then move back to '};
            app.EventstoviewDropDown.ClickedFcn = createCallbackFcn(app, @EventstoviewDropDownClicked, true);
            app.EventstoviewDropDown.Value = '<< Select state >>';
            app.TruncationDropDownLabel = uilabel(app.GridLayout4);
            app.TruncationDropDownLabel.Text = 'Truncation';
            app.TruncationDropDown = uidropdown(app.GridLayout4);
            app.TruncationDropDown.Items = {'Singly-truncated', 'Doubly-truncated', 'All truncated', 'Full events', 'All events'};
            app.TruncationDropDown.ValueChangedFcn = createCallbackFcn(app, @TruncationDropDownValueChanged, true);
            app.TruncationDropDown.Value = 'Singly-truncated';
            app.BinwidthsSpinnerLabel = uilabel(app.GridLayout4);
            app.BinwidthsSpinnerLabel.Text = 'Bin width (s)';
            app.BinwidthsSpinner = uispinner(app.GridLayout4);
            app.BinwidthsSpinner.Step = 0.2;
            app.BinwidthsSpinner.Limits = [0.05 Inf];
            app.BinwidthsSpinner.ValueChangedFcn = createCallbackFcn(app, @BinwidthsSpinnerValueChanged, true);
            app.BinwidthsSpinner.Value = 2;
            app.DisplaystatehistogramButton = uibutton(app.GridLayout4, 'push');
            app.DisplaystatehistogramButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaystatehistogramButtonPushed, true);
            app.DisplaystatehistogramButton.Text = 'Display state histogram';
            app.HistogramdatatoexportDropDownLabel = uilabel(app.GridLayout4);
            app.HistogramdatatoexportDropDownLabel.Text = 'Histogram data to export';
            app.HistogramdatatoexportDropDown = uidropdown(app.GridLayout4);
            app.HistogramdatatoexportDropDown.Items = {'Raw data', 'Only bins shown'};
            app.HistogramdatatoexportDropDown.Tooltip = {'Determines the format of the data to export'; ''; '''Raw data'' (recommended): this will export the underlying raw data that was used to generate the histogram shown; this allows for example other bin sizes to be used in external software.'; ''; '''Only bins shown'': this will export the centre and occupancy of each bin in the displayed histogram; this offers less flexibility, but does not require re-binning of data.'};
            app.HistogramdatatoexportDropDown.Value = 'Raw data';
            app.ExporthistogramButton = uibutton(app.GridLayout4, 'push');
            app.ExporthistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ExporthistogramButtonPushed, true);
            app.ExporthistogramButton.Tooltip = {'Exports the currently-displayed histogram data to a CSV file using the format selected above. The file is saved in a subdirectory of the directory containing the source fluorescence video files called ''saved histogram data''.'};
            app.ExporthistogramButton.Text = 'Export histogram';
            app.MSDanalysisTab = uitab(app.TabGroup4);
            app.MSDanalysisTab.Title = 'MSD analysis';
            app.GridLayout5 = uigridlayout(app.MSDanalysisTab);
            app.ProcessaveragesDropDownLabel = uilabel(app.GridLayout5);
            app.ProcessaveragesDropDownLabel.Text = 'Process averages';
            app.ProcessaveragesDropDown = uidropdown(app.GridLayout5);
            app.ProcessaveragesDropDown.Items = {'Molecule-by-molecule', 'All localisations'};
            app.ProcessaveragesDropDown.Tooltip = {'How to compute averages for each lag time. Molecule-by-molecule (recommended) uses a single entry from each trajectory or classified section. All localisations treats all localisations equally, which leads to bias towards longer trajectories.'};
            app.ProcessaveragesDropDown.Value = 'Molecule-by-molecule';
            app.LagtimetodisplaysSpinnerLabel = uilabel(app.GridLayout5);
            app.LagtimetodisplaysSpinnerLabel.Text = 'Lag time to display (s)';
            app.LagtimetodisplaysSpinner = uispinner(app.GridLayout5);
            app.LagtimetodisplaysSpinner.Limits = [0 Inf];
            app.LagtimetodisplaysSpinner.Tooltip = {'Number of steps to display. Note that the MSDs are calculated for every possible step size in the dataset; this setting determines how many steps are actually displayed on the MSD plot'};
            app.LagtimetodisplaysSpinner.Value = 2;
            app.MinimumlocalisationsforMSDSpinnerLabel = uilabel(app.GridLayout5);
            app.MinimumlocalisationsforMSDSpinnerLabel.Text = 'Minimum localisations for MSD';
            app.MinimumlocalisationsforMSDSpinner = uispinner(app.GridLayout5);
            app.MinimumlocalisationsforMSDSpinner.Limits = [1 Inf];
            app.MinimumlocalisationsforMSDSpinner.ValueDisplayFormat = '%.0f';
            app.MinimumlocalisationsforMSDSpinner.Tooltip = {'Minimum number of localisations in any given molecule or span in order to contribute to the MSD calculations'};
            app.MinimumlocalisationsforMSDSpinner.Value = 5;
            app.ExportMSDdataCheckBox = uicheckbox(app.GridLayout5);
            app.ExportMSDdataCheckBox.Tooltip = {'Save the computed MSD data to file for external plotting'};
            app.ExportMSDdataCheckBox.Text = 'Export MSD data';
            app.NumberofstepsforDcalculationSpinnerLabel = uilabel(app.GridLayout5);
            app.NumberofstepsforDcalculationSpinnerLabel.Text = 'Number of steps for D* calculation';
            app.NumberofstepsforDcalculationSpinner = uispinner(app.GridLayout5);
            app.NumberofstepsforDcalculationSpinner.Limits = [2 Inf];
            app.NumberofstepsforDcalculationSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofstepsforDcalculationSpinner.Tooltip = {'The number of lag time entries to use when computing D* for each state. For example, an entry of 3 will compile MSD data for time intervals up to 3 frames.'; ''; 'It is important to limit this to only the first few points when to avoid the effects of anomolous diffusion.'};
            app.NumberofstepsforDcalculationSpinner.Value = 2;
            app.DcalculationmethodDropDownLabel = uilabel(app.GridLayout5);
            app.DcalculationmethodDropDownLabel.Text = 'D* calculation method';
            app.LocalisationerrormSpinnerLabel = uilabel(app.GridLayout5);
            app.LocalisationerrormSpinnerLabel.Text = 'Localisation error (μm)';
            app.LocalisationerrormSpinner = uispinner(app.GridLayout5);
            app.LocalisationerrormSpinner.Limits = [0 Inf];
            app.LocalisationerrormSpinner.Tooltip = {'Localisation precision in the dataset. This is used is the apparent diffusion calculation when the ''localisation error corrected'' method for the D* calculation is selected. Localiastion precision is typically determined from the RMS of the errors in X and Y in a fixed sample. For most SMLM techniques these values are a few tens of nanometers.'};
            app.LocalisationerrormSpinner.Value = 0.04;
            app.DcalculationmethodDropDown = uidropdown(app.GridLayout5);
            app.DcalculationmethodDropDown.Items = {'MSD/4t', 'Correct for localisation error', '4D*'};
            app.DcalculationmethodDropDown.Tooltip = {'Method used to compute the apparent diffusion coefficient D* from the MSDs.'; ''; 'MSD/4t: this option will compute the gradient of a linear fit to the MSD - lag time plots for each state, dividing this by four (2*n, where n is the number of dimensions).'; ''; 'Correct for localisation error: this will compute D* from the (MSD/4t) - (sigma^2/t), where sigma is the localisation precision.'; ''; '4D*: The output is calculated as the gradient of the MSD-lag time plot, without dividing by 4.'};
            app.DcalculationmethodDropDown.Value = 'MSD/4t';
            app.FastmodeCheckBox = uicheckbox(app.GridLayout5);
            app.FastmodeCheckBox.Tooltip = {'In fast mode MSD data is compiled only for lag times used in the calculation (dfined in `Number of steps....` control in this tab).'; ''; 'This greatly improves processing speed, however the resulting MSD - lag time plot will have an upper limit defined by the maximum lag time.'; ''; 'If you wish to observe the lag time plot on longer timescales while limiting the calculation of the apparent diffusion coefficient, deselect this checkbox.'};
            app.FastmodeCheckBox.Text = 'Fast mode';
            app.FastmodeCheckBox.Value = true;
            app.ComputeDButton = uibutton(app.GridLayout5, 'push');
            app.ComputeDButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeDButtonPushed, true);
            app.ComputeDButton.Tooltip = {'Compute the MSD and apparent diffusion coefficients for all labelled molecules and sections, across all states using the options above.'; ''; 'Note that the source of data labels used to perform these calculations is provided by the selected item in the `Source data` dropdown of the `Dataset overview` tab.'};
            app.ComputeDButton.Text = 'Compute D*';
            app.DiffusionhistogramsTab = uitab(app.TabGroup4);
            app.DiffusionhistogramsTab.Title = 'Diffusion histograms';
            app.GridLayout35 = uigridlayout(app.DiffusionhistogramsTab);
            app.GeneratediffusionhistogramsButton = uibutton(app.GridLayout35, 'push');
            app.GeneratediffusionhistogramsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratediffusionhistogramsButtonPushed, true);
            app.GeneratediffusionhistogramsButton.Text = 'Generate diffusion histograms';
            app.Binwidthm2sLabel = uilabel(app.GridLayout35);
            app.Binwidthm2sLabel.Text = 'Bin width (μm^2/s)';
            app.DiffusionHistBinSizeSpinner = uispinner(app.GridLayout35);
            app.DiffusionHistBinSizeSpinner.Step = 0.05;
            app.DiffusionHistBinSizeSpinner.Limits = [0.001 Inf];
            app.DiffusionHistBinSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @DiffusionHistBinSizeSpinnerValueChanged, true);
            app.DiffusionHistBinSizeSpinner.Value = 0.1;
            app.StateDropDownLabel = uilabel(app.GridLayout35);
            app.StateDropDownLabel.Text = 'State';
            app.DiffusionHistStateDropDown = uidropdown(app.GridLayout35);
            app.DiffusionHistStateDropDown.Items = {'<< Select state >>'};
            app.DiffusionHistStateDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DiffusionHistStateDropDownOpening, true);
            app.DiffusionHistStateDropDown.ValueChangedFcn = createCallbackFcn(app, @DiffusionHistStateDropDownValueChanged, true);
            app.DiffusionHistStateDropDown.Value = '<< Select state >>';
            app.MincelllengthmSpinnerLabel = uilabel(app.GridLayout35);
            app.MincelllengthmSpinnerLabel.Text = 'Min cell length (μm)';
            app.DiffusionHistMincelllengthmSpinner = uispinner(app.GridLayout35);
            app.DiffusionHistMincelllengthmSpinner.Step = 0.5;
            app.DiffusionHistMincelllengthmSpinner.Limits = [0 Inf];
            app.DiffusionHistMincelllengthmSpinner.ValueChangedFcn = createCallbackFcn(app, @DiffusionHistMincelllengthmSpinnerValueChanged, true);
            app.DiffusionHistMincelllengthmSpinner.Value = 2;
            app.MaxcelllengthmSpinnerLabel = uilabel(app.GridLayout35);
            app.MaxcelllengthmSpinnerLabel.Text = 'Max cell length (μm)';
            app.DiffusionHistMaxcelllengthmSpinner = uispinner(app.GridLayout35);
            app.DiffusionHistMaxcelllengthmSpinner.Step = 0.5;
            app.DiffusionHistMaxcelllengthmSpinner.Limits = [0 Inf];
            app.DiffusionHistMaxcelllengthmSpinner.ValueChangedFcn = createCallbackFcn(app, @DiffusionHistMaxcelllengthmSpinnerValueChanged, true);
            app.DiffusionHistMaxcelllengthmSpinner.Value = 4;
            app.DiffusionHistRestricttorangeofcelllengthsCheckBox = uicheckbox(app.GridLayout35);
            app.DiffusionHistRestricttorangeofcelllengthsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DiffusionHistRestricttorangeofcelllengthsCheckBoxValueChanged, true);
            app.DiffusionHistRestricttorangeofcelllengthsCheckBox.Text = 'Restrict to range of cell lengths';
            app.PlotlocationDropDownLabel = uilabel(app.GridLayout35);
            app.PlotlocationDropDownLabel.Text = 'Plot location';
            app.DiffusionHistPlotlocationDropDown = uidropdown(app.GridLayout35);
            app.DiffusionHistPlotlocationDropDown.Items = {'Inside GUI', 'External'};
            app.DiffusionHistPlotlocationDropDown.Value = 'Inside GUI';
            app.DiffusionHistExportdatatoCSVCheckBox = uicheckbox(app.GridLayout35);
            app.DiffusionHistExportdatatoCSVCheckBox.Text = 'Export data to CSV';
            app.MaxlagframesSpinnerLabel = uilabel(app.GridLayout35);
            app.MaxlagframesSpinnerLabel.Text = 'Max lag (frames)';
            app.DiffusionHistMaxlagframesSpinner = uispinner(app.GridLayout35);
            app.DiffusionHistMaxlagframesSpinner.Limits = [2 Inf];
            app.DiffusionHistMaxlagframesSpinner.ValueDisplayFormat = '%.0f';
            app.DiffusionHistMaxlagframesSpinner.ValueChangedFcn = createCallbackFcn(app, @DiffusionHistMaxlagframesSpinnerValueChanged, true);
            app.DiffusionHistMaxlagframesSpinner.Value = 2;
            app.DiffusionHistRunincontinuousupdatemodeCheckBox = uicheckbox(app.GridLayout35);
            app.DiffusionHistRunincontinuousupdatemodeCheckBox.Tooltip = {'Selecting this option will continuously update the plot as you change parameters, on the condition that [Export data to CSV] is unchecked, and the [Plot location] dropdown box is set to "Inside GUI".'; ''; 'Note that for very large datasets this can slow performance, especially when the checkbox [Restrict to range of cell lengths] is also checked.'};
            app.DiffusionHistRunincontinuousupdatemodeCheckBox.Text = 'Run in continuous update mode';
            app.DiffusionHistRunincontinuousupdatemodeCheckBox.Value = true;
            app.DiffusionHistPerformgammafitCheckBox = uicheckbox(app.GridLayout35);
            app.DiffusionHistPerformgammafitCheckBox.ValueChangedFcn = createCallbackFcn(app, @DiffusionHistPerformgammafitCheckBoxValueChanged, true);
            app.DiffusionHistPerformgammafitCheckBox.Text = 'Perform gamma fit';
            app.NumberoffitsLabel = uilabel(app.GridLayout35);
            app.NumberoffitsLabel.Text = 'Number of functions';
            app.DiffusionHistNumberofGammasSpinner = uispinner(app.GridLayout35);
            app.DiffusionHistNumberofGammasSpinner.Limits = [1 10];
            app.DiffusionHistNumberofGammasSpinner.ValueDisplayFormat = '%.0f';
            app.DiffusionHistNumberofGammasSpinner.ValueChangedFcn = createCallbackFcn(app, @DiffusionHistNumberofGammasSpinnerValueChanged, true);
            app.DiffusionHistNumberofGammasSpinner.Value = 1;
            app.DiffusionHistSetmanualinitialGammafitparametersCheckBox = uicheckbox(app.GridLayout35);
            app.DiffusionHistSetmanualinitialGammafitparametersCheckBox.Text = 'Set manual initial Gamma fit parameters';
            app.DiffusionHistGammaInitialValues = uitable(app.GridLayout35);
            app.DiffusionHistGammaInitialValues.ColumnName = {'Coefficient'; 'Occupancy'};
            app.DiffusionHistGammaInitialValues.RowName = {};
            app.DiffusionHistGammaInitialValues.ColumnEditable = true;
            app.DiffusionHistPlotresidualsCheckBox = uicheckbox(app.GridLayout35);
            app.DiffusionHistPlotresidualsCheckBox.Text = 'Plot residuals';
            app.SpatialmappingTab = uitab(app.TabGroup4);
            app.SpatialmappingTab.Title = 'Spatial mapping';
            app.GridLayout30 = uigridlayout(app.SpatialmappingTab);
            app.GenerateheatmapsButton = uibutton(app.GridLayout30, 'push');
            app.GenerateheatmapsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateheatmapsButtonPushed, true);
            app.GenerateheatmapsButton.Text = 'Generate heatmaps';
            app.FigurewidthpxSpinnerLabel = uilabel(app.GridLayout30);
            app.FigurewidthpxSpinnerLabel.Text = 'Figure width (px)';
            app.HeatmapfigurewidthpxSpinner = uispinner(app.GridLayout30);
            app.HeatmapfigurewidthpxSpinner.Step = 100;
            app.HeatmapfigurewidthpxSpinner.Limits = [200 Inf];
            app.HeatmapfigurewidthpxSpinner.ValueDisplayFormat = '%.0f';
            app.HeatmapfigurewidthpxSpinner.Value = 800;
            app.FigureheightpxSpinnerLabel = uilabel(app.GridLayout30);
            app.FigureheightpxSpinnerLabel.Text = 'Figure height (px)';
            app.HeatmapfigureheightpxSpinner = uispinner(app.GridLayout30);
            app.HeatmapfigureheightpxSpinner.Step = 100;
            app.HeatmapfigureheightpxSpinner.Limits = [100 Inf];
            app.HeatmapfigureheightpxSpinner.ValueDisplayFormat = '%.0f';
            app.HeatmapfigureheightpxSpinner.Value = 400;
            app.ModelcelllengthmSpinnerLabel = uilabel(app.GridLayout30);
            app.ModelcelllengthmSpinnerLabel.Text = 'Model cell length (μm)';
            app.ModelcelllengthmSpinner = uispinner(app.GridLayout30);
            app.ModelcelllengthmSpinner.Step = 0.5;
            app.ModelcelllengthmSpinner.Limits = [1 Inf];
            app.ModelcelllengthmSpinner.Value = 3;
            app.ModelcellwidthmSpinnerLabel = uilabel(app.GridLayout30);
            app.ModelcellwidthmSpinnerLabel.Text = 'Model cell width (μm)';
            app.ModelcellwidthmSpinner = uispinner(app.GridLayout30);
            app.ModelcellwidthmSpinner.Step = 0.5;
            app.ModelcellwidthmSpinner.Limits = [0.2 Inf];
            app.ModelcellwidthmSpinner.Value = 0.75;
            app.HeatmapbinsizenmLabel = uilabel(app.GridLayout30);
            app.HeatmapbinsizenmLabel.Text = 'Heatmap bin size (nm)';
            app.HeatmapBinsizenmSpinner = uispinner(app.GridLayout30);
            app.HeatmapBinsizenmSpinner.Step = 25;
            app.HeatmapBinsizenmSpinner.Limits = [5 Inf];
            app.HeatmapBinsizenmSpinner.Value = 25;
            app.HeatmapstyleDropDownLabel = uilabel(app.GridLayout30);
            app.HeatmapstyleDropDownLabel.Text = 'Heatmap style';
            app.HeatmapstyleDropDown = uidropdown(app.GridLayout30);
            app.HeatmapstyleDropDown.Items = {'2D binned heatmap', 'Normalised image reconstruction', 'Cell axes projections'};
            app.HeatmapstyleDropDown.Value = '2D binned heatmap';
            app.HeatmapAvailableDatasetsTree = uitree(app.GridLayout30, 'checkbox');
            app.AlllocalisastionscombinedNode = uitreenode(app.HeatmapAvailableDatasetsTree);
            app.AlllocalisastionscombinedNode.Text = 'All localisastions combined';
            app.AllavailableclassesNode = uitreenode(app.HeatmapAvailableDatasetsTree);
            app.AllavailableclassesNode.Text = 'All available classes';
            app.noclassesavailableNode = uitreenode(app.AllavailableclassesNode);
            app.noclassesavailableNode.Text = '<< no classes available >>';
            % Assign Checked Nodes
            app.HeatmapAvailableDatasetsTree.CheckedNodes = [app.AlllocalisastionscombinedNode];
            app.ReconstructionPSFFWHMSpinnerLabel = uilabel(app.GridLayout30);
            app.ReconstructionPSFFWHMSpinnerLabel.Text = 'Reconstruction PSF FWHM';
            app.ReconstructionPSFFWHMSpinner = uispinner(app.GridLayout30);
            app.ReconstructionPSFFWHMSpinner.Limits = [1 Inf];
            app.ReconstructionPSFFWHMSpinner.Value = 50;
            app.BackgroundcolourDropDownLabel = uilabel(app.GridLayout30);
            app.BackgroundcolourDropDownLabel.Text = 'Background colour';
            app.BackgroundcolourDropDown = uidropdown(app.GridLayout30);
            app.BackgroundcolourDropDown.Items = {'Black', 'White'};
            app.BackgroundcolourDropDown.Tooltip = {'Background colour of spatial map. This also determines the colour map''s lower limit.'};
            app.BackgroundcolourDropDown.Value = 'White';
            app.SpatialMappingMirrorplotCheckBox = uicheckbox(app.GridLayout30);
            app.SpatialMappingMirrorplotCheckBox.Text = 'Mirror plot';
            app.GalleryviewerTab = uitab(app.TabGroup4);
            app.GalleryviewerTab.Title = 'Gallery viewer';
            app.GridLayout6 = uigridlayout(app.GalleryviewerTab);
            app.GeneratetrackgalleryButton = uibutton(app.GridLayout6, 'push');
            app.GeneratetrackgalleryButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratetrackgalleryButtonPushed, true);
            app.GeneratetrackgalleryButton.Text = 'Generate track gallery';
            app.GalleryFeatureDropDown = uidropdown(app.GridLayout6);
            app.GalleryFeatureDropDown.Items = {'Step size (nm)'};
            app.GalleryFeatureDropDown.DropDownOpeningFcn = createCallbackFcn(app, @GalleryFeatureDropDownOpening, true);
            app.GalleryFeatureDropDown.Value = 'Step size (nm)';
            app.FeatureDropDownLabel_2 = uilabel(app.GridLayout6);
            app.FeatureDropDownLabel_2.Text = 'Feature';
            app.TrackcolouringDropDownLabel = uilabel(app.GridLayout6);
            app.TrackcolouringDropDownLabel.Text = 'Track colouring';
            app.TrackcolouringDropDown = uidropdown(app.GridLayout6);
            app.TrackcolouringDropDown.Items = {'Feature', 'Classified state'};
            app.TrackcolouringDropDown.Value = 'Feature';
            app.ClassificationsourceDropDownLabel = uilabel(app.GridLayout6);
            app.ClassificationsourceDropDownLabel.Text = 'Classification source';
            app.GalleryClassificationsourceDropDown = uidropdown(app.GridLayout6);
            app.GalleryClassificationsourceDropDown.Items = {'<< Select classifications >>'};
            app.GalleryClassificationsourceDropDown.DropDownOpeningFcn = createCallbackFcn(app, @GalleryClassificationsourceDropDownOpening, true);
            app.GalleryClassificationsourceDropDown.Value = '<< Select classifications >>';
            app.StopgalleryButton = uibutton(app.GridLayout6, 'state');
            app.StopgalleryButton.Text = 'Stop gallery';
            app.EventoverlayerTab = uitab(app.TabGroup4);
            app.EventoverlayerTab.Title = 'Event overlayer';
            app.GridLayout7 = uigridlayout(app.EventoverlayerTab);
            app.EventoverlayerSOI = uidropdown(app.GridLayout7);
            app.EventoverlayerSOI.Items = {'<< Select state >>'};
            app.EventoverlayerSOI.DropDownOpeningFcn = createCallbackFcn(app, @EventoverlayerSOIDropDownOpening, true);
            app.EventoverlayerSOI.Value = '<< Select state >>';
            app.DisplayalloverlayedeventsButton = uibutton(app.GridLayout7, 'push');
            app.DisplayalloverlayedeventsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayalloverlayedeventsButtonPushed, true);
            app.DisplayalloverlayedeventsButton.Text = 'Display all overlayed events';
            app.StateofinterestLabel = uilabel(app.GridLayout7);
            app.StateofinterestLabel.Text = 'State of interest';
            app.OverlayplotsettingsPanel = uipanel(app.GridLayout7);
            app.OverlayplotsettingsPanel.Title = 'Overlay plot settings';
            app.GridLayout8 = uigridlayout(app.OverlayplotsettingsPanel);
            app.Overlayerstyle = uidropdown(app.GridLayout8);
            app.Overlayerstyle.Items = {'Lines', 'Points'};
            app.Overlayerstyle.Value = 'Lines';
            app.StyleDropDownLabel = uilabel(app.GridLayout8);
            app.StyleDropDownLabel.Text = 'Style';
            app.AutoaticallysetfeatureaxislimitsCheckBox = uicheckbox(app.GridLayout8);
            app.AutoaticallysetfeatureaxislimitsCheckBox.Tooltip = {'Automatically sets the y-axis (feature axis) limits. This option is on by default as it is useful for most users. To override this setting, deselect this option and enter values in to the y-axis inputs below'};
            app.AutoaticallysetfeatureaxislimitsCheckBox.Text = 'Autoatically set feature axis limits';
            app.AutoaticallysetfeatureaxislimitsCheckBox.Value = true;
            app.StarttimerelativetoeventsSpinnerLabel = uilabel(app.GridLayout8);
            app.StarttimerelativetoeventsSpinnerLabel.Text = 'Start time relative to event (s)';
            app.StarttimerelativetoeventsSpinner = uispinner(app.GridLayout8);
            app.StarttimerelativetoeventsSpinner.Tooltip = {'This variable sets the lower limit of time relative to each event. Negative values represent the time prior to the event, while positive values represent time after the event.'; ''; 'When plotting static entry/exit plots this determines the lower limit of the x-axis (time).'; ''; 'When generating event histogram videos, this determines the start time of the video relative to the event.'};
            app.StarttimerelativetoeventsSpinner.Value = -5;
            app.EndtimerelativetoeventsSpinnerLabel = uilabel(app.GridLayout8);
            app.EndtimerelativetoeventsSpinnerLabel.Text = 'End time relative to event (s)';
            app.EndtimerelativetoeventsSpinner = uispinner(app.GridLayout8);
            app.EndtimerelativetoeventsSpinner.Tooltip = {'This variable sets the upper limit of time relative to each event. Negative values represent the time prior to the event, while positive values represent time after the event.'; ''; 'When plotting static entry/exit plots this determines the upper limit of the x-axis (time).'; ''; 'When generating event histogram videos, this determines the end time of the video relative to the event.'};
            app.EndtimerelativetoeventsSpinner.Value = 5;
            app.OverridefeatureaxislowerlimitSpinnerLabel = uilabel(app.GridLayout8);
            app.OverridefeatureaxislowerlimitSpinnerLabel.Text = 'Override feature axis lower limit';
            app.OverridefeatureaxislowerlimitSpinner = uispinner(app.GridLayout8);
            app.OverridefeatureaxisupperlimitSpinnerLabel = uilabel(app.GridLayout8);
            app.OverridefeatureaxisupperlimitSpinnerLabel.Text = 'Override feature axis upper limit';
            app.OverridefeatureaxisupperlimitSpinner = uispinner(app.GridLayout8);
            app.OverridefeatureaxisupperlimitSpinner.Value = 650;
            app.EventhistogramvideosettingsPanel = uipanel(app.GridLayout7);
            app.EventhistogramvideosettingsPanel.Title = 'Event histogram video settings';
            app.GridLayout9 = uigridlayout(app.EventhistogramvideosettingsPanel);
            app.EventhistogramvideobinwidthSpinnerLabel = uilabel(app.GridLayout9);
            app.EventhistogramvideobinwidthSpinnerLabel.Text = 'Event histogram video bin width';
            app.EntriesorexitsDropDown = uidropdown(app.GridLayout9);
            app.EntriesorexitsDropDown.Items = {'Entries', 'Exits'};
            app.EntriesorexitsDropDown.Value = 'Entries';
            app.EventhistogramvideobinwidthSpinner = uispinner(app.GridLayout9);
            app.EventhistogramvideobinwidthSpinner.Value = 25;
            app.EntriesorexitsDropDownLabel = uilabel(app.GridLayout9);
            app.EntriesorexitsDropDownLabel.Text = 'Entries or exits?';
            app.FeatureofinterestDropDownLabel = uilabel(app.GridLayout7);
            app.FeatureofinterestDropDownLabel.Text = 'Feature of interest';
            app.EventOverlayerFeatureDropDown = uidropdown(app.GridLayout7);
            app.EventOverlayerFeatureDropDown.Items = {'<< Select feature >>'};
            app.EventOverlayerFeatureDropDown.DropDownOpeningFcn = createCallbackFcn(app, @EventOverlayerFeatureDropDownOpening, true);
            app.EventOverlayerFeatureDropDown.Value = '<< Select feature >>';
            app.SaveplotteddataCheckBox = uicheckbox(app.GridLayout7);
            app.SaveplotteddataCheckBox.Text = 'Save plotted data';
            app.GeneratevideoCheckBox = uicheckbox(app.GridLayout7);
            app.GeneratevideoCheckBox.Tooltip = {'Tick this box to generate a video of the time evolution of the selected feature for each timestep in the time series from start time relative to event to end time relative to event.'; ''; 'This option is disabled by default as it is computationally intensive, and not useful for the majority of users.'; ''; 'Note: this currently '};
            app.GeneratevideoCheckBox.Text = 'Generate video';
            app.AdvancedsettingsTab = uitab(app.TabGroup4);
            app.AdvancedsettingsTab.Title = 'Advanced settings';
            app.GridLayout33 = uigridlayout(app.AdvancedsettingsTab);
            app.StatsoverlapCheckBox = uicheckbox(app.GridLayout33);
            app.StatsoverlapCheckBox.Tooltip = {'This setting restricts descriptive statistics of a dataset (e.g. time spent in each state, or diffusion coefficient) to only tracks which are also present in the human annotated data (if this is available).'; ''; 'This option is particularly useful in various scenarios; for example:'; '1. When obtaining accurate comparisons between human and model annotations.'; '2. To enable the user to remove erroneous tracks from the dataset visually, as the user often identifies over-connection of tracks, missed localisations, erroneous particle identification, etc..'};
            app.StatsoverlapCheckBox.Text = 'Only compute descriptive stats for overlap with human annotations';
            app.MLclassificationTab = uitab(app.MainTabGroup);
            app.MLclassificationTab.Title = 'ML classification';
            app.GridLayout13 = uigridlayout(app.MLclassificationTab);
            app.MLDataView = uiaxes(app.GridLayout13);
            title(app.MLDataView, 'No data plotted')
            xlabel(app.MLDataView, 'X')
            ylabel(app.MLDataView, 'Y')
            zlabel(app.MLDataView, 'Z')
            app.MLfeatures = uitree(app.GridLayout13, 'checkbox');
            app.MLfeatures.Tag = 'cbtree';
            % Assign Checked Nodes
            app.MLfeatures.CheckedNodesChangedFcn = createCallbackFcn(app, @MLfeaturesCheckedNodesChanged, true);
            app.FeaturesLabel = uilabel(app.GridLayout13);
            app.FeaturesLabel.Text = 'Features';
            app.MLControlTabs = uitabgroup(app.GridLayout13);
            app.MLControlTabs.SelectionChangedFcn = createCallbackFcn(app, @MLControlTabsSelectionChanged, true);
            app.FeaturevisualisationTab = uitab(app.MLControlTabs);
            app.FeaturevisualisationTab.Title = 'Feature visualisation';
            app.GridLayout34 = uigridlayout(app.FeaturevisualisationTab);
            app.PlotclassconditionalfeaturedistributionsButton = uibutton(app.GridLayout34, 'push');
            app.PlotclassconditionalfeaturedistributionsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotclassconditionalfeaturedistributionsButtonPushed, true);
            app.PlotclassconditionalfeaturedistributionsButton.Text = 'Plot class-conditional feature distributions';
            app.SmoothingfactorSpinnerLabel = uilabel(app.GridLayout34);
            app.SmoothingfactorSpinnerLabel.Text = 'Smoothing factor';
            app.SmoothingfactorSpinner = uispinner(app.GridLayout34);
            app.SmoothingfactorSpinner.Limits = [0 5];
            app.SmoothingfactorSpinner.Tooltip = {'Adjust the smoothing intensity; options are,'; ''; '- For histograms: Higher values apply stronger Gaussian smoothing to counts. Setting this value to zero applies no smoothing.'; '- For KDE, this controls the bandwidth, affecting the smoothness of the density curve. There is a minimum bandwidth of 0.01 that will be applied even if selecting 0 <= s < 0.01.'};
            app.SmoothingfactorSpinner.Value = 0.5;
            app.PlotresolutionSpinnerLabel = uilabel(app.GridLayout34);
            app.PlotresolutionSpinnerLabel.Text = 'Plot resolution';
            app.PlotresolutionSpinner = uispinner(app.GridLayout34);
            app.PlotresolutionSpinner.Limits = [100 5000];
            app.PlotresolutionSpinner.Tooltip = {'Set the resolution for plots.'; ''; '- For histograms, this specifies the number of bins.'; '- For KDE, this sets the number of points used to compute the density.'};
            app.PlotresolutionSpinner.Value = 200;
            app.SourcedataDropDown_2Label_2 = uilabel(app.GridLayout34);
            app.SourcedataDropDown_2Label_2.Text = 'Source data';
            app.FeatureAnalysisSourcedataDropDown = uidropdown(app.GridLayout34);
            app.FeatureAnalysisSourcedataDropDown.Items = {'<< Select dataset >>'};
            app.FeatureAnalysisSourcedataDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FeatureAnalysisSourcedataDropDownOpening, true);
            app.FeatureAnalysisSourcedataDropDown.Tooltip = {'Select the dataset to use for analysis, options are,'; ''; '- ''Ground truth'' for training data.'; '- ''Human annotations'' for manually annotated tracks.'; ''; 'These options will udpate based on the data present in the current analysis file.'};
            app.FeatureAnalysisSourcedataDropDown.Value = '<< Select dataset >>';
            app.VisualisationMethodLabel = uilabel(app.GridLayout34);
            app.VisualisationMethodLabel.Text = 'Visualisation method';
            app.FeatureVisualisationMethodDropDown = uidropdown(app.GridLayout34);
            app.FeatureVisualisationMethodDropDown.Items = {'Histogram', 'Kernel density estimator'};
            app.FeatureVisualisationMethodDropDown.Tooltip = {'Visualization method; options are,'; ''; '- ''Histogram'': direct binning of raw data using a histogram; the data will also be smoothed using a Gaussian filter when the smoothing factor below is set to a non-zero value.'; '- ''Kernel density estimator'': Produces a smoothed probability density function.'; ''; 'Note that applying a smoothing parameter >0 will enable smoothing of the histogram data.'};
            app.FeatureVisualisationMethodDropDown.Value = 'Histogram';
            app.IgnorerowsfromstartSpinner_2Label = uilabel(app.GridLayout34);
            app.IgnorerowsfromstartSpinner_2Label.Text = 'Ignore rows from start';
            app.FeatureAnalysisIgnorerowsfromstartSpinner = uispinner(app.GridLayout34);
            app.FeatureAnalysisIgnorerowsfromstartSpinner.Value = 2;
            app.IgnorerowsfromendSpinner_2Label = uilabel(app.GridLayout34);
            app.IgnorerowsfromendSpinner_2Label.Text = 'Ignore rows from end';
            app.FeatureAnalysisIgnorerowsfromendSpinner = uispinner(app.GridLayout34);
            app.FeatureAnalysisIgnorerowsfromendSpinner.Value = 2;
            app.DatasubsetDropDownLabel = uilabel(app.GridLayout34);
            app.DatasubsetDropDownLabel.Text = 'Data subset';
            app.FeatureAnalysisDatasubsetDropDown = uidropdown(app.GridLayout34);
            app.FeatureAnalysisDatasubsetDropDown.Items = {'All data', 'Changepoint-proximal', 'Changepoint-distal'};
            app.FeatureAnalysisDatasubsetDropDown.Value = 'All data';
            app.TransitionregionrangestepsSpinnerLabel = uilabel(app.GridLayout34);
            app.TransitionregionrangestepsSpinnerLabel.Text = 'Transition region range (steps)';
            app.FeatureAnalysisTransitionregionrangestepsSpinner = uispinner(app.GridLayout34);
            app.FeatureAnalysisTransitionregionrangestepsSpinner.Limits = [1 Inf];
            app.FeatureAnalysisTransitionregionrangestepsSpinner.ValueDisplayFormat = '%.0f';
            app.FeatureAnalysisTransitionregionrangestepsSpinner.Value = 3;
            app.ClassADropDownLabel = uilabel(app.GridLayout34);
            app.ClassADropDownLabel.Text = 'Class A';
            app.FeatureAnalysisClassADropDown = uidropdown(app.GridLayout34);
            app.FeatureAnalysisClassADropDown.Items = {'<< Select class >>'};
            app.FeatureAnalysisClassADropDown.DropDownOpeningFcn = createCallbackFcn(app, @FeatureAnalysisClassADropDownOpening, true);
            app.FeatureAnalysisClassADropDown.Value = '<< Select class >>';
            app.ClassseparabilitymetricDropDownLabel = uilabel(app.GridLayout34);
            app.ClassseparabilitymetricDropDownLabel.Text = 'Class separability metric';
            app.ClassseparabilitymetricDropDown = uidropdown(app.GridLayout34);
            app.ClassseparabilitymetricDropDown.Items = {'Kolmogorov-Smirnov', 'Wasserstein distance', 'Histogram overlap', 'All', 'None'};
            app.ClassseparabilitymetricDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassseparabilitymetricDropDownValueChanged, true);
            app.ClassseparabilitymetricDropDown.Tooltip = {'Select metrics here to quantify class separability. These quantities will be displayed on the class distribution plots.'};
            app.ClassseparabilitymetricDropDown.Value = 'Kolmogorov-Smirnov';
            app.ClassBDropDownLabel = uilabel(app.GridLayout34);
            app.ClassBDropDownLabel.Text = 'Class B';
            app.FeatureAnalysisClassBDropDown = uidropdown(app.GridLayout34);
            app.FeatureAnalysisClassBDropDown.Items = {'<< Select class >>'};
            app.FeatureAnalysisClassBDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FeatureAnalysisClassBDropDownOpening, true);
            app.FeatureAnalysisClassBDropDown.Value = '<< Select class >>';
            app.FeaturerankingTab = uitab(app.MLControlTabs);
            app.FeaturerankingTab.Title = 'Feature ranking';
            app.GridLayout36 = uigridlayout(app.FeaturerankingTab);
            app.RankfeaturesbyimportanceButton = uibutton(app.GridLayout36, 'push');
            app.RankfeaturesbyimportanceButton.ButtonPushedFcn = createCallbackFcn(app, @RankfeaturesbyimportanceButtonPushed, true);
            app.RankfeaturesbyimportanceButton.Text = 'Rank features by importance';
            app.FeaturesubsetDropDownLabel = uilabel(app.GridLayout36);
            app.FeaturesubsetDropDownLabel.Text = 'Feature subset';
            app.FeatureImportanceFeaturesubsetDropDown = uidropdown(app.GridLayout36);
            app.FeatureImportanceFeaturesubsetDropDown.Items = {'All features', 'Selected features'};
            app.FeatureImportanceFeaturesubsetDropDown.Value = 'All features';
            app.SourcedataDropDown_3Label = uilabel(app.GridLayout36);
            app.SourcedataDropDown_3Label.Text = 'Source data';
            app.FeatureImportanceSourcedataDropDown = uidropdown(app.GridLayout36);
            app.FeatureImportanceSourcedataDropDown.Items = {'<< Select dataset >>'};
            app.FeatureImportanceSourcedataDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FeatureImportanceSourcedataDropDownOpening, true);
            app.FeatureImportanceSourcedataDropDown.Value = '<< Select dataset >>';
            app.FeatureimportancemetricDropDownLabel = uilabel(app.GridLayout36);
            app.FeatureimportancemetricDropDownLabel.Text = 'Feature importance metric';
            app.FeatureimportancemetricDropDown = uidropdown(app.GridLayout36);
            app.FeatureimportancemetricDropDown.Items = {'Mutual information (recommended)', 'Difference of histograms', 'Wasserstein distance'};
            app.FeatureimportancemetricDropDown.ValueChangedFcn = createCallbackFcn(app, @FeatureimportancemetricDropDownValueChanged, true);
            app.FeatureimportancemetricDropDown.Value = 'Mutual information (recommended)';
            app.FiguredisplaylocationDropDownLabel = uilabel(app.GridLayout36);
            app.FiguredisplaylocationDropDownLabel.Text = 'Figure display location';
            app.FeatureImportanceFiguredisplaylocationDropDown = uidropdown(app.GridLayout36);
            app.FeatureImportanceFiguredisplaylocationDropDown.Items = {'Inside GUI', 'External'};
            app.FeatureImportanceFiguredisplaylocationDropDown.Value = 'Inside GUI';
            app.DisplayfeaturecorrelationsButton = uibutton(app.GridLayout36, 'push');
            app.DisplayfeaturecorrelationsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayfeaturecorrelationsButtonPushed, true);
            app.DisplayfeaturecorrelationsButton.Text = 'Display feature correlations';
            app.ChangepointmasksizeSpinnerLabel = uilabel(app.GridLayout36);
            app.ChangepointmasksizeSpinnerLabel.Text = 'Changepoint mask size';
            app.FeatureImportanceChangepointmasksizeSpinner = uispinner(app.GridLayout36);
            app.FeatureImportanceChangepointmasksizeSpinner.Limits = [1 Inf];
            app.FeatureImportanceChangepointmasksizeSpinner.ValueDisplayFormat = '%.0f';
            app.FeatureImportanceChangepointmasksizeSpinner.Tooltip = {'Size of changepoint region, in localisations.'; ''; 'This parameter is used to set the number of localisations before and after each changepoint to be considered changepoint-proximal The value is used to set the mask used when splitting data into changepoint-proximal, and changepoint-distal sections for analysis of mutual information.'};
            app.FeatureImportanceChangepointmasksizeSpinner.Value = 2;
            app.DisplaymutualinformationbetweenfeaturesButton = uibutton(app.GridLayout36, 'push');
            app.DisplaymutualinformationbetweenfeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaymutualinformationbetweenfeaturesButtonPushed, true);
            app.DisplaymutualinformationbetweenfeaturesButton.Text = 'Display mutual information between features';
            app.GeneratetrainingdataTab = uitab(app.MLControlTabs);
            app.GeneratetrainingdataTab.Title = 'Generate training data';
            app.GridLayout15 = uigridlayout(app.GeneratetrainingdataTab);
            app.FeaturescalingDropDown = uidropdown(app.GridLayout15);
            app.FeaturescalingDropDown.Items = {'None', 'Z-score', 'Normalise (0-1)'};
            app.FeaturescalingDropDown.Tooltip = {'Select the Feature Scaling Method for Model Training & Classification:'; ''; '"None": No feature scaling applied. Ideal for ensemble decision trees like Random Forest, where feature scaling is typically not necessary.'; ''; '"Z-score": Standardizes features using the global mean and standard deviation from all localizations across all labeled trajectories. This transforms the data such that each feature has a mean of 0 and a standard deviation of 1. Recommended for most models, especially neural networks such as Gated Recurrent Unit (GRU) models, as it handles effectively features with different scales or units.'; ''; '"Normalise (0-1)": Scales each feature linearly to fit within a range from 0 to 1. While this can be beneficial for certain algorithms, it may not be suitable if the model encounters data beyond this range during classification. Use with caution if data variability is significant.'};
            app.FeaturescalingDropDown.Value = 'Z-score';
            app.ShufflingmethodLabel = uilabel(app.GridLayout15);
            app.ShufflingmethodLabel.Text = 'Shuffling method';
            app.ShufflingmethodDropDown = uidropdown(app.GridLayout15);
            app.ShufflingmethodDropDown.Items = {'Localisation', 'Molecule'};
            app.ShufflingmethodDropDown.Tooltip = {'The method used to shuffle the data, options are,'; ''; '"Localisation": individual localisations are treated as independent examples, and temporal information is encoded through feature engineering. This option produces a dataset consising of a single matrix. This option should be used when working with ensemble decision trees such as random forest models.'; ''; '"Molecule": trajectories remain intact, and their indices are shuffled, ensuring the random assignment of trajectories to the training, validation, and test datasets. This is the default option for use with neural network based models, such as Gated Recurrent Unit models.'};
            app.ShufflingmethodDropDown.Value = 'Molecule';
            app.TracksamplingDropDown = uidropdown(app.GridLayout15);
            app.TracksamplingDropDown.Items = {'Random subsampling (recommended)', 'Non-overapping segments', 'Whole tracks'};
            app.TracksamplingDropDown.Tooltip = {'Determines the span length of each trajectory that is used for training and classification.'; ''; 'Note that this only applies when using the molecule-wise shuffling method.'; ''; '"Whole trajectories": this involves sequence-to-sequence classification of each full trajectory. For all but the longest trajectory in the training set this introduces padding, and also produces a new feature which acts as a mask for the padded data.'; ''; '"Segments": the trajectory is separated into segments with the individual spans used for training and classification. This elimiinates the need for padding and masking, and also enables the model to focus on local/recent information.'; ''; '"Sliding window": each trajectory is replaced with a large number of small segments which are generated by moving a sliding window, frame-by-frame, from start to end.'};
            app.TracksamplingDropDown.Value = 'Random subsampling (recommended)';
            app.TrainingvalidationtestsplitEditFieldLabel = uilabel(app.GridLayout15);
            app.TrainingvalidationtestsplitEditFieldLabel.Text = 'Training : validation : test split';
            app.TrainingvalidationtestsplitEditField = uieditfield(app.GridLayout15, 'text');
            app.TrainingvalidationtestsplitEditField.Tooltip = {'The proportions with which to split the labelled dataset. Enter three floating point numbers here separated by commas. The three values must sum to 1.'};
            app.TrainingvalidationtestsplitEditField.Value = '0.85, 0.14, 0.01';
            app.GeneratetrainingsetButton = uibutton(app.GridLayout15, 'push');
            app.GeneratetrainingsetButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratetrainingsetButtonPushed, true);
            app.GeneratetrainingsetButton.Tooltip = {'Generate a new training set from the labelled data using the features selected in the features selection list.'};
            app.GeneratetrainingsetButton.Text = 'Generate training set';
            app.ExporttrainingdataButton = uibutton(app.GridLayout15, 'push');
            app.ExporttrainingdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttrainingdataButtonPushed, true);
            app.ExporttrainingdataButton.Text = 'Export training data';
            app.SourcedataDropDown = uidropdown(app.GridLayout15);
            app.SourcedataDropDown.Items = {'<< Select dataset >>'};
            app.SourcedataDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SourcedataDropDownOpening, true);
            app.SourcedataDropDown.Value = '<< Select dataset >>';
            app.FeaturescalingDropDownLabel = uilabel(app.GridLayout15);
            app.FeaturescalingDropDownLabel.Text = 'Feature scaling';
            app.IgnorerowsfromstartSpinnerLabel = uilabel(app.GridLayout15);
            app.IgnorerowsfromstartSpinnerLabel.Text = 'Ignore rows from start';
            app.KnockoutzerosCheckBox = uicheckbox(app.GridLayout15);
            app.KnockoutzerosCheckBox.Tooltip = {'Remove any rows containing zeros in the selected feature columns; this is currently only implemented for the Localisation-based shuffling method used for ensemble decision trees such as random forest models'};
            app.KnockoutzerosCheckBox.Text = 'Knock out zeros';
            app.IgnorerowsfromendSpinnerLabel = uilabel(app.GridLayout15);
            app.IgnorerowsfromendSpinnerLabel.Text = 'Ignore rows from end';
            app.TracksamplingDropDownLabel = uilabel(app.GridLayout15);
            app.TracksamplingDropDownLabel.Text = 'Track sampling';
            app.ContextlengthLabel = uilabel(app.GridLayout15);
            app.ContextlengthLabel.Text = 'Context length (frames)';
            app.ContextLengthSpinner = uispinner(app.GridLayout15);
            app.ContextLengthSpinner.Limits = [0 Inf];
            app.ContextLengthSpinner.ValueDisplayFormat = '%.0f';
            app.ContextLengthSpinner.Value = 25;
            app.IgnorerowsfromstartSpinner = uispinner(app.GridLayout15);
            app.IgnorerowsfromstartSpinner.Limits = [0 Inf];
            app.IgnorerowsfromstartSpinner.ValueDisplayFormat = '%.0f';
            app.IgnorerowsfromstartSpinner.Tooltip = {'The number of steps to ignore at the start of each trajectory. Some specific features may not be present at the start or end of a trajectory.'; ''; 'Example: step size; as this represents the Euclidean distance between localisations there is no information encoded in the first frame, and the first row of every trajectory is therefore ignored from both the training and later classified data'; ''; 'Values here will update automatically when known features are selected by the user. When there is a need to override this setting (e.g. the user inputs an unknown feature name from a new localisation algorithm) enter a number of rows to ignore after selecting features, and before generating the training set.'};
            app.IgnorerowsfromendSpinner = uispinner(app.GridLayout15);
            app.IgnorerowsfromendSpinner.Limits = [0 Inf];
            app.IgnorerowsfromendSpinner.ValueDisplayFormat = '%.0f';
            app.IgnorerowsfromendSpinner.Tooltip = {'The number of steps to ignore at the end of each trajectory. Some specific features may not be present at the start or end of a trajectory.'; ''; 'Example: following step size; as this represents the Euclidean distance between the current localisation and the following localistion there is no information encoded in the final frame, and the final row of every trajectory is therefore ignored from both the training and later classified data'; ''; 'Values here will update automatically when known features are selected by the user. When there is a need to override this setting (e.g. the user inputs an unknown feature name from a new localisation algorithm) enter a number of rows to ignore after selecting features, and before generating the training set.'};
            app.SourcedataDropDownLabel = uilabel(app.GridLayout15);
            app.SourcedataDropDownLabel.Text = 'Source data';
            app.TrainingdataexportformatDropDownLabel = uilabel(app.GridLayout15);
            app.TrainingdataexportformatDropDownLabel.Text = 'Training data export format';
            app.TrainingdataexportformatDropDown = uidropdown(app.GridLayout15);
            app.TrainingdataexportformatDropDown.Items = {'CSV', 'JSON', 'HDF5'};
            app.TrainingdataexportformatDropDown.Value = 'CSV';
            app.SetmaxwindowButton = uibutton(app.GridLayout15, 'push');
            app.SetmaxwindowButton.ButtonPushedFcn = createCallbackFcn(app, @SetmaxwindowButtonPushed, true);
            app.SetmaxwindowButton.Tooltip = {'Computes the maximum permitted sliding window size, which is set as ten frames shorter than the shortest track in the dataset.'};
            app.SetmaxwindowButton.Text = 'Set max window';
            app.VisualisationTab = uitab(app.MLControlTabs);
            app.VisualisationTab.Title = 'Visualisation';
            app.GridLayout17 = uigridlayout(app.VisualisationTab);
            app.XaxisDropDownLabel = uilabel(app.GridLayout17);
            app.XaxisDropDownLabel.Text = 'X-axis';
            app.DatatodisplayDropDown = uidropdown(app.GridLayout17);
            app.DatatodisplayDropDown.Items = {'All user-classified data', 'Training data', 'Validation data', 'Test data', 'ML classified data'};
            app.DatatodisplayDropDown.Tooltip = {'This is the dataset to visualise in the ML data viewer'};
            app.DatatodisplayDropDown.Value = 'All user-classified data';
            app.XaxisDropDown = uidropdown(app.GridLayout17);
            app.XaxisDropDown.Items = {'Feature 1', 'Feature 2', 'Feature 3'};
            app.XaxisDropDown.Tooltip = {'The selected feature to display along the X-axis'};
            app.XaxisDropDown.Value = 'Feature 1';
            app.YaxisDropDownLabel = uilabel(app.GridLayout17);

            app.YaxisDropDownLabel.Text = 'Y-axis';
            app.YaxisDropDown = uidropdown(app.GridLayout17);
            app.YaxisDropDown.Items = {'Feature 1', 'Feature 2', 'Feature 3'};
            app.YaxisDropDown.Tooltip = {'The selected feature to display along the Y-axis'};
            app.YaxisDropDown.Value = 'Feature 2';
            app.ZaxisDropDownLabel = uilabel(app.GridLayout17);
            app.ZaxisDropDownLabel.Text = 'Z-axis';
            app.ZaxisDropDown = uidropdown(app.GridLayout17);
            app.ZaxisDropDown.Items = {'Feature 1', 'Feature 2', 'Feature 3'};
            app.ZaxisDropDown.Tooltip = {'The selected feature to display along the Z-axis'};
            app.ZaxisDropDown.Value = 'Feature 3';
            app.DatatodisplayDropDownLabel = uilabel(app.GridLayout17);
            app.DatatodisplayDropDownLabel.Text = 'Data to display';
            app.MaxpointstodisplaySpinner = uispinner(app.GridLayout17);
            app.MaxpointstodisplaySpinner.Limits = [1 Inf];
            app.MaxpointstodisplaySpinner.ValueDisplayFormat = '%.0f';
            app.MaxpointstodisplaySpinner.Tooltip = {'The maximum number of points to display. Entering a number here smaller than the size of the dataset instructs the software to select a random subset of molecules'};
            app.MaxpointstodisplaySpinner.Value = 1000;
            app.UpdateButton = uibutton(app.GridLayout17, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Tooltip = {'Update the ML data viewer with the above chosen settings. If the maximum number of points displayed is smaller than the total number available, a random subset will be displayed each time you press this button.'};
            app.UpdateButton.Text = 'Update';
            app.PlotcontrolsPanel = uipanel(app.GridLayout17);
            app.PlotcontrolsPanel.Title = 'Plot controls';
            app.GridLayout23 = uigridlayout(app.PlotcontrolsPanel);
            app.PanButton = uibutton(app.GridLayout23, 'push');
            app.PanButton.ButtonPushedFcn = createCallbackFcn(app, @PanButtonPushed, true);
            app.PanButton.Text = 'Pan';
            app.ZoomButton = uibutton(app.GridLayout23, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Text = 'Zoom';
            app.RotateButton = uibutton(app.GridLayout23, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.XYButton = uibutton(app.GridLayout23, 'push');
            app.XYButton.ButtonPushedFcn = createCallbackFcn(app, @XYButtonPushed, true);
            app.XYButton.Text = 'X-Y';
            app.XZButton = uibutton(app.GridLayout23, 'push');
            app.XZButton.ButtonPushedFcn = createCallbackFcn(app, @XZButtonPushed, true);
            app.XZButton.Text = 'X-Z';
            app.YZButton = uibutton(app.GridLayout23, 'push');
            app.YZButton.ButtonPushedFcn = createCallbackFcn(app, @YZButtonPushed, true);
            app.YZButton.Text = 'Y-Z';
            app.ResetButton = uibutton(app.GridLayout23, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.XLimLowSpinner = uispinner(app.GridLayout23);
            app.XLimLowSpinner.ValueDisplayFormat = '%6.4g';
            app.XLimLowSpinner.ValueChangedFcn = createCallbackFcn(app, @XLimLowSpinnerValueChanged, true);
            app.XLimHighSpinner = uispinner(app.GridLayout23);
            app.XLimHighSpinner.ValueDisplayFormat = '%6.4g';
            app.XLimHighSpinner.ValueChangedFcn = createCallbackFcn(app, @XLimHighSpinnerValueChanged, true);
            app.YLimLowSpinner = uispinner(app.GridLayout23);
            app.YLimLowSpinner.ValueDisplayFormat = '%6.4g';
            app.YLimLowSpinner.ValueChangedFcn = createCallbackFcn(app, @YLimLowSpinnerValueChanged, true);
            app.YLimHighSpinner = uispinner(app.GridLayout23);
            app.YLimHighSpinner.ValueDisplayFormat = '%6.4g';
            app.YLimHighSpinner.ValueChangedFcn = createCallbackFcn(app, @YLimHighSpinnerValueChanged, true);
            app.ZLimLowSpinner = uispinner(app.GridLayout23);
            app.ZLimLowSpinner.ValueDisplayFormat = '%6.4g';
            app.ZLimLowSpinner.ValueChangedFcn = createCallbackFcn(app, @ZLimLowSpinnerValueChanged, true);
            app.ZLimHighSpinner = uispinner(app.GridLayout23);
            app.ZLimHighSpinner.ValueDisplayFormat = '%6.4g';
            app.ZLimHighSpinner.ValueChangedFcn = createCallbackFcn(app, @ZLimHighSpinnerValueChanged, true);
            app.XaxisLabel = uilabel(app.GridLayout23);
            app.XaxisLabel.Text = 'X-axis';
            app.YaxisLabel = uilabel(app.GridLayout23);
            app.YaxisLabel.Text = 'Y-axis';
            app.ZaxisLabel = uilabel(app.GridLayout23);
            app.ZaxisLabel.Text = 'Z-axis';
            app.MaxpointstodisplaySpinnerLabel = uilabel(app.GridLayout17);
            app.MaxpointstodisplaySpinnerLabel.Text = 'Max points to display';
            app.TrainmodelsTab = uitab(app.MLControlTabs);
            app.TrainmodelsTab.Title = 'Train models';
            app.GridLayout16 = uigridlayout(app.TrainmodelsTab);
            app.ModeltypeDropDown = uidropdown(app.GridLayout16);
            app.ModeltypeDropDown.Items = {'Random forest', 'Gradient boosted trees', 'Support Vector Machine (SVM)', 'Gated Recurrent Unit (GRU)', 'Bidirectional Gated Recurrent Unit (BiGRU)', 'Long Short-Term Memory (LSTM)', 'Bidirectional LSTM (BiLSTM)', 'Temporal Convolutional Network (TCN)'};
            app.ModeltypeDropDown.Tooltip = {'Select the type of model to train. Options are,'; ''; '"Random forest"'; ''; '"Gated Recurrent Unit (GRU)"'};
            app.ModeltypeDropDown.Value = 'Bidirectional LSTM (BiLSTM)';
            app.TrainMLmodelButton = uibutton(app.GridLayout16, 'push');
            app.TrainMLmodelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainMLmodelButtonPushed, true);
            app.TrainMLmodelButton.Tooltip = {'Train a machine learning model. Be sure to check you have already generated or loaded suitable training and test data for the model being trained.'};
            app.TrainMLmodelButton.Text = 'Train ML model';
            app.SavemodelButton = uibutton(app.GridLayout16, 'push');
            app.SavemodelButton.ButtonPushedFcn = createCallbackFcn(app, @SavemodelButtonPushed, true);
            app.SavemodelButton.Tooltip = {''};
            app.SavemodelButton.Text = 'Save model';
            app.ModeltypeDropDownLabel = uilabel(app.GridLayout16);
            app.ModeltypeDropDownLabel.Text = 'Model type';
            app.ModelexportformatDropDownLabel = uilabel(app.GridLayout16);
            app.ModelexportformatDropDownLabel.Text = 'Model export format';
            app.ExportmodelButton = uibutton(app.GridLayout16, 'push');
            app.ExportmodelButton.Text = 'Export model';
            app.KfoldsSpinnerLabel = uilabel(app.GridLayout16);
            app.KfoldsSpinnerLabel.Text = 'K-folds';
            app.KfoldsSpinner = uispinner(app.GridLayout16);
            app.KfoldsSpinner.Limits = [0 Inf];
            app.KfoldsSpinner.ValueDisplayFormat = '%.0f';
            app.KfoldsSpinner.Value = 5;
            app.UsebootstrappingCheckBox = uicheckbox(app.GridLayout16);
            app.UsebootstrappingCheckBox.Text = 'Use bootstrapping';
            app.BootstrapsamplesSpinnerLabel = uilabel(app.GridLayout16);
            app.BootstrapsamplesSpinnerLabel.Text = 'Bootstrap samples';
            app.BootstrapsamplesSpinner = uispinner(app.GridLayout16);
            app.BootstrapsamplesSpinner.Limits = [0 Inf];
            app.BootstrapsamplesSpinner.ValueDisplayFormat = '%.0f';
            app.BootstrapsamplesSpinner.Value = 200;
            app.ModelexportformatDropDown = uidropdown(app.GridLayout16);
            app.ModelexportformatDropDown.Items = {'TensorFlow', 'PyTorch'};
            app.ModelexportformatDropDown.Value = 'TensorFlow';
            app.TrainingmodeDropDownLabel = uilabel(app.GridLayout16);
            app.TrainingmodeDropDownLabel.Text = 'Training mode';
            app.TrainingmodeDropDown = uidropdown(app.GridLayout16);
            app.TrainingmodeDropDown.Items = {'Final training', 'Cross-validation'};
            app.TrainingmodeDropDown.Value = 'Final training';
            app.WatchallcrossvalidationtrainingCheckBox = uicheckbox(app.GridLayout16);
            app.WatchallcrossvalidationtrainingCheckBox.Text = 'Watch all cross-validation training';
            app.LossfunctionDropDownLabel = uilabel(app.GridLayout16);
            app.LossfunctionDropDownLabel.Text = 'Loss function';
            app.LossfunctionDropDown = uidropdown(app.GridLayout16);
            app.LossfunctionDropDown.Items = {'Class weighted', 'Transition and class weighted'};
            app.LossfunctionDropDown.Value = 'Class weighted';
            app.AttentionCheckBox = uicheckbox(app.GridLayout16);
            app.AttentionCheckBox.Text = 'Attention';
            app.AttentionCheckBox.Value = true;
            app.AttentionheadsSpinnerLabel = uilabel(app.GridLayout16);
            app.AttentionheadsSpinnerLabel.Text = 'Attention heads';
            app.AttentionheadsSpinner = uispinner(app.GridLayout16);
            app.AttentionheadsSpinner.Tooltip = {'Deprecated - this is currently only used for cross-validation. The value used in the final training is taken from the pop-up app which launches after clicking ''Train ML model''.'};
            app.AttentionheadsSpinner.Value = 1;
            app.ClassifyusingmodelTab = uitab(app.MLControlTabs);
            app.ClassifyusingmodelTab.Title = 'Classify using model';
            app.GridLayout18 = uigridlayout(app.ClassifyusingmodelTab);
            app.LoadexistingmodelButton = uibutton(app.GridLayout18, 'push');
            app.LoadexistingmodelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexistingmodelButtonPushed, true);
            app.LoadexistingmodelButton.Tooltip = {'Load a saved pre-trained model.'; ''; 'It is important that all of the features in the trained model exist in the new data to be classified. '; ''; 'Note that if you select a standard IVK saved analysis file this will import the most recently trained model before the file was saved.'};
            app.LoadexistingmodelButton.Text = 'Load existing model';
            app.LoaddatasetforclassificationButton = uibutton(app.GridLayout18, 'push');
            app.LoaddatasetforclassificationButton.Tooltip = {'Load a new kinetics file. Note that this will overwrite any unsaved classifications in the current file'};
            app.LoaddatasetforclassificationButton.Text = 'Load dataset for classification';
            app.ClassifywithmodelButton = uibutton(app.GridLayout18, 'push');
            app.ClassifywithmodelButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifywithmodelButtonPushed, true);
            app.ClassifywithmodelButton.Text = 'Classify with model';
            app.CurrentmodelfileTextArea = uitextarea(app.GridLayout18);
            app.CurrentmodelfileTextArea.Value = {'Internal or not loaded'};
            app.DisplaymodeldetailsButton = uibutton(app.GridLayout18, 'push');
            app.DisplaymodeldetailsButton.Text = 'Display model details';
            app.CurrentmodelfileTextAreaLabel = uilabel(app.GridLayout18);
            app.CurrentmodelfileTextAreaLabel.Text = 'Current model file:';
            app.DatasetforclassificationTextAreaLabel = uilabel(app.GridLayout18);
            app.DatasetforclassificationTextAreaLabel.Text = 'Dataset for classification:';
            app.DatasetforclassificationTextArea = uitextarea(app.GridLayout18);
            app.DisplaydatasetoverviewButton = uibutton(app.GridLayout18, 'push');
            app.DisplaydatasetoverviewButton.Text = 'Display dataset overview';
            app.PermutationimportanceTab = uitab(app.MLControlTabs);
            app.PermutationimportanceTab.Title = 'Permutation importance';
            app.GridLayout37 = uigridlayout(app.PermutationimportanceTab);
            app.ComputefeaturepermutationimportanceButton = uibutton(app.GridLayout37, 'push');
            app.ComputefeaturepermutationimportanceButton.ButtonPushedFcn = createCallbackFcn(app, @ComputefeaturepermutationimportanceButtonPushed, true);
            app.ComputefeaturepermutationimportanceButton.Text = 'Compute feature permutation importance';
            app.RepeatsperfeatureSpinnerLabel = uilabel(app.GridLayout37);
            app.RepeatsperfeatureSpinnerLabel.Text = 'Repeats per feature';
            app.PermutationImportanceRepeatsperfeatureSpinner = uispinner(app.GridLayout37);
            app.PermutationImportanceRepeatsperfeatureSpinner.Limits = [1 Inf];
            app.PermutationImportanceRepeatsperfeatureSpinner.ValueDisplayFormat = '%.0f';
            app.PermutationImportanceRepeatsperfeatureSpinner.Value = 5;
            app.SourcedataDropDown_2Label_3 = uilabel(app.GridLayout37);
            app.SourcedataDropDown_2Label_3.Text = 'Source data';
            app.PermutationImportanceSourcedataDropDown = uidropdown(app.GridLayout37);
            app.PermutationImportanceSourcedataDropDown.Items = {'<< Select dataset >>'};
            app.PermutationImportanceSourcedataDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PermutationImportanceSourcedataDropDownOpening, true);
            app.PermutationImportanceSourcedataDropDown.Tooltip = {'Select the dataset to use for analysis, options are,'; ''; '- ''Ground truth'' for training data.'; '- ''Human annotations'' for manually annotated tracks.'; ''; 'These options will udpate based on the data present in the current analysis file.'};
            app.PermutationImportanceSourcedataDropDown.Value = '<< Select dataset >>';
            app.DatasubsetDropDownLabel_2 = uilabel(app.GridLayout37);
            app.DatasubsetDropDownLabel_2.Text = 'Data subset';
            app.PermutationImportanceDatasubsetDropDown = uidropdown(app.GridLayout37);
            app.PermutationImportanceDatasubsetDropDown.Items = {'All data', 'Changepoint mask'};
            app.PermutationImportanceDatasubsetDropDown.Value = 'All data';
            app.ChangepointrangeSpinnerLabel = uilabel(app.GridLayout37);
            app.ChangepointrangeSpinnerLabel.Text = 'Changepoint range';
            app.PermutationImportanceChangepointrangeSpinner = uispinner(app.GridLayout37);
            app.PermutationImportanceChangepointrangeSpinner.Limits = [1 Inf];
            app.PermutationImportanceChangepointrangeSpinner.ValueDisplayFormat = '%.0f';
            app.PermutationImportanceChangepointrangeSpinner.Value = 3;
            app.SHAPTab = uitab(app.MLControlTabs);
            app.SHAPTab.Title = 'SHAP';
            app.GridLayout24 = uigridlayout(app.SHAPTab);
            app.NumberofexamplesSpinnerLabel = uilabel(app.GridLayout24);
            app.NumberofexamplesSpinnerLabel.Text = 'Number of examples';
            app.NumberofexamplesSpinner = uispinner(app.GridLayout24);
            app.NumberofexamplesSpinner.Limits = [0 Inf];
            app.NumberofexamplesSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofexamplesSpinner.Value = 20;
            app.SHAPvaluedisplayDropDownLabel = uilabel(app.GridLayout24);
            app.SHAPvaluedisplayDropDownLabel.Text = 'SHAP value display';
            app.SHAPvaluedisplayDropDown = uidropdown(app.GridLayout24);
            app.SHAPvaluedisplayDropDown.Items = {'Absolute', 'Raw'};
            app.SHAPvaluedisplayDropDown.Value = 'Absolute';
            app.ComputeglobalfeatureimportanceButton = uibutton(app.GridLayout24, 'push');
            app.ComputeglobalfeatureimportanceButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeglobalfeatureimportanceButtonPushed, true);
            app.ComputeglobalfeatureimportanceButton.Text = 'Compute global feature importance';
            app.DisplaySHAPvaluetableButton = uibutton(app.GridLayout24, 'push');
            app.DisplaySHAPvaluetableButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaySHAPvaluetableButtonPushed, true);
            app.DisplaySHAPvaluetableButton.Text = 'Display SHAP value table';
            app.EvaluateindividualframesButton = uibutton(app.GridLayout24, 'push');
            app.EvaluateindividualframesButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateindividualframesButtonPushed, true);
            app.EvaluateindividualframesButton.Text = 'Evaluate individual frames';
            app.PostprocessingTab = uitab(app.MLControlTabs);
            app.PostprocessingTab.Title = 'Post-processing';
            app.GridLayout27 = uigridlayout(app.PostprocessingTab);
            app.PostprocessclassificationsButton = uibutton(app.GridLayout27, 'push');
            app.PostprocessclassificationsButton.ButtonPushedFcn = createCallbackFcn(app, @PostprocessclassificationsButtonPushed, true);
            app.PostprocessclassificationsButton.Text = 'Post process classifications';
            app.PostprocessingwindowSpinnerLabel = uilabel(app.GridLayout27);
            app.PostprocessingwindowSpinnerLabel.Text = 'Post processing window';
            app.PostprocessingwindowSpinner = uispinner(app.GridLayout27);
            app.PostprocessingwindowSpinner.Value = 3;
            app.PostprocessingmethodDropDownLabel = uilabel(app.GridLayout27);
            app.PostprocessingmethodDropDownLabel.Text = 'Post processing method';
            app.PostprocessingmethodDropDown = uidropdown(app.GridLayout27);
            app.PostprocessingmethodDropDown.Items = {'Majority vote smoothing', 'Gaussian smoothing', 'Exponential moving average smoothing', 'Confidence-based smoothing', 'Categorical smoothing', 'Gap closing', 'Temporal constraint', 'Forbidden transitions'};
            app.PostprocessingmethodDropDown.Value = 'Majority vote smoothing';
            app.InspectannotationsTab = uitab(app.MainTabGroup);
            app.InspectannotationsTab.Title = 'Inspect annotations';
            app.GridLayout28 = uigridlayout(app.InspectannotationsTab);
            app.AnnotationUIAxes = uiaxes(app.GridLayout28);
            app.TabGroup5 = uitabgroup(app.GridLayout28);
            app.PlottrackannotationTab = uitab(app.TabGroup5);
            app.PlottrackannotationTab.Title = 'Plot track annotation';
            app.GridLayout29 = uigridlayout(app.PlottrackannotationTab);
            app.DisplaytrackButton = uibutton(app.GridLayout29, 'push');
            app.DisplaytrackButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaytrackButtonPushed, true);
            app.DisplaytrackButton.Text = 'Display track';
            app.CellIDSpinnerLabel = uilabel(app.GridLayout29);
            app.CellIDSpinnerLabel.Text = 'Cell ID';
            app.CellIDSpinner = uispinner(app.GridLayout29);
            app.CellIDSpinner.Limits = [1 Inf];
            app.CellIDSpinner.ValueDisplayFormat = '%.0f';
            app.CellIDSpinner.Value = 1;
            app.MolIDSpinnerLabel = uilabel(app.GridLayout29);
            app.MolIDSpinnerLabel.Text = 'Mol ID';
            app.MolIDSpinner = uispinner(app.GridLayout29);
            app.MolIDSpinner.Limits = [1 Inf];
            app.MolIDSpinner.ValueDisplayFormat = '%.0f';
            app.MolIDSpinner.Value = 1;
            app.CompareAnnotationsTree = uitree(app.GridLayout29, 'checkbox');
            app.CompareAnnotationsTree.ClickedFcn = createCallbackFcn(app, @CompareAnnotationsTreeClicked, true);
            app.NoannotationsloadedNode = uitreenode(app.CompareAnnotationsTree);
            app.NoannotationsloadedNode.Text = '<< No annotations loaded >>';
            app.PopoutannotationsButton = uibutton(app.GridLayout29, 'push');
            app.PopoutannotationsButton.ButtonPushedFcn = createCallbackFcn(app, @PopoutannotationsButtonPushed, true);
            app.PopoutannotationsButton.Text = 'Pop-out annotations';
            app.ExportannotationdataButton = uibutton(app.GridLayout29, 'push');
            app.ExportannotationdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportannotationdataButtonPushed, true);
            app.ExportannotationdataButton.Text = 'Export annotation data';
            app.PlotannotatedtrackButton = uibutton(app.GridLayout29, 'push');
            app.PlotannotatedtrackButton.ButtonPushedFcn = createCallbackFcn(app, @PlotannotatedtrackButtonPushed, true);
            app.PlotannotatedtrackButton.Text = 'Plot annotated track';
            app.CreatevideoillustrationButton = uibutton(app.GridLayout29, 'push');
            app.CreatevideoillustrationButton.ButtonPushedFcn = createCallbackFcn(app, @CreatevideoillustrationButtonPushed, true);
            app.CreatevideoillustrationButton.Text = 'Create video illustration';
            app.ButtonGroup = uibuttongroup(app.GridLayout29);
            app.SpecifictrackButton = uiradiobutton(app.ButtonGroup);
            app.SpecifictrackButton.Text = 'Specific track';
            app.RandomtrackButton = uiradiobutton(app.ButtonGroup);
            app.RandomtrackButton.Text = 'Random track';
            app.RandomtrackButton.Value = true;
            app.InspectfeatureDropDownLabel = uilabel(app.GridLayout29);
            app.InspectfeatureDropDownLabel.Text = 'Inspect feature';
            app.InspectfeatureDropDown = uidropdown(app.GridLayout29);
            app.InspectfeatureDropDown.Items = {'<< select feature >>'};
            app.InspectfeatureDropDown.ValueChangedFcn = createCallbackFcn(app, @InspectfeatureDropDownValueChanged, true);
            app.InspectfeatureDropDown.Value = '<< select feature >>';
            app.ComputemetricsTab = uitab(app.TabGroup5);
            app.ComputemetricsTab.Title = 'Compute metrics';
            app.GridLayout31 = uigridlayout(app.ComputemetricsTab);
            app.ComputeannotationmetricsButton = uibutton(app.GridLayout31, 'push');
            app.ComputeannotationmetricsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeannotationmetricsButtonPushed, true);
            app.ComputeannotationmetricsButton.Text = 'Compute annotation metrics';
            app.IgnoreframeatstartoftrackSpinnerLabel = uilabel(app.GridLayout31);
            app.IgnoreframeatstartoftrackSpinnerLabel.Text = 'Ignore frame at start of track';
            app.IgnoreframeatstartoftrackSpinner = uispinner(app.GridLayout31);
            app.IgnoreframeatstartoftrackSpinner.Limits = [0 Inf];
            app.IgnoreframeatstartoftrackSpinner.ValueDisplayFormat = '%.0f';
            app.IgnoreframeatstartoftrackSpinner.Value = 1;
            app.IgnoreatendoftrackSpinnerLabel = uilabel(app.GridLayout31);
            app.IgnoreatendoftrackSpinnerLabel.Text = 'Ignore at end of track';
            app.IgnoreatendoftrackSpinner = uispinner(app.GridLayout31);
            app.IgnoreatendoftrackSpinner.Limits = [0 Inf];
            app.IgnoreatendoftrackSpinner.ValueDisplayFormat = '%.0f';
            app.MinimumchangepointseparationSpinnerLabel = uilabel(app.GridLayout31);
            app.MinimumchangepointseparationSpinnerLabel.Text = 'Minimum changepoint separation';
            app.MinimumchangepointseparationSpinner = uispinner(app.GridLayout31);
            app.MinimumchangepointseparationSpinner.Limits = [0 Inf];
            app.MinimumchangepointseparationSpinner.ValueDisplayFormat = '%.0f';
            app.MinimumchangepointseparationSpinner.Tooltip = {'The minimum separation between any changepoints to be used in the changepoint metrics'};
            app.MinimumchangepointseparationSpinner.Value = 3;
            app.MaxchangepointerrorSpinnerLabel = uilabel(app.GridLayout31);
            app.MaxchangepointerrorSpinnerLabel.Text = 'Max changepoint error';
            app.MaxchangepointerrorSpinner = uispinner(app.GridLayout31);
            app.MaxchangepointerrorSpinner.Limits = [1 Inf];
            app.MaxchangepointerrorSpinner.ValueDisplayFormat = '%.0f';
            app.MaxchangepointerrorSpinner.Value = 5;
            app.VideoandtrackplottingoptionsTab = uitab(app.TabGroup5);
            app.VideoandtrackplottingoptionsTab.Title = 'Video and track plotting options';
            app.GridLayout32 = uigridlayout(app.VideoandtrackplottingoptionsTab);
            app.UsecellreferenceimageasbackgroundCheckBox = uicheckbox(app.GridLayout32);
            app.UsecellreferenceimageasbackgroundCheckBox.Text = 'Use cell reference image as background';
            app.SegmentedlinecolourDropDownLabel = uilabel(app.GridLayout32);
            app.SegmentedlinecolourDropDownLabel.Text = 'Segmented line colour';
            app.AnnotationInspectormeshlinecolourDropDown = uidropdown(app.GridLayout32);
            app.AnnotationInspectormeshlinecolourDropDown.Items = {'black', 'white', 'red', 'blue', 'green', 'yellow', 'cyan'};
            app.AnnotationInspectormeshlinecolourDropDown.Value = 'black';
            app.FixdynamicrangeacrossvideoCheckBox = uicheckbox(app.GridLayout32);
            app.FixdynamicrangeacrossvideoCheckBox.Text = 'Fix dynamic range across video';
            app.FlipvideoverticallyCheckBox = uicheckbox(app.GridLayout32);
            app.FlipvideoverticallyCheckBox.Text = 'Flip video vertically';
            app.InspectCellID = uilabel(app.GridLayout28);
            app.InspectCellID.Text = '1';
            app.CellIDLabel = uilabel(app.GridLayout28);
            app.CellIDLabel.Text = 'Cell ID:';
            app.MolIDLabel_2 = uilabel(app.GridLayout28);
            app.MolIDLabel_2.Text = 'Mol ID:';
            app.InspectMolID = uilabel(app.GridLayout28);
            app.InspectMolID.Text = '1';
            app.DiscoveryTab = uitab(app.MainTabGroup);
            app.DiscoveryTab.Title = 'Discovery';
            app.DiscoveryGrid = uigridlayout(app.DiscoveryTab);
            app.DiscoveryUIAxes = uiaxes(app.DiscoveryGrid);
            xlabel(app.DiscoveryUIAxes, 'X')
            ylabel(app.DiscoveryUIAxes, 'Y')
            zlabel(app.DiscoveryUIAxes, 'Z')
            app.TabGroup6 = uitabgroup(app.DiscoveryGrid);
            app.ExplorerelationshipsTab = uitab(app.TabGroup6);
            app.ExplorerelationshipsTab.Title = 'Explore relationships';
            app.ExploreRelationshipsGrid = uigridlayout(app.ExplorerelationshipsTab);
            app.FindHiddenrelationshipsButton = uibutton(app.ExploreRelationshipsGrid, 'push');
            app.FindHiddenrelationshipsButton.ButtonPushedFcn = createCallbackFcn(app, @FindHiddenrelationshipsButtonPushed, true);
            app.FindHiddenrelationshipsButton.Text = 'Find hidden relationships';
            app.SourcedataDropDown_4Label = uilabel(app.ExploreRelationshipsGrid);
            app.SourcedataDropDown_4Label.Text = 'Source data';
            app.DiscoverySourceDataDropDown = uidropdown(app.ExploreRelationshipsGrid);
            app.DiscoverySourceDataDropDown.Items = {'<< Select dataset >>'};
            app.DiscoverySourceDataDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DiscoverySourceDataDropDownOpening, true);
            app.DiscoverySourceDataDropDown.Value = '<< Select dataset >>';
            app.RedundancythresholdSpinnerLabel = uilabel(app.ExploreRelationshipsGrid);
            app.RedundancythresholdSpinnerLabel.Text = 'Redundancy threshold';
            app.DiscoveryRedundancythresholdSpinner = uispinner(app.ExploreRelationshipsGrid);
            app.DiscoveryRedundancythresholdSpinner.Step = 0.05;
            app.DiscoveryRedundancythresholdSpinner.Limits = [0 1];
            app.DiscoveryRedundancythresholdSpinner.Tooltip = {'The maximum Pearson correlation between each unused feature, and any of the features used by the model. Above this threshold unused features are ignored during the discovery process as the information they encode is too similar to features already used.'; ''; 'Reducing this number from the default value of 0.9 enables features more similar to those already used by the model to be considered.'};
            app.DiscoveryRedundancythresholdSpinner.Value = 0.8;
            app.MutualinformationthresholdSpinnerLabel = uilabel(app.ExploreRelationshipsGrid);
            app.MutualinformationthresholdSpinnerLabel.Text = 'Mutual information threshold';
            app.MutualinformationthresholdSpinner = uispinner(app.ExploreRelationshipsGrid);
            app.MutualinformationthresholdSpinner.Step = 0.05;
            app.MutualinformationthresholdSpinner.Limits = [0 1];
            app.MutualinformationthresholdSpinner.Value = 0.15;
            app.PlotlocationDropDownLabel_2 = uilabel(app.ExploreRelationshipsGrid);
            app.PlotlocationDropDownLabel_2.Text = 'Plot location';
            app.DiscoveryPlotlocationDropDown = uidropdown(app.ExploreRelationshipsGrid);
            app.DiscoveryPlotlocationDropDown.Items = {'Inside GUI', 'External'};
            app.DiscoveryPlotlocationDropDown.Value = 'Inside GUI';
            app.DisplayDropDownLabel = uilabel(app.ExploreRelationshipsGrid);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DiscoveryDisplayDropDown = uidropdown(app.ExploreRelationshipsGrid);
            app.DiscoveryDisplayDropDown.Items = {'All unused features', 'All non-redundant features above MI threshold', 'All non-redundant features'};
            app.DiscoveryDisplayDropDown.Value = 'All unused features';
            % Show the figure after all components are created
            app.InVivoKineticsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InVivoKineticsGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InVivoKineticsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InVivoKineticsUIFigure)
        end
    end
end