classdef LocalWindowSizePopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LocalWindowSizeFigure         matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        OKButton                      matlab.ui.control.Button
        WindowsizeframesSpinner       matlab.ui.control.Spinner
        WindowsizeframesSpinnerLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
    end

    properties (Access = private)
        mainapp; %handles of main app
        
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
            app.mainapp.movie_data.state.local_win_size = app.WindowsizeframesSpinner.Value;
            
            %move the prompt to be in the centre of wherever the main GUI is
            app.LocalWindowSizeFigure.Position(1) = (app.mainapp.InVivoKineticsUIFigure.Position(1) + (app.mainapp.InVivoKineticsUIFigure.Position(3)/2)) - ((app.LocalWindowSizeFigure.Position(3)/2));
            app.LocalWindowSizeFigure.Position(2) = (app.mainapp.InVivoKineticsUIFigure.Position(2) + (app.mainapp.InVivoKineticsUIFigure.Position(4)/2)) - ((app.LocalWindowSizeFigure.Position(4)/2));
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            delete(app.LocalWindowSizeFigure);
        end

        % Value changed function: WindowsizeframesSpinner
        function WindowsizeframesSpinnerValueChanged(app, event)
            app.mainapp.movie_data.state.local_win_size = app.WindowsizeframesSpinner.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LocalWindowSizeFigure = uifigure('Visible', 'off');
            app.LocalWindowSizeFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.LocalWindowSizeFigure);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Please select a window size for feature engineering of all features that are computed using a single local sliding window. The window size here is given here as the number of localisations (frames). For most features the window size is applied in both directions. A common default is 4 frames. See the manual for more information and advice.';
            app.WindowsizeframesSpinnerLabel = uilabel(app.GridLayout);
            app.WindowsizeframesSpinnerLabel.Text = 'Window size (frames)';
            app.WindowsizeframesSpinner = uispinner(app.GridLayout);
            app.WindowsizeframesSpinner.Limits = [2 Inf];
            app.WindowsizeframesSpinner.ValueDisplayFormat = '%.0f';
            app.WindowsizeframesSpinner.ValueChangedFcn = createCallbackFcn(app, @WindowsizeframesSpinnerValueChanged, true);
            app.WindowsizeframesSpinner.Value = 4;
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.LocalWindowSizeFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LocalWindowSizePopUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LocalWindowSizeFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LocalWindowSizeFigure)
        end
    end
end