classdef PrepareDataPopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PrepareDataUIFigure             matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        RestoredefaultvaluesButton      matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        YourdatahasbeenimportedsuccessfullyThefollowingoptionsdeterminehowtracksareconnectedandfilteredFormoreinformationabouteachparameterhoverovertheinputvaluetodisplaymoreinformationorconsulttheMethodssectionoftheDeepTRACEpublicationLabel  matlab.ui.control.Label
        SavevaluestoconfigfileButton    matlab.ui.control.Button
        LoadvaluesfromconfigfileButton  matlab.ui.control.Button
        FilteragainstDropDown           matlab.ui.control.DropDown
        FilteragainstDropDownLabel      matlab.ui.control.Label
        FilteringmethodDropDown         matlab.ui.control.DropDown
        FilteringmethodDropDownLabel    matlab.ui.control.Label
        FiltertolerancelocalisationsSpinner  matlab.ui.control.Spinner
        FiltertolerancelocalisationsSpinnerLabel  matlab.ui.control.Label
        IllustrationborderpixelsSpinner  matlab.ui.control.Spinner
        IllustrationborderpixelsSpinnerLabel  matlab.ui.control.Label
        MemoryparameterframesSpinner    matlab.ui.control.Spinner
        MemoryparameterframesSpinnerLabel  matlab.ui.control.Label
        MinimumtracklengthframesSpinner  matlab.ui.control.Spinner
        MinimumtracklengthframesSpinnerLabel  matlab.ui.control.Label
        TrackbufferframesSpinner        matlab.ui.control.Spinner
        TrackbufferframesSpinnerLabel   matlab.ui.control.Label
        PixelscalenmpixelSpinner        matlab.ui.control.Spinner
        PixelscalenmpixelSpinnerLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp;

        px_scale        %pixel scale (nm/pixel)
        track_buffer    %track buffer (frames)
        min_track_len   %minimum track length
        mem_param       %memory parameter
        border          %border around all illustrations (pixels)
        filter_meth     %filtering method
        filter_against  %filter against (localisations or tracks)
        tolerance       %filter tolerance
        cancel          %true if user presses cancel
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
            
            %move the prompt to be in the centre of wherever the main GUI is
            app.PrepareDataUIFigure.Position(1) = (app.mainapp.InVivoKineticsUIFigure.Position(1) + (app.mainapp.InVivoKineticsUIFigure.Position(3)/2)) - ((app.PrepareDataUIFigure.Position(3)/2));
            app.PrepareDataUIFigure.Position(2) = (app.mainapp.InVivoKineticsUIFigure.Position(2) + (app.mainapp.InVivoKineticsUIFigure.Position(4)/2)) - ((app.PrepareDataUIFigure.Position(4)/2));

            %search for parameters in loaded config
            
        end

        % Button pushed function: RestoredefaultvaluesButton
        function RestoredefaultvaluesButtonPushed(app, event)
            app.PixelscalenmpixelSpinner.Value              = 96;
            app.TrackbufferframesSpinner.Value              = 3;
            app.MinimumtracklengthframesSpinner.Value       = 50;
            app.MemoryparameterframesSpinner.Value          = 1;
            app.FilteringmethodDropDown.Value               = 'truncate';
            app.FilteragainstDropDown.Value                 = 'localisations';
            app.FiltertolerancelocalisationsSpinner.Value   = 1;
            app.IllustrationborderpixelsSpinner.Value       = 5;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            %write values to main GUI params

            %obtain parameters from GUI inputs - not placed in startupFcn because the contents of app.movie_data is overwritten by the LoColi file import
            app.mainapp.movie_data.params.px_scale                  = app.mainapp.PixelscaleEditField.Value;            %pixel scale (nm/pixel)
            app.mainapp.movie_data.params.track_buffer              = app.mainapp.TrackbufferframesSpinner.Value;       %minimum number of empty frames between tracks in same cell
            app.mainapp.movie_data.params.ill_border                = app.mainapp.IllustrationborderSpinner.Value;      %size of border around cells in illustrations (pixels)
            app.mainapp.movie_data.params.overlay_pc                = app.mainapp.OverlayopacityEditField.Value;        %percentage intensity of brightfield overlay in illustrations
            app.mainapp.movie_data.params.min_track_len             = app.mainapp.MinimumtracklengthSpinner.Value;      %minimum length of a track (in localisations) to use in analysis
            app.mainapp.movie_data.params.filtering_method          = app.mainapp.FilteringmethodDropDown.Value;        %filtering method which determines how filterTracks() handles multiple trajectories with temporal overlaps in a cell; this is currently used as a record of the process used to filter, assigned during filterTracks.m
            app.mainapp.movie_data.params.filter_against            = app.mainapp.FilteragainstDropDown.Value;          %determine whether to filter basaed on the presence of other tracks or other localisations; this has been superceeded as a record of the filtering process by app.movie_data.params.filtering_method, which is assigned during filterTracks.m
            app.mainapp.movie_data.params.filter_tolerance          = app.mainapp.FiltertoleranceSpinner.Value;         %maximum number of localisations that can coincide with the track before it is filtered when filtering by localisations
            app.mainapp.movie_data.params.mem_param                 = app.mainapp.MemoryparameterSpinner.Value;         %memory parameter used to filter tracks
            app.mainapp.movie_data.state.cancel                     = false;                                            %user did not press cancel
            
            %legacy location to place the parameters - these were previously in the prepare data button callback when this was part of the GUI. These will be moved to the toolbar in a future update.
            app.mainapp.movie_data.params.dr_lo                     = app.mainapp.DynamicrangelowEditField.Value;       %lower limit of dynamic range of fluor data in 'manual' mode
            app.mainapp.movie_data.params.dr_hi                     = app.mainapp.DynamicrangehighEditField.Value;      %upper limit of dynamic range of fluor data in 'manual' mode
            app.mainapp.movie_data.params.dr_auto                   = app.mainapp.DynamicrangemodeDropDown.Value;       %mode used to set dynamic range of fluor data in illustrations ('auto' or 'manual')
            app.mainapp.movie_data.params.FOV                       = size(app.mainapp.movie_data.brightfield_image);   %dimensions of recording
            app.mainapp.movie_data.params.video_upscaling_factor    = app.mainapp.VideoupscalefactorSpinner.Value;      %integer value used to upscale exported videos
            app.mainapp.movie_data.params.export_frame_rate         = app.mainapp.ExportframerateEditField.Value;       %frame rate used for export videos
            app.mainapp.movie_data.state.play_exported_video        = app.mainapp.PlayexportedvideoCheckBox.Value;      %play back exported video generated by the human annotation system
            app.mainapp.movie_data.params.hist_bin_wid              = app.mainapp.BinwidthsSpinner.Value;               %histogram bin width used by the Insights tab
            
            delete(app.PrepareDataUIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.mainapp.movie_data.state.cancel = true;
            delete(app.PrepareDataUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PrepareDataUIFigure = uifigure('Visible', 'off');
            app.PrepareDataUIFigure.Name = 'DeepTRACE: Data Preparation Settings';
            app.GridLayout = uigridlayout(app.PrepareDataUIFigure);
            app.PixelscalenmpixelSpinnerLabel = uilabel(app.GridLayout);
            app.PixelscalenmpixelSpinnerLabel.Text = 'Pixel scale (nm/pixel)';
            app.PixelscalenmpixelSpinner = uispinner(app.GridLayout);
            app.PixelscalenmpixelSpinner.Limits = [0 Inf];
            app.PixelscalenmpixelSpinner.Tooltip = {'The size of each pixel in the video.'; ''; 'Important: the value used here during data preparation will scale the entire downstream analysis, updating this later will not change the pixel scale of existing data.'};
            app.PixelscalenmpixelSpinner.Value = 96;
            app.TrackbufferframesSpinnerLabel = uilabel(app.GridLayout);
            app.TrackbufferframesSpinnerLabel.Text = 'Track buffer (frames)';
            app.TrackbufferframesSpinner = uispinner(app.GridLayout);
            app.TrackbufferframesSpinner.Limits = [0 Inf];
            app.TrackbufferframesSpinner.ValueDisplayFormat = '%.0f';
            app.TrackbufferframesSpinner.Tooltip = {'Minimum number of empty frames between tracks in the same cell.'; ''; 'Trajectories spaced by less than this number will result in a conflict, which is resolved using the settings below.'};
            app.TrackbufferframesSpinner.Value = 3;
            app.MinimumtracklengthframesSpinnerLabel = uilabel(app.GridLayout);
            app.MinimumtracklengthframesSpinnerLabel.Text = 'Minimum track length (frames)';
            app.MinimumtracklengthframesSpinner = uispinner(app.GridLayout);
            app.MinimumtracklengthframesSpinner.Limits = [0 Inf];
            app.MinimumtracklengthframesSpinner.ValueDisplayFormat = '%.0f';
            app.MinimumtracklengthframesSpinner.Tooltip = {'Minimum length of trajectories. Trajectories shorter than this minimum length are filtered in the data preparation stage. Note that following filtering using the ''truncate'' method some trajectories may have shorter durations.'};
            app.MinimumtracklengthframesSpinner.Value = 50;
            app.MemoryparameterframesSpinnerLabel = uilabel(app.GridLayout);
            app.MemoryparameterframesSpinnerLabel.Text = 'Memory parameter (frames)';
            app.MemoryparameterframesSpinner = uispinner(app.GridLayout);
            app.MemoryparameterframesSpinner.Limits = [0 Inf];
            app.MemoryparameterframesSpinner.ValueDisplayFormat = '%.0f';
            app.MemoryparameterframesSpinner.Tooltip = {'The number of consecutive frames that can be missed from a trajectory without filtering.'; ''; 'A memory parameter of 1 is typical in single molecule tracking applications to account for the possilbility of blinking events.'};
            app.MemoryparameterframesSpinner.Value = 1;
            app.IllustrationborderpixelsSpinnerLabel = uilabel(app.GridLayout);
            app.IllustrationborderpixelsSpinnerLabel.Text = 'Illustration border (pixels)';
            app.IllustrationborderpixelsSpinner = uispinner(app.GridLayout);
            app.IllustrationborderpixelsSpinner.Limits = [0 Inf];
            app.IllustrationborderpixelsSpinner.ValueDisplayFormat = '%.0f';
            app.IllustrationborderpixelsSpinner.Tooltip = {'Size of border (in pixels) around each cell''s ROI to use in illustrations. This parameter is provided here in order to pre-process reference image overlays.'};
            app.IllustrationborderpixelsSpinner.Value = 5;
            app.FiltertolerancelocalisationsSpinnerLabel = uilabel(app.GridLayout);
            app.FiltertolerancelocalisationsSpinnerLabel.Text = 'Filter tolerance (localisations)';
            app.FiltertolerancelocalisationsSpinner = uispinner(app.GridLayout);
            app.FiltertolerancelocalisationsSpinner.Limits = [0 Inf];
            app.FiltertolerancelocalisationsSpinner.ValueDisplayFormat = '%.0f';
            app.FiltertolerancelocalisationsSpinner.Tooltip = {'Minimum number of conflicts (defined by the trajectory conflict resolution settings above) before a trajectory is filtered. A higher number here indicates a more tolerate approach to filtering, and may require the user to discard more molecules during the labelling process.'};
            app.FiltertolerancelocalisationsSpinner.Value = 1;
            app.FilteringmethodDropDownLabel = uilabel(app.GridLayout);
            app.FilteringmethodDropDownLabel.Text = 'Filtering method';
            app.FilteringmethodDropDown = uidropdown(app.GridLayout);
            app.FilteringmethodDropDown.Items = {'truncate', 'eliminate', 'keep all'};
            app.FilteringmethodDropDown.Tooltip = {'Determines how the software handles temporal overlaps of molecules in the same cell. Options are,'; ''; '''truncate'': eliminates all frames from overlapping regions of tracks to retain only the regions for which a single molecule is present, splitting tracks if necessary.'; ''; '''eliminate'': removes all trajectories that fall within the track buffer of neighbouring trajectories.'; ''; '''keep all'': does not apply any filtering resulting from temporal overlaps of multiple molecules in the same cell.'};
            app.FilteringmethodDropDown.Value = 'truncate';
            app.FilteragainstDropDownLabel = uilabel(app.GridLayout);
            app.FilteragainstDropDownLabel.Text = 'Filter against';
            app.FilteragainstDropDown = uidropdown(app.GridLayout);
            app.FilteragainstDropDown.Items = {'localisations', 'tracks'};
            app.FilteragainstDropDown.Tooltip = {'Determines whether to perform the filtering process against the list of other existing trajectories in the same cell, or against the full database of localisations, which includes those that did not form trajectories.'; ''; 'Filtering against localisations imposes a stricter criteria for trajectory conflict resolution.'};
            app.FilteragainstDropDown.Value = 'localisations';
            app.LoadvaluesfromconfigfileButton = uibutton(app.GridLayout, 'push');
            app.LoadvaluesfromconfigfileButton.Tooltip = {'Select a config file to populate these values.'};
            app.LoadvaluesfromconfigfileButton.Text = 'Load values from config file';
            app.SavevaluestoconfigfileButton = uibutton(app.GridLayout, 'push');
            app.SavevaluestoconfigfileButton.Tooltip = {'Select an existing configuration file to preserve these settings to re-use later. You will be asked to select a DeepTRACE config file.'; ''; 'If you select a file with existing configuration for these settings, these values will overwrite those that exist.'; ''; 'If you select a file without existing settings for these values, then these values will be appended to that file.'; ''; 'If you type the filename for a file which does not exist, then these values will be used to start a new config file.'};
            app.SavevaluestoconfigfileButton.Text = 'Save values to config file';
            app.YourdatahasbeenimportedsuccessfullyThefollowingoptionsdeterminehowtracksareconnectedandfilteredFormoreinformationabouteachparameterhoverovertheinputvaluetodisplaymoreinformationorconsulttheMethodssectionoftheDeepTRACEpublicationLabel = uilabel(app.GridLayout);
            app.YourdatahasbeenimportedsuccessfullyThefollowingoptionsdeterminehowtracksareconnectedandfilteredFormoreinformationabouteachparameterhoverovertheinputvaluetodisplaymoreinformationorconsulttheMethodssectionoftheDeepTRACEpublicationLabel.Text = {'Your data has been imported successfully.'; ''; 'The following options determine how tracks are connected and filtered.'; ''; 'For more information about each parameter hover over the input value to display more information or consult the Methods section of the DeepTRACE publication.'};
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.RestoredefaultvaluesButton = uibutton(app.GridLayout, 'push');
            app.RestoredefaultvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @RestoredefaultvaluesButtonPushed, true);
            app.RestoredefaultvaluesButton.Text = 'Restore default values';
            % Show the figure after all components are created
            app.PrepareDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PrepareDataPopUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PrepareDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PrepareDataUIFigure)
        end
    end
end