classdef ReferencePointPopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReferencetimepointentryUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        PleaseenterareferencetimepointLabel  matlab.ui.control.Label
        ReferencetimepointsSpinner     matlab.ui.control.Spinner
        ReferencetimepointsLabel       matlab.ui.control.Label
        LabelEditField                 matlab.ui.control.EditField
        LabelEditFieldLabel            matlab.ui.control.Label
        SaveandaddanotherButton        matlab.ui.control.Button
        SavefinalreferencepointButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainapp;        %storing a reference to the main GUI
        t_ref_points;   %cell array storing the reference points
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %AUTHOR: OLIVER JAMES PAMBOS, DEPARTMENT OF PHYSICS, UNIVERSITY OF OXFORD
            %CONTACT: oliver.pambos@physics.ox.ac.uk
            %
            %ATTRIBUTION AND DISCLAIMER
            %This code was conceived and developed entirely by Oliver James Pambos, and
            %is distributed as part of DeepTRACE.
            %
            %If this code contributes to results presented in a scientific publication,
            %the following article should be cited:
            %
            %   https://doi.org/10.1101/2025.05.15.654348
            %
            %The publicly available version of DeepTRACE, including documentation and
            %updates, is available at:
            %
            %   https://github.com/opambos/DeepTRACE
            %
            %For full license, attribution, and citation terms, see the LICENSE and
            %NOTICE files distributed with DeepTRACE.
            %
            %Copyright 2022-2026 Oliver James Pambos
            %
            %Licensed under the Apache License, Version 2.0 (the "License");
            %you may not use this file except in compliance with the License.
            %You may obtain a copy of the License at
            %
            %   http://www.apache.org/licenses/LICENSE-2.0
            %
            %Unless required by applicable law or agreed to in writing, software
            %distributed under the License is distributed on an "AS IS" BASIS,
            %WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
            %See the License for the specific language governing permissions and
            %limitations under the License.
            
            %store the handles to the main app
            app.mainapp = mainapp;

            %move the prompt to be in the centre of wherever the main GUI is
            app.ReferencetimepointentryUIFigure.Position(1) = (app.mainapp.DeepTRACEUIFigure.Position(1) + (app.mainapp.DeepTRACEUIFigure.Position(3)/2)) - ((app.ReferencetimepointentryUIFigure.Position(3)/2));
            app.ReferencetimepointentryUIFigure.Position(2) = (app.mainapp.DeepTRACEUIFigure.Position(2) + (app.mainapp.DeepTRACEUIFigure.Position(4)/2)) - ((app.ReferencetimepointentryUIFigure.Position(4)/2));
            
            %cell array to gather inputs
            app.t_ref_points = {};
        end

        % Button pushed function: SaveandaddanotherButton
        function SaveandaddanotherButtonPushed(app, event)
            app.t_ref_points(end+1, :) = {app.ReferencetimepointsSpinner.Value, app.LabelEditField.Value};
            app.ReferencetimepointsSpinner.Value = 0;
            app.LabelEditField.Value = "<< Enter next label >>";
        end

        % Button pushed function: SavefinalreferencepointButton
        function SavefinalreferencepointButtonPushed(app, event)
            app.t_ref_points(end+1, :)      = {app.ReferencetimepointsSpinner.Value, app.LabelEditField.Value};
            app.mainapp.movie_data.params.t_ref_points  = app.t_ref_points;
            
            delete(ReferencePointPopUp);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReferencetimepointentryUIFigure = uifigure('Visible', 'off');
            app.ReferencetimepointentryUIFigure.Name = 'Reference time point entry';
            app.GridLayout = uigridlayout(app.ReferencetimepointentryUIFigure);
            app.SavefinalreferencepointButton = uibutton(app.GridLayout, 'push');
            app.SavefinalreferencepointButton.ButtonPushedFcn = createCallbackFcn(app, @SavefinalreferencepointButtonPushed, true);
            app.SavefinalreferencepointButton.Text = 'Save final reference point';
            app.SaveandaddanotherButton = uibutton(app.GridLayout, 'push');
            app.SaveandaddanotherButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandaddanotherButtonPushed, true);
            app.SaveandaddanotherButton.Text = 'Save and add another';
            app.LabelEditFieldLabel = uilabel(app.GridLayout);
            app.LabelEditField = uieditfield(app.GridLayout, 'text');
            app.LabelEditField.CharacterLimits = [0 50];
            app.LabelEditField.Value = 'start of IPTG induction';
            app.ReferencetimepointsLabel = uilabel(app.GridLayout);
            app.ReferencetimepointsLabel.Text = 'Reference time point (s)';
            app.ReferencetimepointsSpinner = uispinner(app.GridLayout);
            app.PleaseenterareferencetimepointLabel = uilabel(app.GridLayout);
            app.PleaseenterareferencetimepointLabel.Text = {'Please enter a reference timepoint.'; ''; 'For each point enter a time in seconds at which it occurs after the start of recording, and a text label describing the reference point.'};
            % Show the figure after all components are created
            app.ReferencetimepointentryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReferencePointPopUp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ReferencetimepointentryUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ReferencetimepointentryUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReferencetimepointentryUIFigure)
        end
    end
end