classdef SelectLocalDiffusionParamsPopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectLocalDiffusionParamsFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        DontusefeatureButton          matlab.ui.control.Button
        OKButton                      matlab.ui.control.Button
        WindowsizeframesSpinner       matlab.ui.control.Spinner
        WindowsizeframesSpinnerLabel  matlab.ui.control.Label
        FeatureengineeringforlocalapparentdiffusioncoefficientLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp; %handles of main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %AUTHOR: OLIVER JAMES PAMBOS, DEPARTMENT OF PHYSICS, UNIVERSITY OF OXFORD
            %CONTACT: oliver.pambos@physics.ox.ac.uk
            %
            %ATTRIBUTION AND DISCLAIMER
            %This code was conceived and developed entirely by Oliver James Pambos, and
            %is distributed as part of DeepTRACE.
            %
            %If this code contributes to results presented in a scientific publication,
            %the following article should be cited:
            %
            %   https://doi.org/10.1101/2025.05.15.654348
            %
            %The publicly available version of DeepTRACE, including documentation and
            %updates, is available at:
            %
            %   https://github.com/opambos/DeepTRACE
            %
            %For full license, attribution, and citation terms, see the LICENSE and
            %NOTICE files distributed with DeepTRACE.
            %
            %Copyright 2022-2026 Oliver James Pambos
            %
            %Licensed under the Apache License, Version 2.0 (the "License");
            %you may not use this file except in compliance with the License.
            %You may obtain a copy of the License at
            %
            %   http://www.apache.org/licenses/LICENSE-2.0
            %
            %Unless required by applicable law or agreed to in writing, software
            %distributed under the License is distributed on an "AS IS" BASIS,
            %WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
            %See the License for the specific language governing permissions and
            %limitations under the License.
            
            app.mainapp = mainapp;
            app.mainapp.movie_data.state.local_dstar_win_size = app.WindowsizeframesSpinner.Value;
            
            %move the prompt to be in the centre of wherever the main GUI is
            app.SelectLocalDiffusionParamsFigure.Position(1) = (app.mainapp.DeepTRACEUIFigure.Position(1) + (app.mainapp.DeepTRACEUIFigure.Position(3)/2)) - ((app.SelectLocalDiffusionParamsFigure.Position(3)/2));
            app.SelectLocalDiffusionParamsFigure.Position(2) = (app.mainapp.DeepTRACEUIFigure.Position(2) + (app.mainapp.DeepTRACEUIFigure.Position(4)/2)) - ((app.SelectLocalDiffusionParamsFigure.Position(4)/2));
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
             delete(app.SelectLocalDiffusionParamsFigure);
        end

        % Button pushed function: DontusefeatureButton
        function DontusefeatureButtonPushed(app, event)
            app.mainapp.movie_data.state.local_dstar_win_size = 0;
            delete(app.SelectLocalDiffusionParamsFigure);
        end

        % Value changed function: WindowsizeframesSpinner
        function WindowsizeframesSpinnerValueChanged(app, event)
            app.mainapp.movie_data.state.local_dstar_win_size = app.WindowsizeframesSpinner.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectLocalDiffusionParamsFigure = uifigure('Visible', 'off');
            app.SelectLocalDiffusionParamsFigure.Name = 'Settings for feature engineering of local apparent diffusion coefficient';
            app.GridLayout = uigridlayout(app.SelectLocalDiffusionParamsFigure);
            app.FeatureengineeringforlocalapparentdiffusioncoefficientLabel = uilabel(app.GridLayout);
            app.FeatureengineeringforlocalapparentdiffusioncoefficientLabel.Text = {'Feature engineering for local apparent diffusion coefficient. This feature computes a local estimate for the diffusion coefficient for every tracked localisation in the dataset. The feature can assist in identifying subtle differences between slow moving states, particularly when step sizes fall below the localisation precision.'; ''; 'Please enter a window size below. Your input will define the maximum time (in frames) before or after from each localisation that may be used in the computation. Choosing a smaller value increases resolution available for precise changepoint analysis, while a larger value will better suppress statistical noise.'; ''; 'The computation is performed using all pair-wise distances within this temporal range. Note that the system will ignore missing localisations, compiling the values only from frames for which data exist; the window will not be extended to compensate for missing values, ensuring temporal ranges remain consistent across the dataset. For further information please consult the manual or supplementary information of the publication.'};
            app.WindowsizeframesSpinnerLabel = uilabel(app.GridLayout);
            app.WindowsizeframesSpinnerLabel.Text = 'Window size (frames)';
            app.WindowsizeframesSpinner = uispinner(app.GridLayout);
            app.WindowsizeframesSpinner.Limits = [1 Inf];
            app.WindowsizeframesSpinner.ValueDisplayFormat = '%.0f';
            app.WindowsizeframesSpinner.ValueChangedFcn = createCallbackFcn(app, @WindowsizeframesSpinnerValueChanged, true);
            app.WindowsizeframesSpinner.Value = 3;
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.DontusefeatureButton = uibutton(app.GridLayout, 'push');
            app.DontusefeatureButton.ButtonPushedFcn = createCallbackFcn(app, @DontusefeatureButtonPushed, true);
            app.DontusefeatureButton.Text = 'Don''t use feature';
            % Show the figure after all components are created
            app.SelectLocalDiffusionParamsFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectLocalDiffusionParamsPopUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectLocalDiffusionParamsFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectLocalDiffusionParamsFigure)
        end
    end
end