classdef SetWindowSizeStepSmoothingPopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        SmoothingmethodDropDown       matlab.ui.control.DropDown
        SmoothingmethodDropDownLabel  matlab.ui.control.Label
        DontusefeatureButton          matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        WindowsizeframesSpinner       matlab.ui.control.Spinner
        WindowsizeframesSpinnerLabel  matlab.ui.control.Label
        SetwindowsizeforsmoothingSettheframerangeoverwhichLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp; %main GUI app handles, this enables data to be returned to the main app, but will also later enable user configured defaults to be passed to this pop-up
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;  %make main app handles accessible locally
            
            %record the default values
            app.mainapp.movie_data.state.step_smoothing_win_size = app.WindowsizeframesSpinner.Value;
            app.mainapp.movie_data.state.smoothing_method = app.SmoothingmethodDropDown.Value;
            
            %move the prompt to be in the centre of wherever the main GUI is
            app.UIFigure.Position(1) = (app.mainapp.InVivoKineticsUIFigure.Position(1) + (app.mainapp.InVivoKineticsUIFigure.Position(3)/2)) - ((app.UIFigure.Position(3)/2));
            app.UIFigure.Position(2) = (app.mainapp.InVivoKineticsUIFigure.Position(2) + (app.mainapp.InVivoKineticsUIFigure.Position(4)/2)) - ((app.UIFigure.Position(4)/2));
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.mainapp.movie_data.state.step_smoothing_win_size = app.WindowsizeframesSpinner.Value;
            
            delete(SetWindowSizeStepSmoothingPopUp);
        end

        % Button pushed function: DontusefeatureButton
        function DontusefeatureButtonPushed(app, event)
            app.mainapp.movie_data.state.step_smoothing_win_size = 0;
            
            delete(SetWindowSizeStepSmoothingPopUp);
        end

        % Value changed function: SmoothingmethodDropDown
        function SmoothingmethodDropDownValueChanged(app, event)
            app.mainapp.movie_data.state.smoothing_method = app.SmoothingmethodDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SetwindowsizeforsmoothingSettheframerangeoverwhichLabel = uilabel(app.GridLayout);
            app.SetwindowsizeforsmoothingSettheframerangeoverwhichLabel.Text = {'Set window size for smoothing'; ''; 'Set the method and frame range over which smoothing will be applied to smooth the step size distribution.'};
            app.WindowsizeframesSpinnerLabel = uilabel(app.GridLayout);
            app.WindowsizeframesSpinnerLabel.Text = 'Window size (frames)';
            app.WindowsizeframesSpinner = uispinner(app.GridLayout);
            app.WindowsizeframesSpinner.Limits = [1 Inf];
            app.WindowsizeframesSpinner.ValueDisplayFormat = '%.0f';
            app.WindowsizeframesSpinner.Value = 3;
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.DontusefeatureButton = uibutton(app.GridLayout, 'push');
            app.DontusefeatureButton.ButtonPushedFcn = createCallbackFcn(app, @DontusefeatureButtonPushed, true);
            app.DontusefeatureButton.Text = 'Don''t use feature';
            app.SmoothingmethodDropDownLabel = uilabel(app.GridLayout);
            app.SmoothingmethodDropDownLabel.Text = 'Smoothing method';
            app.SmoothingmethodDropDown = uidropdown(app.GridLayout);
            app.SmoothingmethodDropDown.Items = {'Moving mean', 'Moving median', 'Gaussian kernel', 'Local linear regression (LOWESS)', 'Local quadratic regression (LOESS)', 'Robust local linear regression (RLOWESS)', 'Robust local quadratic regression (RLOESS)', 'Savitzky-Golay filter'};
            app.SmoothingmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SmoothingmethodDropDownValueChanged, true);
            app.SmoothingmethodDropDown.Tooltip = {'This is the method used for smoothing your data. Options are as follows.'; ''; 'Moving mean (default): Smooths the data by averaging values within a sliding window.'; ''; 'Moving median: Smooths the data by taking the median value within a sliding window, useful for reducing the impact of outliers.'; ''; 'Gaussian kernel: Smooths the data using a Gaussian filter, giving more weight to nearby points within the window.'; ''; 'Local linear regression (LOWESS): Smooths the data using local linear regression within the local window, reducing discontinuities in the data.'; ''; 'Local quadratic regression (LOESS): Smooths the data using local quadratic regression, allowing for more complex trends in the data.'; ''; 'Robust local linear regression (RLOWESS): Smooths the data using local linear regression with enhanced robustness to outliers.'; ''; 'Robust local quadratic regression (RLOESS): Smooths the data using local quadratic regression with enhanced robustness to outliers.'; ''; 'Savitzky-Golay filter: Smooths the data using the Savitzky-Golay filter, preserving data shape while reducing high-frequency noise.'};
            app.SmoothingmethodDropDown.Value = 'Moving median';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetWindowSizeStepSmoothingPopUp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end