classdef SlidingPairWindowSizePopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SlidingPairWindowSizeFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        OKButton                     matlab.ui.control.Button
        WindowsizeframesSpinner      matlab.ui.control.Spinner
        WindowsizeframesLabel        matlab.ui.control.Label
        Label                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp; %handles of main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp;
            app.mainapp.movie_data.state.rolling_delta_win_size = app.WindowsizeframesSpinner.Value;

            %move the prompt to be in the centre of wherever the main GUI is
            app.SlidingPairWindowSizeFigure.Position(1) = (app.mainapp.InVivoKineticsUIFigure.Position(1) + (app.mainapp.InVivoKineticsUIFigure.Position(3)/2)) - ((app.SlidingPairWindowSizeFigure.Position(3)/2));
            app.SlidingPairWindowSizeFigure.Position(2) = (app.mainapp.InVivoKineticsUIFigure.Position(2) + (app.mainapp.InVivoKineticsUIFigure.Position(4)/2)) - ((app.SlidingPairWindowSizeFigure.Position(4)/2));
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            delete(app.SlidingPairWindowSizeFigure);
        end

        % Value changed function: WindowsizeframesSpinner
        function WindowsizeframesSpinnerValueChanged(app, event)
            app.mainapp.movie_data.state.rolling_delta_win_size = app.WindowsizeframesSpinner.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SlidingPairWindowSizeFigure = uifigure('Visible', 'off');
            app.SlidingPairWindowSizeFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.SlidingPairWindowSizeFigure);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Please select a window size for feature engineering of all features that involve comparisons to pairs of sliding windows. The window size here is the size (in number of localisations) of each window.';
            app.WindowsizeframesLabel = uilabel(app.GridLayout);
            app.WindowsizeframesLabel.Text = 'Window size (frames)';
            app.WindowsizeframesSpinner = uispinner(app.GridLayout);
            app.WindowsizeframesSpinner.Limits = [2 Inf];
            app.WindowsizeframesSpinner.ValueDisplayFormat = '%.0f';
            app.WindowsizeframesSpinner.ValueChangedFcn = createCallbackFcn(app, @WindowsizeframesSpinnerValueChanged, true);
            app.WindowsizeframesSpinner.Value = 5;
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.SlidingPairWindowSizeFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SlidingPairWindowSizePopUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SlidingPairWindowSizeFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SlidingPairWindowSizeFigure)
        end
    end
end