classdef TemporalContextPopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        InstructionsforuserLabel  matlab.ui.control.Label
        FramesafterSpinner        matlab.ui.control.Spinner
        FramesafterSpinnerLabel   matlab.ui.control.Label
        FramesbeforeSpinner       matlab.ui.control.Spinner
        FramesbeforeSpinnerLabel  matlab.ui.control.Label
        CancelButton              matlab.ui.control.Button
        OKButton                  matlab.ui.control.Button
    end

    properties (Access = private)
        mainapp;   %storing a reference to the main GUI
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %AUTHOR: OLIVER JAMES PAMBOS, DEPARTMENT OF PHYSICS, UNIVERSITY OF OXFORD
            %CONTACT: oliver.pambos@physics.ox.ac.uk
            %
            %ATTRIBUTION AND DISCLAIMER
            %This code was conceived and developed entirely by Oliver James Pambos, and
            %is distributed as part of DeepTRACE.
            %
            %If this code contributes to results presented in a scientific publication,
            %the following article should be cited:
            %
            %   https://doi.org/10.1101/2025.05.15.654348
            %
            %The publicly available version of DeepTRACE, including documentation and
            %updates, is available at:
            %
            %   https://github.com/opambos/DeepTRACE
            %
            %For full license, attribution, and citation terms, see the LICENSE and
            %NOTICE files distributed with DeepTRACE.
            %
            %Copyright 2022-2026 Oliver James Pambos
            %
            %Licensed under the Apache License, Version 2.0 (the "License");
            %you may not use this file except in compliance with the License.
            %You may obtain a copy of the License at
            %
            %   http://www.apache.org/licenses/LICENSE-2.0
            %
            %Unless required by applicable law or agreed to in writing, software
            %distributed under the License is distributed on an "AS IS" BASIS,
            %WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
            %See the License for the specific language governing permissions and
            %limitations under the License.
            
            %store the handles to the main app
            app.mainapp = mainapp;
            
            app.mainapp.movie_data.state.frames_before  = app.FramesbeforeSpinner.Value;
            app.mainapp.movie_data.state.frames_after   = app.FramesafterSpinner.Value;
            
            %move the prompt to be in the centre of wherever the main GUI is
            app.UIFigure.Position(1) = (app.mainapp.DeepTRACEUIFigure.Position(1) + (app.mainapp.DeepTRACEUIFigure.Position(3)/2)) - ((app.UIFigure.Position(3)/2));
            app.UIFigure.Position(2) = (app.mainapp.DeepTRACEUIFigure.Position(2) + (app.mainapp.DeepTRACEUIFigure.Position(4)/2)) - ((app.UIFigure.Position(4)/2));
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.mainapp.movie_data.state.frames_before = app.FramesbeforeSpinner.Value;
            app.mainapp.movie_data.state.frames_after = app.FramesafterSpinner.Value;
            
            delete(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.mainapp.movie_data.state.frames_before = 0;
            app.mainapp.movie_data.state.frames_after = 0;
            
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.FramesbeforeSpinnerLabel = uilabel(app.GridLayout);
            app.FramesbeforeSpinnerLabel.Text = 'Frames before';
            app.FramesbeforeSpinner = uispinner(app.GridLayout);
            app.FramesbeforeSpinner.Limits = [0 Inf];
            app.FramesbeforeSpinner.ValueDisplayFormat = '%.0f';
            app.FramesbeforeSpinner.Value = 4;
            app.FramesafterSpinnerLabel = uilabel(app.GridLayout);
            app.FramesafterSpinnerLabel.Text = 'Frames after';
            app.FramesafterSpinner = uispinner(app.GridLayout);
            app.FramesafterSpinner.Limits = [0 Inf];
            app.FramesafterSpinner.ValueDisplayFormat = '%.0f';
            app.FramesafterSpinner.Value = 4;
            app.InstructionsforuserLabel = uilabel(app.GridLayout);
            app.InstructionsforuserLabel.Text = {'Please provide a suitable range of frames before and after each timepoint to generate time shifted step sizes.'; ''; 'This provides temporal context for use with models that do not natively handle this.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TemporalContextPopUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end