classdef AboutDeepTRACE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeepTRACEUIFigure              matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        AboutHTML                      matlab.ui.control.HTML
        Image                          matlab.ui.control.Image
        AboutDeepTRACEprereleaseLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        main_app_pos
    end
    
    methods (Access = private)
        
        function handleAboutHtmlEvent(app, event)
        
            if ~isprop(event, "HTMLEventName") || ~isprop(event, "HTMLEventData")
                return
            end
        
            if ~strcmp(event.HTMLEventName, "copy")
                return
            end
        
            payload = char(string(event.HTMLEventData));
            if isempty(payload)
                return
            end
        
            clipboard('copy', payload);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app_pos)
            %AUTHOR: OLIVER JAMES PAMBOS, DEPARTMENT OF PHYSICS, UNIVERSITY OF OXFORD
            %CONTACT: oliver.pambos@physics.ox.ac.uk
            %
            %ATTRIBUTION AND DISCLAIMER
            %This code was conceived and developed entirely by Oliver James Pambos, and
            %is distributed as part of DeepTRACE.
            %
            %If this code contributes to results presented in a scientific publication,
            %the following article should be cited:
            %
            %   https://doi.org/10.1101/2025.05.15.654348
            %
            %The publicly available version of DeepTRACE, including documentation and
            %updates, is available at:
            %
            %   https://github.com/opambos/DeepTRACE
            %
            %For full license, attribution, and citation terms, see the LICENSE and
            %NOTICE files distributed with DeepTRACE.
            %
            %Copyright 2022-2026 Oliver James Pambos
            %
            %Licensed under the Apache License, Version 2.0 (the "License");
            %you may not use this file except in compliance with the License.
            %You may obtain a copy of the License at
            %
            %   http://www.apache.org/licenses/LICENSE-2.0
            %
            %Unless required by applicable law or agreed to in writing, software
            %distributed under the License is distributed on an "AS IS" BASIS,
            %WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
            %See the License for the specific language governing permissions and
            %limitations under the License.

            about_pos = app.DeepTRACEUIFigure.Position;
            
            %get center of the main app
            x_centre = main_app_pos(1) + main_app_pos(3)/2;
            y_centre = main_app_pos(2) + main_app_pos(4)/2;
            
            about_x = x_centre - about_pos(3)/2;
            about_y = y_centre - about_pos(4)/2;
            
            %move the About app
            app.DeepTRACEUIFigure.Position = [about_x, about_y, about_pos(3), about_pos(4)];
            
            %=====================================
            %Generate the main body text with html
            %=====================================
            github_url   = "https://github.com/opambos/DeepTRACE";
            article_url = "https://www.biorxiv.org/content/10.1101/2025.05.15.654348";
            email_addr   = "oliver.pambos@physics.ox.ac.uk";
            
            lines = [
                "<!doctype html>"
                "<html><head><meta charset='utf-8'></head>"
                "<body style='font-family: Arial, Helvetica, sans-serif; font-size: 13px; margin: 10px;'>"
                
                "<script>" + ...
                "let hc;" + ...
                "function setup(htmlComponent){ hc = htmlComponent; }" + ...
                "function copyText(t){ if(hc){ hc.sendEventToMATLAB('copy', t); } }" + ...
                "</script>"
                
                "<div style='text-align: justify;'>" + ...
                "<b>DeepTRACE</b> provides tools for interactive annotation, machine-learning-based segmentation, and downstream analysis of single-molecule tracking data in living cells." + ...
                "</div><br>"
                
                "<b>Source code: </b>" + github_url + ...
                " <button onclick=""copyText('" + github_url + "')"">copy link</button><br><br>"
                
                "If DeepTRACE is used in published work, the following article should be cited:<br><br>"
                "Pambos et al., <b><i>DeepTRACE: Flexible Machine Learning for Analysis and Discovery in Single Molecule Tracks</i></b> (2025). " + ...
                article_url + ...
                " <button onclick=""copyText('" + article_url + "')"">copy link</button><br><br>"
                
                "<b>Feedback</b><br>"
                "DeepTRACE is under continuous development; feedback and bug reports are welcome:<br>" + email_addr + ...
                " <button onclick=""copyText('" + email_addr + "')"">copy link</button><br><br>"
                
                "&copy; 2022&ndash;2025 Oliver Pambos<br>"
                "Department of Physics<br>"
                "University of Oxford<br>"
                "United Kingdom<br>"
                "OX1 3PU<br>"
                
                "</body></html>"];
            
            app.AboutHTML.HTMLSource = strjoin(lines, "");
            app.AboutHTML.HTMLEventReceivedFcn = @(src,event)handleAboutHtmlEvent(app,event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DeepTRACEUIFigure = uifigure('Visible', 'off');
            app.DeepTRACEUIFigure.Name = 'DeepTRACE';
            app.DeepTRACEUIFigure.Icon = fullfile(pathToMLAPP, 'DeepTRACE logo.png');
            app.GridLayout = uigridlayout(app.DeepTRACEUIFigure);
            app.AboutDeepTRACEprereleaseLabel = uilabel(app.GridLayout);
            app.AboutDeepTRACEprereleaseLabel.Text = {'About DeepTRACE'; '(pre-release)'};
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'DeepTRACE logo.png';
            app.AboutHTML = uihtml(app.GridLayout);
            % Show the figure after all components are created
            app.DeepTRACEUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AboutDeepTRACE(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeepTRACEUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeepTRACEUIFigure)
        end
    end
end