classdef DataImportViewerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataImportViewerUIFigure        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        SetdisplayPanel                 matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        FlipimageandcelloutlinesButton  matlab.ui.control.StateButton
        DisplayreferenceimageCheckBox   matlab.ui.control.CheckBox
        ColoursetDropDown               matlab.ui.control.DropDown
        ColoursetLabel                  matlab.ui.control.Label
        ManualglobalstaticoffsetPanel   matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        SetstaticoffsetandexitButton    matlab.ui.control.Button
        MovementsteppxSpinner           matlab.ui.control.Spinner
        MovementsteppxSpinnerLabel      matlab.ui.control.Label
        ManualoffsetcorrectionPanel     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        DownButton                      matlab.ui.control.Button
        RightButton                     matlab.ui.control.Button
        LeftButton                      matlab.ui.control.Button
        UpButton                        matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        global_offset;  %offset applied to tracks
        step_move;      %step size in nm
        tracks_data;    %localisations to adjust
        mainapp;        %handle to main app
        colours;        %light or dark colours for overlayed data (outlines and localisations)
        flipped;
    end
    
    methods (Access = private)
        
        function [] = displayData(app)
            cla(app.UIAxes);
            hold(app.UIAxes, "on");

            [img_h, img_w] = size(app.mainapp.movie_data.brightfield_image);
            xlim(app.UIAxes, [1 img_w]);
            ylim(app.UIAxes, [1 img_h]);
            app.UIAxes.YDir = 'reverse';

            if app.DisplayreferenceimageCheckBox.Value
                h_ref_img = imshow(app.mainapp.movie_data.brightfield_image, [], 'Parent', app.UIAxes);
                uistack(h_ref_img, 'bottom');
            end
            

            switch app.colours
                case "Light"
                    %plot segmented cell boundaries
                    for ii = 1:size(app.mainapp.movie_data.cellROI_data, 1)
                        plot(app.UIAxes, app.mainapp.movie_data.cellROI_data(ii).mesh(:,1), app.mainapp.movie_data.cellROI_data(ii).mesh(:,2), "Color", [0, 0.4471, 0.7412]);
                        plot(app.UIAxes, app.mainapp.movie_data.cellROI_data(ii).mesh(:,3), app.mainapp.movie_data.cellROI_data(ii).mesh(:,4), "Color", [0, 0.4471, 0.7412]);
                    end

                    %plot localisations
                    scatter(app.UIAxes, app.tracks_data(:,1) + app.global_offset(1), app.tracks_data(:,2) + app.global_offset(2), '.', 'MarkerEdgeColor', [0.639, 0.757, 0.678]);   %Cambridge blue! Very nice, happy
                case "Dark"
                    %plot segmented cell boundaries
                    for ii = 1:size(app.mainapp.movie_data.cellROI_data, 1)
                        plot(app.UIAxes, app.mainapp.movie_data.cellROI_data(ii).mesh(:,1), app.mainapp.movie_data.cellROI_data(ii).mesh(:,2), "Color", [0, 0, 0]);
                        plot(app.UIAxes, app.mainapp.movie_data.cellROI_data(ii).mesh(:,3), app.mainapp.movie_data.cellROI_data(ii).mesh(:,4), "Color", [0, 0, 0]);
                    end

                    %plot localisations
                    scatter(app.UIAxes, app.tracks_data(:,1) + app.global_offset(1), app.tracks_data(:,2) + app.global_offset(2), '.', 'MarkerEdgeColor', [0, 0.13, 0.28]);         %Oxford blue, ugly, evil, sad
                otherwise

            end


            hold(app.UIAxes, "off");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, tracks_data)
            %Author: Oliver J. Pambos, Department of Physics, University of Oxford, UK
            %(oliver.pambos@physics.ox.ac.uk).
            %
            %ATTRIBUTION AND DISCLAIMER
            %This code was conceived and developed by Oliver J. Pambos, and is
            %distributed as part of the single-molecule track analysis software
            %DeepTRACE.
            %
            %For citation of this work, refer to:
            %
            %   Pambos et al., Commun Biol (2026)
            %   https://doi.org/10.1038/s42003-026-09899-y
            %
            %The publicly available version of DeepTRACE, including documentation and
            %updates, is available at:
            %
            %   https://github.com/opambos/DeepTRACE
            %
            %For license and attribution terms, see the LICENSE and NOTICE files.
            %
            %Copyright 2022-2026 Oliver J. Pambos
            %
            %Licensed under the Apache License, Version 2.0 (the "License");
            %you may not use this file except in compliance with the License.
            %You may obtain a copy of the License at
            %
            %   http://www.apache.org/licenses/LICENSE-2.0
            %
            %Unless required by applicable law or agreed to in writing, software
            %distributed under the License is distributed on an "AS IS" BASIS,
            %WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
            %See the License for the specific language governing permissions and
            %limitations under the License.
            
            %acquire data from main GUI
            app.mainapp = mainapp;
            app.tracks_data = tracks_data;

            app.global_offset = [0, 0];
            app.step_move = app.MovementsteppxSpinner.Value;
            app.colours = app.ColoursetDropDown.Value;
            app.flipped = false;
            displayData(app);
        end

        % Button pushed function: SetstaticoffsetandexitButton
        function SetstaticoffsetandexitButtonPushed(app, event)
            %apply offsets to all localisations, and return to main GUI
            app.tracks_data(:,1) = app.tracks_data(:,1) + app.global_offset(1);
            app.tracks_data(:,2) = app.tracks_data(:,2) + app.global_offset(2);
            app.mainapp.movie_data.params.flipped = app.flipped;
            app.mainapp.movie_data.state.temp_tracks = app.tracks_data;
            app.mainapp.movie_data.params.pixelshift = app.global_offset;
            
            delete(app);
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            app.global_offset(2) = app.global_offset(2) - app.step_move;
            displayData(app);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.global_offset(1) = app.global_offset(1) + app.step_move;
            displayData(app);
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            app.global_offset(2) = app.global_offset(2) + app.step_move;
            displayData(app);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.global_offset(1) = app.global_offset(1) - app.step_move;
            displayData(app);
        end

        % Value changed function: FlipimageandcelloutlinesButton
        function FlipimageandcelloutlinesButtonValueChanged(app, event)
            app.mainapp.movie_data.brightfield_image = flipud(app.mainapp.movie_data.brightfield_image);
            app.flipped = ~app.flipped;
            for ii = 1:numel(app.mainapp.movie_data.cellROI_data)
                app.mainapp.movie_data.cellROI_data(ii).mesh(:,[2 4]) = size(app.mainapp.movie_data.brightfield_image,1) + 1 - app.mainapp.movie_data.cellROI_data(ii).mesh(:,[2 4]);
            end
            displayData(app);
        end

        % Value changed function: MovementsteppxSpinner
        function MovementsteppxSpinnerValueChanged(app, event)
            app.step_move = app.MovementsteppxSpinner.Value;
        end

        % Value changed function: ColoursetDropDown
        function ColoursetDropDownValueChanged(app, event)
            app.colours = app.ColoursetDropDown.Value;
            displayData(app);
        end

        % Value changed function: DisplayreferenceimageCheckBox
        function DisplayreferenceimageCheckBoxValueChanged(app, event)
            %either display the reference image behind the data, or delete an existing one
            if app.DisplayreferenceimageCheckBox.Value
                h_ref_img = imshow(app.mainapp.movie_data.brightfield_image, [], 'Parent', app.UIAxes);
                uistack(h_ref_img, 'bottom');
            else
                img = findobj(app.UIAxes, 'Type', 'image');
                delete(img);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataImportViewerUIFigure = uifigure('Visible', 'off');
            app.DataImportViewerUIFigure.Name = 'Data Import Viewer';
            app.DataImportViewerUIFigure.Icon = 'DeepTRACE logo.png';
            app.GridLayout = uigridlayout(app.DataImportViewerUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.ManualglobalstaticoffsetPanel = uipanel(app.GridLayout);
            app.ManualglobalstaticoffsetPanel.Title = '1: Manual global static offset';
            app.GridLayout3 = uigridlayout(app.ManualglobalstaticoffsetPanel);
            app.ManualoffsetcorrectionPanel = uipanel(app.GridLayout3);
            app.ManualoffsetcorrectionPanel.Title = 'Manual offset correction';
            app.GridLayout2 = uigridlayout(app.ManualoffsetcorrectionPanel);
            app.UpButton = uibutton(app.GridLayout2, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.LeftButton = uibutton(app.GridLayout2, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.GridLayout2, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.DownButton = uibutton(app.GridLayout2, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.MovementsteppxSpinnerLabel = uilabel(app.GridLayout3);
            app.MovementsteppxSpinnerLabel.Text = 'Movement step (px)';
            app.MovementsteppxSpinner = uispinner(app.GridLayout3);
            app.MovementsteppxSpinner.Step = 0.05;
            app.MovementsteppxSpinner.ValueChangedFcn = createCallbackFcn(app, @MovementsteppxSpinnerValueChanged, true);
            app.MovementsteppxSpinner.Value = 0.2;
            app.SetstaticoffsetandexitButton = uibutton(app.GridLayout3, 'push');
            app.SetstaticoffsetandexitButton.ButtonPushedFcn = createCallbackFcn(app, @SetstaticoffsetandexitButtonPushed, true);
            app.SetstaticoffsetandexitButton.Text = 'Set static offset and exit';
            app.SetdisplayPanel = uipanel(app.GridLayout);
            app.SetdisplayPanel.Title = 'Set display';
            app.GridLayout5 = uigridlayout(app.SetdisplayPanel);
            app.ColoursetLabel = uilabel(app.GridLayout5);
            app.ColoursetLabel.Text = 'Colour set:';
            app.ColoursetDropDown = uidropdown(app.GridLayout5);
            app.ColoursetDropDown.Items = {'Light', 'Dark'};
            app.ColoursetDropDown.ValueChangedFcn = createCallbackFcn(app, @ColoursetDropDownValueChanged, true);
            app.ColoursetDropDown.Value = 'Light';
            app.DisplayreferenceimageCheckBox = uicheckbox(app.GridLayout5);
            app.DisplayreferenceimageCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayreferenceimageCheckBoxValueChanged, true);
            app.DisplayreferenceimageCheckBox.Text = 'Display reference image';
            app.DisplayreferenceimageCheckBox.Value = true;
            app.FlipimageandcelloutlinesButton = uibutton(app.GridLayout5, 'state');
            app.FlipimageandcelloutlinesButton.ValueChangedFcn = createCallbackFcn(app, @FlipimageandcelloutlinesButtonValueChanged, true);
            app.FlipimageandcelloutlinesButton.Text = 'Flip image and cell outlines';
            % Show the figure after all components are created
            app.DataImportViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataImportViewerGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataImportViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataImportViewerUIFigure)
        end
    end
end