classdef FeatureEngineeringMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FeatureEngineeringMenuUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        CancelButton                    matlab.ui.control.Button
        DoneButton                      matlab.ui.control.Button
        PleaseselecttheoptionalengineeredfeaturestouseLabel  matlab.ui.control.Label
        feature_tree                    matlab.ui.container.CheckBoxTree
        AlloptionalfeaturesNode         matlab.ui.container.TreeNode
        StaticfeaturesNode              matlab.ui.container.TreeNode
        StepsizeNode                    matlab.ui.container.TreeNode
        SmoothedstepsizeNode            matlab.ui.container.TreeNode
        TimestepfrompreviouslocalisationNode  matlab.ui.container.TreeNode
        TimesincestartofexperimentNode  matlab.ui.container.TreeNode
        SetexperimentreferencetimepointsNode  matlab.ui.container.TreeNode
        CurrentdistancefromstartoftrackNode  matlab.ui.container.TreeNode
        CumulativedistancetravelledNode  matlab.ui.container.TreeNode
        TimeshiftedstepsizesNode        matlab.ui.container.TreeNode
        DistancetocellmembraneNode      matlab.ui.container.TreeNode
        DistancetonearestcellpoleNode   matlab.ui.container.TreeNode
        SpotsizeNode                    matlab.ui.container.TreeNode
        SpotareaNode                    matlab.ui.container.TreeNode
        StepanglerelativetopreviousstepNode  matlab.ui.container.TreeNode
        StepanglerelativetofieldofviewNode  matlab.ui.container.TreeNode
        StepanglerelativetocellmajoraxisNode  matlab.ui.container.TreeNode
        ProximitytoendsoftrackNode      matlab.ui.container.TreeNode
        LocalwindowfeaturesNode         matlab.ui.container.TreeNode
        LocaltrappednessNode            matlab.ui.container.TreeNode
        LocaldiffusioncoefficientNode   matlab.ui.container.TreeNode
        LocalanomalousdiffusionexponentNode  matlab.ui.container.TreeNode
        LocalefficiencyNode             matlab.ui.container.TreeNode
        LocalstraightnessNode           matlab.ui.container.TreeNode
        LocalkurtosisNode               matlab.ui.container.TreeNode
        LocalstepsizekurtosisNode       matlab.ui.container.TreeNode
        LocalfractaldimensionNode       matlab.ui.container.TreeNode
        LocalstepangleasymmetryNode     matlab.ui.container.TreeNode
        LocalvelocityautocorrelationfunctionNode  matlab.ui.container.TreeNode
        LocalmaximalexcursionNode       matlab.ui.container.TreeNode
        PairedwindowfeaturesNode        matlab.ui.container.TreeNode
        LocalchangeindiffusioncoefficientNode  matlab.ui.container.TreeNode
        LocalchangeinmeanstepsizeNode   matlab.ui.container.TreeNode
        LocalchangeinstandarddeviationofstepsizesNode  matlab.ui.container.TreeNode
        LocalchangeinstandarddeviationofpositionsNode  matlab.ui.container.TreeNode
        LocalchangeindispersionofpositionsNode  matlab.ui.container.TreeNode
        LocaldisplacementincentroidoflocalisationsNode  matlab.ui.container.TreeNode
    end

    
    properties (Access = private)
        mainapp;            %handle for the main app, enabling access to its data
        selected_features;  %keeps hold user choices of which features to engineer
    end
    
    methods (Access = private)
        
        function [] = expandAllNodes(app, node)
            %recursive function to expand all nodes in the feature menu tree
            expand(node);
            if ~isempty(node.Children)
                for ii = 1:numel(node.Children)
                    %expand recursively expand child nodes
                    app.expandAllNodes(node.Children(ii));
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %Author: Oliver J. Pambos, Department of Physics, University of Oxford, UK
            %(oliver.pambos@physics.ox.ac.uk).
            %
            %ATTRIBUTION AND DISCLAIMER
            %This code was conceived and developed by Oliver J. Pambos, and is
            %distributed as part of the single-molecule track analysis software
            %DeepTRACE.
            %
            %For citation of this work, refer to:
            %
            %   Pambos et al., Commun Biol (2026)
            %   https://doi.org/10.1038/s42003-026-09899-y
            %
            %The publicly available version of DeepTRACE, including documentation and
            %updates, is available at:
            %
            %   https://github.com/opambos/DeepTRACE
            %
            %For license and attribution terms, see the LICENSE and NOTICE files.
            %
            %Copyright 2022-2026 Oliver J. Pambos
            %
            %Licensed under the Apache License, Version 2.0 (the "License");
            %you may not use this file except in compliance with the License.
            %You may obtain a copy of the License at
            %
            %   http://www.apache.org/licenses/LICENSE-2.0
            %
            %Unless required by applicable law or agreed to in writing, software
            %distributed under the License is distributed on an "AS IS" BASIS,
            %WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
            %See the License for the specific language governing permissions and
            %limitations under the License.
            
            app.mainapp = mainapp;
            app.expandAllNodes(app.feature_tree);   %pass root node of tree to expand it, and all children of the root node
            
            %move the prompt to be in the centre of wherever the main GUI is
            app.FeatureEngineeringMenuUIFigure.Position(1) = (app.mainapp.DeepTRACEUIFigure.Position(1) + (app.mainapp.DeepTRACEUIFigure.Position(3)/2)) - ((app.FeatureEngineeringMenuUIFigure.Position(3)/2));
            app.FeatureEngineeringMenuUIFigure.Position(2) = (app.mainapp.DeepTRACEUIFigure.Position(2) + (app.mainapp.DeepTRACEUIFigure.Position(4)/2)) - ((app.FeatureEngineeringMenuUIFigure.Position(4)/2));

            %add arbitrary features node if arbitrary feature columns are present
            arbitrary_cols = app.mainapp.movie_data.params.arbitrary_feature_cols;
            
            if ~isempty(arbitrary_cols)
                arb_node = uitreenode(app.AlloptionalfeaturesNode);
                arb_node.Text = 'Arbitrary features';
            
                %col_titles = [app.mainapp.movie_data.params.column_titles.tracks, app.mainapp.movie_data.params.arbitrary_features];
                for ii = 1:numel(app.mainapp.movie_data.params.arbitrary_features)
                    label = app.mainapp.movie_data.params.arbitrary_features{ii};
                    feature_node = uitreenode(arb_node);
                    feature_node.Text = label;
                end

                app.expandAllNodes(arb_node);
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            %retrieve all checked nodes, and extract text of all checked nodes
            checked_nodes = app.feature_tree.CheckedNodes;
            app.selected_features = arrayfun(@(node) node.Text, checked_nodes, 'UniformOutput', false);
            
            %strip top groups of tree nodes (e.g. static features, rolling features, etc.) from list of features to engineer
            features_to_remove = {
                'All optional features', ...
                'Static features', ...
                'Local window features', ...
                'Paired window features', ...
                'Arbitrary features'};
            app.selected_features = setdiff(app.selected_features, features_to_remove, 'stable');
            
            %write selected_features into main app's app.movie_data.state substruct
            if isvalid(app.mainapp)
                app.mainapp.movie_data.state.selected_features = app.selected_features;
            end
            
            %close 
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            %return an empty cell array if user presses cancel, regardless of any boxes that may have been checked
            app.mainapp.movie_data.state.selected_features = {};
            
            %close the feature selection menu
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FeatureEngineeringMenuUIFigure = uifigure('Visible', 'off');
            app.FeatureEngineeringMenuUIFigure.Name = 'Feature Engineering Menu';
            app.FeatureEngineeringMenuUIFigure.Icon = 'DeepTRACE logo.png';
            app.GridLayout = uigridlayout(app.FeatureEngineeringMenuUIFigure);
            app.feature_tree = uitree(app.GridLayout, 'checkbox');
            app.AlloptionalfeaturesNode = uitreenode(app.feature_tree);
            app.AlloptionalfeaturesNode.Text = 'All optional features';
            app.StaticfeaturesNode = uitreenode(app.AlloptionalfeaturesNode);
            app.StaticfeaturesNode.Text = 'Static features';
            app.StepsizeNode = uitreenode(app.StaticfeaturesNode);
            app.StepsizeNode.Text = 'Step size';
            app.SmoothedstepsizeNode = uitreenode(app.StaticfeaturesNode);
            app.SmoothedstepsizeNode.Text = 'Smoothed step size';
            app.TimestepfrompreviouslocalisationNode = uitreenode(app.StaticfeaturesNode);
            app.TimestepfrompreviouslocalisationNode.Text = 'Time step from previous localisation';
            app.TimesincestartofexperimentNode = uitreenode(app.StaticfeaturesNode);
            app.TimesincestartofexperimentNode.Text = 'Time since start of experiment';
            app.SetexperimentreferencetimepointsNode = uitreenode(app.StaticfeaturesNode);
            app.SetexperimentreferencetimepointsNode.Text = 'Set experiment reference timepoints';
            app.CurrentdistancefromstartoftrackNode = uitreenode(app.StaticfeaturesNode);
            app.CurrentdistancefromstartoftrackNode.Text = 'Current distance from start of track';
            app.CumulativedistancetravelledNode = uitreenode(app.StaticfeaturesNode);
            app.CumulativedistancetravelledNode.Text = 'Cumulative distance travelled';
            app.TimeshiftedstepsizesNode = uitreenode(app.StaticfeaturesNode);
            app.TimeshiftedstepsizesNode.Text = 'Time shifted step sizes';
            app.DistancetocellmembraneNode = uitreenode(app.StaticfeaturesNode);
            app.DistancetocellmembraneNode.Text = 'Distance to cell membrane';
            app.DistancetonearestcellpoleNode = uitreenode(app.StaticfeaturesNode);
            app.DistancetonearestcellpoleNode.Text = 'Distance to nearest cell pole';
            app.SpotsizeNode = uitreenode(app.StaticfeaturesNode);
            app.SpotsizeNode.Text = 'Spot size';
            app.SpotareaNode = uitreenode(app.StaticfeaturesNode);
            app.SpotareaNode.Text = 'Spot area';
            app.StepanglerelativetopreviousstepNode = uitreenode(app.StaticfeaturesNode);
            app.StepanglerelativetopreviousstepNode.Text = 'Step angle relative to previous step';
            app.StepanglerelativetofieldofviewNode = uitreenode(app.StaticfeaturesNode);
            app.StepanglerelativetofieldofviewNode.Text = 'Step angle relative to field of view';
            app.StepanglerelativetocellmajoraxisNode = uitreenode(app.StaticfeaturesNode);
            app.StepanglerelativetocellmajoraxisNode.Text = 'Step angle relative to cell major axis';
            app.ProximitytoendsoftrackNode = uitreenode(app.StaticfeaturesNode);
            app.ProximitytoendsoftrackNode.Text = 'Proximity to ends of track';
            app.LocalwindowfeaturesNode = uitreenode(app.AlloptionalfeaturesNode);
            app.LocalwindowfeaturesNode.Text = 'Local window features';
            app.LocaltrappednessNode = uitreenode(app.LocalwindowfeaturesNode);
            app.LocaltrappednessNode.Text = 'Local trappedness';
            app.LocaldiffusioncoefficientNode = uitreenode(app.LocalwindowfeaturesNode);
            app.LocaldiffusioncoefficientNode.Text = 'Local diffusion coefficient';
            app.LocalanomalousdiffusionexponentNode = uitreenode(app.LocalwindowfeaturesNode);
            app.LocalanomalousdiffusionexponentNode.Text = 'Local anomalous diffusion exponent';
            app.LocalefficiencyNode = uitreenode(app.LocalwindowfeaturesNode);
            app.LocalefficiencyNode.Text = 'Local efficiency';
            app.LocalstraightnessNode = uitreenode(app.LocalwindowfeaturesNode);
            app.LocalstraightnessNode.Text = 'Local straightness';
            app.LocalkurtosisNode = uitreenode(app.LocalwindowfeaturesNode);
            app.LocalkurtosisNode.Text = 'Local kurtosis';
            app.LocalstepsizekurtosisNode = uitreenode(app.LocalwindowfeaturesNode);
            app.LocalstepsizekurtosisNode.Text = 'Local step size kurtosis';
            app.LocalfractaldimensionNode = uitreenode(app.LocalwindowfeaturesNode);
            app.LocalfractaldimensionNode.Text = 'Local fractal dimension';
            app.LocalstepangleasymmetryNode = uitreenode(app.LocalwindowfeaturesNode);
            app.LocalstepangleasymmetryNode.Text = 'Local step angle asymmetry';
            app.LocalvelocityautocorrelationfunctionNode = uitreenode(app.LocalwindowfeaturesNode);
            app.LocalvelocityautocorrelationfunctionNode.Text = 'Local velocity autocorrelation function';
            app.LocalmaximalexcursionNode = uitreenode(app.LocalwindowfeaturesNode);
            app.LocalmaximalexcursionNode.Text = 'Local maximal excursion';
            app.PairedwindowfeaturesNode = uitreenode(app.AlloptionalfeaturesNode);
            app.PairedwindowfeaturesNode.Text = 'Paired window features';
            app.LocalchangeindiffusioncoefficientNode = uitreenode(app.PairedwindowfeaturesNode);
            app.LocalchangeindiffusioncoefficientNode.Text = 'Local change in diffusion coefficient';
            app.LocalchangeinmeanstepsizeNode = uitreenode(app.PairedwindowfeaturesNode);
            app.LocalchangeinmeanstepsizeNode.Text = 'Local change in mean step size';
            app.LocalchangeinstandarddeviationofstepsizesNode = uitreenode(app.PairedwindowfeaturesNode);
            app.LocalchangeinstandarddeviationofstepsizesNode.Text = 'Local change in standard deviation of step sizes';
            app.LocalchangeinstandarddeviationofpositionsNode = uitreenode(app.PairedwindowfeaturesNode);
            app.LocalchangeinstandarddeviationofpositionsNode.Text = 'Local change in standard deviation of positions';
            app.LocalchangeindispersionofpositionsNode = uitreenode(app.PairedwindowfeaturesNode);
            app.LocalchangeindispersionofpositionsNode.Text = 'Local change in dispersion of positions';
            app.LocaldisplacementincentroidoflocalisationsNode = uitreenode(app.PairedwindowfeaturesNode);
            app.LocaldisplacementincentroidoflocalisationsNode.Text = 'Local displacement in centroid of localisations';
            app.PleaseselecttheoptionalengineeredfeaturestouseLabel = uilabel(app.GridLayout);
            app.PleaseselecttheoptionalengineeredfeaturestouseLabel.Text = {'Please select the optional engineered features from the menu below.'; ''; 'For a full description of each feature, and advice on which may be most useful for your application, please consult the user manual and publication.'; ''; 'The features you select below will be available for all downstream analysis.'; ''; 'Selecting all features can help evaluate which you find most useful, however this will require additional time to complete the feature engineering procedure and require further user input.'};
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.FeatureEngineeringMenuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FeatureEngineeringMenu(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FeatureEngineeringMenuUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FeatureEngineeringMenuUIFigure)
        end
    end
end