classdef FileOverviewPopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FileOverviewUIFigure        matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Image                       matlab.ui.control.Image
        DeepTRACEFileOverviewLabel  matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
    end

    
    properties (Access = private)
        mainapp;    %handle to main DeepTRACE GUI calling this pop-up
    end
    
    methods (Access = public)
        
        function [] = setMainApp(app, mainapp)
            app.mainapp = mainapp;   %DeepTRACE main GUI passed to handles
            
            startupFcn(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %AUTHOR: OLIVER JAMES PAMBOS, DEPARTMENT OF PHYSICS, UNIVERSITY OF OXFORD
            %CONTACT: oliver.pambos@physics.ox.ac.uk
            %
            %ATTRIBUTION AND DISCLAIMER
            %This code was conceived and developed entirely by Oliver James Pambos, and
            %is distributed as part of DeepTRACE.
            %
            %If this code contributes to results presented in a scientific publication,
            %the following article should be cited:
            %
            %   https://doi.org/10.1101/2025.05.15.654348
            %
            %The publicly available version of DeepTRACE, including documentation and
            %updates, is available at:
            %
            %   https://github.com/opambos/DeepTRACE
            %
            %For full license, attribution, and citation terms, see the LICENSE and
            %NOTICE files distributed with DeepTRACE.
            %
            %Copyright 2022-2026 Oliver James Pambos
            %
            %Licensed under the Apache License, Version 2.0 (the "License");
            %you may not use this file except in compliance with the License.
            %You may obtain a copy of the License at
            %
            %   http://www.apache.org/licenses/LICENSE-2.0
            %
            %Unless required by applicable law or agreed to in writing, software
            %distributed under the License is distributed on an "AS IS" BASIS,
            %WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
            %See the License for the specific language governing permissions and
            %limitations under the License.
            
            if isempty(app.mainapp)
                return;
            end
            
            if isprop(app.mainapp, 'DeepTRACEUIFigure') && isvalid(app.mainapp.DeepTRACEUIFigure)
                main_pos    = app.mainapp.DeepTRACEUIFigure.Position;
                popup_size  = app.FileOverviewUIFigure.Position(3:4);
        
                %compute new centred position
                new_left    = main_pos(1) + (main_pos(3) - popup_size(1)) / 2;
                new_bottom  = main_pos(2) + (main_pos(4) - popup_size(2)) / 2;
        
                %apply position
                app.FileOverviewUIFigure.Position(1:2) = [new_left new_bottom];
            end
            
            %populate the table with information
            table_data = {};
            
            if isprop(app.mainapp, "movie_data") && isfield(app.mainapp.movie_data, "params")
                %check if movie_data and title exist
                if isfield(app.mainapp.movie_data.params, "title")
                    table_data(end+1, :) = {'Dataset name', char(app.mainapp.movie_data.params.title)};
                end
                
                if isfield(app.mainapp.movie_data.params, "user")
                    table_data(end+1, :) = {'Current user', char(app.mainapp.movie_data.params.user)};
                end
                
                if isfield(app.mainapp.movie_data.params, "class_names")
                    table_data(end+1, :) = {'Class names', strjoin(app.mainapp.movie_data.params.class_names, ', ');};
                end
                
                if isfield(app.mainapp.movie_data.params, "pipeline")
                    table_data(end+1, :) = {'Input pipeline', char(app.mainapp.movie_data.params.pipeline)};
                end
                
                if isfield(app.mainapp.movie_data.params, "px_scale")
                    table_data(end+1, :) = {'Pixel scale', char(num2str(app.mainapp.movie_data.params.px_scale) + " nm/pixel")};
                end
                
                if isfield(app.mainapp.movie_data.params, "FOV")
                    table_data(end+1, :) = {'Field of view', char(num2str(app.mainapp.movie_data.params.FOV(1)) + " x " + num2str(app.mainapp.movie_data.params.FOV(1)) + " μm")};
                end
                
                if isfield(app.mainapp.movie_data.params, "frame_rate")
                    table_data(end+1, :) = {'Frame rate', char(num2str(app.mainapp.movie_data.params.frame_rate) + " Hz")};
                end
                
                if isfield(app.mainapp.movie_data.params, "ffPath")
                    table_data(end+1, :) = {'File path', char(app.mainapp.movie_data.params.ffPath)};
                end
                
                if isfield(app.mainapp.movie_data.params, "ffFile")
                    if iscell(app.mainapp.movie_data.params.ffFile)
                        table_data(end+1, :) = {'Fluorescence video files', strjoin(app.mainapp.movie_data.params.ffFile, newline)};
                    else
                        table_data(end+1, :) = {'Fluorescence video files', app.mainapp.movie_data.params.ffFile};
                    end
                end
                
            end
            
            if isprop(app.mainapp, "movie_data") && isfield(app.mainapp.movie_data, "cellROI_data")
                table_data(end+1, :) = {'Number of cells', num2str(numel(app.mainapp.movie_data.cellROI_data))};
            end
            
            if isprop(app.mainapp, "movie_data") && isfield(app.mainapp.movie_data, "results")
                if isfield(app.mainapp.movie_data.results, "GroundTruth") || isfield(app.mainapp.movie_data.results, "VisuallyLabelled") || isfield(app.mainapp.movie_data.results, "BiLSTMLabelled") || isfield(app.mainapp.movie_data.results, "ResAnDi")
                    %construct overview of annotations
                    annotation_str = "";    %"This file contains," + newline;
                    if isfield(app.mainapp.movie_data.results, "GroundTruth")
                        %get the number of at least partially annotated tracks, and write to string
                        N_annotated = sum(cellfun(@(x) any(x.Mol(:,end)~=-1), app.mainapp.movie_data.results.GroundTruth.LabelledMols));
                        annotation_str = annotation_str + num2str(N_annotated);
                        annotation_str = annotation_str + " ground truth labelled tracks" + newline;
                    end
                    
                    if isfield(app.mainapp.movie_data.results, "VisuallyLabelled")
                        %get the number of at least partially annotated tracks, and write to string
                        N_annotated = sum(cellfun(@(x) any(x.Mol(:,end)~=-1), app.mainapp.movie_data.results.VisuallyLabelled.LabelledMols));
                        annotation_str = annotation_str + num2str(N_annotated);
                        annotation_str = annotation_str + " human annotated tracks;" + newline;
                    end
                    
                    if isfield(app.mainapp.movie_data.results, "BiLSTMLabelled")
                        %get the number of at least partially annotated tracks, and write to string
                        N_annotated = sum(cellfun(@(x) any(x.Mol(:,end)~=-1), app.mainapp.movie_data.results.BiLSTMLabelled.LabelledMols));
                        annotation_str = annotation_str + num2str(N_annotated);
                        annotation_str = annotation_str + " DeepTRACE BiLSTM model annotated tracks" + newline;
                    end

                    if isfield(app.mainapp.movie_data.results, "ResAnDi")
                        %get the number of at least partially annotated tracks, and write to string
                        N_annotated = sum(cellfun(@(x) any(x.Mol(:,end)~=-1), app.mainapp.movie_data.results.ResAnDi.LabelledMols));
                        annotation_str = annotation_str + num2str(N_annotated);
                        annotation_str = annotation_str + " ResAnDi2 model annotated tracks" + newline;
                    end
                    
                else
                    annotation_str = "There are currently no annotations.";
                end
                
                %write results to table
                table_data(end+1, :) = {'Annotations', char(annotation_str)};
            end
            
            if isprop(app.mainapp, "movie_data") && isfield(app.mainapp.movie_data, "models") && isfield(app.mainapp.movie_data.models, "current_model")
                table_data(end+1, :) = {'Current loaded model', char(app.mainapp.movie_data.models.current_model)};
            else
                table_data(end+1, :) = {'Current loaded model', 'No currently loaded model'};
            end
            
            %move data into table component
            app.UITable.Data = table_data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FileOverviewUIFigure = uifigure('Visible', 'off');
            app.FileOverviewUIFigure.Name = 'DeepTRACE';
            app.GridLayout = uigridlayout(app.FileOverviewUIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Parameter'; 'Details'};
            app.UITable.RowName = {};
            app.DeepTRACEFileOverviewLabel = uilabel(app.GridLayout);
            app.DeepTRACEFileOverviewLabel.Text = 'DeepTRACE File Overview';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'DeepTRACE logo.png');
            % Show the figure after all components are created
            app.FileOverviewUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FileOverviewPopUp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FileOverviewUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FileOverviewUIFigure)
        end
    end
end