classdef RequestSourceFiles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectSourceFilesUIFigure       matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        BrowseforfluorescencevideofilesButton  matlab.ui.control.Button
        FluorescencevideofilesTextArea  matlab.ui.control.TextArea
        FluorescencevideofilesTextAreaLabel  matlab.ui.control.Label
        InterframetimesSpinner          matlab.ui.control.Spinner
        InterframetimesSpinnerLabel     matlab.ui.control.Label
        FramerateHzSpinner              matlab.ui.control.Spinner
        FramerateHzSpinnerLabel         matlab.ui.control.Label
        ORLabel                         matlab.ui.control.Label
        status                          matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        LocalisationortrackingfilesTextArea  matlab.ui.control.TextArea
        LocalisationortrackingfilesTextAreaLabel  matlab.ui.control.Label
        BrowsefortrackingfilesButton    matlab.ui.control.Button
        SegmentedcellboundariesTextArea  matlab.ui.control.TextArea
        SegmentedcellboundariesTextAreaLabel  matlab.ui.control.Label
        BrowseforsegmentationfileButton  matlab.ui.control.Button
        BrowseforreferenceimageButton   matlab.ui.control.Button
        ReferenceimageegbrightfieldTextArea  matlab.ui.control.TextArea
        ReferenceimageegbrightfieldTextAreaLabel  matlab.ui.control.Label
        InstructionsLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        reference_path      = "";
        segmentation_path   = "";
        tracking_pathnames  = {};
        fluor_path          = "";
        fluor_file          = {};
        frame_rate          = 50;
        frame_offsets       = [];
        frames_per_file     = [];
        success             = false;
        input_pipeline      = "";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, input_pipeline)
            %Author: Oliver J. Pambos, Department of Physics, University of Oxford, UK
            %(oliver.pambos@physics.ox.ac.uk).
            %
            %ATTRIBUTION AND DISCLAIMER
            %This code was conceived and developed by Oliver J. Pambos, and is
            %distributed as part of the single-molecule track analysis software
            %DeepTRACE.
            %
            %For citation of this work, refer to:
            %
            %   Pambos et al., Commun Biol (2026)
            %   https://doi.org/10.1038/s42003-026-09899-y
            %
            %The publicly available version of DeepTRACE, including documentation and
            %updates, is available at:
            %
            %   https://github.com/opambos/DeepTRACE
            %
            %For license and attribution terms, see the LICENSE and NOTICE files.
            %
            %Copyright 2022-2026 Oliver J. Pambos
            %
            %Licensed under the Apache License, Version 2.0 (the "License");
            %you may not use this file except in compliance with the License.
            %You may obtain a copy of the License at
            %
            %   http://www.apache.org/licenses/LICENSE-2.0
            %
            %Unless required by applicable law or agreed to in writing, software
            %distributed under the License is distributed on an "AS IS" BASIS,
            %WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
            %See the License for the specific language governing permissions and
            %limitations under the License.
            
            app.input_pipeline = input_pipeline;

            %update the expected input data type based on the pipeline used
            switch input_pipeline
                case "Picasso"
                    app.LocalisationortrackingfilesTextAreaLabel.Text = "Picasso localisation file (hdf5)";
                    app.BrowsefortrackingfilesButton.Text = "Browse for localisation file(s)";

                case "TrackMate"
                    app.LocalisationortrackingfilesTextAreaLabel.Text = "TrackMate tracking file (.csv)";
                    app.BrowsefortrackingfilesButton.Text = "Browse for tracking file(s)";

                otherwise
                    %leave defaults
                    
            end
        end

        % Button pushed function: BrowseforreferenceimageButton
        function BrowseforreferenceimageButtonPushed(app, event)
            %user provides reference image
            app.status.Text = "Please provide a reference image for the full field of view. Typically this is a phase contrast or brightfield image. " + ...
                "If you do not have one, please provide a single frame image from the fluorescence movie";
            
            [temp_file, temp_path] = uigetfile({'*.tif;*.tiff;*.fits', 'Select a reference image (*.tif, *.tiff, *.fits)'},  'Select a reference image for the field of view');
            if isequal(temp_file, 0) || isequal(temp_path, 0)
                app.BrowseforreferenceimageButton.FontColor = 'r';
                app.status.Text = "No reference image was provided by user. Please try again.";
                return;
            else
                app.ReferenceimageegbrightfieldTextArea.Value = fullfile(temp_path, temp_file);
                app.BrowseforreferenceimageButton.FontColor = 'k';
                app.status.Text = "Reference image has been provided.";
            end
        end

        % Button pushed function: BrowseforsegmentationfileButton
        function BrowseforsegmentationfileButtonPushed(app, event)
            %user provides segmentation file
            app.status.Text = "Please provide a file containing segmented cell boundaries.";
            [temp_file, temp_path] = uigetfile({'*.dat;*.csv;*.mat', 'Segmented cell boundaries (*.dat, *.csv, *.mat)'},  'Select a segmentation file');
            if isequal(temp_file, 0) || isequal(temp_path, 0)
                app.BrowseforsegmentationfileButton.FontColor = 'r';
                app.status.Text = "No segmentation file was provided by user. Please try again.";
                return;
            else
                app.SegmentedcellboundariesTextArea.Value = fullfile(temp_path, temp_file);
                app.BrowseforsegmentationfileButton.FontColor = 'k';
                app.status.Text = "Segmentation file has been provided.";
            end
        end

        % Button pushed function: BrowsefortrackingfilesButton
        function BrowsefortrackingfilesButtonPushed(app, event)
            %prompt user to identify either Picasso localisation data or TrackMate tracking data
            switch app.input_pipeline
                case "Picasso"
                    app.status.Text = "Please select a valid Picasso localisation file(s) (*.hdf5)";
                    [temp_file, temp_path] = uigetfile('*.hdf5', 'Localisation data (*.hdf5)',  'Select a tracking file', 'MultiSelect', 'on');
                    
                case "TrackMate"
                    app.status.Text = "Please select a valid TrackMate tracks file(s) (*.csv)";
                    [temp_file, temp_path] = uigetfile({'*.tracks;*.csv;*.mat', 'Tracking data (*.tracks, *.csv, *.mat)'},  'Select a tracking file', 'MultiSelect', 'on');
                    
                otherwise
                    
            end
            
            if isequal(temp_file, 0) || isequal(temp_path, 0)
                app.BrowsefortrackingfilesButton.FontColor = 'r';
                app.status.Text = "No tracking file was provided by user.";
                return;
            else
                if ischar(temp_file) || isstring(temp_file)
                    temp_file = {temp_file};
                end
                full_paths = fullfile(temp_path, temp_file);

                %confirm and optionally reorder file list (full paths)
                if size(full_paths, 2) > 1
                    full_paths = confirmVideoOrder(full_paths);
                end
                
                %store paths
                app.tracking_pathnames = full_paths;
                
                %display file names without paths
                track_filenames = cell(size(full_paths));
                for ii = 1:numel(full_paths)
                    [~, name, ext] = fileparts(full_paths{ii});
                    track_filenames{ii} = [name ext];
                end
                app.LocalisationortrackingfilesTextArea.Value = track_filenames;
                
                app.BrowsefortrackingfilesButton.FontColor = 'k';
                app.status.Text = "Tracking data has been provided.";
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if strlength(string(app.ReferenceimageegbrightfieldTextArea.Value)) > 0
                app.reference_path = string(app.ReferenceimageegbrightfieldTextArea.Value{1});
            else
                uialert(app.SelectSourceFilesUIFigure, "You have not provided a reference image!", "No reference image");
                app.BrowseforreferenceimageButton.FontColor = 'r';
                return;
            end
    
            if strlength(string(app.SegmentedcellboundariesTextArea.Value)) > 0
                app.segmentation_path = string(app.SegmentedcellboundariesTextArea.Value{1});
            else
                uialert(app.SelectSourceFilesUIFigure, "You have not provided a segmentation file!", "No segmentation file");
                app.BrowseforsegmentationfileButton.FontColor = 'r';
                return;
            end
            
            if ~isempty(app.tracking_pathnames)
                %already stored as full path(s) during browse
            else
                uialert(app.SelectSourceFilesUIFigure, "You have not provided a tracking file!", "No tracking file");
                app.BrowsefortrackingfilesButton.FontColor = 'r';
                return;
            end
            
            if ~isempty(app.FramerateHzSpinner.Value)
                app.frame_rate = app.FramerateHzSpinner.Value;
            else
                uialert(app.SelectSourceFilesUIFigure, "You have not provided a frame rate!", "No frame rate");
                return;
            end
            
            %check user supplied fluor files
            if app.fluor_path == "" || isempty(app.fluor_file)
                uialert(app.SelectSourceFilesUIFigure, "You have not provided fluorescence video file(s)!", "No fluorescence videos");
                app.BrowseforfluorescencevideofilesButton.FontColor = 'r';
                return;
            end
            
            %check there is one tracks file for each fluor file
            if numel(app.fluor_file) ~= numel(app.tracking_pathnames)
                uialert(app.SelectSourceFilesUIFigure, "The number of fluorescence video files must match the number of tracking files.", "File count mismatch");
                return;
            end
            
            %compute frame offsets
            [metadata_frame_rate, temp_frames_per_file, temp_frame_offsets, frame_offsets_exist] = computeFrameOffsets(app.fluor_path, app.fluor_file);
            if ~frame_offsets_exist
                uialert(app.SelectSourceFilesUIFigure, "Could not compute frame offsets from the selected fluorescence video file(s).", "Frame offset error");
                return;
            end
            %write to handles if valid
            app.frames_per_file = temp_frames_per_file;
            app.frame_offsets   = temp_frame_offsets;
            
            % %populate the frame rate and interframe time spinners if they can be read from metadata - needs to be moved to fluor file reading
            % if ~isnan(metadata_frame_rate)
            %     app.frame_rate = metadata_frame_rate;
            %     app.FramerateHzSpinner.Value = metadata_frame_rate;
            %     app.InterframetimesSpinner.Value = 1/metadata_frame_rate;
            % end
            
            %user finished successfully
            app.success = true;
            
            %release uiwait in calling code
            uiresume(app.SelectSourceFilesUIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.success = false;
            uiresume(app.SelectSourceFilesUIFigure);
        end

        % Button pushed function: BrowseforfluorescencevideofilesButton
        function BrowseforfluorescencevideofilesButtonPushed(app, event)
            %prompt user to input fluorescence video data
            
            %prompt user to identify either Picasso localisation data or TrackMate tracking data
            switch app.input_pipeline
                case "Picasso"
                    app.status.Text = "Please select a valid fluorescence video file(s) (e.g. *.fits, *.tif)";
                    [temp_file, temp_path] = uigetfile({'*.tif;*.tiff', 'Fluorescence video data (*.tif, *.tiff)'},  'Select fluorescence video file(s)', 'MultiSelect', 'on');

                case "TrackMate"
                    app.status.Text = "Please select a valid fluorescence video file(s) (e.g. *.fits, *.tif)";
                    [temp_file, temp_path] = uigetfile({'*.fits;*.tif;*.tiff', 'Fluorescence video data (*.fits, *.tif, *.tiff)'},  'Select fluorescence video file(s)', 'MultiSelect', 'on');
                otherwise
                    
            end
            
            if isequal(temp_file, 0) || isequal(temp_path, 0)
                app.BrowseforfluorescencevideofilesButton.FontColor = 'r';
                app.status.Text = "No fluorescence video file was provided by user.";
                return;
            else
                if ischar(temp_file) || isstring(temp_file)
                    temp_file = {temp_file};
                end
                full_paths = fullfile(temp_path, temp_file);
                
                %confirm and optionally reorder file list (full paths)
                if size(full_paths, 2) > 1
                    full_paths = confirmVideoOrder(full_paths);
                end
                
                %store fluorescence video path
                app.fluor_path = string(temp_path);
                
                %convert ordered full paths back into filenames
                fluor_files = cell(1, numel(full_paths));
                for ii = 1:numel(full_paths)
                    [~, name, ext] = fileparts(full_paths{ii});
                    fluor_files{ii} = [name ext];
                end
                
                app.fluor_file = fluor_files;
                app.FluorescencevideofilesTextArea.Value = app.fluor_file;
                
                app.BrowseforfluorescencevideofilesButton.FontColor = 'k';
                app.status.Text = "Fluorescence video data has been provided.";
            end
        end

        % Callback function: FramerateHzSpinner, FramerateHzSpinner
        function FramerateHzSpinnerValueChanged(app, event)
            app.InterframetimesSpinner.Value = 1/app.FramerateHzSpinner.Value;
        end

        % Value changed function: InterframetimesSpinner
        function InterframetimesSpinnerValueChanged(app, event)
            app.FramerateHzSpinner.Value = 1/app.InterframetimesSpinner.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectSourceFilesUIFigure = uifigure('Visible', 'off');
            app.SelectSourceFilesUIFigure.Name = 'Select Source Files';
            app.SelectSourceFilesUIFigure.Icon = 'DeepTRACE logo.png';
            app.GridLayout = uigridlayout(app.SelectSourceFilesUIFigure);
            app.InstructionsLabel = uilabel(app.GridLayout);
            app.InstructionsLabel.Text = 'Please indentify the files containing the data to be analysed. You must here provide the reference image, segmentation file, tracking file(s), and fluorescence video(s) for a single experiment. When selecting multiple files, ensure that they are contiguous recordings (i.e. each file follows directly from the previous one).';
            app.ReferenceimageegbrightfieldTextAreaLabel = uilabel(app.GridLayout);
            app.ReferenceimageegbrightfieldTextAreaLabel.Text = 'Reference image (e.g. brightfield)';
            app.ReferenceimageegbrightfieldTextArea = uitextarea(app.GridLayout);
            app.BrowseforreferenceimageButton = uibutton(app.GridLayout, 'push');
            app.BrowseforreferenceimageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseforreferenceimageButtonPushed, true);
            app.BrowseforreferenceimageButton.Text = 'Browse for reference image';
            app.BrowseforsegmentationfileButton = uibutton(app.GridLayout, 'push');
            app.BrowseforsegmentationfileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseforsegmentationfileButtonPushed, true);
            app.BrowseforsegmentationfileButton.Text = 'Browse for segmentation file';
            app.SegmentedcellboundariesTextAreaLabel = uilabel(app.GridLayout);
            app.SegmentedcellboundariesTextAreaLabel.Text = 'Segmented cell boundaries';
            app.SegmentedcellboundariesTextArea = uitextarea(app.GridLayout);
            app.BrowsefortrackingfilesButton = uibutton(app.GridLayout, 'push');
            app.BrowsefortrackingfilesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsefortrackingfilesButtonPushed, true);
            app.BrowsefortrackingfilesButton.Text = 'Browse for tracking file(s)';
            app.LocalisationortrackingfilesTextAreaLabel = uilabel(app.GridLayout);
            app.LocalisationortrackingfilesTextAreaLabel.Text = 'Localisation or tracking file(s)';
            app.LocalisationortrackingfilesTextArea = uitextarea(app.GridLayout);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.status = uilabel(app.GridLayout);
            app.status.Text = '';
            app.ORLabel = uilabel(app.GridLayout);
            app.ORLabel.Text = '       OR';
            app.FramerateHzSpinnerLabel = uilabel(app.GridLayout);
            app.FramerateHzSpinnerLabel.Text = 'Frame rate (Hz)';
            app.FramerateHzSpinner = uispinner(app.GridLayout);
            app.FramerateHzSpinner.ValueChangingFcn = createCallbackFcn(app, @FramerateHzSpinnerValueChanged, true);
            app.FramerateHzSpinner.Limits = [1e-05 Inf];
            app.FramerateHzSpinner.ValueChangedFcn = createCallbackFcn(app, @FramerateHzSpinnerValueChanged, true);
            app.FramerateHzSpinner.Value = 20;
            app.InterframetimesSpinnerLabel = uilabel(app.GridLayout);
            app.InterframetimesSpinnerLabel.Text = 'Inter-frame time (s)';
            app.InterframetimesSpinner = uispinner(app.GridLayout);
            app.InterframetimesSpinner.Step = 0.05;
            app.InterframetimesSpinner.Limits = [5e-06 Inf];
            app.InterframetimesSpinner.ValueDisplayFormat = '%11.6g';
            app.InterframetimesSpinner.ValueChangedFcn = createCallbackFcn(app, @InterframetimesSpinnerValueChanged, true);
            app.InterframetimesSpinner.Value = 0.05;
            app.FluorescencevideofilesTextAreaLabel = uilabel(app.GridLayout);
            app.FluorescencevideofilesTextAreaLabel.Text = 'Fluorescence video file(s)';
            app.FluorescencevideofilesTextArea = uitextarea(app.GridLayout);
            app.BrowseforfluorescencevideofilesButton = uibutton(app.GridLayout, 'push');
            app.BrowseforfluorescencevideofilesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseforfluorescencevideofilesButtonPushed, true);
            app.BrowseforfluorescencevideofilesButton.Text = 'Browse for fluorescence video file(s)';
            % Show the figure after all components are created
            app.SelectSourceFilesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RequestSourceFiles(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectSourceFilesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectSourceFilesUIFigure)
        end
    end
end