classdef SelectTrainingFilesPopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectTrainingDataUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        RemoveafileButton           matlab.ui.control.Button
        PleaseselectfilescontainingtrainingdataLabel  matlab.ui.control.Label
        LoadedFiles                 matlab.ui.control.ListBox
        DoneButton                  matlab.ui.control.Button
        LoadnewfileButton           matlab.ui.control.Button
    end

    properties (Access = private)
        mainapp;    %main GUI app handles
        file_list;  %cell array with two columns,
                        %col1: entire pathname of file
                        %col2: file name for display and selection in listbox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %Author: Oliver J. Pambos, Department of Physics, University of Oxford, UK
            %(oliver.pambos@physics.ox.ac.uk).
            %
            %ATTRIBUTION AND DISCLAIMER
            %This code was conceived and developed by Oliver J. Pambos, and is
            %distributed as part of the single-molecule track analysis software
            %DeepTRACE.
            %
            %For citation of this work, refer to:
            %
            %   Pambos et al., Commun Biol (2026)
            %   https://doi.org/10.1038/s42003-026-09899-y
            %
            %The publicly available version of DeepTRACE, including documentation and
            %updates, is available at:
            %
            %   https://github.com/opambos/DeepTRACE
            %
            %For license and attribution terms, see the LICENSE and NOTICE files.
            %
            %Copyright 2022-2026 Oliver J. Pambos
            %
            %Licensed under the Apache License, Version 2.0 (the "License");
            %you may not use this file except in compliance with the License.
            %You may obtain a copy of the License at
            %
            %   http://www.apache.org/licenses/LICENSE-2.0
            %
            %Unless required by applicable law or agreed to in writing, software
            %distributed under the License is distributed on an "AS IS" BASIS,
            %WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
            %See the License for the specific language governing permissions and
            %limitations under the License.
            
            %store handles to main app
            app.mainapp = mainapp;
            
            %move the prompt to be in the centre of wherever the main GUI is
            app.SelectTrainingDataUIFigure.Position(1) = (app.mainapp.DeepTRACEUIFigure.Position(1) + (app.mainapp.DeepTRACEUIFigure.Position(3)/2)) - ((app.SelectTrainingDataUIFigure.Position(3)/2));
            app.SelectTrainingDataUIFigure.Position(2) = (app.mainapp.DeepTRACEUIFigure.Position(2) + (app.mainapp.DeepTRACEUIFigure.Position(4)/2)) - ((app.SelectTrainingDataUIFigure.Position(4)/2));
            
            %ask user if they want to include currently loaded data, if so make a special entry at start of list for this
            choice = uiconfirm(app.SelectTrainingDataUIFigure, 'Do you want to include annotations in the currently loaded data to be included?', ...
                               'Include Current Data', ...
                               'Options', {'Yes', 'No'}, ...
                               'DefaultOption', 1, 'CancelOption', 2);
            if strcmp(choice, 'Yes')
                if isempty(app.file_list)
                    app.file_list = {};
                end
                %insert entry
                app.file_list = [{'[Currently loaded annotations]', 'Annotations from currently loaded data file'}; app.file_list];
                app.LoadedFiles.Items = app.file_list(1, 2);
            end
        end

        % Button pushed function: LoadnewfileButton
        function LoadnewfileButtonPushed(app, event)
            %get external files to append to list of files
            [file, path] = uigetfile({'*.mat', 'Saved analysis file (*.mat)'}, 'Select analysis file with labelled data:', 'MultiSelect', 'on');
            if isequal(file, 0)
                return;
            end
            
            if isempty(app.file_list)
                app.file_list = {};  % Initialize as an empty cell array
            end
            
            %add new files to file list
            if iscell(file)
                %if multiple files
                new_files = cell(size(file, 2), 2);
                for idx = 1:length(file)
                    new_files{idx, 1} = fullfile(path, file{idx});
                    new_files{idx, 2} = file{idx};
                end
                app.file_list = [app.file_list; new_files];
            else
                %if single file
                app.file_list(end+1, :) = {fullfile(path, file), file};
            end
            
            %update listbox with new filenames
            app.LoadedFiles.Items = app.file_list(:, 2);
        end

        % Button pushed function: RemoveafileButton
        function RemoveafileButtonPushed(app, event)
            %get name of the currently selected file from listbox
            selectedFileName = app.LoadedFiles.Value;
            
            if isempty(selectedFileName)
                uialert(app.SelectTrainingDataUIFigure, 'Please select a file to remove.', 'No Selection');
                return;
            end
            
            %find list index of selected file
            selectedIndex = find(strcmp(app.file_list(:, 2), selectedFileName));
            
            %error handling, just in case
            if isempty(selectedIndex)
                uialert(app.SelectTrainingDataUIFigure, 'Invalid selection.', 'Error');
                return;
            end
            
            %check selected index is valid, if so remove file from list
            if selectedIndex > 0 && selectedIndex <= size(app.file_list, 1)
                app.file_list(selectedIndex, :) = [];
                app.LoadedFiles.Items = app.file_list(:, 2);
                
                %reset listbox selection
                if isempty(app.LoadedFiles.Items)
                    app.LoadedFiles.Value = [];
                else
                    app.LoadedFiles.Value = app.LoadedFiles.Items{1};
                end
            else
                %handle out of range error
                uialert(app.SelectTrainingDataUIFigure, 'Selected index is out of range.', 'Error');
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            %write the selected filepaths to the main app, and exit
            app.mainapp.movie_data.params.train_data_source = app.file_list(:,1);
            delete(app.SelectTrainingDataUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectTrainingDataUIFigure = uifigure('Visible', 'off');
            app.SelectTrainingDataUIFigure.Name = 'Select Training Data';
            app.SelectTrainingDataUIFigure.Icon = 'DeepTRACE logo.png';
            app.GridLayout = uigridlayout(app.SelectTrainingDataUIFigure);
            app.LoadnewfileButton = uibutton(app.GridLayout, 'push');
            app.LoadnewfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadnewfileButtonPushed, true);
            app.LoadnewfileButton.Text = 'Load new file';
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.LoadedFiles = uilistbox(app.GridLayout);
            app.LoadedFiles.Items = {};
            app.LoadedFiles.Value = {};
            app.PleaseselectfilescontainingtrainingdataLabel = uilabel(app.GridLayout);
            app.PleaseselectfilescontainingtrainingdataLabel.Text = {'Please select files containing training data'; ''; 'To load a datafile select [Load new file]. Once you have all data selected click [Done]'};
            app.RemoveafileButton = uibutton(app.GridLayout, 'push');
            app.RemoveafileButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveafileButtonPushed, true);
            app.RemoveafileButton.Text = 'Remove a file';
            % Show the figure after all components are created
            app.SelectTrainingDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectTrainingFilesPopUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectTrainingDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectTrainingDataUIFigure)
        end
    end
end