classdef SetWindowSizeStepSmoothingPopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StepSizeSmoothingUIFigure     matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        SmoothingmethodDropDown       matlab.ui.control.DropDown
        SmoothingmethodDropDownLabel  matlab.ui.control.Label
        DontusefeatureButton          matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        WindowsizeframesSpinner       matlab.ui.control.Spinner
        WindowsizeframesSpinnerLabel  matlab.ui.control.Label
        SetwindowsizeforsmoothingSettheframerangeoverwhichLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp; %main GUI app handles
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %Author: Oliver J. Pambos, Department of Physics, University of Oxford, UK
            %(oliver.pambos@physics.ox.ac.uk).
            %
            %ATTRIBUTION AND DISCLAIMER
            %This code was conceived and developed by Oliver J. Pambos, and is
            %distributed as part of the single-molecule track analysis software
            %DeepTRACE.
            %
            %For citation of this work, refer to:
            %
            %   Pambos et al., Commun Biol (2026)
            %   https://doi.org/10.1038/s42003-026-09899-y
            %
            %The publicly available version of DeepTRACE, including documentation and
            %updates, is available at:
            %
            %   https://github.com/opambos/DeepTRACE
            %
            %For license and attribution terms, see the LICENSE and NOTICE files.
            %
            %Copyright 2022-2026 Oliver J. Pambos
            %
            %Licensed under the Apache License, Version 2.0 (the "License");
            %you may not use this file except in compliance with the License.
            %You may obtain a copy of the License at
            %
            %   http://www.apache.org/licenses/LICENSE-2.0
            %
            %Unless required by applicable law or agreed to in writing, software
            %distributed under the License is distributed on an "AS IS" BASIS,
            %WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
            %See the License for the specific language governing permissions and
            %limitations under the License.
            
            app.mainapp = mainapp;  %make main app handles accessible locally
            
            %record the default values
            app.mainapp.movie_data.state.step_smoothing_win_size = app.WindowsizeframesSpinner.Value;
            app.mainapp.movie_data.state.smoothing_method = app.SmoothingmethodDropDown.Value;
            
            %move the prompt to be in the centre of wherever the main GUI is
            app.StepSizeSmoothingUIFigure.Position(1) = (app.mainapp.DeepTRACEUIFigure.Position(1) + (app.mainapp.DeepTRACEUIFigure.Position(3)/2)) - ((app.StepSizeSmoothingUIFigure.Position(3)/2));
            app.StepSizeSmoothingUIFigure.Position(2) = (app.mainapp.DeepTRACEUIFigure.Position(2) + (app.mainapp.DeepTRACEUIFigure.Position(4)/2)) - ((app.StepSizeSmoothingUIFigure.Position(4)/2));
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.mainapp.movie_data.state.step_smoothing_win_size = app.WindowsizeframesSpinner.Value;
            
            delete(SetWindowSizeStepSmoothingPopUp);
        end

        % Button pushed function: DontusefeatureButton
        function DontusefeatureButtonPushed(app, event)
            app.mainapp.movie_data.state.step_smoothing_win_size = 0;
            
            delete(SetWindowSizeStepSmoothingPopUp);
        end

        % Value changed function: SmoothingmethodDropDown
        function SmoothingmethodDropDownValueChanged(app, event)
            app.mainapp.movie_data.state.smoothing_method = app.SmoothingmethodDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StepSizeSmoothingUIFigure = uifigure('Visible', 'off');
            app.StepSizeSmoothingUIFigure.Name = 'Step Size Smoothing';
            app.StepSizeSmoothingUIFigure.Icon = 'DeepTRACE logo.png';
            app.GridLayout = uigridlayout(app.StepSizeSmoothingUIFigure);
            app.SetwindowsizeforsmoothingSettheframerangeoverwhichLabel = uilabel(app.GridLayout);
            app.SetwindowsizeforsmoothingSettheframerangeoverwhichLabel.Text = {'Set window size for smoothing'; ''; 'Set the method and frame range over which smoothing will be applied to smooth the step size distribution.'};
            app.WindowsizeframesSpinnerLabel = uilabel(app.GridLayout);
            app.WindowsizeframesSpinnerLabel.Text = 'Window size (frames)';
            app.WindowsizeframesSpinner = uispinner(app.GridLayout);
            app.WindowsizeframesSpinner.Limits = [1 Inf];
            app.WindowsizeframesSpinner.ValueDisplayFormat = '%.0f';
            app.WindowsizeframesSpinner.Value = 3;
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.DontusefeatureButton = uibutton(app.GridLayout, 'push');
            app.DontusefeatureButton.ButtonPushedFcn = createCallbackFcn(app, @DontusefeatureButtonPushed, true);
            app.DontusefeatureButton.Text = 'Don''t use feature';
            app.SmoothingmethodDropDownLabel = uilabel(app.GridLayout);
            app.SmoothingmethodDropDownLabel.Text = 'Smoothing method';
            app.SmoothingmethodDropDown = uidropdown(app.GridLayout);
            app.SmoothingmethodDropDown.Items = {'Moving mean', 'Moving median', 'Gaussian kernel', 'Local linear regression (LOWESS)', 'Local quadratic regression (LOESS)', 'Robust local linear regression (RLOWESS)', 'Robust local quadratic regression (RLOESS)', 'Savitzky-Golay filter'};
            app.SmoothingmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SmoothingmethodDropDownValueChanged, true);
            app.SmoothingmethodDropDown.Tooltip = {'This is the method used for smoothing your data. Options are as follows.'; ''; 'Moving mean (default): Smooths the data by averaging values within a sliding window.'; ''; 'Moving median: Smooths the data by taking the median value within a sliding window, useful for reducing the impact of outliers.'; ''; 'Gaussian kernel: Smooths the data using a Gaussian filter, giving more weight to nearby points within the window.'; ''; 'Local linear regression (LOWESS): Smooths the data using local linear regression within the local window, reducing discontinuities in the data.'; ''; 'Local quadratic regression (LOESS): Smooths the data using local quadratic regression, allowing for more complex trends in the data.'; ''; 'Robust local linear regression (RLOWESS): Smooths the data using local linear regression with enhanced robustness to outliers.'; ''; 'Robust local quadratic regression (RLOESS): Smooths the data using local quadratic regression with enhanced robustness to outliers.'; ''; 'Savitzky-Golay filter: Smooths the data using the Savitzky-Golay filter, preserving data shape while reducing high-frequency noise.'};
            app.SmoothingmethodDropDown.Value = 'Moving median';
            % Show the figure after all components are created
            app.StepSizeSmoothingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetWindowSizeStepSmoothingPopUp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.StepSizeSmoothingUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.StepSizeSmoothingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StepSizeSmoothingUIFigure)
        end
    end
end