classdef SlidingPairWindowSizePopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SlidingPairWindowSizeFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        OKButton                     matlab.ui.control.Button
        WindowsizeframesSpinner      matlab.ui.control.Spinner
        WindowsizeframesLabel        matlab.ui.control.Label
        Label                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp; %handles of main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %Author: Oliver J. Pambos, Department of Physics, University of Oxford, UK
            %(oliver.pambos@physics.ox.ac.uk).
            %
            %ATTRIBUTION AND DISCLAIMER
            %This code was conceived and developed by Oliver J. Pambos, and is
            %distributed as part of the single-molecule track analysis software
            %DeepTRACE.
            %
            %For citation of this work, refer to:
            %
            %   Pambos et al., Commun Biol (2026)
            %   https://doi.org/10.1038/s42003-026-09899-y
            %
            %The publicly available version of DeepTRACE, including documentation and
            %updates, is available at:
            %
            %   https://github.com/opambos/DeepTRACE
            %
            %For license and attribution terms, see the LICENSE and NOTICE files.
            %
            %Copyright 2022-2026 Oliver J. Pambos
            %
            %Licensed under the Apache License, Version 2.0 (the "License");
            %you may not use this file except in compliance with the License.
            %You may obtain a copy of the License at
            %
            %   http://www.apache.org/licenses/LICENSE-2.0
            %
            %Unless required by applicable law or agreed to in writing, software
            %distributed under the License is distributed on an "AS IS" BASIS,
            %WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
            %See the License for the specific language governing permissions and
            %limitations under the License.
            
            app.mainapp = mainapp;
            app.mainapp.movie_data.state.rolling_delta_win_size = app.WindowsizeframesSpinner.Value;

            %move the prompt to be in the centre of wherever the main GUI is
            app.SlidingPairWindowSizeFigure.Position(1) = (app.mainapp.DeepTRACEUIFigure.Position(1) + (app.mainapp.DeepTRACEUIFigure.Position(3)/2)) - ((app.SlidingPairWindowSizeFigure.Position(3)/2));
            app.SlidingPairWindowSizeFigure.Position(2) = (app.mainapp.DeepTRACEUIFigure.Position(2) + (app.mainapp.DeepTRACEUIFigure.Position(4)/2)) - ((app.SlidingPairWindowSizeFigure.Position(4)/2));
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            delete(app.SlidingPairWindowSizeFigure);
        end

        % Value changed function: WindowsizeframesSpinner
        function WindowsizeframesSpinnerValueChanged(app, event)
            app.mainapp.movie_data.state.rolling_delta_win_size = app.WindowsizeframesSpinner.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SlidingPairWindowSizeFigure = uifigure('Visible', 'off');
            app.SlidingPairWindowSizeFigure.Name = 'Delta Feature Settings';
            app.SlidingPairWindowSizeFigure.Icon = 'DeepTRACE logo.png';
            app.GridLayout = uigridlayout(app.SlidingPairWindowSizeFigure);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Please select a window size for feature engineering of all features that involve comparisons to pairs of sliding windows. The window size here is the number of localisations in each window.';
            app.WindowsizeframesLabel = uilabel(app.GridLayout);
            app.WindowsizeframesLabel.Text = 'Window size (frames)';
            app.WindowsizeframesSpinner = uispinner(app.GridLayout);
            app.WindowsizeframesSpinner.Limits = [2 Inf];
            app.WindowsizeframesSpinner.ValueDisplayFormat = '%.0f';
            app.WindowsizeframesSpinner.ValueChangedFcn = createCallbackFcn(app, @WindowsizeframesSpinnerValueChanged, true);
            app.WindowsizeframesSpinner.Value = 5;
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.SlidingPairWindowSizeFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SlidingPairWindowSizePopUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SlidingPairWindowSizeFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SlidingPairWindowSizeFigure)
        end
    end
end