classdef TrappedConstantsPopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrappedUIFigure       matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        C2Spinner             matlab.ui.control.Spinner
        C2SpinnerLabel        matlab.ui.control.Label
        C1Spinner             matlab.ui.control.Spinner
        C1SpinnerLabel        matlab.ui.control.Label
        DoneButton            matlab.ui.control.Button
        DontusefeatureButton  matlab.ui.control.Button
        SetconstantsforcalculationoftrappednessLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp;    %handles to main app to retrieve/write data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %Author: Oliver J. Pambos, Department of Physics, University of Oxford, UK
            %(oliver.pambos@physics.ox.ac.uk).
            %
            %ATTRIBUTION AND DISCLAIMER
            %This code was conceived and developed by Oliver J. Pambos, and is
            %distributed as part of the single-molecule track analysis software
            %DeepTRACE.
            %
            %For citation of this work, refer to:
            %
            %   Pambos et al., Commun Biol (2026)
            %   https://doi.org/10.1038/s42003-026-09899-y
            %
            %The publicly available version of DeepTRACE, including documentation and
            %updates, is available at:
            %
            %   https://github.com/opambos/DeepTRACE
            %
            %For license and attribution terms, see the LICENSE and NOTICE files.
            %
            %Copyright 2022-2026 Oliver J. Pambos
            %
            %Licensed under the Apache License, Version 2.0 (the "License");
            %you may not use this file except in compliance with the License.
            %You may obtain a copy of the License at
            %
            %   http://www.apache.org/licenses/LICENSE-2.0
            %
            %Unless required by applicable law or agreed to in writing, software
            %distributed under the License is distributed on an "AS IS" BASIS,
            %WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
            %See the License for the specific language governing permissions and
            %limitations under the License.
            
            app.mainapp = mainapp;
            app.mainapp.movie_data.state.trapped_consts = [0, 0];   %set to zero if there is an error
            
            %move the prompt to be in the centre of wherever the main GUI is
            app.TrappedUIFigure.Position(1) = (app.mainapp.DeepTRACEUIFigure.Position(1) + (app.mainapp.DeepTRACEUIFigure.Position(3)/2)) - ((app.TrappedUIFigure.Position(3)/2));
            app.TrappedUIFigure.Position(2) = (app.mainapp.DeepTRACEUIFigure.Position(2) + (app.mainapp.DeepTRACEUIFigure.Position(4)/2)) - ((app.TrappedUIFigure.Position(4)/2));
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.mainapp.movie_data.state.trapped_consts = [app.C1Spinner.Value, app.C2Spinner.Value];
            
            delete(app.TrappedUIFigure);
        end

        % Button pushed function: DontusefeatureButton
        function DontusefeatureButtonPushed(app, event)
            app.mainapp.movie_data.state.trapped_consts = [0, 0];
            
            delete(app.TrappedUIFigure);
        end

        % Value changed function: C1Spinner
        function C1SpinnerValueChanged(app, event)
            app.mainapp.movie_data.state.trapped_consts(1) = app.C1Spinner.Value;
        end

        % Value changed function: C2Spinner
        function C2SpinnerValueChanged(app, event)
            app.mainapp.movie_data.state.trapped_consts(1) = app.C2Spinner.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrappedUIFigure = uifigure('Visible', 'off');
            app.TrappedUIFigure.Name = 'Trappedness';
            app.TrappedUIFigure.Icon = 'DeepTRACE logo.png';
            app.GridLayout = uigridlayout(app.TrappedUIFigure);
            app.SetconstantsforcalculationoftrappednessLabel = uilabel(app.GridLayout);
            app.SetconstantsforcalculationoftrappednessLabel.Text = {'Set constants for calculation of trappedness.'; 'The degree of trappedness is defined by Saxton1993 as,'; 'T = 1 - exp(C1 - C2*(Dτ/(r^2)))'; ''; 'Where the variables are as follows,'; 'D: diffusion coefficient'; 'τ: interframe time'; 'r: a characteristic radius that defines the scale of the trapped region'; ''; 'In the original application of this equation in trapping in very small regions of the plasma membrane the default values (pre-filled below) were useful, however to make this feature extensible to other applications we leave these as user-controllable values. For most fast diffusion applications in the bacterial cytoplasm increasing C1 and decreasing C2 substantially lead to best results. For your specific application you can test values by importing simulations and engineering features with various constants.'};
            app.DontusefeatureButton = uibutton(app.GridLayout, 'push');
            app.DontusefeatureButton.ButtonPushedFcn = createCallbackFcn(app, @DontusefeatureButtonPushed, true);
            app.DontusefeatureButton.Text = 'Don''t use feature';
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.C1SpinnerLabel = uilabel(app.GridLayout);
            app.C1SpinnerLabel.Text = 'C1';
            app.C1Spinner = uispinner(app.GridLayout);
            app.C1Spinner.ValueChangedFcn = createCallbackFcn(app, @C1SpinnerValueChanged, true);
            app.C1Spinner.Value = 0.2048;
            app.C2SpinnerLabel = uilabel(app.GridLayout);
            app.C2SpinnerLabel.Text = 'C2';
            app.C2Spinner = uispinner(app.GridLayout);
            app.C2Spinner.ValueChangedFcn = createCallbackFcn(app, @C2SpinnerValueChanged, true);
            app.C2Spinner.Value = 0.25117;
            % Show the figure after all components are created
            app.TrappedUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrappedConstantsPopUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrappedUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrappedUIFigure)
        end
    end
end