classdef getRNNSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        genRNNSettingsFigure            matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LearningratedropfractionSpinner  matlab.ui.control.Spinner
        LearningratedropfractionSpinnerLabel  matlab.ui.control.Label
        LearningratedropperiodepochsSpinner  matlab.ui.control.Spinner
        LearningratedropperiodepochsSpinnerLabel  matlab.ui.control.Label
        TransitionweightingSpinner      matlab.ui.control.Spinner
        TransitionweightingSpinnerLabel  matlab.ui.control.Label
        LossfunctionDropDown            matlab.ui.control.DropDown
        LossfunctionDropDownLabel       matlab.ui.control.Label
        DropoutbetweenlayersSpinner     matlab.ui.control.Spinner
        DropoutbetweenlayersSpinnerLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SaveconfigurationfileButton     matlab.ui.control.Button
        BiasL2factorSpinner             matlab.ui.control.Spinner
        BiasL2factorSpinnerLabel        matlab.ui.control.Label
        RecurrentweightsL2Spinner       matlab.ui.control.Spinner
        RecurrentweightsL2SpinnerLabel  matlab.ui.control.Label
        InputweightsL2Spinner           matlab.ui.control.Spinner
        InputweightsL2SpinnerLabel      matlab.ui.control.Label
        PostRNNdropoutSpinner           matlab.ui.control.Spinner
        PostRNNdropoutSpinnerLabel      matlab.ui.control.Label
        ValidationpatienceSpinner       matlab.ui.control.Spinner
        ValidationpatienceSpinnerLabel  matlab.ui.control.Label
        LoadconfigurationfileButton     matlab.ui.control.Button
        InitiallearningrateSpinner      matlab.ui.control.Spinner
        InitiallearningrateSpinnerLabel  matlab.ui.control.Label
        NumberofattentionheadsSpinner   matlab.ui.control.Spinner
        NumberofattentionheadsSpinnerLabel  matlab.ui.control.Label
        AttentionCheckBox               matlab.ui.control.CheckBox
        ValidationintervaliterationsSpinner  matlab.ui.control.Spinner
        ValidationintervaliterationsSpinnerLabel  matlab.ui.control.Label
        BatchsizeSpinner                matlab.ui.control.Spinner
        BatchsizeSpinnerLabel           matlab.ui.control.Label
        TrainingsettingsLabel           matlab.ui.control.Label
        ModelsettingsLabel              matlab.ui.control.Label
        MaximumepochsSpinner            matlab.ui.control.Spinner
        MaximumepochsSpinnerLabel       matlab.ui.control.Label
        NumberofrecurrentlayersSpinner  matlab.ui.control.Spinner
        NumberofrecurrentlayersSpinnerLabel  matlab.ui.control.Label
        NumberofhiddenunitsperlayerSpinner  matlab.ui.control.Spinner
        NumberofhiddenunitsperlayerSpinnerLabel  matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        StatusBar                       matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        PleaseentersettingsusedtotraintheRNNmodelLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp;
    end
    
    methods (Access = private)
        
        function [] = updateModelSize(app)
            %compute model size, and display this to the user
            N_features  = numel(app.mainapp.movie_data.models.temp_params.feature_names);
            N_units     = app.NumberofhiddenunitsperlayerSpinner.Value;
            N_layers    = app.NumberofrecurrentlayersSpinner.Value;
            N_classes   = numel(app.mainapp.movie_data.params.class_names);
            attn        = app.AttentionCheckBox.Value;
            model_type  = app.mainapp.ModeltypeDropDown.Value;
            
            model_size = calcModelSize(N_features, N_units, N_layers, N_classes, attn, model_type);
            
            app.StatusBar.Text = "The model has an estimated size of " + num2str(model_size) + " parameters.";
        end
        
        function [] = updateStateSubstruct(app)
            %add the parameters to the main app's 'state' substruct
            app.mainapp.movie_data.state.RNN.N_units                = app.NumberofhiddenunitsperlayerSpinner.Value;
            app.mainapp.movie_data.state.RNN.N_layers               = app.NumberofrecurrentlayersSpinner.Value;
            app.mainapp.movie_data.state.RNN.interlayer_dropout     = app.DropoutbetweenlayersSpinner.Value;
            app.mainapp.movie_data.state.RNN.attn                   = app.AttentionCheckBox.Value;
            app.mainapp.movie_data.state.RNN.N_heads                = app.NumberofattentionheadsSpinner.Value;
            app.mainapp.movie_data.state.RNN.post_RNN_dropout       = app.PostRNNdropoutSpinner.Value;
            app.mainapp.movie_data.state.RNN.input_weights_L2       = app.InputweightsL2Spinner.Value;
            app.mainapp.movie_data.state.RNN.recurrent_weights_L2   = app.RecurrentweightsL2Spinner.Value;
            app.mainapp.movie_data.state.RNN.bias_L2                = app.BiasL2factorSpinner.Value;
            app.mainapp.movie_data.state.training.max_epochs        = app.MaximumepochsSpinner.Value;
            app.mainapp.movie_data.state.training.batch_size        = app.BatchsizeSpinner.Value;
            app.mainapp.movie_data.state.training.val_interval      = app.ValidationintervaliterationsSpinner.Value;
            app.mainapp.movie_data.state.training.val_patience      = app.ValidationpatienceSpinner.Value;
            app.mainapp.movie_data.state.training.learn_rate        = app.InitiallearningrateSpinner.Value;
            app.mainapp.movie_data.state.training.loss_fn           = app.LossfunctionDropDown.Value;
            app.mainapp.movie_data.state.training.transition_weight = app.TransitionweightingSpinner.Value;
            app.mainapp.movie_data.state.training.LR_drop_period    = app.LearningratedropperiodepochsSpinner.Value;
            app.mainapp.movie_data.state.training.LR_drop_fraction  = app.LearningratedropfractionSpinner.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %Author: Oliver J. Pambos, Department of Physics, University of Oxford, UK
            %(oliver.pambos@physics.ox.ac.uk).
            %
            %ATTRIBUTION AND DISCLAIMER
            %This code was conceived and developed by Oliver J. Pambos, and is
            %distributed as part of the single-molecule track analysis software
            %DeepTRACE.
            %
            %For citation of this work, refer to:
            %
            %   Pambos et al., Commun Biol (2026)
            %   https://doi.org/10.1038/s42003-026-09899-y
            %
            %The publicly available version of DeepTRACE, including documentation and
            %updates, is available at:
            %
            %   https://github.com/opambos/DeepTRACE
            %
            %For license and attribution terms, see the LICENSE and NOTICE files.
            %
            %Copyright 2022-2026 Oliver J. Pambos
            %
            %Licensed under the Apache License, Version 2.0 (the "License");
            %you may not use this file except in compliance with the License.
            %You may obtain a copy of the License at
            %
            %   http://www.apache.org/licenses/LICENSE-2.0
            %
            %Unless required by applicable law or agreed to in writing, software
            %distributed under the License is distributed on an "AS IS" BASIS,
            %WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.
            %See the License for the specific language governing permissions and
            %limitations under the License.
            
            %store the main app's GUI handle for data access in this app
            app.mainapp = mainapp;
            
            %move the prompt to be in the centre of wherever the main GUI is
            app.genRNNSettingsFigure.Position(1) = (app.mainapp.DeepTRACEUIFigure.Position(1) + (app.mainapp.DeepTRACEUIFigure.Position(3)/2)) - ((app.genRNNSettingsFigure.Position(3)/2));
            app.genRNNSettingsFigure.Position(2) = (app.mainapp.DeepTRACEUIFigure.Position(2) + (app.mainapp.DeepTRACEUIFigure.Position(4)/2)) - ((app.genRNNSettingsFigure.Position(4)/2));
            
            %update the contents of the state substruct
            updateStateSubstruct(app);
            
            %update the model size estimation
            updateModelSize(app);
        end

        % Value changed function: NumberofhiddenunitsperlayerSpinner
        function NumberofhiddenunitsperlayerSpinnerValueChanged(app, event)
            updateModelSize(app);
        end

        % Value changed function: NumberofrecurrentlayersSpinner
        function NumberofrecurrentlayersSpinnerValueChanged(app, event)
            updateModelSize(app);
        end

        % Value changed function: AttentionCheckBox
        function AttentionCheckBoxValueChanged(app, event)
            updateModelSize(app);
        end

        % Value changed function: NumberofattentionheadsSpinner
        function NumberofattentionheadsSpinnerValueChanged(app, event)
            updateModelSize(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            %write latest changes to 'state' substruct, and close figure
            updateStateSubstruct(app);
            app.mainapp.movie_data.state.training.continue = true;
            delete(app.genRNNSettingsFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            %record that user does not wish to continue, then close figure
            app.mainapp.movie_data.state.training.continue = false;
            delete(app.genRNNSettingsFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.genRNNSettingsFigure = uifigure('Visible', 'off');
            app.genRNNSettingsFigure.Name = 'Recurrent Neural Network Settings';
            app.genRNNSettingsFigure.Icon = 'DeepTRACE logo.png';
            app.GridLayout = uigridlayout(app.genRNNSettingsFigure);
            app.PleaseentersettingsusedtotraintheRNNmodelLabel = uilabel(app.GridLayout);
            app.PleaseentersettingsusedtotraintheRNNmodelLabel.Text = 'Please enter settings used to train the RNN model';
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.StatusBar = uilabel(app.GridLayout);
            app.StatusBar.Text = '';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.NumberofhiddenunitsperlayerSpinnerLabel = uilabel(app.GridLayout);
            app.NumberofhiddenunitsperlayerSpinnerLabel.Text = 'Number of hidden units per layer';
            app.NumberofhiddenunitsperlayerSpinner = uispinner(app.GridLayout);
            app.NumberofhiddenunitsperlayerSpinner.Limits = [1 Inf];
            app.NumberofhiddenunitsperlayerSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofhiddenunitsperlayerSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofhiddenunitsperlayerSpinnerValueChanged, true);
            app.NumberofhiddenunitsperlayerSpinner.Tooltip = {'Sets the number of neurons in each recurrent layer. More units can capture complex patterns but increases training time, and may lead to overfitting in which the model learns the specifics of the training data rather than the underlying general rules which allow it to apply more broadly to unseen data. 15 - 30 units is suitable for capturing simple dependencies in straightforward tasks; larger values are only necessary when attempting to identify complex, multi-feature events.'};
            app.NumberofhiddenunitsperlayerSpinner.Value = 100;
            app.NumberofrecurrentlayersSpinnerLabel = uilabel(app.GridLayout);
            app.NumberofrecurrentlayersSpinnerLabel.Text = 'Number of recurrent layers';
            app.NumberofrecurrentlayersSpinner = uispinner(app.GridLayout);
            app.NumberofrecurrentlayersSpinner.Limits = [1 5];
            app.NumberofrecurrentlayersSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofrecurrentlayersSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofrecurrentlayersSpinnerValueChanged, true);
            app.NumberofrecurrentlayersSpinner.Tooltip = {'Specifies the number of stacked recurrent layers. 1-2 layers are suitable for most tasks. Adding more layers can help the model to understand more complex relationships but will make training slower and may be more prone to overfitting.'};
            app.NumberofrecurrentlayersSpinner.Value = 1;
            app.MaximumepochsSpinnerLabel = uilabel(app.GridLayout);
            app.MaximumepochsSpinnerLabel.Text = 'Maximum epochs';
            app.MaximumepochsSpinner = uispinner(app.GridLayout);
            app.MaximumepochsSpinner.Limits = [1 Inf];
            app.MaximumepochsSpinner.ValueDisplayFormat = '%.0f';
            app.MaximumepochsSpinner.Tooltip = {'Sets the upper limit on training cycles. Each epoch represents a single pass of the entire dataset to train the model. Use 10-50 epochs for small datasets and monitor validation loss for early stopping. More epochs may improve results but increase the risk of overfitting.'};
            app.MaximumepochsSpinner.Value = 100;
            app.ModelsettingsLabel = uilabel(app.GridLayout);
            app.ModelsettingsLabel.Text = 'Model settings';
            app.TrainingsettingsLabel = uilabel(app.GridLayout);
            app.TrainingsettingsLabel.Text = 'Training settings';
            app.BatchsizeSpinnerLabel = uilabel(app.GridLayout);
            app.BatchsizeSpinnerLabel.Text = 'Batch size';
            app.BatchsizeSpinner = uispinner(app.GridLayout);
            app.BatchsizeSpinner.Step = 8;
            app.BatchsizeSpinner.Limits = [1 Inf];
            app.BatchsizeSpinner.ValueDisplayFormat = '%.0f';
            app.BatchsizeSpinner.Tooltip = {'Specifies the number of sequences used in each training step. Smaller batches provide more updates but noisier training. Smaller batches may help the model to escape local minima in training but take longer to train. Larger batches stabilise training and may need adjustments in the learning rate.'};
            app.BatchsizeSpinner.Value = 16;
            app.ValidationintervaliterationsSpinnerLabel = uilabel(app.GridLayout);
            app.ValidationintervaliterationsSpinnerLabel.Text = 'Validation interval (iterations)';
            app.ValidationintervaliterationsSpinner = uispinner(app.GridLayout);
            app.ValidationintervaliterationsSpinner.Limits = [1 Inf];
            app.ValidationintervaliterationsSpinner.ValueDisplayFormat = '%.0f';
            app.ValidationintervaliterationsSpinner.Tooltip = {'Determines how often the model checks its performance on validation data during training. A lower interval gives more feedback but can slow training.'; ''; 'During training you will see these validation checks as an orange line overlayed on the training progress plot. You will notice a delay while the validation checks are being performed. It is crucial that the validation loss and accuracy follow closely the training loss and training accuracy - separation between the two would suggest that the validation data is not representative, that the validation dataset provided is too small, or that the model is overfitting the training data. See the manual for more information.'};
            app.ValidationintervaliterationsSpinner.Value = 500;
            app.AttentionCheckBox = uicheckbox(app.GridLayout);
            app.AttentionCheckBox.ValueChangedFcn = createCallbackFcn(app, @AttentionCheckBoxValueChanged, true);
            app.AttentionCheckBox.Tooltip = {'Enables self-attention, allowing the model to focus on important parts of the sequence. Use attention for tasks requiring complex sequences of events where patterns are not evenly spread across the tracks. Using attention is recommended for most track segmentation tasks.'};
            app.AttentionCheckBox.Text = 'Attention';
            app.AttentionCheckBox.Value = true;
            app.NumberofattentionheadsSpinnerLabel = uilabel(app.GridLayout);
            app.NumberofattentionheadsSpinnerLabel.Text = 'Number of attention heads';
            app.NumberofattentionheadsSpinner = uispinner(app.GridLayout);
            app.NumberofattentionheadsSpinner.Limits = [1 10];
            app.NumberofattentionheadsSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofattentionheadsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofattentionheadsSpinnerValueChanged, true);
            app.NumberofattentionheadsSpinner.Tooltip = {'Sets the number of attention heads. More heads allow the model to learn different relationships within the data. Start with 1-2 heads; more heads may increase computational cost without guaranteed benefits, while also limiting the context that each attention head can access.'; ''; 'Note that when using attention the number of hidden units must be an integer multiple of the number of attention heads.'};
            app.NumberofattentionheadsSpinner.Value = 1;
            app.InitiallearningrateSpinnerLabel = uilabel(app.GridLayout);
            app.InitiallearningrateSpinnerLabel.Text = 'Initial learning rate';
            app.InitiallearningrateSpinner = uispinner(app.GridLayout);
            app.InitiallearningrateSpinner.Limits = [1e-10 1];
            app.InitiallearningrateSpinner.Tooltip = {'Sets the starting speed of learning. Common values range from 0.001 to 0.01. Too high may cause erratic training; too low may lead to slow progress. Adjust if training is unstable or too slow.'; ''; 'The learning rate will automatically decrease during training, helping the model to fine-tune its learning as it progresses.'};
            app.InitiallearningrateSpinner.Value = 0.0001;
            app.LoadconfigurationfileButton = uibutton(app.GridLayout, 'push');
            app.LoadconfigurationfileButton.Text = 'Load configuration file';
            app.ValidationpatienceSpinnerLabel = uilabel(app.GridLayout);
            app.ValidationpatienceSpinnerLabel.Text = 'Validation patience';
            app.ValidationpatienceSpinner = uispinner(app.GridLayout);
            app.ValidationpatienceSpinner.Limits = [1 Inf];
            app.ValidationpatienceSpinner.ValueDisplayFormat = '%.0f';
            app.ValidationpatienceSpinner.Tooltip = {'Defines how many times validation loss can fail to improve before stopping. Use patience of 5-10 evaluations to prevent stopping too soon. Increase patience if training stops while loss is still decreasing.'};
            app.ValidationpatienceSpinner.Value = 15;
            app.PostRNNdropoutSpinnerLabel = uilabel(app.GridLayout);
            app.PostRNNdropoutSpinnerLabel.Text = 'Post-RNN dropout';
            app.PostRNNdropoutSpinner = uispinner(app.GridLayout);
            app.PostRNNdropoutSpinner.Limits = [0 1];
            app.PostRNNdropoutSpinner.ValueDisplayFormat = '%.2f';
            app.PostRNNdropoutSpinner.Tooltip = {'Applies dropout after the final recurrent layer to prevent overfitting. A value of 0.3-0.5 is typical. Use higher values if validation accuracy peaks early, indicating overfitting.'};
            app.PostRNNdropoutSpinner.Value = 0.5;
            app.InputweightsL2SpinnerLabel = uilabel(app.GridLayout);
            app.InputweightsL2SpinnerLabel.Text = 'Input weights L2';
            app.InputweightsL2Spinner = uispinner(app.GridLayout);
            app.InputweightsL2Spinner.Limits = [0 Inf];
            app.InputweightsL2Spinner.Tooltip = {'Applies regularisation to the input weights, discouraging excessively large values that may lead to overfitting. Typical values range from 0 to 0.01. Increase if validation accuracy does not match training accuracy.'};
            app.InputweightsL2Spinner.Value = 2e-05;
            app.RecurrentweightsL2SpinnerLabel = uilabel(app.GridLayout);
            app.RecurrentweightsL2SpinnerLabel.Text = 'Recurrent weights L2';
            app.RecurrentweightsL2Spinner = uispinner(app.GridLayout);
            app.RecurrentweightsL2Spinner.Limits = [0 Inf];
            app.RecurrentweightsL2Spinner.Tooltip = {'Adds regularisation to the connections within each layer, controlling complexity. A small value (0 to 0.01) is often sufficient. Adjust if the model overfits to the training data.'};
            app.RecurrentweightsL2Spinner.Value = 2e-05;
            app.BiasL2factorSpinnerLabel = uilabel(app.GridLayout);
            app.BiasL2factorSpinnerLabel.Text = 'Bias L2 factor';
            app.BiasL2factorSpinner = uispinner(app.GridLayout);
            app.BiasL2factorSpinner.Tooltip = {'Controls the regularisation of biases in the recurrent layers. Regularising biases is usually less critical than weights, and a typical setting is zero. Adjust only if biases grow excessively, indicating potential instability in learning.'};
            app.SaveconfigurationfileButton = uibutton(app.GridLayout, 'push');
            app.SaveconfigurationfileButton.Text = 'Save configuration file';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Guidance is provided for each setting by hovering over the input. For more detailed information please consult the PDF manual.';
            app.DropoutbetweenlayersSpinnerLabel = uilabel(app.GridLayout);
            app.DropoutbetweenlayersSpinnerLabel.Text = 'Dropout between layers';
            app.DropoutbetweenlayersSpinner = uispinner(app.GridLayout);
            app.DropoutbetweenlayersSpinner.Limits = [0 1];
            app.DropoutbetweenlayersSpinner.ValueDisplayFormat = '%.2f';
            app.DropoutbetweenlayersSpinner.Tooltip = {'Controls the fraction of neurons randomly deactivated between layers during training to reduce overfitting. Typical values are 0.2-0.5. Higher values provide more regularisation but can also slow down learning. Dropout between layers is typically lower than in the final dropout layer.'};
            app.DropoutbetweenlayersSpinner.Value = 0.3;
            app.LossfunctionDropDownLabel = uilabel(app.GridLayout);
            app.LossfunctionDropDownLabel.Text = 'Loss function';
            app.LossfunctionDropDown = uidropdown(app.GridLayout);
            app.LossfunctionDropDown.Items = {'Class weighted', 'Transition and class weighted'};
            app.LossfunctionDropDown.Tooltip = {'The loss function determines how the model evaluates its performance on the data used for training. Options are,'; ''; '''Class weighted'': Suitable for most tasks, this function adjusts the loss based on class frequencies, ensuring that each class has an equal influence on the classification results.'; ''; '''Transition and class weighted'': Suited to scenarios in which accurate changepoint identification is paramount. It places additional emphasis on evaluating regions near changepoints, with slightly less emphasis on other regions, allowing the model to prioritise transitions.'};
            app.LossfunctionDropDown.Value = 'Class weighted';
            app.TransitionweightingSpinnerLabel = uilabel(app.GridLayout);
            app.TransitionweightingSpinnerLabel.Text = 'Transition weighting';
            app.TransitionweightingSpinner = uispinner(app.GridLayout);
            app.TransitionweightingSpinner.Limits = [1 Inf];
            app.TransitionweightingSpinner.ValueDisplayFormat = '%.0f';
            app.TransitionweightingSpinner.Tooltip = {'Determines the additional emphasis placed on datapoints close to change points.'};
            app.TransitionweightingSpinner.Value = 3;
            app.LearningratedropperiodepochsSpinnerLabel = uilabel(app.GridLayout);
            app.LearningratedropperiodepochsSpinnerLabel.Text = 'Learning rate drop period (epochs)';
            app.LearningratedropperiodepochsSpinner = uispinner(app.GridLayout);
            app.LearningratedropperiodepochsSpinner.Limits = [1 Inf];
            app.LearningratedropperiodepochsSpinner.ValueDisplayFormat = '%.0f';
            app.LearningratedropperiodepochsSpinner.Tooltip = {'Sets the number of epochs between each reduction in the learning rate'};
            app.LearningratedropperiodepochsSpinner.Value = 10;
            app.LearningratedropfractionSpinnerLabel = uilabel(app.GridLayout);
            app.LearningratedropfractionSpinnerLabel.Text = 'Learning rate drop fraction';
            app.LearningratedropfractionSpinner = uispinner(app.GridLayout);
            app.LearningratedropfractionSpinner.Step = 0.1;
            app.LearningratedropfractionSpinner.Limits = [0 1];
            app.LearningratedropfractionSpinner.Tooltip = {'Sets the fraction by which the learning rate is multiplied at each dropdown'};
            app.LearningratedropfractionSpinner.Value = 0.3;
            % Show the figure after all components are created
            app.genRNNSettingsFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = getRNNSettings(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.genRNNSettingsFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.genRNNSettingsFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.genRNNSettingsFigure)
        end
    end
end